classdef Analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        AnalysisOptionsTab        matlab.ui.container.Tab
        Image_ELF                 matlab.ui.control.Image
        Image_Eigen               matlab.ui.control.Image
        Typepopup                 matlab.ui.control.DropDown
        text30_3                  matlab.ui.control.Label
        Image_IDA                 matlab.ui.control.Image
        Image_EQ_Sa               matlab.ui.control.Image
        Image_EQ_SF               matlab.ui.control.Image
        Image_PO                  matlab.ui.control.Image
        RunOptionsTab             matlab.ui.container.Tab
        text69_5                  matlab.ui.control.Label
        nCores                    matlab.ui.control.NumericEditField
        parallelCB                matlab.ui.control.CheckBox
        TipIcon6                  matlab.ui.control.Button
        editUncertainty           matlab.ui.control.EditField
        Sigmabutton               matlab.ui.control.Button
        uncertaintyCB             matlab.ui.control.CheckBox
        text32_5                  matlab.ui.control.Label
        text32_4                  matlab.ui.control.Label
        text32_3                  matlab.ui.control.Label
        AnimY                     matlab.ui.control.NumericEditField
        AnimX                     matlab.ui.control.NumericEditField
        AnimSF                    matlab.ui.control.NumericEditField
        ShowScopeCB               matlab.ui.control.CheckBox
        TipIcon5                  matlab.ui.control.Button
        MaxRunTime                matlab.ui.control.NumericEditField
        text32_2                  matlab.ui.control.Label
        text31_2                  matlab.ui.control.Label
        showStatusCB              matlab.ui.control.CheckBox
        AnimationCB               matlab.ui.control.CheckBox
        PushoverELFParametersTab  matlab.ui.container.Tab
        editELF                   matlab.ui.control.EditField
        ELFbutton                 matlab.ui.control.Button
        text28_2                  matlab.ui.control.Label
        POtargetdrift             matlab.ui.control.NumericEditField
        POmode                    matlab.ui.control.NumericEditField
        text32                    matlab.ui.control.Label
        text31                    matlab.ui.control.Label
        text30                    matlab.ui.control.Label
        DynamicParametersTab      matlab.ui.container.Tab
        text6_9                   matlab.ui.control.Label
        text39_7                  matlab.ui.control.Label
        coeff_T_upper             matlab.ui.control.NumericEditField
        coeff_T_lower             matlab.ui.control.NumericEditField
        text39_6                  matlab.ui.control.Label
        zetaSA                    matlab.ui.control.NumericEditField
        text39_5                  matlab.ui.control.Label
        text39_4                  matlab.ui.control.Label
        text6_7                   matlab.ui.control.Label
        text6_6                   matlab.ui.control.Label
        text6_5                   matlab.ui.control.Label
        text6_4                   matlab.ui.control.Label
        text6_3                   matlab.ui.control.Label
        text6_2                   matlab.ui.control.Label
        TipIcon4                  matlab.ui.control.Button
        IDAslopeLimit             matlab.ui.control.NumericEditField
        text27_3                  matlab.ui.control.Label
        CollapseSDR               matlab.ui.control.NumericEditField
        text27_2                  matlab.ui.control.Label
        AdapTstepCB               matlab.ui.control.CheckBox
        TargetSA                  matlab.ui.control.NumericEditField
        SF                        matlab.ui.control.NumericEditField
        SAstepmin                 matlab.ui.control.NumericEditField
        T1                        matlab.ui.control.NumericEditField
        SA_Step                   matlab.ui.control.NumericEditField
        text38                    matlab.ui.control.Label
        SApopup                   matlab.ui.control.DropDown
        text27                    matlab.ui.control.Label
        text20                    matlab.ui.control.Label
        text6                     matlab.ui.control.Label
        text16                    matlab.ui.control.Label
        text9                     matlab.ui.control.Label
        DampingTab                matlab.ui.container.Tab
        zeta                      matlab.ui.control.NumericEditField
        text39_2                  matlab.ui.control.Label
        DampModeJ                 matlab.ui.control.NumericEditField
        DampModeI                 matlab.ui.control.NumericEditField
        text39                    matlab.ui.control.Label
        text35                    matlab.ui.control.Label
        text34                    matlab.ui.control.Label
        GroundMotionsTab          matlab.ui.container.Tab
        inspectGMButton           matlab.ui.control.Button
        text41_3                  matlab.ui.control.Label
        text6_8                   matlab.ui.control.Label
        GMFolderPath              matlab.ui.control.EditField
        dtstep                    matlab.ui.control.NumericEditField
        text41_2                  matlab.ui.control.Label
        GMbutton                  matlab.ui.control.Button
        TFreeVibration            matlab.ui.control.NumericEditField
        text41                    matlab.ui.control.Label
        GM_Last                   matlab.ui.control.NumericEditField
        GM_Start                  matlab.ui.control.NumericEditField
        text8                     matlab.ui.control.Label
        text7                     matlab.ui.control.Label
        text28                    matlab.ui.control.Label
        RecordersTab              matlab.ui.container.Tab
        checkbox36                matlab.ui.control.CheckBox
        checkbox35                matlab.ui.control.CheckBox
        checkbox34                matlab.ui.control.CheckBox
        checkbox33                matlab.ui.control.CheckBox
        checkbox32                matlab.ui.control.CheckBox
        checkbox31                matlab.ui.control.CheckBox
        checkbox29                matlab.ui.control.CheckBox
        checkbox30                matlab.ui.control.CheckBox
        checkbox26                matlab.ui.control.CheckBox
        checkbox28                matlab.ui.control.CheckBox
        checkbox27                matlab.ui.control.CheckBox
        checkbox25                matlab.ui.control.CheckBox
        checkbox24                matlab.ui.control.CheckBox
        checkbox23                matlab.ui.control.CheckBox
        checkbox22                matlab.ui.control.CheckBox
        checkbox21                matlab.ui.control.CheckBox
        checkbox20                matlab.ui.control.CheckBox
        checkbox19                matlab.ui.control.CheckBox
        TsunamiPushoverTab        matlab.ui.container.Tab
        text69_4                  matlab.ui.control.Label
        Drag_Coeff                matlab.ui.control.NumericEditField
        text31_6                  matlab.ui.control.Label
        text38_3                  matlab.ui.control.Label
        SApopup_3                 matlab.ui.control.DropDown
        text70                    matlab.ui.control.Label
        ro_water                  matlab.ui.control.NumericEditField
        text31_5                  matlab.ui.control.Label
        text38_2                  matlab.ui.control.Label
        CDPOpattern               matlab.ui.control.DropDown
        FroudeNumber              matlab.ui.control.NumericEditField
        text31_4                  matlab.ui.control.Label
        text69                    matlab.ui.control.Label
        dinundation               matlab.ui.control.NumericEditField
        text31_3                  matlab.ui.control.Label
        TsunamiTimeHistoryTab     matlab.ui.container.Tab
        text69_3                  matlab.ui.control.Label
        editTsunami               matlab.ui.control.EditField
        Tsunamibutton             matlab.ui.control.Button
        text28_3                  matlab.ui.control.Label
        SubmitButton              matlab.ui.control.Button
    end


    properties (Access = private)
        MainApp;
        TipWindow;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Analysis_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image.Visible='on';

            clc;

            global  ProjectName ProjectPath resource_root
            load(strcat(ProjectPath,ProjectName),'ANALYSIS','LAYOUT','PROJECT','MODEL','STATUS','RECORDERS');


            cluster = parcluster('local');
            ANALYSIS.maxWorkers = cluster.NumWorkers;
            app.nCores.Limits=[1 ANALYSIS.maxWorkers];


            app.Image_Eigen.ImageSource = strjoin(resource_root+"Eigen.png");
            app.Image_PO.ImageSource    = strjoin(resource_root+"PO.png");
            app.Image_IDA.ImageSource   = strjoin(resource_root+"IDA.png");
            app.Image_EQ_SF.ImageSource = strjoin(resource_root+"EQ_SF.png");
            app.Image_EQ_Sa.ImageSource = strjoin(resource_root+"EQ_Sa.png");
            app.Image_ELF.ImageSource   = strjoin(resource_root+"ELF.png");

            if PROJECT.Units == 2
                app.text69.Text='in';
                app.text70.Text='lbs/in3';
            end

            if LAYOUT.NStory>2
                app.POmode.Limits    = [1 LAYOUT.NStory];
                app.DampModeI.Limits = [1 LAYOUT.NStory-1];
                app.DampModeJ.Limits = [app.DampModeI.Value-1 LAYOUT.NStory];
            elseif LAYOUT.NStory==2
                app.POmode.Limits    = [1 2];
                app.DampModeI.Limits = [1 2];
                app.DampModeJ.Limits = [1 2];
            else
                app.DampModeI.Enable = 'off';
                app.DampModeJ.Enable = 'off';
            end

            if MODEL.PM_Option==1
                app.checkbox22.Value  = 1;
                app.checkbox22.Enable = 'off';
            end

            if STATUS.Analysis~=1
                app.DampModeJ.Value = LAYOUT.NStory;
                app.DampModeI.Value = 1;
                app.DampModeJ.Value = min(3,LAYOUT.NStory);
                if PROJECT.FrameType==1
                    app.checkbox29.Enable = 'off';
                    app.checkbox31.Enable = 'off';
                else
                    app.checkbox31.Value  = 1;
                    app.checkbox31.Enable = 'on';
                end
            else
                app.Typepopup.Value     = num2str(ANALYSIS.Type);
                app.AnimationCB.Value   = ANALYSIS.Animation;
                app.AnimSF.Value        = ANALYSIS.AnimSF;
                app.AnimX.Value         = ANALYSIS.AnimX;
                app.AnimY.Value         = ANALYSIS.AnimY;
                app.showStatusCB.Value  = ANALYSIS.ShowOpenseesStatus;
                app.ShowScopeCB.Value   = ANALYSIS.ShowScope;
                app.MaxRunTime.Value    = ANALYSIS.MaxRunTime;
                app.uncertaintyCB.Value = ANALYSIS.Uncertainty;

                app.POmode.Value        = ANALYSIS.ModePO;
                app.POtargetdrift.Value = ANALYSIS.DriftPO*100;

                app.SApopup.Value       = num2str(ANALYSIS.SA_metric);
                app.SA_Step.Value       = ANALYSIS.SA_Step;
                app.SAstepmin.Value     = ANALYSIS.SAstepmin;
                app.T1.Value            = ANALYSIS.T1;
                app.SF.Value            = ANALYSIS.SF;
                app.TargetSA.Value      = ANALYSIS.TargetSA;
                app.CollapseSDR.Value   = ANALYSIS.CollapseSDR;
                app.IDAslopeLimit.Value = ANALYSIS.IDAslopeLimit;
                app.AdapTstepCB.Value   = ANALYSIS.AdaptiveTimeStep;

                app.DampModeI.Value     = ANALYSIS.DampModeI;
                app.DampModeJ.Value     = ANALYSIS.DampModeJ;
                app.zeta.Value          = ANALYSIS.zeta*100;


                if ANALYSIS.Parallel==1
                    app.parallelCB.Value = ANALYSIS.Parallel;
                    app.nCores.Value     = ANALYSIS.nCores;
                else
                    app.nCores.Value=ANALYSIS.maxWorkers;
                end

                if ANALYSIS.Uncertainty ==1
                    app.editUncertainty.FontColor = [0.0 1.0 0.0];
                    app.editUncertainty.Value     = 'Standard deviations are defined!';
                end


                if isstruct(ANALYSIS.GM)
                    app.inspectGMButton.Enable = 'on';
                end

                if ANALYSIS.EQ==1
                    app.GMFolderPath.Value=PROJECT.GMFolderPath;
                end

                app.GM_Start.Value      = ANALYSIS.GM_Start;
                app.GM_Last.Value       = ANALYSIS.GM_Last;
                app.TFreeVibration.Value= ANALYSIS.TFreeVibration;
                app.dtstep.Value        = ANALYSIS.dtstep;

                %app.checkbox19.Value = RECORDERS.Time;
                app.checkbox20.Value = RECORDERS.Disp;
                %app.checkbox21.Value = RECORDERS.SDR;
                app.checkbox22.Value = RECORDERS.Column;
                app.checkbox23.Value = RECORDERS.ColSpring;
                app.checkbox24.Value = RECORDERS.Beam;
                app.checkbox25.Value = RECORDERS.BeamSpring;
                app.checkbox26.Value = RECORDERS.PZ;
                %app.checkbox27.Value = RECORDERS.RFA;
                app.checkbox28.Value = RECORDERS.FloorLink;
                app.checkbox29.Value = RECORDERS.Brace;
                app.checkbox31.Value = RECORDERS.CGP;
                app.checkbox32.Value = RECORDERS.Support;
                app.checkbox33.Value = RECORDERS.RFV;
                app.checkbox34.Value = RECORDERS.EigenModes;
                app.checkbox35.Value = RECORDERS.EigenVectors;
                app.checkbox36.Value = RECORDERS.EGFconnection;
                app.checkbox30.Value = RECORDERS.Delete_Flag;


            end

            Typepopup_Callback(app);
            AnimationCBValueChanged(app);
            uncertaintyCBValueChanged(app);
            parallelCBValueChanged(app);

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global  ProjectName ProjectPath
            load(strcat(ProjectPath,ProjectName));
            clc

            ANALYSIS.Type               = str2double(app.Typepopup.Value);
            ANALYSIS.Animation          = app.AnimationCB.Value;
            ANALYSIS.AnimSF             = app.AnimSF.Value;
            ANALYSIS.AnimX              = app.AnimX.Value;
            ANALYSIS.AnimY              = app.AnimY.Value;
            ANALYSIS.ShowOpenseesStatus = app.showStatusCB.Value;
            ANALYSIS.MaxRunTime         = app.MaxRunTime.Value;
            ANALYSIS.Uncertainty        = app.uncertaintyCB.Value;
            ANALYSIS.Parallel           = app.parallelCB.Value;
            ANALYSIS.nCores             = app.nCores.Value;

            if ANALYSIS.Uncertainty==0; ANALYSIS.nRealizations=1; end

            switch ANALYSIS.Type
                case 1
                    EV=1; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=0; CDPO=0; TTH=0;
                case 2
                    EV=0; PO=1; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=0; CDPO=0; TTH=0;
                case 3
                    EV=0; PO=0; IDA=1; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=1; ELF=0; CDPO=0; TTH=0;
                case 4
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=1; Dynamic_TargetSA=0; EQ=1; ELF=0; CDPO=0; TTH=0;
                case 5
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=1; EQ=1; ELF=0; CDPO=0; TTH=0;
                case 6
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=1; CDPO=0; TTH=0;
                case 7
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=0; CDPO=1; TTH=0;
                case 8
                    EV=0; PO=0; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=0; EQ=0; ELF=0; CDPO=0; TTH=1;
            end

            ANALYSIS.EV                  = EV;
            ANALYSIS.PO                  = PO;
            ANALYSIS.IDA                 = IDA;
            ANALYSIS.ELF                 = ELF;
            ANALYSIS.Dynamic_TargetSF    = Dynamic_TargetSF;
            ANALYSIS.Dynamic_TargetSA    = Dynamic_TargetSA;
            ANALYSIS.EQ                  = EQ;
            ANALYSIS.CDPO                = CDPO;
            ANALYSIS.TTH                 = TTH;

            if ANALYSIS.EQ==1 && isfield(PROJECT,'GMFolderPath')==0 %|| exist(GMFolderPath,'dir')==0)
                errordlg('Please define the ground motion data first!');
                return
            end

            if ANALYSIS.Uncertainty==1 && STATUS.Sigma==0
                errordlg('Modeling uncertainty is seleceted but standard devaiations were not defined!');
                return
            end

            RECORDERS.Time          = app.checkbox19.Value;
            RECORDERS.Disp          = app.checkbox20.Value;
            RECORDERS.SDR           = app.checkbox21.Value;
            RECORDERS.Column        = app.checkbox22.Value;
            RECORDERS.ColSpring     = app.checkbox23.Value;
            RECORDERS.Beam          = app.checkbox24.Value;
            RECORDERS.BeamSpring    = app.checkbox25.Value;
            RECORDERS.PZ            = app.checkbox26.Value;
            RECORDERS.RFA           = app.checkbox27.Value;
            RECORDERS.FloorLink     = app.checkbox28.Value;
            RECORDERS.Brace         = app.checkbox29.Value;
            RECORDERS.CGP           = app.checkbox31.Value;
            RECORDERS.Support       = app.checkbox32.Value;
            RECORDERS.RFV           = app.checkbox33.Value;
            RECORDERS.EigenModes    = app.checkbox34.Value;
            RECORDERS.EigenVectors  = app.checkbox35.Value;
            RECORDERS.EGFconnection = app.checkbox36.Value;
            RECORDERS.Delete_Flag   = app.checkbox30.Value;

            ANALYSIS.ModePO  = app.POmode.Value;
            ANALYSIS.DriftPO = app.POtargetdrift.Value/100;

            ANALYSIS.SA_metric       = str2double(app.SApopup.Value);
            ANALYSIS.zetaSA          = app.zetaSA.Value/100;
            ANALYSIS.T1              = app.T1.Value;
            ANALYSIS.coeff_T_lower   = app.coeff_T_lower.Value;
            ANALYSIS.coeff_T_upper   = app.coeff_T_upper.Value;
            ANALYSIS.SA_Step         = app.SA_Step.Value;
            ANALYSIS.SAstepmin       = app.SAstepmin.Value;
            ANALYSIS.SF              = app.SF.Value;
            ANALYSIS.TargetSA        = app.TargetSA.Value;
            ANALYSIS.CollapseSDR     = app.CollapseSDR.Value;
            ANALYSIS.IDAslopeLimit   = app.IDAslopeLimit.Value;
            ANALYSIS.AdaptiveTimeStep= app.AdapTstepCB.Value;
            ANALYSIS.ShowScope       = app.ShowScopeCB.Value;

            ANALYSIS.DampModeI       = app.DampModeI.Value;
            ANALYSIS.DampModeJ       = app.DampModeJ.Value;
            ANALYSIS.zeta            = app.zeta.Value/100;

            ANALYSIS.GM_Start        = app.GM_Start.Value;
            ANALYSIS.GM_Last         = app.GM_Last.Value;
            ANALYSIS.TFreeVibration  = app.TFreeVibration.Value;
            ANALYSIS.dtstep          = app.dtstep.Value;

            ANALYSIS.dinundation     = app.dinundation.Value;
            ANALYSIS.Drag_Coeff      = app.ro_water.Value;
            ANALYSIS.ro_water        = app.ro_water.Value;
            ANALYSIS.CDPOpattern     = app.CDPOpattern.Value;


            if (ANALYSIS.IDA==1 || ANALYSIS.Dynamic_TargetSA ==1 || ANALYSIS.Dynamic_TargetSF==1) && isinf(ANALYSIS.T1)==1
                errordlg('Period T1 value (used for GM scaling) is not set properly!');
                return
            end

            if ANALYSIS.IDA==1 || ANALYSIS.Dynamic_TargetSA ==1 || ANALYSIS.Dynamic_TargetSF==1
                for GM_No = ANALYSIS.GM_Start : ANALYSIS.GM_Last
                    %% GET IM FOR SCALING
                    [Sa] = get_IM (ANALYSIS.T1, ANALYSIS.GM.acc{:,GM_No}, ANALYSIS.GM.dt{:,GM_No}, ANALYSIS.g, ANALYSIS.zetaSA, ANALYSIS.SA_metric, ANALYSIS.coeff_T_lower, ANALYSIS.coeff_T_upper);
                    ANALYSIS.GM.sa{:,GM_No}=Sa;
                end
                clear GM_No;
            else
                ANALYSIS.GM=NaN;
            end

            %%
            STATUS.Analysis = 1;

            save(strcat(ProjectPath,ProjectName), 'ANALYSIS', 'STATUS', 'RECORDERS', '-append')

            app.MainApp.status2.BackgroundColor = 'g';
            app.MainApp.status2.FontColor       = 'k';
            if     IDA==1;              app.MainApp.status2.Text=['Dynamic: IDA'];
            elseif Dynamic_TargetSA==1; app.MainApp.status2.Text=['Dynamic: Target Sa'];
            elseif Dynamic_TargetSF==1; app.MainApp.status2.Text=['Dynamic: Target SF'];
            elseif PO==1;               app.MainApp.status2.Text=['Pushover Analysis'];
            elseif EV==1;               app.MainApp.status2.Text=['Eigenvalue Analysis'];
            elseif ELF==1;              app.MainApp.status2.Text=['ELF Analysis'];
            elseif CDPO==1;             app.MainApp.status2.Text=['CDPO Analysis'];
            elseif TTH==1;              app.MainApp.status2.Text=['TTH Analysis'];           end

            app.MainApp.status2b.BackgroundColor = 'g';
            app.MainApp.RunModelButton.Enable    = 'on';

            app.MainApp.ProgressText.FontColor='g';
            app.MainApp.ProgressText.Text=('Analysis data defined'); drawnow;

            figure1CloseRequest(app);
        end

        % Value changed function: Typepopup
        function Typepopup_Callback(app, event)

            Type =str2double(app.Typepopup.Value);
            switch Type
                case 1
                    app.Image_Eigen.Enable='on';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='off';
                    app.editUncertainty.Enable='off';
                    app.uncertaintyCB.Enable = 'off';
                    app.SApopup.Enable='off';
                    app.T1.Enable='off';
                    app.GM_Start.Enable='off';
                    app.GM_Last.Enable='off';
                    app.SA_Step.Enable='off';
                    app.SAstepmin.Enable='off';
                    app.SF.Enable='off';
                    app.TargetSA.Enable='off';
                    app.CollapseSDR.Enable='off';
                    app.POmode.Enable='off';
                    app.POtargetdrift.Enable='off';
                    app.DampModeI.Enable='off';
                    app.DampModeJ.Enable='off';
                    app.zeta.Enable='off';
                    app.zetaSA.Enable='off';
                    app.IDAslopeLimit.Enable='off';
                    app.TFreeVibration.Enable='off';
                    app.dtstep.Enable='off';
                    app.GMbutton.Enable='off';
                    app.AdapTstepCB.Enable='off';
                    app.ShowScopeCB.Enable='off';
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=0;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'off';
                    app.checkbox20.Value = 0;
                    app.parallelCB.Enable = 'off';
                    app.parallelCB.Value = 0;
                case 2
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='on';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='off';
                    app.editUncertainty.Enable='off';
                    app.uncertaintyCB.Enable = 'off';
                    app.SApopup.Enable='off';
                    app.T1.Enable='off';
                    app.GM_Start.Enable='off';
                    app.GM_Last.Enable='off';
                    app.SA_Step.Enable='off';
                    app.SAstepmin.Enable='off';
                    app.SF.Enable='off';   app.text20.Enable='off';
                    app.TargetSA.Enable='off';   app.text27.Enable='off'; app.text6_3.Enable='off';
                    app.CollapseSDR.Enable='off';
                    app.POmode.Enable='on';
                    app.POtargetdrift.Enable='on';
                    app.DampModeI.Enable='off';
                    app.DampModeJ.Enable='off';
                    app.zeta.Enable='off';
                    app.zetaSA.Enable='off';
                    app.IDAslopeLimit.Enable='off';
                    app.TFreeVibration.Enable='off';
                    app.dtstep.Enable='off';
                    app.GMbutton.Enable='off';
                    app.AdapTstepCB.Enable='off';
                    app.ShowScopeCB.Enable='off';
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=0;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'off';
                    app.checkbox20.Value = 1;
                    app.parallelCB.Enable = 'off';
                    app.parallelCB.Value = 0;
                case 3
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='on';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='off';
                    app.editUncertainty.Enable='off';
                    app.uncertaintyCB.Enable = 'off';
                    app.SApopup.Enable='on';
                    app.T1.Enable='on';
                    app.GM_Start.Enable='on';
                    app.GM_Last.Enable='on';
                    app.SA_Step.Enable='on';
                    app.SAstepmin.Enable='on';
                    app.SF.Enable='off';   app.text20.Enable='off';
                    app.TargetSA.Enable='off';   app.text27.Enable='off'; app.text6_3.Enable='off';
                    app.CollapseSDR.Enable='on';
                    app.POmode.Enable='off';
                    app.POtargetdrift.Enable='off';
                    app.DampModeI.Enable='on';
                    app.DampModeJ.Enable='on';
                    app.zeta.Enable='on';
                    app.zetaSA.Enable='on';
                    app.IDAslopeLimit.Enable='on';
                    app.TFreeVibration.Enable='on';
                    app.dtstep.Enable='on';
                    app.GMbutton.Enable='on';
                    app.AdapTstepCB.Enable='on';
                    app.ShowScopeCB.Enable='on';
                    app.checkbox30.Enable='on';
                    app.checkbox30.Value=1;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=1;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'on';
                    app.TFreeVibration.Value=0;
                    app.parallelCB.Enable = 'off';
                    app.parallelCB.Value = 0;

                    app.uncertaintyCB.Value=0;
                    uncertaintyCBValueChanged(app);
                case 4
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='on';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='on';
                    app.editUncertainty.Enable='on';
                    app.uncertaintyCB.Enable = 'on';
                    app.SApopup.Enable='on';
                    app.T1.Enable='on';
                    app.GM_Start.Enable='on';
                    app.GM_Last.Enable='on';
                    app.SA_Step.Enable='off';
                    app.SAstepmin.Enable='off';
                    app.SF.Enable='on';  app.text20.Enable='on';
                    app.TargetSA.Enable='off'; app.text27.Enable='off';  app.text6_3.Enable='off';
                    app.CollapseSDR.Enable='on';  
                    app.POmode.Enable='off';
                    app.POtargetdrift.Enable='off';
                    app.DampModeI.Enable='on';
                    app.DampModeJ.Enable='on';
                    app.zeta.Enable='on';
                    app.IDAslopeLimit.Enable='off';
                    app.TFreeVibration.Enable='on';
                    app.dtstep.Enable='on';
                    app.GMbutton.Enable='on';
                    app.AdapTstepCB.Enable='off';
                    app.ShowScopeCB.Enable='on';
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=1;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'on';
                    app.TFreeVibration.Value=10;
                    app.parallelCB.Enable = 'on';
                case 5
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='on';
                    app.Image_ELF.Enable='off';
                    app.Sigmabutton.Enable='on';
                    app.editUncertainty.Enable='on';
                    app.uncertaintyCB.Enable = 'on';
                    app.SApopup.Enable='on';
                    app.T1.Enable='on';
                    app.GM_Start.Enable='on';
                    app.GM_Last.Enable='on';
                    app.SA_Step.Enable='off';
                    app.SAstepmin.Enable='off';
                    app.SF.Enable='off'; app.text20.Enable='off';
                    app.TargetSA.Enable='on';  app.text27.Enable='on'; app.text6_3.Enable='on';
                    app.CollapseSDR.Enable='on';
                    app.POmode.Enable='off';
                    app.POtargetdrift.Enable='off';
                    app.DampModeI.Enable='on';
                    app.DampModeJ.Enable='on';
                    app.zeta.Enable='on';
                    app.IDAslopeLimit.Enable='off';
                    app.TFreeVibration.Enable='on';
                    app.dtstep.Enable='on';
                    app.GMbutton.Enable='on';
                    app.AdapTstepCB.Enable='off';
                    app.ShowScopeCB.Enable='on';
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=1;
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.editELF.Enable='off';
                    app.ELFbutton.Enable='off';
                    app.checkbox20.Enable = 'on';
                    app.TFreeVibration.Value=10;
                    app.parallelCB.Enable = 'on';
                case 6
                    app.Image_Eigen.Enable='off';
                    app.Image_PO.Enable='off';
                    app.Image_IDA.Enable='off';
                    app.Image_EQ_SF.Enable='off';
                    app.Image_EQ_Sa.Enable='off';
                    app.Image_ELF.Enable='on';
                    app.Sigmabutton.Enable='off';
                    app.editUncertainty.Enable='off';
                    app.uncertaintyCB.Enable = 'off';
                    app.SApopup.Enable='off';
                    app.T1.Enable='off';
                    app.GM_Start.Enable='off';
                    app.GM_Last.Enable='off';
                    app.SA_Step.Enable='off';
                    app.SAstepmin.Enable='off';
                    app.SF.Enable='off';   app.text20.Enable='off';
                    app.TargetSA.Enable='off';   app.text27.Enable='off'; app.text6_3.Enable='off';
                    app.CollapseSDR.Enable='off';
                    app.POmode.Enable='off';
                    app.POtargetdrift.Enable='off';
                    app.DampModeI.Enable='off';
                    app.DampModeJ.Enable='off';
                    app.zeta.Enable='off';
                    app.IDAslopeLimit.Enable='off';
                    app.TFreeVibration.Enable='off';
                    app.dtstep.Enable='off';
                    app.GMbutton.Enable='off';
                    app.AdapTstepCB.Enable='off';
                    app.ShowScopeCB.Enable='off';
                    app.checkbox30.Enable='off';
                    app.checkbox30.Value=0;
                    app.checkbox27.Enable='off';
                    app.checkbox27.Value=0;
                    app.editELF.Enable='on';
                    app.ELFbutton.Enable='on';
                    app.checkbox20.Enable = 'off';
                    app.checkbox20.Value = 1;
                    app.parallelCB.Enable = 'off';
                    app.parallelCB.Value = 0;
                case 7
                    app.Typepopup.Value = "1";
                    Typepopup_Callback(app);
                case 8
                    app.Typepopup.Value = "1";
                    Typepopup_Callback(app);
            end
            parallelCBValueChanged(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible='off';
            delete(app)
        end

        % Button pushed function: GMbutton
        function GMbuttonButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath

            try
                load (strcat(ProjectPath,ProjectName),'PROJECT','ANALYSIS');
                GMFolderPath= uigetdir;

                app.GMFolderPath.FontColor = [1.0 1.0 0.0];
                app.GMFolderPath.Value     = 'Please Wait...'; drawnow;

                [GMFolderPath, GMFolderName, GM, nGM]=import_GM_Data(GMFolderPath);
                app.GMFolderPath.FontColor = [0.0 1.0 0.0];
                app.GMFolderPath.Value     = GMFolderPath;
                if nGM>1
                    app.GM_Start.Limits     = [1 nGM];
                    app.GM_Last.Limits     = [1 nGM];
                end
                app.GM_Last.Value      = nGM;

                PROJECT.GMFolderPath=GMFolderPath;
                PROJECT.GMFolderName=GMFolderName;
                ANALYSIS.GM=GM;
                ANALYSIS.nGM=nGM;
                save (strcat(ProjectPath,ProjectName),'PROJECT','ANALYSIS','-append');

                app.inspectGMButton.Enable='on';

            catch ME
                cd (MainDirectory)
                app.GMFolderPath.FontColor = [1.0 0.0 0.0];
                app.GMFolderPath.Value     = 'Error importing GM data!'; drawnow;
            end


        end

        % Button pushed function: TipIcon5
        function TipIcon5ButtonPushed(app, event)
            str{1}='This time will be used to terminate the analysis.';
            str{2}='This is generally improtant in cases were a convergence state cannot be reached.';
            str{3}='For dynamic analysis, this time represents the maximum allowed analysis time for a single GM record.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: TipIcon4
        function TipIcon4ButtonPushed(app, event)
            str{1}='This is an additional criteria that will be used to track the collapse point.';
            str{2}='Collapse is flagged if the slope of the IDA curve (SDR vs Intensity) exceeds the specified value.';
            str{3}='A slope value of 0.4 is recommended. This corrosponds to an increase of 0.04rad per 0.1g';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Value changed function: AdapTstepCB
        function AdapTstepCBValueChanged(app, event)
            if app.AdapTstepCB.Value ==1
                app.text9.Text='Initial Increment';
            else
                app.text9.Text='Constant Increment';
            end

        end

        % Button pushed function: ELFbutton
        function ELFbuttonButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath

            load(strcat(ProjectPath,ProjectName), 'LAYOUT', 'ANALYSIS')

            v2struct(LAYOUT);
            v2struct(ANALYSIS);

            [ELFFileName,ELFFilePath] = uigetfile('*.txt','Select the .txt file containing lateral force profile');

            try
                app.editELF.FontColor = [0.0 0.0 0.0];
                app.editELF.Value     = ('Please wait ...'); drawnow;

                % Read ELF Force Data
                cd(ELFFilePath);

                ELF_Profile=importdata(ELFFileName); % Read the file data

                % If the file "GM Data.txt" exits
                if size(ELF_Profile,1)~=NStory
                    d=errordlg(['The number of data rows in the file shall be equal to',num2str(NStory), '!'],'Error','modal');
                    waitfor(d);
                    return
                end

                ANALYSIS.ELFFilePath=ELFFilePath;
                ANALYSIS.ELFFileName=ELFFileName;
                ANALYSIS.ELF_Profile=ELF_Profile;
                save(strcat(ProjectPath,ProjectName),'ANALYSIS','-append');

                cd (MainDirectory);
                app.editELF.FontColor = [0.47 0.67 0.19];
                app.editELF.Value     = 'Import successful!'; drawnow;
            catch ME
                cd (MainDirectory);
                write_Error(ME);
                app.editELF.FontColor = [1 0. 0.];
                app.editELF.Value     = 'Error importing file. See Error file!'; drawnow;
            end
        end

        % Value changed function: AnimationCB
        function AnimationCBValueChanged(app, event)
            if app.AnimationCB.Value==1
                app.AnimSF.Enable='on';
                app.AnimX.Enable='on';
                app.AnimY.Enable='on';
            else
                app.AnimSF.Enable='off';
                app.AnimX.Enable='off';
                app.AnimY.Enable='off';
            end
        end

        % Button pushed function: TipIcon6
        function TipIcon6ButtonPushed(app, event)
            str{1}='Include the uncertainty associated with the numerical modeling parameters.';
            str{2}='This can be used as part of a Monte Carlo simulation scheme involving multiple numerical realization for each ground motion record.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: Sigmabutton
        function SigmabuttonButtonPushed(app, event)
            global  ProjectName ProjectPath

            app.editUncertainty.FontColor = [1.0 1.0 0.0];
            app.editUncertainty.Value = 'waiting for user input...';
            waitfor(Uncertainty_App(app));

            load(strcat(ProjectPath,ProjectName),'STATUS')

            if STATUS.Sigma==0
                app.editUncertainty.FontColor = [1.0 0.0 0.0];
                app.editUncertainty.Value     = 'Standard deviations are not defined!';
            else
                app.editUncertainty.FontColor = [0.0 1.0 0.0];
                app.editUncertainty.Value     = 'Standard deviations are defined!';
            end

        end

        % Value changed function: uncertaintyCB
        function uncertaintyCBValueChanged(app, event)
            if app.uncertaintyCB.Value==0
                app.Sigmabutton.Enable      = 'off';
                app.editUncertainty.Enable  = 'off';
                app.parallelCB.Enable       = 'on';
            else
                app.Sigmabutton.Enable      = 'on';
                app.editUncertainty.Enable  = 'on';
                app.parallelCB.Enable       = 'off';
                app.parallelCB.Value        = 0;
            end

          Type =str2double(app.Typepopup.Value);

            if Type ==4 || Type ==5
                app.parallelCB.Value    = 0;
                app.parallelCB.Enable   = 'on';                
            else
                app.parallelCB.Value    = 0;
                app.parallelCB.Enable   = 'off';
            end
            
            parallelCBValueChanged(app);
        end

        % Button pushed function: Tsunamibutton
        function TsunamibuttonButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath

            load(strcat(ProjectPath,ProjectName))

            v2struct(PROJECT);
            v2struct(PROPERTY);
            v2struct(DRAW);
            v2struct(LAYOUT);
            v2struct(ANALYSIS);

            [TTHFileName,TTHFilePath] = uigetfile('*.txt','Select the .txt file containing tsunami time history');

            try
                app.editTsunami.FontColor = [0.0 0.0 0.0];
                app.editTsunami.Value     = ('Please wait ...'); drawnow;

                % Read ELF Force Data
                cd(TTHFilePath);

                TTH_Data=importdata(TTHFileName); % Read the file data

                % If the file "GM Data.txt" exits
                if size(TTH_Data,2)~=3
                    d=errordlg(['The number of data columns in the file shall be equal to 3!'],'Error','modal');
                    waitfor(d);
                    return
                end

                Drag_Coeff = app.ro_water.Value;
                ro_water   = app.ro_water.Value;

                [TTHdt, TTHpoints, F, Hinundation] = get_Tsunami_Force(TTH_Data, TD_MF, ro_water, Drag_Coeff, Units);

                [Floor_F]=get_Tsunami_ForceTH_Breakdown(NStory,HStory, Elevation, F, Hinundation,Units);

                Floor_F(isnan(Floor_F))=0;

                ANALYSIS.TTHFileName=TTHFileName;
                ANALYSIS.THFilePath=THFilePath;
                ANALYSIS.TTH_Data=TTH_Data;
                ANALYSIS.TTHdt=TTHdt;
                ANALYSIS.Floor_F=Floor_F;
                ANALYSIS.TTHpoints=TTHpoints;
                save(strcat(ProjectPath,ProjectName),'ANALYSIS','-append');

                app.editTsunami.FontColor = [0.47 0.67 0.19];
                app.editTsunami.Value     = 'Import successful!'; drawnow;


            catch ME
                cd (MainDirectory);
                write_Error(ME);
                app.editTsunami.FontColor = [1 0. 0.];
                app.editTsunami.Value     = 'Error importing file. See Error file!'; drawnow;
            end
        end

        % Value changed function: SApopup
        function SApopupValueChanged(app, event)
            if app.SApopup.Value ==1
                app.coeff_T_lower.Enable='off';
                app.coeff_T_upper.Enable='off';
            else
                app.coeff_T_lower.Enable='on';
                app.coeff_T_upper.Enable='on';
            end

        end

        % Button pushed function: inspectGMButton
        function inspectGMButtonPushed(app, event)
            Visualize_GM_App();
        end

        % Value changed function: parallelCB
        function parallelCBValueChanged(app, event)
            value = app.parallelCB.Value;
            Type  = str2double(app.Typepopup.Value);

            if value ==1
                app.nCores.Enable = 'on';
                cluster = parcluster('local');
                maxWorkers = cluster.NumWorkers;
                app.nCores.Limits=[2 maxWorkers];
                app.nCores.Value=maxWorkers;
            else
                app.nCores.Enable = 'off';
                cluster = parcluster('local');
                maxWorkers = cluster.NumWorkers;
                app.nCores.Limits=[0 maxWorkers];
                app.nCores.Value=1;
            end

            if Type==1 || Type==2
                app.ShowScopeCB.Value   = 0;
                app.ShowScopeCB.Enable  = 'off';
            elseif  value ==1 && (Type==3 ||Type==4 || Type==5)
                app.ShowScopeCB.Enable  = 'off';

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Analysis Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'Analyzebutton';
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.AnalysisOptionsTab = uitab(app.TabGroup);
            app.AnalysisOptionsTab.Title = 'Analysis Options';
            app.Image_PO = uiimage(app.AnalysisOptionsTab);
            app.Image_PO.ImageSource = fullfile(pathToMLAPP, 'PO.png');
            app.Image_EQ_SF = uiimage(app.AnalysisOptionsTab);
            app.Image_EQ_SF.ImageSource = fullfile(pathToMLAPP, 'EQ_SF.png');
            app.Image_EQ_Sa = uiimage(app.AnalysisOptionsTab);
            app.Image_EQ_Sa.ImageSource = fullfile(pathToMLAPP, 'EQ_Sa.png');
            app.Image_IDA = uiimage(app.AnalysisOptionsTab);
            app.Image_IDA.ImageSource = fullfile(pathToMLAPP, 'IDA.png');
            app.text30_3 = uilabel(app.AnalysisOptionsTab);
            app.text30_3.Tag = 'text30';
            app.text30_3.Text = 'Analysis Type';
            app.Typepopup = uidropdown(app.AnalysisOptionsTab);
            app.Typepopup.Items = {'Eigenvalue Analysis', 'Static Pushover Analysis', 'Incremental Dynamic Analysis', 'Dynamic: Target Scale Factor', 'Dynamic: Target Intesity', 'Equivelant Lateral Force Analysis', 'Tsunami Constant Depth Pushover', 'Tsunami Time History'};
            app.Typepopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.Typepopup.ValueChangedFcn = createCallbackFcn(app, @Typepopup_Callback, true);
            app.Typepopup.Tag = 'popupmenu1';
            app.Typepopup.Value = '1';
            app.Image_Eigen = uiimage(app.AnalysisOptionsTab);
            app.Image_Eigen.ImageSource = fullfile(pathToMLAPP, 'Eigen.png');
            app.Image_ELF = uiimage(app.AnalysisOptionsTab);
            app.Image_ELF.ImageSource = fullfile(pathToMLAPP, 'ELF.png');
            app.RunOptionsTab = uitab(app.TabGroup);
            app.RunOptionsTab.Title = 'Run Options';
            app.AnimationCB = uicheckbox(app.RunOptionsTab);
            app.AnimationCB.ValueChangedFcn = createCallbackFcn(app, @AnimationCBValueChanged, true);
            app.AnimationCB.Tag = 'checkbox3';
            app.AnimationCB.Text = 'Show animation';
            app.AnimationCB.Value = true;
            app.showStatusCB = uicheckbox(app.RunOptionsTab);
            app.showStatusCB.Tag = 'checkbox4';
            app.showStatusCB.Text = 'Show OpenSEES status';
            app.text31_2 = uilabel(app.RunOptionsTab);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'Maximum runtime';
            app.text32_2 = uilabel(app.RunOptionsTab);
            app.text32_2.Tag = 'text32';
            app.text32_2.Text = 'minutes';
            app.MaxRunTime = uieditfield(app.RunOptionsTab, 'numeric');
            app.MaxRunTime.Limits = [0.1 Inf];
            app.MaxRunTime.ValueDisplayFormat = '%.1f';
            app.MaxRunTime.Value = 10;
            app.TipIcon5 = uibutton(app.RunOptionsTab, 'push');
            app.TipIcon5.ButtonPushedFcn = createCallbackFcn(app, @TipIcon5ButtonPushed, true);
            app.TipIcon5.Text = '?';
            app.ShowScopeCB = uicheckbox(app.RunOptionsTab);
            app.ShowScopeCB.Tag = 'checkbox22';
            app.ShowScopeCB.Tooltip = {'Plots the maximum SDR and PFA values for each ground motion record during analysis.'};
            app.ShowScopeCB.Text = 'Show scope';
            app.ShowScopeCB.Value = true;
            app.AnimSF = uieditfield(app.RunOptionsTab, 'numeric');
            app.AnimSF.Limits = [0.1 50];
            app.AnimSF.ValueDisplayFormat = '%.1f';
            app.AnimSF.Value = 5;
            app.AnimX = uieditfield(app.RunOptionsTab, 'numeric');
            app.AnimX.Limits = [50 3000];
            app.AnimX.ValueDisplayFormat = '%.0f';
            app.AnimX.Value = 1000;
            app.AnimY = uieditfield(app.RunOptionsTab, 'numeric');
            app.AnimY.Limits = [50 3000];
            app.AnimY.ValueDisplayFormat = '%.0f';
            app.AnimY.Value = 750;
            app.text32_3 = uilabel(app.RunOptionsTab);
            app.text32_3.Tag = 'text32';
            app.text32_3.Text = 'scale';
            app.text32_4 = uilabel(app.RunOptionsTab);
            app.text32_4.Tag = 'text32';
            app.text32_4.Text = 'x';
            app.text32_5 = uilabel(app.RunOptionsTab);
            app.text32_5.Tag = 'text32';
            app.text32_5.Text = 'window size';
            app.uncertaintyCB = uicheckbox(app.RunOptionsTab);
            app.uncertaintyCB.ValueChangedFcn = createCallbackFcn(app, @uncertaintyCBValueChanged, true);
            app.uncertaintyCB.Tag = 'checkbox22';
            app.uncertaintyCB.Text = 'Include modeling uncertainty';
            app.Sigmabutton = uibutton(app.RunOptionsTab, 'push');
            app.Sigmabutton.ButtonPushedFcn = createCallbackFcn(app, @SigmabuttonButtonPushed, true);
            app.Sigmabutton.Tag = 'Savebutton';
            app.Sigmabutton.Text = 'define';
            app.editUncertainty = uieditfield(app.RunOptionsTab, 'text');
            app.editUncertainty.Editable = 'off';
            app.TipIcon6 = uibutton(app.RunOptionsTab, 'push');
            app.TipIcon6.ButtonPushedFcn = createCallbackFcn(app, @TipIcon6ButtonPushed, true);
            app.TipIcon6.Text = '?';
            app.parallelCB = uicheckbox(app.RunOptionsTab);
            app.parallelCB.ValueChangedFcn = createCallbackFcn(app, @parallelCBValueChanged, true);
            app.parallelCB.Tag = 'checkbox4';
            app.parallelCB.Tooltip = {'This option used parallel computing for dynamic analysis runs. This option might not work on some PCs.'};
            app.parallelCB.Text = 'Run using parallel cores';
            app.nCores = uieditfield(app.RunOptionsTab, 'numeric');
            app.nCores.Limits = [0.1 Inf];
            app.nCores.ValueDisplayFormat = '%.0f';
            app.nCores.Tooltip = {'number of cores to use'};
            app.nCores.Value = 10;
            app.text69_5 = uilabel(app.RunOptionsTab);
            app.text69_5.Tag = 'text6';
            app.text69_5.Text = '*beta (new)';
            app.PushoverELFParametersTab = uitab(app.TabGroup);
            app.PushoverELFParametersTab.Title = 'Pushover/ELF Parameters';
            app.text30 = uilabel(app.PushoverELFParametersTab);
            app.text30.Tag = 'text30';
            app.text30.Text = 'Pushover pattern based on Mode';
            app.text31 = uilabel(app.PushoverELFParametersTab);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Target roof drift level';
            app.text32 = uilabel(app.PushoverELFParametersTab);
            app.text32.Tag = 'text32';
            app.text32.Tooltip = {'Roof drift ratio as a percentage of total building height.'};
            app.text32.Text = '%';
            app.POmode = uieditfield(app.PushoverELFParametersTab, 'numeric');
            app.POmode.Limits = [1 Inf];
            app.POmode.ValueDisplayFormat = '%.0f';
            app.POmode.Value = 1;
            app.POtargetdrift = uieditfield(app.PushoverELFParametersTab, 'numeric');
            app.POtargetdrift.Limits = [0.1 Inf];
            app.POtargetdrift.ValueDisplayFormat = '%.1f';
            app.POtargetdrift.Tooltip = {'Roof drift ratio as a percentage of total building height.'};
            app.POtargetdrift.Value = 10;
            app.text28_2 = uilabel(app.PushoverELFParametersTab);
            app.text28_2.Tag = 'text28';
            app.text28_2.Text = 'ELF profile';
            app.ELFbutton = uibutton(app.PushoverELFParametersTab, 'push');
            app.ELFbutton.ButtonPushedFcn = createCallbackFcn(app, @ELFbuttonButtonPushed, true);
            app.ELFbutton.Tag = 'Savebutton';
            app.ELFbutton.Tooltip = {'Browse for a text file containing a single column of values representing the equivelant lateral forces at each floor.'};
            app.ELFbutton.Text = 'browse';
            app.editELF = uieditfield(app.PushoverELFParametersTab, 'text');
            app.editELF.Editable = 'off';
            app.DynamicParametersTab = uitab(app.TabGroup);
            app.DynamicParametersTab.Title = 'Dynamic Parameters';
            app.text9 = uilabel(app.DynamicParametersTab);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Initial increment';
            app.text16 = uilabel(app.DynamicParametersTab);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Collapse point tolerance';
            app.text6 = uilabel(app.DynamicParametersTab);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Scaling period';
            app.text20 = uilabel(app.DynamicParametersTab);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Scaling factor';
            app.text27 = uilabel(app.DynamicParametersTab);
            app.text27.Tag = 'text27';
            app.text27.Text = 'Target intensity';
            app.SApopup = uidropdown(app.DynamicParametersTab);
            app.SApopup.Items = {'Sa(T1)', 'Sa_avg'};
            app.SApopup.ItemsData = {'1', '2'};
            app.SApopup.ValueChangedFcn = createCallbackFcn(app, @SApopupValueChanged, true);
            app.SApopup.Tag = 'SApopup';
            app.SApopup.Value = '1';
            app.text38 = uilabel(app.DynamicParametersTab);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Intensity measure';
            app.SA_Step = uieditfield(app.DynamicParametersTab, 'numeric');
            app.SA_Step.Limits = [0.01 Inf];
            app.SA_Step.ValueDisplayFormat = '%.2f';
            app.SA_Step.Value = 0.2;
            app.T1 = uieditfield(app.DynamicParametersTab, 'numeric');
            app.T1.Limits = [0.001 Inf];
            app.T1.ValueDisplayFormat = '%.3f';
            app.T1.Value = 1;
            app.SAstepmin = uieditfield(app.DynamicParametersTab, 'numeric');
            app.SAstepmin.Limits = [0.01 Inf];
            app.SAstepmin.ValueDisplayFormat = '%.2f';
            app.SAstepmin.Value = 0.05;
            app.SF = uieditfield(app.DynamicParametersTab, 'numeric');
            app.SF.Limits = [0.001 Inf];
            app.SF.ValueDisplayFormat = '%.3f';
            app.SF.Value = 1;
            app.TargetSA = uieditfield(app.DynamicParametersTab, 'numeric');
            app.TargetSA.Limits = [0.001 Inf];
            app.TargetSA.ValueDisplayFormat = '%.3f';
            app.TargetSA.Value = 1;
            app.AdapTstepCB = uicheckbox(app.DynamicParametersTab);
            app.AdapTstepCB.ValueChangedFcn = createCallbackFcn(app, @AdapTstepCBValueChanged, true);
            app.AdapTstepCB.Tag = 'checkbox22';
            app.AdapTstepCB.Text = 'Adaptive intensity increment';
            app.AdapTstepCB.Value = true;
            app.text27_2 = uilabel(app.DynamicParametersTab);
            app.text27_2.Tag = 'text27';
            app.text27_2.Text = 'Collapse drift limit';
            app.CollapseSDR = uieditfield(app.DynamicParametersTab, 'numeric');
            app.CollapseSDR.Limits = [0.001 Inf];
            app.CollapseSDR.ValueDisplayFormat = '%.3f';
            app.CollapseSDR.Value = 0.15;
            app.text27_3 = uilabel(app.DynamicParametersTab);
            app.text27_3.Tag = 'text27';
            app.text27_3.Text = 'Maximum IDA curve slope';
            app.IDAslopeLimit = uieditfield(app.DynamicParametersTab, 'numeric');
            app.IDAslopeLimit.Limits = [0.001 Inf];
            app.IDAslopeLimit.ValueDisplayFormat = '%.3f';
            app.IDAslopeLimit.Value = 0.5;
            app.TipIcon4 = uibutton(app.DynamicParametersTab, 'push');
            app.TipIcon4.ButtonPushedFcn = createCallbackFcn(app, @TipIcon4ButtonPushed, true);
            app.TipIcon4.Text = '?';
            app.text6_2 = uilabel(app.DynamicParametersTab);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = '[sec]';
            app.text6_3 = uilabel(app.DynamicParametersTab);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = '[g]';
            app.text6_4 = uilabel(app.DynamicParametersTab);
            app.text6_4.Tag = 'text6';
            app.text6_4.Text = '[g]';
            app.text6_5 = uilabel(app.DynamicParametersTab);
            app.text6_5.Tag = 'text6';
            app.text6_5.Text = '[rad]';
            app.text6_6 = uilabel(app.DynamicParametersTab);
            app.text6_6.Tag = 'text6';
            app.text6_6.Text = '[rad/g]';
            app.text6_7 = uilabel(app.DynamicParametersTab);
            app.text6_7.Tag = 'text6';
            app.text6_7.Text = '[g]';
            app.text39_4 = uilabel(app.DynamicParametersTab);
            app.text39_4.Tag = 'text39';
            app.text39_4.Text = ', ξ =';
            app.text39_5 = uilabel(app.DynamicParametersTab);
            app.text39_5.Tag = 'text39';
            app.text39_5.Text = '%';
            app.zetaSA = uieditfield(app.DynamicParametersTab, 'numeric');
            app.zetaSA.Limits = [0.001 Inf];
            app.zetaSA.ValueDisplayFormat = '%.1f';
            app.zetaSA.Value = 5;
            app.text39_6 = uilabel(app.DynamicParametersTab);
            app.text39_6.Tag = 'text39';
            app.text39_6.Text = 'to';
            app.coeff_T_lower = uieditfield(app.DynamicParametersTab, 'numeric');
            app.coeff_T_lower.Limits = [0.001 Inf];
            app.coeff_T_lower.ValueDisplayFormat = '%.1f';
            app.coeff_T_lower.Value = 0.2;
            app.coeff_T_upper = uieditfield(app.DynamicParametersTab, 'numeric');
            app.coeff_T_upper.Limits = [0.001 Inf];
            app.coeff_T_upper.ValueDisplayFormat = '%.1f';
            app.coeff_T_upper.Value = 3;
            app.text39_7 = uilabel(app.DynamicParametersTab);
            app.text39_7.Tag = 'text39';
            app.text39_7.Text = 'T';
            app.text6_9 = uilabel(app.DynamicParametersTab);
            app.text6_9.Tag = 'text6';
            app.text6_9.Text = 'Range =';
            app.DampingTab = uitab(app.TabGroup);
            app.DampingTab.Title = 'Damping';
            app.text34 = uilabel(app.DampingTab);
            app.text34.Tag = 'text34';
            app.text34.Tooltip = 'Specify the two periods for the Raleigh damping model';
            app.text34.Text = 'Raleigh periods';
            app.text35 = uilabel(app.DampingTab);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Damping ratio';
            app.text39 = uilabel(app.DampingTab);
            app.text39.Tag = 'text39';
            app.text39.Text = '%';
            app.DampModeI = uieditfield(app.DampingTab, 'numeric');
            app.DampModeI.Limits = [1 Inf];
            app.DampModeI.ValueDisplayFormat = '%.0f';
            app.DampModeI.Value = 1;
            app.DampModeJ = uieditfield(app.DampingTab, 'numeric');
            app.DampModeJ.Limits = [1 Inf];
            app.DampModeJ.ValueDisplayFormat = '%.0f';
            app.DampModeJ.Value = 1;
            app.text39_2 = uilabel(app.DampingTab);
            app.text39_2.Tag = 'text39';
            app.text39_2.Text = 'and';
            app.zeta = uieditfield(app.DampingTab, 'numeric');
            app.zeta.Limits = [0 Inf];
            app.zeta.ValueDisplayFormat = '%.1f';
            app.zeta.Value = 2;
            app.GroundMotionsTab = uitab(app.TabGroup);
            app.GroundMotionsTab.Title = 'Ground Motions';
            app.text28 = uilabel(app.GroundMotionsTab);
            app.text28.Tag = 'text28';
            app.text28.Text = 'Ground motion folder';
            app.text7 = uilabel(app.GroundMotionsTab);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Consider GM records #';
            app.text8 = uilabel(app.GroundMotionsTab);
            app.text8.Tag = 'text8';
            app.text8.Text = 'to';
            app.GM_Start = uieditfield(app.GroundMotionsTab, 'numeric');
            app.GM_Start.Limits = [1 Inf];
            app.GM_Start.ValueDisplayFormat = '%.0f';
            app.GM_Start.Value = 1;
            app.GM_Last = uieditfield(app.GroundMotionsTab, 'numeric');
            app.GM_Last.Limits = [1 Inf];
            app.GM_Last.ValueDisplayFormat = '%.0f';
            app.GM_Last.Value = 1;
            app.text41 = uilabel(app.GroundMotionsTab);
            app.text41.Tag = 'text41';
            app.text41.Tooltip = {''};
            app.text41.Text = 'Free vibration time';
            app.TFreeVibration = uieditfield(app.GroundMotionsTab, 'numeric');
            app.TFreeVibration.Limits = [0 Inf];
            app.TFreeVibration.ValueDisplayFormat = '%.1f';
            app.TFreeVibration.Value = 10;
            app.GMbutton = uibutton(app.GroundMotionsTab, 'push');
            app.GMbutton.ButtonPushedFcn = createCallbackFcn(app, @GMbuttonButtonPushed, true);
            app.GMbutton.Tag = 'Savebutton';
            app.GMbutton.Text = 'browse';
            app.text41_2 = uilabel(app.GroundMotionsTab);
            app.text41_2.Tag = 'text41';
            app.text41_2.Tooltip = {''};
            app.text41_2.Text = 'Analysis time step';
            app.dtstep = uieditfield(app.GroundMotionsTab, 'numeric');
            app.dtstep.Limits = [0.001 1];
            app.dtstep.ValueDisplayFormat = '%.2f';
            app.dtstep.Value = 0.5;
            app.GMFolderPath = uieditfield(app.GroundMotionsTab, 'text');
            app.GMFolderPath.Editable = 'off';
            app.text6_8 = uilabel(app.GroundMotionsTab);
            app.text6_8.Tag = 'text6';
            app.text6_8.Text = '[sec]';
            app.text41_3 = uilabel(app.GroundMotionsTab);
            app.text41_3.Tag = 'text41';
            app.text41_3.Tooltip = {''};
            app.text41_3.Text = 'x record time step';
            app.inspectGMButton = uibutton(app.GroundMotionsTab, 'push');
            app.inspectGMButton.ButtonPushedFcn = createCallbackFcn(app, @inspectGMButtonPushed, true);
            app.inspectGMButton.Tag = 'BrowseButton';
            app.inspectGMButton.Icon = fullfile(pathToMLAPP, 'scope.png');
            app.inspectGMButton.Enable = 'off';
            app.inspectGMButton.Tooltip = {'inspect the imported GM records'};
            app.inspectGMButton.Text = 'Inspect GM';
            app.RecordersTab = uitab(app.TabGroup);
            app.RecordersTab.Title = 'Recorders';
            app.checkbox19 = uicheckbox(app.RecordersTab);
            app.checkbox19.Tag = 'checkbox19';
            app.checkbox19.Enable = 'off';
            app.checkbox19.Text = 'Time';
            app.checkbox19.Value = true;
            app.checkbox20 = uicheckbox(app.RecordersTab);
            app.checkbox20.Tag = 'checkbox20';
            app.checkbox20.Enable = 'off';
            app.checkbox20.Text = 'Floor Displacement';
            app.checkbox20.Value = true;
            app.checkbox21 = uicheckbox(app.RecordersTab);
            app.checkbox21.Tag = 'checkbox21';
            app.checkbox21.Enable = 'off';
            app.checkbox21.Text = 'Story Drift Ratio';
            app.checkbox21.Value = true;
            app.checkbox22 = uicheckbox(app.RecordersTab);
            app.checkbox22.Tag = 'checkbox22';
            app.checkbox22.Text = 'Column Element Forces';
            app.checkbox22.Value = true;
            app.checkbox23 = uicheckbox(app.RecordersTab);
            app.checkbox23.Tag = 'checkbox23';
            app.checkbox23.Text = 'Column Spring M/R';
            app.checkbox24 = uicheckbox(app.RecordersTab);
            app.checkbox24.Tag = 'checkbox24';
            app.checkbox24.Text = 'Beam Element Forces';
            app.checkbox25 = uicheckbox(app.RecordersTab);
            app.checkbox25.Tag = 'checkbox25';
            app.checkbox25.Text = 'Beam Spring M/R';
            app.checkbox27 = uicheckbox(app.RecordersTab);
            app.checkbox27.Tag = 'checkbox27';
            app.checkbox27.Enable = 'off';
            app.checkbox27.Text = 'Floor Acceleration';
            app.checkbox27.Value = true;
            app.checkbox28 = uicheckbox(app.RecordersTab);
            app.checkbox28.Tag = 'checkbox28';
            app.checkbox28.Text = 'Floor Link F/D';
            app.checkbox26 = uicheckbox(app.RecordersTab);
            app.checkbox26.Tag = 'checkbox26';
            app.checkbox26.Text = 'Panel Zone Spring M/R';
            app.checkbox30 = uicheckbox(app.RecordersTab);
            app.checkbox30.Tag = 'checkbox21';
            app.checkbox30.Tooltip = {'Consider selecting this option when running IDA.'};
            app.checkbox30.Text = 'Delete output data files after finishing the run';
            app.checkbox29 = uicheckbox(app.RecordersTab);
            app.checkbox29.Tag = 'checkbox28';
            app.checkbox29.Text = 'Brace F/D';
            app.checkbox31 = uicheckbox(app.RecordersTab);
            app.checkbox31.Tag = 'checkbox28';
            app.checkbox31.Text = 'Corner Gusset Plate Force';
            app.checkbox32 = uicheckbox(app.RecordersTab);
            app.checkbox32.Tag = 'checkbox28';
            app.checkbox32.Enable = 'off';
            app.checkbox32.Text = 'Support Reaction';
            app.checkbox32.Value = true;
            app.checkbox33 = uicheckbox(app.RecordersTab);
            app.checkbox33.Tag = 'checkbox27';
            app.checkbox33.Text = 'Floor Velocity';
            app.checkbox34 = uicheckbox(app.RecordersTab);
            app.checkbox34.Tag = 'checkbox28';
            app.checkbox34.Enable = 'off';
            app.checkbox34.Text = 'Eigen Modes';
            app.checkbox34.Value = true;
            app.checkbox35 = uicheckbox(app.RecordersTab);
            app.checkbox35.Tag = 'checkbox28';
            app.checkbox35.Enable = 'off';
            app.checkbox35.Text = 'Eigen Vectors';
            app.checkbox35.Value = true;
            app.checkbox36 = uicheckbox(app.RecordersTab);
            app.checkbox36.Tag = 'checkbox26';
            app.checkbox36.Text = 'EGF Connection Spring M/R';
            app.TsunamiPushoverTab = uitab(app.TabGroup);
            app.TsunamiPushoverTab.Title = 'Tsunami Pushover';
            app.text31_3 = uilabel(app.TsunamiPushoverTab);
            app.text31_3.Tag = 'text31';
            app.text31_3.Text = 'Maximum inundation depth';
            app.dinundation = uieditfield(app.TsunamiPushoverTab, 'numeric');
            app.dinundation.Limits = [0.1 Inf];
            app.dinundation.ValueDisplayFormat = '%.1f';
            app.dinundation.Tooltip = {''};
            app.dinundation.Value = 10;
            app.text69 = uilabel(app.TsunamiPushoverTab);
            app.text69.Tag = 'text6';
            app.text69.Text = 'm';
            app.text31_4 = uilabel(app.TsunamiPushoverTab);
            app.text31_4.Tag = 'text31';
            app.text31_4.Text = 'Froude number';
            app.FroudeNumber = uieditfield(app.TsunamiPushoverTab, 'numeric');
            app.FroudeNumber.Limits = [0 3];
            app.FroudeNumber.ValueDisplayFormat = '%.1f';
            app.FroudeNumber.Tooltip = {''};
            app.FroudeNumber.Value = 1;
            app.CDPOpattern = uidropdown(app.TsunamiPushoverTab);
            app.CDPOpattern.Items = {'Inverted Triangle', 'Constant'};
            app.CDPOpattern.ItemsData = {'1', '2'};
            app.CDPOpattern.Tag = 'SApopup';
            app.CDPOpattern.Value = '1';
            app.text38_2 = uilabel(app.TsunamiPushoverTab);
            app.text38_2.Tag = 'text38';
            app.text38_2.Text = 'Pushover pattern';
            app.text31_5 = uilabel(app.TsunamiPushoverTab);
            app.text31_5.Tag = 'text31';
            app.text31_5.Text = 'Water density';
            app.ro_water = uieditfield(app.TsunamiPushoverTab, 'numeric');
            app.ro_water.Limits = [0 Inf];
            app.ro_water.ValueDisplayFormat = '%.1f';
            app.ro_water.Tooltip = {''};
            app.ro_water.Value = 1.2;
            app.text70 = uilabel(app.TsunamiPushoverTab);
            app.text70.Tag = 'text6';
            app.text70.Text = 'ton/m3';
            app.SApopup_3 = uidropdown(app.TsunamiPushoverTab);
            app.SApopup_3.Items = {'ASCE 7', 'Foster et al (2017**)'};
            app.SApopup_3.ItemsData = {'1', '2'};
            app.SApopup_3.Tag = 'SApopup';
            app.SApopup_3.Enable = 'off';
            app.SApopup_3.Value = '1';
            app.text38_3 = uilabel(app.TsunamiPushoverTab);
            app.text38_3.Tag = 'text38';
            app.text38_3.Text = 'Force computation';
            app.text31_6 = uilabel(app.TsunamiPushoverTab);
            app.text31_6.Tag = 'text31';
            app.text31_6.Text = 'Drag Coefficient';
            app.Drag_Coeff = uieditfield(app.TsunamiPushoverTab, 'numeric');
            app.Drag_Coeff.Limits = [0 Inf];
            app.Drag_Coeff.ValueDisplayFormat = '%.1f';
            app.Drag_Coeff.Tooltip = {'Roof drift ratio as a percentage of total building height.'};
            app.Drag_Coeff.Value = 1.2;
            app.text69_4 = uilabel(app.TsunamiPushoverTab);
            app.text69_4.Tag = 'text6';
            app.text69_4.Text = '*beta';
            app.TsunamiTimeHistoryTab = uitab(app.TabGroup);
            app.TsunamiTimeHistoryTab.Title = 'Tsunami Time History';
            app.text28_3 = uilabel(app.TsunamiTimeHistoryTab);
            app.text28_3.Tag = 'text28';
            app.text28_3.Text = 'Tsunami data file';
            app.Tsunamibutton = uibutton(app.TsunamiTimeHistoryTab, 'push');
            app.Tsunamibutton.ButtonPushedFcn = createCallbackFcn(app, @TsunamibuttonButtonPushed, true);
            app.Tsunamibutton.Tag = 'Savebutton';
            app.Tsunamibutton.Text = 'browse';
            app.editTsunami = uieditfield(app.TsunamiTimeHistoryTab, 'text');
            app.editTsunami.Editable = 'off';
            app.text69_3 = uilabel(app.TsunamiTimeHistoryTab);
            app.text69_3.Tag = 'text6';
            app.text69_3.Text = '*beta';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Analysis_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end