classdef Build_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        BuildingGeometryTab       matlab.ui.container.Tab
        considereddirectionLabel  matlab.ui.control.Label
        illustrativebuildinglayoutLabel  matlab.ui.control.Label
        NStory                    matlab.ui.control.NumericEditField
        text35_23                 matlab.ui.control.Label
        text35_22                 matlab.ui.control.Label
        text25                    matlab.ui.control.Label
        nMRF                      matlab.ui.control.NumericEditField
        text65                    matlab.ui.control.Label
        TAin1                     matlab.ui.control.NumericEditField
        TAex1                     matlab.ui.control.NumericEditField
        TAin2                     matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        DIM2                      matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        DIM1                      matlab.ui.control.NumericEditField
        NBay                      matlab.ui.control.NumericEditField
        Image2                    matlab.ui.control.Image
        BraceLayoutpopup          matlab.ui.control.DropDown
        text67                    matlab.ui.control.Label
        showMRFButton             matlab.ui.control.Button
        editExcel                 matlab.ui.control.EditField
        nGB                       matlab.ui.control.NumericEditField
        nGC                       matlab.ui.control.NumericEditField
        text84                    matlab.ui.control.Label
        BrowseButton              matlab.ui.control.Button
        text25_2                  matlab.ui.control.Label
        text32_2                  matlab.ui.control.Label
        text33                    matlab.ui.control.Label
        text32                    matlab.ui.control.Label
        SupportsConnectionsTab    matlab.ui.container.Tab
        text314                   matlab.ui.control.Label
        SupportGFSpopup           matlab.ui.control.DropDown
        text35_20                 matlab.ui.control.Label
        text35_19                 matlab.ui.control.Label
        text35_18                 matlab.ui.control.Label
        text311                   matlab.ui.control.Label
        text313                   matlab.ui.control.Label
        Orientationpopup          matlab.ui.control.DropDown
        text312                   matlab.ui.control.Label
        GFconnectionpopup         matlab.ui.control.DropDown
        k_beambracing             matlab.ui.control.NumericEditField
        text58_2                  matlab.ui.control.Label
        text71_2                  matlab.ui.control.Label
        TipIcon6                  matlab.ui.control.Button
        ConnectionOddpopup        matlab.ui.control.DropDown
        text301                   matlab.ui.control.Label
        text303                   matlab.ui.control.Label
        MidSpanpopup              matlab.ui.control.DropDown
        text30                    matlab.ui.control.Label
        ConnectionEvenpopup       matlab.ui.control.DropDown
        text302                   matlab.ui.control.Label
        TipIcon5                  matlab.ui.control.Button
        text31_7                  matlab.ui.control.Label
        Splicepopup               matlab.ui.control.DropDown
        FuseF_ProfileCB           matlab.ui.control.CheckBox
        Ks                        matlab.ui.control.NumericEditField
        Fs                        matlab.ui.control.NumericEditField
        c                         matlab.ui.control.NumericEditField
        b                         matlab.ui.control.NumericEditField
        a                         matlab.ui.control.NumericEditField
        Image                     matlab.ui.control.Image
        text31_4                  matlab.ui.control.Label
        text75                    matlab.ui.control.Label
        text74                    matlab.ui.control.Label
        text73                    matlab.ui.control.Label
        text72                    matlab.ui.control.Label
        Linkpopup                 matlab.ui.control.DropDown
        text304                   matlab.ui.control.Label
        text71                    matlab.ui.control.Label
        text70                    matlab.ui.control.Label
        text69                    matlab.ui.control.Label
        text58                    matlab.ui.control.Label
        text57                    matlab.ui.control.Label
        text56                    matlab.ui.control.Label
        Connectionpopup           matlab.ui.control.DropDown
        text31_2                  matlab.ui.control.Label
        Supportpopup              matlab.ui.control.DropDown
        LoadsTab                  matlab.ui.container.Tab
        text35_11                 matlab.ui.control.Label
        text35_10                 matlab.ui.control.Label
        LoadApplypopup            matlab.ui.control.DropDown
        text24_2                  matlab.ui.control.Label
        cGL_M                     matlab.ui.control.NumericEditField
        text40_5                  matlab.ui.control.Label
        cGL_W                     matlab.ui.control.NumericEditField
        GLroof                    matlab.ui.control.NumericEditField
        GLtyp                     matlab.ui.control.NumericEditField
        text36_2                  matlab.ui.control.Label
        CB1                       matlab.ui.control.CheckBox
        cCL_M                     matlab.ui.control.NumericEditField
        cLL_M                     matlab.ui.control.NumericEditField
        cDL_M                     matlab.ui.control.NumericEditField
        text35_3                  matlab.ui.control.Label
        text40_4                  matlab.ui.control.Label
        text40_3                  matlab.ui.control.Label
        text40_2                  matlab.ui.control.Label
        CL                        matlab.ui.control.NumericEditField
        LLroof                    matlab.ui.control.NumericEditField
        LLtyp                     matlab.ui.control.NumericEditField
        DLroof                    matlab.ui.control.NumericEditField
        DLtyp                     matlab.ui.control.NumericEditField
        cCL_W                     matlab.ui.control.NumericEditField
        cLL_W                     matlab.ui.control.NumericEditField
        cDL_W                     matlab.ui.control.NumericEditField
        text35_2                  matlab.ui.control.Label
        text44                    matlab.ui.control.Label
        text24                    matlab.ui.control.Label
        text36                    matlab.ui.control.Label
        text37                    matlab.ui.control.Label
        text35                    matlab.ui.control.Label
        text41                    matlab.ui.control.Label
        text40                    matlab.ui.control.Label
        MaterialsTab              matlab.ui.container.Tab
        text53_11                 matlab.ui.control.Label
        Ry_gusset                 matlab.ui.control.NumericEditField
        text53_10                 matlab.ui.control.Label
        Ry_brace                  matlab.ui.control.NumericEditField
        text53_9                  matlab.ui.control.Label
        Ry_beam                   matlab.ui.control.NumericEditField
        text111                   matlab.ui.control.Label
        text53_8                  matlab.ui.control.Label
        Ry_column                 matlab.ui.control.NumericEditField
        text52_2                  matlab.ui.control.Label
        text53_7                  matlab.ui.control.Label
        fub                       matlab.ui.control.NumericEditField
        text35_17                 matlab.ui.control.Label
        fyb                       matlab.ui.control.NumericEditField
        fu                        matlab.ui.control.NumericEditField
        fuP                       matlab.ui.control.NumericEditField
        text35_16                 matlab.ui.control.Label
        fyP                       matlab.ui.control.NumericEditField
        text53_6                  matlab.ui.control.Label
        text35_13                 matlab.ui.control.Label
        text35_12                 matlab.ui.control.Label
        fyGP                      matlab.ui.control.NumericEditField
        fyBrace                   matlab.ui.control.NumericEditField
        muC                       matlab.ui.control.NumericEditField
        Ec                        matlab.ui.control.NumericEditField
        fc                        matlab.ui.control.NumericEditField
        text53_5                  matlab.ui.control.Label
        muR                       matlab.ui.control.NumericEditField
        Er                        matlab.ui.control.NumericEditField
        fyR                       matlab.ui.control.NumericEditField
        Matpopup                  matlab.ui.control.DropDown
        text35_9                  matlab.ui.control.Label
        text35_8                  matlab.ui.control.Label
        text35_7                  matlab.ui.control.Label
        mu0                       matlab.ui.control.NumericEditField
        text53_2                  matlab.ui.control.Label
        fy                        matlab.ui.control.NumericEditField
        E                         matlab.ui.control.NumericEditField
        text43                    matlab.ui.control.Label
        text53                    matlab.ui.control.Label
        text52                    matlab.ui.control.Label
        OtherTab                  matlab.ui.container.Tab
        text35_21                 matlab.ui.control.Label
        text6                     matlab.ui.control.Label
        text5                     matlab.ui.control.Label
        text56_6                  matlab.ui.control.Label
        text56_5                  matlab.ui.control.Label
        ts                        matlab.ui.control.NumericEditField
        trib                      matlab.ui.control.NumericEditField
        text56_4                  matlab.ui.control.Label
        Comp_I_GC                 matlab.ui.control.NumericEditField
        text56_3                  matlab.ui.control.Label
        Comp_I                    matlab.ui.control.NumericEditField
        text56_2                  matlab.ui.control.Label
        text333_2                 matlab.ui.control.Label
        bond_slip                 matlab.ui.control.CheckBox
        text333                   matlab.ui.control.Label
        coeff_cracked             matlab.ui.control.NumericEditField
        text73_3                  matlab.ui.control.Label
        SubmitButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
        TipWindow;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Build_App_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image.Visible='on';  

            global  ProjectName ProjectPath resource_root 
            load(strcat(ProjectPath,ProjectName),'PROJECT','LAYOUT','CONNECTION','LOAD','MATERIAL','STATUS', 'MODEL')

            clc;

            app.Image.ImageSource  = strjoin(resource_root+"RBS.png");
            app.Image2.ImageSource  = strjoin(resource_root+"MRF_Layout_2D.png");
            app.showMRFButton.Icon = strjoin(resource_root+"scope.png");
            
            if PROJECT.Units==1
                app.text25.Text     = 'Building Geometry [unit: mm]';
                app.text24.Text     = 'Loads [kN/m2]';
                app.text43.Text     = 'Material [kN/mm2]';
                app.text74.Text     = 'kN';
                app.text75.Text     = 'kN/mm';
                app.text5.Text      = 'mm';
                app.text6.Text      = 'mm';
                convUnit            = 1000*1000;
            else
                app.text25.Text     = 'Building Geometry [unit: inches]';
                app.text24.Text     = 'Loads [psf]';
                app.text43.Text     = 'Material [ksi]';
                app.text74.Text     = 'kip';
                app.text75.Text     = 'kip/in';
                app.text5.Text      = 'in';
                app.text6.Text      = 'in';
                convUnit            = 1000*12*12;
            end

            if PROJECT.FrameType~=1
                app.LoadApplypopup.Value        = num2str(1);
                app.LoadApplypopup.Enable       = "off";
                % app.showMRFButton.Enable        = "off";
                app.NBay.Enable                 = 1;
                app.NBay.Enable                 = "off";
                app.text67.Visible              = "on";
                app.text30.Visible              = "on";
                app.text301.Visible             = "on";
                app.text302.Visible             = "on";
                app.text303.Visible             = "on";
                app.BraceLayoutpopup.Visible    = "on";
                app.MidSpanpopup.Visible        = "on";
                app.ConnectionEvenpopup.Visible = "on";
                app.ConnectionOddpopup.Visible  = "on";
                app.TipIcon6.Visible            = "on";
                app.text67.Enable               = "on";
                app.text30.Enable               = "on";
                app.text301.Enable              = "on";
                app.text302.Enable              = "on";
                app.text303.Enable              = "on";
                app.BraceLayoutpopup.Enable     = "on";
                app.MidSpanpopup.Enable         = "on";
                app.ConnectionEvenpopup.Enable  = "on";
                app.ConnectionOddpopup.Enable   = "on";
                app.TipIcon6.Enable             = "on";
                app.fyBrace.Enable              = 'on';
                app.fyGP.Enable                 = 'on';
                app.Ry_brace.Enable             = 'on';
                app.Ry_gusset.Enable            = 'on';
                app.Connectionpopup.Enable      = "off";
                app.a.Enable                    = "off";
                app.b.Enable                    = "off";
                app.c.Enable                    = "off";
                app.text304.Enable              = "off";
            end

            if MODEL.CompositeX ==1
                app.Comp_I.Enable   = 'on';
                app.Comp_I_GC.Enable= 'on';
                app.trib.Enable     = 'on';
                app.ts.Enable       = 'on';
                app.Comp_I.Value    = 1.4;
                app.Comp_I_GC.Value = 1.4;
            else
                app.Comp_I.Enable   = 'off';
                app.Comp_I_GC.Enable= 'off';
                app.trib.Enable     = 'off';
                app.ts.Enable       = 'off';                
                app.Comp_I.Value    = 1.0;
                app.Comp_I_GC.Value = 1.0;
            end    

            if STATUS.Build~=1
                
                app.Connectionpopup.Value= '1';
                app.Supportpopup.Value   = '1';
                app.Splicepopup.Value    = '2';
                app.LoadApplypopup.Value = '1';

                if PROJECT.Units==1
                    app.E.Value         = 200;
                    app.fy.Value        = 0.35;
                    app.fu.Value        = 0.47;
                    app.fyP.Value       = 0.35;
                    app.fuP.Value       = 0.47;
                    app.fyb.Value       = 0.9;
                    app.fub.Value       = 1.0;
                    app.fyBrace.Value   = 0.35;
                    app.fyGP.Value      = 0.35;
                    app.Er.Value        = 200;
                    app.fyR.Value       = 0.35;
                    app.Ec.Value        = 0.05;
                    app.fc.Value        = 0.035;
                    app.trib.Value      = 3.5*25.4;
                    app.ts.Value        = 4.0*25.4;
                else
                    app.E.Value         = 29000;
                    app.fy.Value        = 55;
                    app.fu.Value        = 65;
                    app.fyP.Value       = 55;
                    app.fuP.Value       = 65;
                    app.fyb.Value       = 130;
                    app.fub.Value       = 145;
                    app.fyBrace.Value   = 55;
                    app.fyGP.Value      = 55;
                    app.Er.Value        = 29000;
                    app.fyR.Value       = 55;
                    app.Ec.Value        = 2600;
                    app.fc.Value        = 5;   
                    app.trib.Value      = 3.5;
                    app.ts.Value        = 4.0;
                end

            else
            
                %%%%%% LAYOUT
                app.DIM1.Value              = LAYOUT.DIM1;
                app.DIM2.Value              = LAYOUT.DIM2;
                app.nMRF.Value              = LAYOUT.nMF;
                app.NStory.Value            = LAYOUT.NStory;
                app.NBay.Value              = LAYOUT.NBay;
                app.nGC.Value               = LAYOUT.nGC;
                app.nGB.Value               = LAYOUT.nGB;
                app.TAex1.Value             = LAYOUT.TAex1;
                app.TAin1.Value             = LAYOUT.TAin1;
                app.TAin2.Value             = LAYOUT.TAin2;
                app.BraceLayoutpopup.Value  = num2str(LAYOUT.BraceLayout);
                app.editExcel.FontColor     = [0.0 1.0 0.0];
                app.editExcel.Value         = [PROJECT.ExcelFilePath,PROJECT.ExcelFileName];
                app.showMRFButton.Visible   = 'on';

                %%%%%% LOAD
                app.DLtyp.Value     = LOAD.TypicalDL *convUnit;
                app.DLroof.Value    = LOAD.RoofDL    *convUnit;
                app.LLtyp.Value     = LOAD.TypicalLL *convUnit;
                app.LLroof.Value    = LOAD.RoofLL    *convUnit;
                app.CL.Value        = LOAD.Cladding  *convUnit;
                app.GLtyp.Value     = LOAD.TypicalGL *convUnit;
                app.GLroof.Value    = LOAD.RoofGL    *convUnit;
                app.cDL_W.Value     = LOAD.cDL_W;
                app.cLL_W.Value     = LOAD.cLL_W;
                app.cCL_W.Value     = LOAD.cCL_W;
                app.cGL_W.Value     = LOAD.cGL_W;
                app.cDL_M.Value     = LOAD.cDL_M;
                app.cLL_M.Value     = LOAD.cLL_M;
                app.cCL_M.Value     = LOAD.cCL_M;
                app.cGL_M.Value     = LOAD.cGL_M;
                app.CB1.Value       = LOAD.cLoad;
                app.LoadApplypopup.Value = num2str(LOAD.LoadApplyOption);

                %%%%%% MATERIAL
                app.Matpopup.Value  = num2str(MATERIAL.SteelMatID);
                app.E.Value         = MATERIAL.E;
                app.fy.Value        = MATERIAL.fy;
                app.fu.Value        = MATERIAL.fu;
                app.fyP.Value       = MATERIAL.fyP;
                app.fuP.Value       = MATERIAL.fuP;
                app.fyb.Value       = MATERIAL.fyb;
                app.fub.Value       = MATERIAL.fub;
                app.mu0.Value       = MATERIAL.mu0;
                app.Ry_column.Value = MATERIAL.Ry_column;
                app.Ry_beam.Value   = MATERIAL.Ry_beam;
                app.Ry_brace.Value  = MATERIAL.Ry_brace;
                app.Ry_gusset.Value = MATERIAL.Ry_gusset;
                if PROJECT.FrameType==4
                    app.Er.Value  = MATERIAL.Er;
                    app.Ec.Value  = MATERIAL.Ec;
                    app.fc.Value  = MATERIAL.fc;
                    app.fyR.Value = MATERIAL.fyR;
                    app.muR.Value = MATERIAL.muR;
                    app.muC.Value = MATERIAL.muC;
                end

                %%%%%% CONNECTION
                app.Supportpopup.Value          = num2str(CONNECTION.Support);
                app.Splicepopup.Value           = num2str(CONNECTION.SpliceConnection);
                app.Connectionpopup.Value       = num2str(CONNECTION.MFconnection);
                if PROJECT.FrameType==2
                app.MidSpanpopup.Value          = num2str(CONNECTION.MidSpanConstraint);
                app.ConnectionEvenpopup.Value   = num2str(CONNECTION.MFconnectionEven);
                app.ConnectionOddpopup.Value    = num2str(CONNECTION.MFconnectionOdd);    
                end
                app.a.Value                     = CONNECTION.a;
                app.b.Value                     = CONNECTION.b;
                app.c.Value                     = CONNECTION.c;
                app.k_beambracing.Value         = CONNECTION.k_beambracing;
                app.Orientationpopup.Value      = num2str(CONNECTION.Orientation);
                app.SupportGFSpopup.Value       = num2str(CONNECTION.SupportGFS);
                app.GFconnectionpopup.Value     = num2str(CONNECTION.GFconnection);
                app.Linkpopup.Value             = num2str(CONNECTION.FloorLink);
                app.Fs.Value                    = CONNECTION.Fs;
                app.Ks.Value                    = CONNECTION.Ks;

                app.Comp_I.Value                = CONNECTION.Comp_I;
                app.Comp_I_GC.Value             = CONNECTION.Comp_I_GC;
                app.trib.Value                  = CONNECTION.trib;
                app.ts.Value                    = CONNECTION.ts;

                if PROJECT.FrameType==4
                    app.coeff_cracked.Value = CONNECTION.coeff_cracked;
                    app.bond_slip.Value     = CONNECTION.bond_slip;
                end

            end
            
            if MODEL.GFX == 1
                app.nGC.Enable                  = 'on';
                app.nGB.Enable                  = 'on';
                app.text32.Enable               = 'on';
                app.text32_2.Enable             = 'on';
                app.text33.Enable               = 'on';
                app.Orientationpopup.Enable     = 'on';
                app.GFconnectionpopup.Enable   = 'on';
                app.SupportGFSpopup.Enable      = 'on';
                app.text311.Enable              = 'on';
                app.text312.Enable              = 'on';
                app.text313.Enable              = 'on';
                app.text314.Enable              = 'on';
                if app.GFconnectionpopup.Value == 1
                    app.fu.Enable       = 'off';
                    app.fyP.Enable      = 'off';
                    app.fuP.Enable      = 'off';
                    app.fyb.Enable      = 'off';
                    app.fub.Enable      = 'off';
                end
                
            else
                app.nGC.Enable                  = 'off';
                app.nGB.Enable                  = 'off';
                app.text32.Enable               = 'off';
                app.text32_2.Enable             = 'off';
                app.text33.Enable               = 'off';
                app.Orientationpopup.Enable     = 'off';
                app.GFconnectionpopup.Enable   = 'off';
                app.SupportGFSpopup.Enable      = 'off';
                app.SupportGFSpopup.Value       = '2';
                app.text311.Enable              = 'off';
                app.text312.Enable              = 'off';
                app.text313.Enable              = 'off';
                app.text314.Enable              = 'off';
            end


            if PROJECT.FrameType ~= 4
                app.Er.Enable               = 'off';
                app.Ec.Enable               = 'off';
                app.fc.Enable               = 'off';
                app.fyR.Enable              = 'off';
                app.muR.Enable              = 'off';
                app.muC.Enable              = 'off';
                app.text73_3.Visible        = 'off';
                app.text333.Visible         = 'off';
                app.coeff_cracked.Visible   = 'off';
                app.bond_slip.Visible       = 'off';
            else
                app.Splicepopup.Enable      = 'off';
                app.Connectionpopup.Enable  = 'off';
                app.Image.Enable            = 'off';
                app.a.Enable                = 'off';
                app.b.Enable                = 'off';
                app.c.Enable                = 'off';
                app.Er.Enable               = 'on';
                app.Ec.Enable               = 'on';
                app.fc.Enable               = 'on';
                app.fyR.Enable              = 'on';
                app.muR.Enable              = 'on';
                app.muC.Enable              = 'on';
                app.text73_3.Visible        = 'on';
                app.text333.Visible         = 'on';
                app.coeff_cracked.Visible   = 'on';
                app.bond_slip.Visible       = 'on';
            end
                 
            if MODEL.ColElementOption~=1
                app.Ry_column.Enable="on";                
            else
                app.Ry_column.Enable="off";                
            end

            if MODEL.BeamElementOption~=1
                app.Ry_beam.Enable="on";                
            else
                app.Ry_beam.Enable="off";                
            end

            if MODEL.ColElementOption~=1 && MODEL.BeamElementOption~=1
               app.text111.Enable="on";
            else
               app.text111.Enable="off";
            end

            Connectionpopup_Callback(app);
            CB1ValueChanged(app);
            LinkpopupValueChanged(app);

        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName 
            load(strcat(ProjectPath,ProjectName),'PROJECT','MODEL');

            NStory          = app.NStory.Value;
            NBay            = app.NBay.Value;
            FloorLink       = str2double(app.Linkpopup.Value);
            MFconnection    = str2double(app.Connectionpopup.Value);
          
            [ExcelFileName,ExcelFilePath] = uigetfile('*.xlsx','Select the .xlsx file containg building geometry and member sizes');
            
            if ExcelFileName==0; return; end

            try
                app.editExcel.FontColor = [1.0 1.0 0.0];
                app.editExcel.Value     = ('Please wait ...'); drawnow;

                % Read Frame Cross-Section Data From Excel
                if PROJECT.FrameType~=4
                    PROPERTY=import_ExcelData(PROJECT.FrameType, ExcelFilePath,ExcelFileName, NStory, NBay, FloorLink, MFconnection, MODEL.GFX, PROJECT.Units);
                    PROJECT.ExcelFilePath=ExcelFilePath;
                    PROJECT.ExcelFileName=ExcelFileName;
                    save(strcat(ProjectPath,ProjectName),'PROJECT','PROPERTY','-append')
                else
                    Import_ExcelData_RCMRF(ExcelFilePath,ExcelFileName,NStory,NBay,FloorLink);                
                end
                
                app.editExcel.FontColor     = [0 1 0];
                app.editExcel.Value         = 'Import successful!'; drawnow;
                app.showMRFButton.Visible   = 'on';
            catch ME
                cd (MainDirectory)
                write_Error(ME);
                app.editExcel.FontColor = [1 0. 0.];
                app.editExcel.Value     = 'Error importing file. See Error file!'; drawnow;
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath resource_root

            load(strcat(ProjectPath,ProjectName));
            
            if isfield  (PROJECT,'ExcelFileName')==0
                errordlg('Please import first the Excel file containing MRF sections!');
                return;
            end

            if PROJECT.Units==1; convUnit=1000*1000; else; convUnit=1000*12*12; end
            
            try
                
            %% Materials
            if resource_root==''; load(strjoin(resource_root+"Material_Database.mat")); else; load resources\database\Material_Database.mat; end

            MATERIAL.SteelMatID     = str2double(app.Matpopup.Value);
            MATERIAL.SteelMatType   = app.Matpopup.Items{MATERIAL.SteelMatID};
            MATERIAL.E              = app.E.Value;
            MATERIAL.fy             = app.fy.Value;
            MATERIAL.fu             = app.fu.Value;
            MATERIAL.mu0            = app.mu0.Value;
            MATERIAL.fyP            = app.fyP.Value;
            MATERIAL.fuP            = app.fuP.Value;
            MATERIAL.fyb            = app.fyb.Value;
            MATERIAL.fub            = app.fub.Value;
            MATERIAL.fyBrace        = app.fyBrace.Value;
            MATERIAL.fyGP           = app.fyGP.Value;
            MATERIAL.Ec             = app.Ec.Value;
            MATERIAL.fc             = app.fc.Value;
            MATERIAL.muC            = app.muC.Value;
            MATERIAL.Er             = app.Er.Value;
            MATERIAL.fyR            = app.fyR.Value;
            MATERIAL.muR            = app.muR.Value;
            MATERIAL.Ry_column      = app.Ry_column.Value;
            MATERIAL.Ry_beam        = app.Ry_beam.Value;
            MATERIAL.Ry_brace       = app.Ry_brace.Value;
            MATERIAL.Ry_gusset      = app.Ry_gusset.Value;

            %% Layout
            LAYOUT.DIM1    = app.DIM1.Value;
            LAYOUT.DIM2    = app.DIM2.Value;
            LAYOUT.nMF     = app.nMRF.Value;
            LAYOUT.NStory  = app.NStory.Value;
            LAYOUT.NBay    = app.NBay.Value;
            LAYOUT.nGC     = app.nGC.Value;
            LAYOUT.nGB     = app.nGB.Value;
            LAYOUT.TAex1  = app.TAex1.Value;
            LAYOUT.TAex2  = app.TAin2.Value;
            LAYOUT.TAin1  = app.TAin1.Value;
            LAYOUT.TAin2  = app.TAin2.Value;

            LAYOUT.BraceLayout           = str2double(app.BraceLayoutpopup.Value);

            % Check dimensions
            if LAYOUT.DIM1 < 2*LAYOUT.TAex1+(LAYOUT.NBay+1-2)*LAYOUT.TAin1
                errordlg('The dimension of the building in Dir #1 is less than the sum of those of the columns! Please check.');
                return;
            end
            
            if LAYOUT.nMF==1
                errordlg('The number of main frames cannot be less than 2 per building in a given orthogonal direction!');
                return;
            end

            LAYOUT.FootPrintArea = LAYOUT.DIM1 * LAYOUT.DIM2;
            LAYOUT.Perimeter     = 2 * LAYOUT.DIM1 + 2 * LAYOUT.DIM2;
            
            if LAYOUT.nMF==2
                LAYOUT.TA_MF         = LAYOUT.DIM1 * LAYOUT.DIM2 / LAYOUT.nMF;            
                LAYOUT.TD_MF         = LAYOUT.DIM2 / LAYOUT.nMF;            
            else
                LAYOUT.TA_MF         = LAYOUT.DIM1 * LAYOUT.DIM2 / (LAYOUT.nMF-1);            
                LAYOUT.TD_MF         = LAYOUT.DIM2 / (LAYOUT.nMF-1);                 
            end
            LAYOUT.TribAreaEx    = LAYOUT.TAex1 * LAYOUT.TAin2;
            LAYOUT.TribAreaIn    = LAYOUT.TAin1 * LAYOUT.TAin2;
            
            save(strcat(ProjectPath,ProjectName), 'LAYOUT', '-append')

            %% Draw

            if PROJECT.FrameType==1
                  [Column_Index, Beam_Index, Brace_Index, XSecID] = get_Section_Database_Index();
            else
                Column_Index=NaN; Beam_Index=NaN; Brace_Index=NaN; XSecID=NaN;
            end
            [GridX, GridY, AXIS, Elevation, HalfElevation, XTickLabel, YTickLabel] = get_Grid ();

            [Column_Data, Beam_Data, Brace_Data] = get_Member_Data (PROJECT,LAYOUT,PROPERTY,MATERIAL);

            DRAW = v2struct(GridX,GridY,AXIS,Elevation,HalfElevation, XTickLabel, YTickLabel,Column_Index, Beam_Index, Brace_Index, XSecID, Column_Data, Beam_Data, Brace_Data);

            %% Loads
            LOAD.TypicalDL   = app.DLtyp.Value /convUnit;
            LOAD.RoofDL      = app.DLroof.Value /convUnit;
            LOAD.TypicalLL   = app.LLtyp.Value /convUnit;
            LOAD.RoofLL      = app.LLroof.Value /convUnit;
            LOAD.Cladding    = app.CL.Value /convUnit;
            LOAD.TypicalGL   = app.GLtyp.Value/convUnit;
            LOAD.RoofGL      = app.GLroof.Value/convUnit;
                
            if PROJECT.Units==2 && LOAD.TypicalDL*convUnit<1
                warndlg('The entered loads do not seem to be in the correct units (psf)! Please check.', 'Load Alert');
            end

            % Load Combination Coefficeints
            LOAD.cLoad  = app.CB1.Value;
            LOAD.cDL_W  = app.cDL_W.Value;
            LOAD.cLL_W  = app.cLL_W.Value;
            LOAD.cCL_W  = app.cCL_W.Value;
            LOAD.cGL_W  = app.cGL_W.Value;
            LOAD.cDL_M  = app.cDL_M.Value;
            LOAD.cLL_M  = app.cLL_M.Value;
            LOAD.cCL_M  = app.cCL_M.Value;
            LOAD.cGL_M  = app.cGL_M.Value;
            
            LOAD.LoadApplyOption = str2double(app.LoadApplypopup.Value);
            
            %% Supports & Connections
            CONNECTION.Support           = str2double(app.Supportpopup.Value);
            CONNECTION.MFconnection      = str2double(app.Connectionpopup.Value);
            CONNECTION.SpliceConnection  = str2double(app.Splicepopup.Value);
            CONNECTION.Orientation       = str2double(app.Orientationpopup.Value);
            CONNECTION.GFconnection      = str2double(app.GFconnectionpopup.Value);
            CONNECTION.SupportGFS        = str2double(app.SupportGFSpopup.Value);
            
            CONNECTION.a = app.a.Value;
            CONNECTION.b = app.b.Value;
            CONNECTION.c = app.c.Value;

            CONNECTION.k_beambracing = app.k_beambracing.Value;

            if PROJECT.FrameType~=1
                CONNECTION.MFconnectionEven  = str2double(app.ConnectionEvenpopup.Value);
                CONNECTION.MFconnectionOdd   = str2double(app.ConnectionOddpopup.Value);
                CONNECTION.MidSpanConstraint = str2double(app.MidSpanpopup.Value);
            end

            if MODEL.GFX ==1 && CONNECTION.GFconnection >1 &&  ~iscell(PROPERTY.GF_Connection)
                errordlg('You must re-import the EXCEL file and make sure the GFS endplate connection are defined in it.');
                return;
            end
            
            if CONNECTION.MFconnection ==3 && ~iscell(PROPERTY.MF_EEPCs)
                errordlg('You must re-import the EXCEL file and make sure the semi-rigid endplate connection data are defined in it.');
                return;
            end

            if app.Linkpopup.Value=='1'
                CONNECTION.FloorLink = 1;
                CONNECTION.Fs        = 1000000.;
                CONNECTION.Ks        = 0;
            else
                CONNECTION.FloorLink = 2;
                CONNECTION.Fs        = app.Fs.Value;
                CONNECTION.Ks        = app.Ks.Value;
            end
           
            CONNECTION.Comp_I      = app.Comp_I.Value;
            CONNECTION.Comp_I_GC   = app.Comp_I_GC.Value;
            CONNECTION.trib        = app.trib.Value;
            CONNECTION.ts          = app.ts.Value;
            
            if MODEL.CompositeX   == 0
                CONNECTION.Comp_I      = 1;
                CONNECTION.Comp_I_GC   = 1;
            end

            CONNECTION.coeff_cracked = app.coeff_cracked.Value;
            CONNECTION.bond_slip     = app.bond_slip.Value;
                
           
            %%
            ANALYSIS.DampModeJ=min(ANALYSIS.DampModeJ,LAYOUT.NStory);
      
            STATUS.Build = 1;
            
            % Save Data to .mat File
            save(strcat(ProjectPath,ProjectName), 'LAYOUT', 'CONNECTION', 'LOAD', 'MATERIAL', 'DRAW', 'STATUS', 'ANALYSIS', '-append')

            app.MainApp.status1.BackgroundColor  = 'g';
            app.MainApp.status1.FontColor='k';
            if     PROJECT.FrameType==1
                app.MainApp.status1.Text=[num2str(LAYOUT.NStory),'-story MRF']; 
            elseif PROJECT.FrameType == 2
                if LAYOUT.BraceLayout==1; app.MainApp.status1.Text=[num2str(LAYOUT.NStory),'-story CBF - X-Brace']; end
                if LAYOUT.BraceLayout==2; app.MainApp.status1.Text=[num2str(LAYOUT.NStory),'-story CBF - Chevron']; end
            elseif PROJECT.FrameType == 3
                if LAYOUT.BraceLayout==1; app.MainApp.status1.Text=[num2str(LAYOUT.NStory),'-story EBF - Double']; end
                if LAYOUT.BraceLayout==2; app.MainApp.status1.Text=[num2str(LAYOUT.NStory),'-story EBF - Single']; end
            elseif PROJECT.FrameType==4;   app.MainApp.status1.Text=[num2str(LAYOUT.NStory),'-story RC MRF']; end
            app.MainApp.status1b.BackgroundColor = 'g';
            
            app.MainApp.AnalysisButton.Enable       = 'on';
            app.MainApp.GetperiodButton.Enable      = 'on';
            app.MainApp.BuildtclfileButton.Enable   = 'on';
            app.MainApp.ProgressText.FontColor      = 'g';
            app.MainApp.ProgressText.Text           = 'Building data defined'; drawnow;

            fclose all;
            
            figure1CloseRequest(app)
            
            catch ME
                errordlg('An error has occured. Please see Error file!');
                cd(MainDirectory)
                write_Error(ME);
            end
        end

        % Value changed function: Connectionpopup
        function Connectionpopup_Callback(app, event)
            global  ProjectName ProjectPath Private

            load(strcat(ProjectPath,ProjectName),'PROJECT');

            if app.Connectionpopup.Value == '1' && PROJECT.FrameType == 1
                app.a.Enable='on';
                app.b.Enable='on';
                app.c.Enable='on';
                
                app.a.Value=0.625;
                app.b.Value=0.75;
                app.c.Value=0.25;
                
                app.Ry_beam.Value=1.06;

                app.Image.Visible='on';
            else
                app.a.Enable='off';
                app.b.Enable='off';
                app.c.Enable='off';
                
                app.a.Value=0.1;
                app.b.Value=0.0;
                app.c.Value=0.0;
                
                app.Ry_beam.Value=1.17;

                app.Image.Visible='off';
            end

            if Private ~= "Yes"
                if app.Connectionpopup.Value == '3' 
                    warndlg('Option not available yet!');
                    app.Connectionpopup.Value = '2';

                end
            else
                if app.Connectionpopup.Value == '3' 
                    app.fyP.Enable='on';
                    app.fuP.Enable='on';
                    app.fyb.Enable='on';
                    app.fub.Enable='on';
                else
                    app.fyP.Enable='off';
                    app.fuP.Enable='off';
                    app.fyb.Enable='off';
                    app.fub.Enable='off';
                end
            end
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            if app.CB1.Value == 1
                app.cDL_W.Enable='off';
                app.cLL_W.Enable='off'; 
                app.cCL_W.Enable='off'; 
                app.cDL_M.Enable='off'; 
                app.cLL_M.Enable='off'; 
                app.cCL_M.Enable='off'; 
                app.cGL_W.Enable='off'; 
                app.cGL_M.Enable='off'; 
                
                app.cDL_W.Value=1.05;
                app.cLL_W.Value=0.25; 
                app.cCL_W.Value=1.05; 
                app.cDL_M.Value=1.0; 
                app.cLL_M.Value=0.0; 
                app.cCL_M.Value=1.0; 
                app.cGL_W.Value=1.0; 
                app.cGL_M.Value=1.0; 
            else
                app.cDL_W.Enable='on';
                app.cLL_W.Enable='on'; 
                app.cCL_W.Enable='on'; 
                app.cDL_M.Enable='on'; 
                app.cLL_M.Enable='on'; 
                app.cCL_M.Enable='on';    
                app.cGL_W.Enable='on';                 
                app.cGL_M.Enable='on'; 
            end
            
        end

        % Value changed function: Linkpopup
        function LinkpopupValueChanged(app, event)
            if app.Linkpopup.Value == '1'
                app.Fs.Enable='off';
                app.Ks.Enable='off';
                app.FuseF_ProfileCB.Enable='off';

                app.Fs.Value = 10000;
                app.Ks.Value = 0.0;
            else
                app.Fs.Enable='on';
                app.Ks.Enable='on';
                app.FuseF_ProfileCB.Enable='on';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible            = 'off';
            app.MainApp.ProgressText.Text=('Building data defined'); drawnow;
            delete(app)
        end

        % Value changed function: Matpopup
        function MatpopupValueChanged(app, event)
            global MainDirectory Units resource_root
            cd(MainDirectory)

            if resource_root==''; load(strjoin(resource_root+"Material_Database.mat")); else; load resources\database\Material_Database.mat; end
            if Units==1; transUnit=6.89476/1000; else; transUnit=1; end
            x=str2double(app.Matpopup.Value);
            app.E.Value=Material.E(x+1,1)*transUnit;
            app.fy.Value=Material.fy(x+1,1)*transUnit;
            app.fu.Value=Material.fu(x+1,1)*transUnit;
        end

        % Button pushed function: TipIcon5
        function TipIcon5ButtonPushed(app, event)
            global resource_root

            figure('position',[100 100 450 350],'color','white','Resize','off');
            data=imread(strjoin(resource_root+"FLoor Link.png"));
            imshow(data); 
        end

        % Button pushed function: showMRFButton
        function showMRFButtonPushed(app, event)
            global ProjectPath ProjectName

            load(strcat(ProjectPath,ProjectName), 'STATUS');

            if STATUS.Build==0
                warndlg('Submit building definition first then try again!')
                return
            else
                plot_MRF_with_labels(1);
            end


        end

        % Button pushed function: TipIcon6
        function TipIcon6ButtonPushed(app, event)
            global resource_root
            
            figure('position',[100 100 450 350],'color','white','Resize','off');
            data=imread(strjoin(resource_root+"MidPointConstraint.png"));
            imshow(data); 
        end

        % Value changed function: GFconnectionpopup
        function GFconnectionpopupValueChanged(app, event)
            global Private

            if Private ~= "Yes"
                if app.GFconnectionpopup.Value ~='1'
                    warndlg('Option not available yet!');
                    app.GFconnectionpopup.Value = '1';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Building Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'BuildButton';
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.BuildingGeometryTab = uitab(app.TabGroup);
            app.BuildingGeometryTab.Title = 'Building Geometry';
            app.text32 = uilabel(app.BuildingGeometryTab);
            app.text32.Tag = 'text32';
            app.text32.Text = 'GFS configuration:';
            app.text33 = uilabel(app.BuildingGeometryTab);
            app.text33.Tag = 'text33';
            app.text33.Tooltip = {'number of GFS beams in the considered orthogonal direction only'};
            app.text33.Text = 'No. of beams';
            app.text32_2 = uilabel(app.BuildingGeometryTab);
            app.text32_2.Tag = 'text32';
            app.text32_2.Text = 'No. of columns';
            app.text25_2 = uilabel(app.BuildingGeometryTab);
            app.text25_2.Tag = 'text40';
            app.text25_2.Text = 'Building Data';
            app.BrowseButton = uibutton(app.BuildingGeometryTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tag = 'BrowseButton';
            app.BrowseButton.Text = 'browse';
            app.text84 = uilabel(app.BuildingGeometryTab);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Import EXCEL file';
            app.nGC = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.nGC.Limits = [1 Inf];
            app.nGC.ValueDisplayFormat = '%.0f';
            app.nGC.Tooltip = {'Total number of columns in the gravity framing system'};
            app.nGC.Value = 10;
            app.nGB = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.nGB.Limits = [1 Inf];
            app.nGB.ValueDisplayFormat = '%.0f';
            app.nGB.Tooltip = {'Number of beams in the gravity framing system in the considered direction only'};
            app.nGB.Value = 10;
            app.editExcel = uieditfield(app.BuildingGeometryTab, 'text');
            app.editExcel.Editable = 'off';
            app.showMRFButton = uibutton(app.BuildingGeometryTab, 'push');
            app.showMRFButton.ButtonPushedFcn = createCallbackFcn(app, @showMRFButtonPushed, true);
            app.showMRFButton.Tag = 'BrowseButton';
            app.showMRFButton.Icon = fullfile(pathToMLAPP, 'scope.png');
            app.showMRFButton.Visible = 'off';
            app.showMRFButton.Tooltip = {'Show frame sections'};
            app.showMRFButton.Text = '';
            app.text67 = uilabel(app.BuildingGeometryTab);
            app.text67.Tag = 'text67';
            app.text67.Enable = 'off';
            app.text67.Text = 'Bracing layout:';
            app.BraceLayoutpopup = uidropdown(app.BuildingGeometryTab);
            app.BraceLayoutpopup.Items = {'X-Bracing', 'Chevron'};
            app.BraceLayoutpopup.ItemsData = {'1', '2'};
            app.BraceLayoutpopup.Tag = 'BraceLayoutpopup';
            app.BraceLayoutpopup.Enable = 'off';
            app.BraceLayoutpopup.Value = '1';
            app.Image2 = uiimage(app.BuildingGeometryTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'resources', 'images', 'MRF_Layout_2D.png');
            app.NBay = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.NBay.Limits = [1 Inf];
            app.NBay.ValueDisplayFormat = '%.0f';
            app.NBay.Tooltip = {'Number of bays for the MF'};
            app.NBay.Value = 1;
            app.DIM1 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.DIM1.Limits = [0.0001 Inf];
            app.DIM1.ValueDisplayFormat = '%.1f';
            app.DIM1.Tooltip = {'Building width in the considered direction'};
            app.DIM1.Value = 100;
            app.Label = uilabel(app.BuildingGeometryTab);
            app.Label.Text = '';
            app.DIM2 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.DIM2.Limits = [0.0001 Inf];
            app.DIM2.ValueDisplayFormat = '%.1f';
            app.DIM2.Tooltip = {'Building width in the orthogonal direction'};
            app.DIM2.Value = 100;
            app.Label_2 = uilabel(app.BuildingGeometryTab);
            app.Label_2.Text = '';
            app.TAin2 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.TAin2.Limits = [0.0001 Inf];
            app.TAin2.ValueDisplayFormat = '%.1f';
            app.TAin2.Tooltip = {'The MF''s tributary length in the orthogonal direction'};
            app.TAin2.Value = 100;
            app.TAex1 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.TAex1.Limits = [0.0001 Inf];
            app.TAex1.ValueDisplayFormat = '%.1f';
            app.TAex1.Tooltip = {'Tributary length, in the considered direction, for the exterior MF '};
            app.TAex1.Value = 100;
            app.TAin1 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.TAin1.Limits = [0.0001 Inf];
            app.TAin1.ValueDisplayFormat = '%.1f';
            app.TAin1.Tooltip = {'Tributary length, in the considered direction, for the interior MF '};
            app.TAin1.Value = 100;
            app.text65 = uilabel(app.BuildingGeometryTab);
            app.text65.Tag = 'text65';
            app.text65.Text = 'Number of MFs in the considered direction';
            app.nMRF = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.nMRF.Limits = [2 Inf];
            app.nMRF.ValueDisplayFormat = '%.0f';
            app.nMRF.Value = 2;
            app.text25 = uilabel(app.BuildingGeometryTab);
            app.text25.Tag = 'text40';
            app.text25.Text = 'Building Geometry [unit: inches]';
            app.text35_22 = uilabel(app.BuildingGeometryTab);
            app.text35_22.Tag = 'text35';
            app.text35_22.Text = '----------------------------------------------------------------------';
            app.text35_23 = uilabel(app.BuildingGeometryTab);
            app.text35_23.Tag = 'text35';
            app.text35_23.Text = '----------------------------------------------------------------------';
            app.NStory = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.NStory.Limits = [1 Inf];
            app.NStory.ValueDisplayFormat = '%.0f';
            app.NStory.Tooltip = {'Number of stories'};
            app.NStory.Value = 1;
            app.illustrativebuildinglayoutLabel = uilabel(app.BuildingGeometryTab);
            app.illustrativebuildinglayoutLabel.Text = '*illustrative building layout';
            app.considereddirectionLabel = uilabel(app.BuildingGeometryTab);
            app.considereddirectionLabel.Text = '-------------> considered direction';
            app.SupportsConnectionsTab = uitab(app.TabGroup);
            app.SupportsConnectionsTab.Title = 'Supports & Connections';
            app.Supportpopup = uidropdown(app.SupportsConnectionsTab);
            app.Supportpopup.Items = {'Fixed', 'Pinned'};
            app.Supportpopup.ItemsData = {'1', '2'};
            app.Supportpopup.Tag = 'Supportpopup';
            app.Supportpopup.Value = '1';
            app.text31_2 = uilabel(app.SupportsConnectionsTab);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'MF column supports';
            app.Connectionpopup = uidropdown(app.SupportsConnectionsTab);
            app.Connectionpopup.Items = {'Reduced Beam Section', 'Welded/Bolted (Full-Section)', 'Semi-Rigid Extended Endplate'};
            app.Connectionpopup.ItemsData = {'1', '2', '3'};
            app.Connectionpopup.ValueChangedFcn = createCallbackFcn(app, @Connectionpopup_Callback, true);
            app.Connectionpopup.Tag = 'Connectionpopup';
            app.Connectionpopup.Value = '1';
            app.text56 = uilabel(app.SupportsConnectionsTab);
            app.text56.Tag = 'text56';
            app.text56.Text = 'a =';
            app.text57 = uilabel(app.SupportsConnectionsTab);
            app.text57.Tag = 'text57';
            app.text57.Text = 'b =';
            app.text58 = uilabel(app.SupportsConnectionsTab);
            app.text58.Tag = 'text58';
            app.text58.Text = 'c =';
            app.text69 = uilabel(app.SupportsConnectionsTab);
            app.text69.Tag = 'text69';
            app.text69.Text = 'bf';
            app.text70 = uilabel(app.SupportsConnectionsTab);
            app.text70.Tag = 'text70';
            app.text70.Text = 'd';
            app.text71 = uilabel(app.SupportsConnectionsTab);
            app.text71.Tag = 'text71';
            app.text71.Text = 'bf';
            app.text304 = uilabel(app.SupportsConnectionsTab);
            app.text304.Tag = 'text31';
            app.text304.Text = 'MF connection';
            app.Linkpopup = uidropdown(app.SupportsConnectionsTab);
            app.Linkpopup.Items = {'Rigid', 'Flexible'};
            app.Linkpopup.ItemsData = {'1', '2'};
            app.Linkpopup.ValueChangedFcn = createCallbackFcn(app, @LinkpopupValueChanged, true);
            app.Linkpopup.Tag = 'Linkpopup';
            app.Linkpopup.Value = '1';
            app.text72 = uilabel(app.SupportsConnectionsTab);
            app.text72.Tag = 'text72';
            app.text72.Text = 'Fs=';
            app.text73 = uilabel(app.SupportsConnectionsTab);
            app.text73.Tag = 'text73';
            app.text73.Text = 'Ks=';
            app.text74 = uilabel(app.SupportsConnectionsTab);
            app.text74.Tag = 'text74';
            app.text74.Text = 'kip';
            app.text75 = uilabel(app.SupportsConnectionsTab);
            app.text75.Tag = 'text75';
            app.text75.Text = 'kip/in';
            app.text31_4 = uilabel(app.SupportsConnectionsTab);
            app.text31_4.Tag = 'text31';
            app.text31_4.Text = 'MF to EGF link condition';
            app.Image = uiimage(app.SupportsConnectionsTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'RBS.png');
            app.a = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.a.Limits = [0 Inf];
            app.a.ValueDisplayFormat = '%.3f';
            app.a.Value = 0.625;
            app.b = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.b.Limits = [0 Inf];
            app.b.ValueDisplayFormat = '%.3f';
            app.b.Value = 0.75;
            app.c = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.c.Limits = [0 Inf];
            app.c.ValueDisplayFormat = '%.3f';
            app.c.Value = 0.25;
            app.Fs = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.Fs.Limits = [0 Inf];
            app.Fs.Value = 10000;
            app.Ks = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.Ks.Limits = [0 Inf];
            app.FuseF_ProfileCB = uicheckbox(app.SupportsConnectionsTab);
            app.FuseF_ProfileCB.Tag = 'FuseF_ProfileCB';
            app.FuseF_ProfileCB.Text = 'Non-uniform Fs Profile';
            app.Splicepopup = uidropdown(app.SupportsConnectionsTab);
            app.Splicepopup.Items = {'Shear splice', 'Moment splice'};
            app.Splicepopup.ItemsData = {'1', '2'};
            app.Splicepopup.Tag = 'Supportpopup';
            app.Splicepopup.Value = '1';
            app.text31_7 = uilabel(app.SupportsConnectionsTab);
            app.text31_7.Tag = 'text31';
            app.text31_7.Text = 'MF column splice condition';
            app.TipIcon5 = uibutton(app.SupportsConnectionsTab, 'push');
            app.TipIcon5.ButtonPushedFcn = createCallbackFcn(app, @TipIcon5ButtonPushed, true);
            app.TipIcon5.Text = '?';
            app.text302 = uilabel(app.SupportsConnectionsTab);
            app.text302.Tag = 'text78';
            app.text302.Enable = 'off';
            app.text302.Visible = 'off';
            app.text302.Text = 'Even floors';
            app.ConnectionEvenpopup = uidropdown(app.SupportsConnectionsTab);
            app.ConnectionEvenpopup.Items = {'Pinned', 'Shear', 'Moment'};
            app.ConnectionEvenpopup.ItemsData = {'1', '2', '3'};
            app.ConnectionEvenpopup.Tag = 'ConnectionEvenpopup';
            app.ConnectionEvenpopup.Enable = 'off';
            app.ConnectionEvenpopup.Visible = 'off';
            app.ConnectionEvenpopup.Value = '1';
            app.text30 = uilabel(app.SupportsConnectionsTab);
            app.text30.Tag = 'text31';
            app.text30.Enable = 'off';
            app.text30.Visible = 'off';
            app.text30.Text = 'BF beam mid-span condition';
            app.MidSpanpopup = uidropdown(app.SupportsConnectionsTab);
            app.MidSpanpopup.Items = {'Free to Sag', 'Floor-Constrained'};
            app.MidSpanpopup.ItemsData = {'1', '2'};
            app.MidSpanpopup.Tag = 'MidSpanpopup';
            app.MidSpanpopup.Enable = 'off';
            app.MidSpanpopup.Visible = 'off';
            app.MidSpanpopup.Value = '1';
            app.text303 = uilabel(app.SupportsConnectionsTab);
            app.text303.Tag = 'text79';
            app.text303.Enable = 'off';
            app.text303.Visible = 'off';
            app.text303.Text = 'Odd floors';
            app.text301 = uilabel(app.SupportsConnectionsTab);
            app.text301.Tag = 'text31';
            app.text301.Enable = 'off';
            app.text301.Visible = 'off';
            app.text301.Text = 'BF connection';
            app.ConnectionOddpopup = uidropdown(app.SupportsConnectionsTab);
            app.ConnectionOddpopup.Items = {'Pinned', 'Shear', 'Moment'};
            app.ConnectionOddpopup.ItemsData = {'1', '2', '3'};
            app.ConnectionOddpopup.Tag = 'ConnectionOddpopup';
            app.ConnectionOddpopup.Enable = 'off';
            app.ConnectionOddpopup.Visible = 'off';
            app.ConnectionOddpopup.Value = '1';
            app.TipIcon6 = uibutton(app.SupportsConnectionsTab, 'push');
            app.TipIcon6.ButtonPushedFcn = createCallbackFcn(app, @TipIcon6ButtonPushed, true);
            app.TipIcon6.Enable = 'off';
            app.TipIcon6.Visible = 'off';
            app.TipIcon6.Text = '?';
            app.text71_2 = uilabel(app.SupportsConnectionsTab);
            app.text71_2.Tag = 'text71';
            app.text71_2.Text = 'L';
            app.text58_2 = uilabel(app.SupportsConnectionsTab);
            app.text58_2.Tag = 'text58';
            app.text58_2.Text = 'MF beam is laterally braced at          segments';
            app.k_beambracing = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.k_beambracing.Limits = [0.2 1];
            app.k_beambracing.ValueDisplayFormat = '%.3f';
            app.k_beambracing.Tooltip = {'The laterally braced points along the beam length'};
            app.k_beambracing.Value = 0.5;
            app.GFconnectionpopup = uidropdown(app.SupportsConnectionsTab);
            app.GFconnectionpopup.Items = {'Shear-tab', 'Header-plate', 'Flush endplate', 'Extended endplate'};
            app.GFconnectionpopup.ItemsData = {'1', '2', '3', '4'};
            app.GFconnectionpopup.ValueChangedFcn = createCallbackFcn(app, @GFconnectionpopupValueChanged, true);
            app.GFconnectionpopup.Tag = 'Connectionpopup';
            app.GFconnectionpopup.Value = '1';
            app.text312 = uilabel(app.SupportsConnectionsTab);
            app.text312.Tag = 'text78';
            app.text312.Text = 'Column orientation';
            app.Orientationpopup = uidropdown(app.SupportsConnectionsTab);
            app.Orientationpopup.Items = {'Weak-axis', 'Strong-axis'};
            app.Orientationpopup.ItemsData = {'1', '2'};
            app.Orientationpopup.Tag = 'Orientationpopup';
            app.Orientationpopup.Tooltip = {'Orientation of the gravity column cross-section with respect to the considered building direction'};
            app.Orientationpopup.Value = '1';
            app.text313 = uilabel(app.SupportsConnectionsTab);
            app.text313.Tag = 'text78';
            app.text313.Text = 'Gravity connection type';
            app.text311 = uilabel(app.SupportsConnectionsTab);
            app.text311.Tag = 'text31';
            app.text311.Text = 'GFS Specifics';
            app.text35_18 = uilabel(app.SupportsConnectionsTab);
            app.text35_18.Tag = 'text35';
            app.text35_18.Text = '----------------------------------------------------------------------';
            app.text35_19 = uilabel(app.SupportsConnectionsTab);
            app.text35_19.Tag = 'text35';
            app.text35_19.Text = '----------------------------------------------------------------------';
            app.text35_20 = uilabel(app.SupportsConnectionsTab);
            app.text35_20.Tag = 'text35';
            app.text35_20.Text = '----------------------------------------------------------------------';
            app.SupportGFSpopup = uidropdown(app.SupportsConnectionsTab);
            app.SupportGFSpopup.Items = {'Fixed', 'Pinned'};
            app.SupportGFSpopup.ItemsData = {'1', '2'};
            app.SupportGFSpopup.Tag = 'Supportpopup';
            app.SupportGFSpopup.Value = '2';
            app.text314 = uilabel(app.SupportsConnectionsTab);
            app.text314.Tag = 'text78';
            app.text314.Text = 'Support';
            app.LoadsTab = uitab(app.TabGroup);
            app.LoadsTab.Title = 'Loads';
            app.text40 = uilabel(app.LoadsTab);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Typical';
            app.text41 = uilabel(app.LoadsTab);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Roof';
            app.text35 = uilabel(app.LoadsTab);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Floor Dead Load';
            app.text37 = uilabel(app.LoadsTab);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Cladding Load';
            app.text36 = uilabel(app.LoadsTab);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Floor Live Load';
            app.text24 = uilabel(app.LoadsTab);
            app.text24.Tag = 'text40';
            app.text24.Text = 'Loads [psf]';
            app.text44 = uilabel(app.LoadsTab);
            app.text44.Tag = 'text40';
            app.text44.Text = 'Load Combination Coefficients';
            app.text35_2 = uilabel(app.LoadsTab);
            app.text35_2.Tag = 'text35';
            app.text35_2.Text = 'Seismic weight';
            app.cDL_W = uieditfield(app.LoadsTab, 'numeric');
            app.cDL_W.Limits = [0 Inf];
            app.cDL_W.ValueDisplayFormat = '%.2f';
            app.cDL_W.Value = 1.05;
            app.cLL_W = uieditfield(app.LoadsTab, 'numeric');
            app.cLL_W.Limits = [0 Inf];
            app.cLL_W.ValueDisplayFormat = '%.2f';
            app.cLL_W.Value = 1.25;
            app.cCL_W = uieditfield(app.LoadsTab, 'numeric');
            app.cCL_W.Limits = [0 Inf];
            app.cCL_W.ValueDisplayFormat = '%.2f';
            app.cCL_W.Value = 1.05;
            app.DLtyp = uieditfield(app.LoadsTab, 'numeric');
            app.DLtyp.Limits = [0 Inf];
            app.DLroof = uieditfield(app.LoadsTab, 'numeric');
            app.DLroof.Limits = [0 Inf];
            app.LLtyp = uieditfield(app.LoadsTab, 'numeric');
            app.LLtyp.Limits = [0 Inf];
            app.LLroof = uieditfield(app.LoadsTab, 'numeric');
            app.LLroof.Limits = [0 Inf];
            app.CL = uieditfield(app.LoadsTab, 'numeric');
            app.CL.Limits = [0 Inf];
            app.text40_2 = uilabel(app.LoadsTab);
            app.text40_2.Tag = 'text40';
            app.text40_2.Text = 'Dead';
            app.text40_3 = uilabel(app.LoadsTab);
            app.text40_3.Tag = 'text40';
            app.text40_3.Text = 'Live';
            app.text40_4 = uilabel(app.LoadsTab);
            app.text40_4.Tag = 'text40';
            app.text40_4.Text = 'Cladding';
            app.text35_3 = uilabel(app.LoadsTab);
            app.text35_3.Tag = 'text35';
            app.text35_3.Text = 'Mass';
            app.cDL_M = uieditfield(app.LoadsTab, 'numeric');
            app.cDL_M.Limits = [0 Inf];
            app.cDL_M.ValueDisplayFormat = '%.2f';
            app.cDL_M.Value = 1;
            app.cLL_M = uieditfield(app.LoadsTab, 'numeric');
            app.cLL_M.Limits = [0 Inf];
            app.cLL_M.ValueDisplayFormat = '%.2f';
            app.cCL_M = uieditfield(app.LoadsTab, 'numeric');
            app.cCL_M.Limits = [0 Inf];
            app.cCL_M.ValueDisplayFormat = '%.2f';
            app.cCL_M.Value = 1;
            app.CB1 = uicheckbox(app.LoadsTab);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'default values';
            app.CB1.Value = true;
            app.text36_2 = uilabel(app.LoadsTab);
            app.text36_2.Tag = 'text36';
            app.text36_2.Text = 'Floor Generic Load';
            app.GLtyp = uieditfield(app.LoadsTab, 'numeric');
            app.GLtyp.Limits = [0 Inf];
            app.GLroof = uieditfield(app.LoadsTab, 'numeric');
            app.GLroof.Limits = [0 Inf];
            app.cGL_W = uieditfield(app.LoadsTab, 'numeric');
            app.cGL_W.Limits = [0 Inf];
            app.cGL_W.ValueDisplayFormat = '%.2f';
            app.text40_5 = uilabel(app.LoadsTab);
            app.text40_5.Tag = 'text40';
            app.text40_5.Text = 'Generic';
            app.cGL_M = uieditfield(app.LoadsTab, 'numeric');
            app.cGL_M.Limits = [0 Inf];
            app.cGL_M.ValueDisplayFormat = '%.2f';
            app.text24_2 = uilabel(app.LoadsTab);
            app.text24_2.Tag = 'text40';
            app.text24_2.Text = 'Load application option';
            app.LoadApplypopup = uidropdown(app.LoadsTab);
            app.LoadApplypopup.Items = {'Point load', 'Beam uniform load'};
            app.LoadApplypopup.ItemsData = {'1', '2'};
            app.LoadApplypopup.Tag = 'Supportpopup';
            app.LoadApplypopup.Value = '1';
            app.text35_10 = uilabel(app.LoadsTab);
            app.text35_10.Tag = 'text35';
            app.text35_10.Text = '----------------------------------------------------------------------';
            app.text35_11 = uilabel(app.LoadsTab);
            app.text35_11.Tag = 'text35';
            app.text35_11.Text = '----------------------------------------------------------------------';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.text52 = uilabel(app.MaterialsTab);
            app.text52.Tag = 'text52';
            app.text52.Text = 'E';
            app.text53 = uilabel(app.MaterialsTab);
            app.text53.Tag = 'text53';
            app.text53.Text = 'fy';
            app.text43 = uilabel(app.MaterialsTab);
            app.text43.Tag = 'text40';
            app.text43.Text = 'Material [ksi]';
            app.E = uieditfield(app.MaterialsTab, 'numeric');
            app.E.Limits = [0 Inf];
            app.E.Value = 29000;
            app.fy = uieditfield(app.MaterialsTab, 'numeric');
            app.fy.Limits = [0 Inf];
            app.fy.Tooltip = {'Nominal yield stress'};
            app.fy.Value = 55;
            app.text53_2 = uilabel(app.MaterialsTab);
            app.text53_2.Tag = 'text53';
            app.text53_2.Text = 'μ';
            app.mu0 = uieditfield(app.MaterialsTab, 'numeric');
            app.mu0.Limits = [0 Inf];
            app.mu0.Value = 0.3;
            app.text35_7 = uilabel(app.MaterialsTab);
            app.text35_7.Tag = 'text35';
            app.text35_7.Text = 'Beams & columns';
            app.text35_8 = uilabel(app.MaterialsTab);
            app.text35_8.Tag = 'text35';
            app.text35_8.Text = 'Rebar';
            app.text35_9 = uilabel(app.MaterialsTab);
            app.text35_9.Tag = 'text35';
            app.text35_9.Text = 'Concrete';
            app.Matpopup = uidropdown(app.MaterialsTab);
            app.Matpopup.Items = {'User-Defined', 'Generic Gr. 50', 'A992 Gr. 50', 'S355J2+N plate', 'S355J2+N section', 'S460NL', 'S690QL', 'A500 Gr. B', 'BCP325', 'BCR295', 'HYP400'};
            app.Matpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.Matpopup.ValueChangedFcn = createCallbackFcn(app, @MatpopupValueChanged, true);
            app.Matpopup.Tag = 'Supportpopup';
            app.Matpopup.Value = '1';
            app.fyR = uieditfield(app.MaterialsTab, 'numeric');
            app.fyR.Limits = [0 Inf];
            app.fyR.Value = 55;
            app.Er = uieditfield(app.MaterialsTab, 'numeric');
            app.Er.Limits = [0 Inf];
            app.Er.Value = 29000;
            app.muR = uieditfield(app.MaterialsTab, 'numeric');
            app.muR.Limits = [0 Inf];
            app.muR.Value = 0.3;
            app.text53_5 = uilabel(app.MaterialsTab);
            app.text53_5.Tag = 'text53';
            app.text53_5.Text = 'fc';
            app.fc = uieditfield(app.MaterialsTab, 'numeric');
            app.fc.Limits = [0 Inf];
            app.fc.Value = 5;
            app.Ec = uieditfield(app.MaterialsTab, 'numeric');
            app.Ec.Limits = [0 Inf];
            app.Ec.Value = 2600;
            app.muC = uieditfield(app.MaterialsTab, 'numeric');
            app.muC.Limits = [0 Inf];
            app.muC.Value = 0.2;
            app.fyBrace = uieditfield(app.MaterialsTab, 'numeric');
            app.fyBrace.Limits = [0 Inf];
            app.fyBrace.Enable = 'off';
            app.fyBrace.Tooltip = {'Nominal yield stress'};
            app.fyBrace.Value = 55;
            app.fyGP = uieditfield(app.MaterialsTab, 'numeric');
            app.fyGP.Limits = [0 Inf];
            app.fyGP.Enable = 'off';
            app.fyGP.Tooltip = {'Nominal yield stress'};
            app.fyGP.Value = 55;
            app.text35_12 = uilabel(app.MaterialsTab);
            app.text35_12.Tag = 'text35';
            app.text35_12.Text = 'Gusset plate';
            app.text35_13 = uilabel(app.MaterialsTab);
            app.text35_13.Tag = 'text35';
            app.text35_13.Text = 'Brace';
            app.text53_6 = uilabel(app.MaterialsTab);
            app.text53_6.Tag = 'text53';
            app.text53_6.Text = 'fu';
            app.fyP = uieditfield(app.MaterialsTab, 'numeric');
            app.fyP.Limits = [0 Inf];
            app.fyP.Enable = 'off';
            app.fyP.Tooltip = {'Nominal yield stress'};
            app.fyP.Value = 55;
            app.text35_16 = uilabel(app.MaterialsTab);
            app.text35_16.Tag = 'text35';
            app.text35_16.Text = 'Endplate';
            app.fuP = uieditfield(app.MaterialsTab, 'numeric');
            app.fuP.Limits = [0 Inf];
            app.fuP.Enable = 'off';
            app.fuP.Tooltip = {'Nominal ultimate stress'};
            app.fuP.Value = 65;
            app.fu = uieditfield(app.MaterialsTab, 'numeric');
            app.fu.Limits = [0 Inf];
            app.fu.Tooltip = {'Nominal ultimate stress'};
            app.fu.Value = 65;
            app.fyb = uieditfield(app.MaterialsTab, 'numeric');
            app.fyb.Limits = [0 Inf];
            app.fyb.Enable = 'off';
            app.fyb.Tooltip = {'Nominal yield stress'};
            app.fyb.Value = 130;
            app.text35_17 = uilabel(app.MaterialsTab);
            app.text35_17.Tag = 'text35';
            app.text35_17.Text = 'Bolt';
            app.fub = uieditfield(app.MaterialsTab, 'numeric');
            app.fub.Limits = [0 Inf];
            app.fub.Enable = 'off';
            app.fub.Tooltip = {'Nominal ultimate stress'};
            app.fub.Value = 145;
            app.text53_7 = uilabel(app.MaterialsTab);
            app.text53_7.Tag = 'text53';
            app.text53_7.Text = 'μ';
            app.text52_2 = uilabel(app.MaterialsTab);
            app.text52_2.Tag = 'text52';
            app.text52_2.Text = 'E';
            app.Ry_column = uieditfield(app.MaterialsTab, 'numeric');
            app.Ry_column.Limits = [1 2];
            app.Ry_column.Value = 1.15;
            app.text53_8 = uilabel(app.MaterialsTab);
            app.text53_8.Tag = 'text53';
            app.text53_8.Text = 'Column';
            app.text111 = uilabel(app.MaterialsTab);
            app.text111.Tag = 'text35';
            app.text111.Text = 'Expected to nominal stress ratio (Ry)';
            app.Ry_beam = uieditfield(app.MaterialsTab, 'numeric');
            app.Ry_beam.Limits = [1 2];
            app.Ry_beam.Value = 1.17;
            app.text53_9 = uilabel(app.MaterialsTab);
            app.text53_9.Tag = 'text53';
            app.text53_9.Text = 'Beam';
            app.Ry_brace = uieditfield(app.MaterialsTab, 'numeric');
            app.Ry_brace.Limits = [1 2];
            app.Ry_brace.Enable = 'off';
            app.Ry_brace.Value = 1.15;
            app.text53_10 = uilabel(app.MaterialsTab);
            app.text53_10.Tag = 'text53';
            app.text53_10.Text = 'Brace';
            app.Ry_gusset = uieditfield(app.MaterialsTab, 'numeric');
            app.Ry_gusset.Limits = [1 2];
            app.Ry_gusset.Enable = 'off';
            app.Ry_gusset.Value = 1.15;
            app.text53_11 = uilabel(app.MaterialsTab);
            app.text53_11.Tag = 'text53';
            app.text53_11.Text = 'Gusset';
            app.OtherTab = uitab(app.TabGroup);
            app.OtherTab.Title = 'Other';
            app.text73_3 = uilabel(app.OtherTab);
            app.text73_3.Tag = 'text73';
            app.text73_3.Text = 'Reduction factor for cracked section';
            app.coeff_cracked = uieditfield(app.OtherTab, 'numeric');
            app.coeff_cracked.Limits = [0.01 1];
            app.coeff_cracked.ValueDisplayFormat = '%.2f';
            app.coeff_cracked.Value = 0.5;
            app.text333 = uilabel(app.OtherTab);
            app.text333.Tag = 'text35';
            app.text333.Text = 'RC Members';
            app.bond_slip = uicheckbox(app.OtherTab);
            app.bond_slip.Tag = 'FuseF_ProfileCB';
            app.bond_slip.Text = 'Bond slip is possible';
            app.bond_slip.Value = true;
            app.text333_2 = uilabel(app.OtherTab);
            app.text333_2.Tag = 'text35';
            app.text333_2.Text = 'Composite Action';
            app.text56_2 = uilabel(app.OtherTab);
            app.text56_2.Tag = 'text56';
            app.text56_2.Text = 'Stiffness Coefficients:  MF Beams';
            app.Comp_I = uieditfield(app.OtherTab, 'numeric');
            app.Comp_I.Limits = [0 Inf];
            app.Comp_I.ValueDisplayFormat = '%.2f';
            app.Comp_I.Value = 1.4;
            app.text56_3 = uilabel(app.OtherTab);
            app.text56_3.Tag = 'text56';
            app.text56_3.Text = 'Gravity Beams';
            app.Comp_I_GC = uieditfield(app.OtherTab, 'numeric');
            app.Comp_I_GC.Limits = [0 Inf];
            app.Comp_I_GC.ValueDisplayFormat = '%.2f';
            app.Comp_I_GC.Value = 1.4;
            app.text56_4 = uilabel(app.OtherTab);
            app.text56_4.Tag = 'text56';
            app.text56_4.Text = 'Floor Slab Dimensions: ';
            app.trib = uieditfield(app.OtherTab, 'numeric');
            app.trib.Limits = [0 Inf];
            app.trib.ValueDisplayFormat = '%.2f';
            app.trib.Value = 3.5;
            app.ts = uieditfield(app.OtherTab, 'numeric');
            app.ts.Limits = [0 Inf];
            app.ts.ValueDisplayFormat = '%.2f';
            app.ts.Value = 4;
            app.text56_5 = uilabel(app.OtherTab);
            app.text56_5.Tag = 'text56';
            app.text56_5.Tooltip = {'Depth of the steel deck rib'};
            app.text56_5.Text = 't_rib = ';
            app.text56_6 = uilabel(app.OtherTab);
            app.text56_6.Tag = 'text56';
            app.text56_6.Tooltip = {'Thickness of the concrete slab portion above the steel deck rib'};
            app.text56_6.Text = 't_slab = ';
            app.text5 = uilabel(app.OtherTab);
            app.text5.Tag = 'text56';
            app.text5.Text = 'in';
            app.text6 = uilabel(app.OtherTab);
            app.text6.Tag = 'text56';
            app.text6.Text = 'in';
            app.text35_21 = uilabel(app.OtherTab);
            app.text35_21.Tag = 'text35';
            app.text35_21.Text = '----------------------------------------------------------------------';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Build_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Build_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end