classdef Build_CBF_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        BuildingGeometryTab     matlab.ui.container.Tab
        text31_8                matlab.ui.control.Label
        text31_7                matlab.ui.control.Label
        BraceLayoutpopup        matlab.ui.control.DropDown
        text67                  matlab.ui.control.Label
        editExcel               matlab.ui.control.EditField
        edit6                   matlab.ui.control.NumericEditField
        edit5                   matlab.ui.control.NumericEditField
        edit4                   matlab.ui.control.NumericEditField
        edit3                   matlab.ui.control.NumericEditField
        edit14                  matlab.ui.control.NumericEditField
        edit13                  matlab.ui.control.NumericEditField
        edit12                  matlab.ui.control.NumericEditField
        edit11                  matlab.ui.control.NumericEditField
        edit10                  matlab.ui.control.NumericEditField
        edit2                   matlab.ui.control.NumericEditField
        edit1                   matlab.ui.control.NumericEditField
        text84                  matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        text25_2                matlab.ui.control.Label
        text25                  matlab.ui.control.Label
        text32_2                matlab.ui.control.Label
        text42_2                matlab.ui.control.Label
        text65                  matlab.ui.control.Label
        text51                  matlab.ui.control.Label
        text50                  matlab.ui.control.Label
        text49                  matlab.ui.control.Label
        text47                  matlab.ui.control.Label
        text46                  matlab.ui.control.Label
        text45                  matlab.ui.control.Label
        text42                  matlab.ui.control.Label
        text33                  matlab.ui.control.Label
        text32                  matlab.ui.control.Label
        text31                  matlab.ui.control.Label
        SupportsConnectionsTab  matlab.ui.container.Tab
        TipIcon6                matlab.ui.control.Button
        TipIcon5                matlab.ui.control.Button
        ConnectionEvenpopup     matlab.ui.control.DropDown
        ConnectionOddpopup      matlab.ui.control.DropDown
        text31_6                matlab.ui.control.Label
        Splicepopup             matlab.ui.control.DropDown
        FuseF_ProfileCB         matlab.ui.control.CheckBox
        text31_5                matlab.ui.control.Label
        text79_2                matlab.ui.control.Label
        text78                  matlab.ui.control.Label
        MidSpanpopup            matlab.ui.control.DropDown
        edit64                  matlab.ui.control.NumericEditField
        edit63                  matlab.ui.control.NumericEditField
        text31_4                matlab.ui.control.Label
        text75                  matlab.ui.control.Label
        text74                  matlab.ui.control.Label
        text73                  matlab.ui.control.Label
        text72                  matlab.ui.control.Label
        Linkpopup               matlab.ui.control.DropDown
        text31_3                matlab.ui.control.Label
        text31_2                matlab.ui.control.Label
        Supportpopup            matlab.ui.control.DropDown
        LoadsTab                matlab.ui.container.Tab
        text35_8                matlab.ui.control.Label
        edit38                  matlab.ui.control.NumericEditField
        text40_5                matlab.ui.control.Label
        edit37                  matlab.ui.control.NumericEditField
        editGL2                 matlab.ui.control.NumericEditField
        editGL1                 matlab.ui.control.NumericEditField
        text36_2                matlab.ui.control.Label
        CB1                     matlab.ui.control.CheckBox
        edit36                  matlab.ui.control.NumericEditField
        edit35                  matlab.ui.control.NumericEditField
        edit34                  matlab.ui.control.NumericEditField
        text35_3                matlab.ui.control.Label
        text40_4                matlab.ui.control.Label
        text40_3                matlab.ui.control.Label
        text40_2                matlab.ui.control.Label
        edit20                  matlab.ui.control.NumericEditField
        edit19                  matlab.ui.control.NumericEditField
        edit18                  matlab.ui.control.NumericEditField
        edit16                  matlab.ui.control.NumericEditField
        edit15                  matlab.ui.control.NumericEditField
        edit32                  matlab.ui.control.NumericEditField
        edit31                  matlab.ui.control.NumericEditField
        edit30                  matlab.ui.control.NumericEditField
        text35_2                matlab.ui.control.Label
        text44                  matlab.ui.control.Label
        text24                  matlab.ui.control.Label
        text36                  matlab.ui.control.Label
        text37                  matlab.ui.control.Label
        text35                  matlab.ui.control.Label
        text41                  matlab.ui.control.Label
        text40                  matlab.ui.control.Label
        MemberModelingTab       matlab.ui.container.Tab
        Kshearstatus            matlab.ui.control.CheckBox
        text35_7                matlab.ui.control.Label
        textP1                  matlab.ui.control.Label
        text44_2                matlab.ui.control.Label
        edit68                  matlab.ui.control.NumericEditField
        textP2                  matlab.ui.control.Label
        ColMppopup              matlab.ui.control.DropDown
        edit67                  matlab.ui.control.NumericEditField
        edit66                  matlab.ui.control.NumericEditField
        edit65                  matlab.ui.control.NumericEditField
        text75_2                matlab.ui.control.Label
        text74_2                matlab.ui.control.Label
        text73_2                matlab.ui.control.Label
        text35_4                matlab.ui.control.Label
        ColElementpopup         matlab.ui.control.DropDown
        MaterialsTab            matlab.ui.container.Tab
        Materialpopup           matlab.ui.control.DropDown
        mu0                     matlab.ui.control.NumericEditField
        text53_4                matlab.ui.control.Label
        text53_3                matlab.ui.control.Label
        text53_2                matlab.ui.control.Label
        fyGP                    matlab.ui.control.NumericEditField
        fyBrace                 matlab.ui.control.NumericEditField
        fy                      matlab.ui.control.NumericEditField
        E                       matlab.ui.control.NumericEditField
        text43                  matlab.ui.control.Label
        text53                  matlab.ui.control.Label
        text52                  matlab.ui.control.Label
        SubmitButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
        TipWindow;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Build_CBF_App_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image.Visible='on';  
            
            app.ColMppopup.Value='2';
            app.ColMppopup.Enable='off';
            
            global ProjectName ProjectPath FrameType
            clc;
            load(strcat(ProjectPath,ProjectName))
            
            if FrameType==3
                app.text67.Text='EBF Layout';
                app.BraceLayoutpopup.Items={'Double-brace (Chevron)','Single-brace'};
            end
            
            if Units==1
                app.text25.Text='Building Geometry [unit: mm]';
                app.text24.Text='Loads [kN/m2]';
                app.text43.Text='Material [kN/mm2]';
                app.text74.Text='kN';
                app.text75.Text='kN/mm';
                convUnit=1000*1000;
            else
                app.text25.Text='Building Geometry [unit: inches]';
                app.text24.Text='Loads [psf]';
                app.text43.Text='Material [ksi]';
                app.text74.Text='kip';
                app.text75.Text='kip/in';
                convUnit=1000*12*12;
            end
            
            
            if Definestatus~=1
                app.ConnectionEvenpopup.Value='3';
                app.ConnectionOddpopup.Value='2';
                app.Supportpopup.Value='2';
                app.Splicepopup.Value='2';

                if Units==1
                    app.E.Value   = 200;
                    app.fy.Value  = 0.35;

                    app.fyBrace.Value  = 0.35;
                    app.fyGP.Value     = 0.35;

                end

            else
                app.BraceLayoutpopup.Value=num2str(BraceLayout);
            
                app.edit1.Value=DIM1;
                app.edit2.Value=DIM2;
                app.edit14.Value=nMF;
            
                app.edit3.Value=NStory;
                app.edit4.Value=NBay;
                app.edit5.Value=nGC;
                app.edit6.Value=nGB;
            
                app.edit10.Value=TAex1;
                app.edit11.Value=TAex2;
                app.edit12.Value=TAin1;
                app.edit13.Value=TAin2;
            
                app.edit15.Value=TypicalDL  *convUnit;
                app.edit16.Value=RoofDL     *convUnit;
                app.edit18.Value=TypicalLL  *convUnit;
                app.edit19.Value=RoofLL     *convUnit;
                app.edit20.Value=Cladding   *convUnit;
                app.editGL1.Value=TypicalGL *convUnit;
                app.editGL2.Value=RoofGL    *convUnit;
                
                app.CB1.Value=cLoad_status;
                app.edit30.Value=cDL_W;
                app.edit31.Value=cLL_W;
                app.edit32.Value=cCL_W;
                app.edit34.Value=cDL_M;
                app.edit35.Value=cLL_M;
                app.edit36.Value=cCL_M;
                app.edit37.Value=cGL_W;
                app.edit38.Value=cGL_M;
                
                app.MidSpanpopup.Value=num2str(MidSpanConstraint);
            
                app.Materialpopup.Value=num2str(SteelMatID);
                app.E.Value=E;
                app.fy.Value=fy;
                app.fyBrace.Value=fyBrace;
                app.fyGP.Value=fyGP;
                app.mu0.Value=mu0;

                app.ConnectionEvenpopup.Value=num2str(MFconnectionEven);
                app.ConnectionOddpopup.Value=num2str(MFconnectionOdd);
     
                app.ColElementpopup.Value=num2str(ColElementOption);
                app.Splicepopup.Value=num2str(SpliceConnection);
                app.ColMppopup.Value = num2str(PM_Option);
                app.edit68.Enable=Pscale;

                app.Linkpopup.Value=num2str(FloorLink);
                app.edit63.Value=Fs;
                app.edit64.Value=Ks;

                app.editExcel.FontColor = [0.0 1.0 0.0];
                app.editExcel.Value =[ExcelFilePath,ExcelFileName];

            end
            
            if GFX == 1
                app.edit5.Enable='on';
                app.edit6.Enable='on';
            else
                app.edit5.Enable='off';
                app.edit6.Enable='off';
            end
                        
            CB1ValueChanged(app);
            ConnectionEvenpopupValueChanged(app);
            LinkpopupValueChanged(app);
            ColElementpopupValueChanged(app);

        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            global MainDirectory FrameType
            clc

            NStory    = app.edit3.Value;
            NBay      = app.edit4.Value;
            FloorLink = str2double(app.Linkpopup.Value);
                
            [ExcelFileName,ExcelFilePath] = uigetfile('*.xlsx','Select the .xlsx file containg building geometry and member sizes');
            
            if ExcelFileName==0; return; end

            try
                app.editExcel.FontColor = [1.0 1.0 0.0];
                app.editExcel.Value     = ('Please wait ...'); drawnow

                % Read Frame Cross-Section Data From Excel
                if FrameType==2
                Import_ExcelData_CBF(ExcelFilePath,ExcelFileName, NStory, NBay,FloorLink);
                elseif FrameType==3
                Import_ExcelData_EBF(ExcelFilePath,ExcelFileName, NStory, NBay,FloorLink);
                end
                
                app.editExcel.FontColor  = [0.0 1.0 0.0];
                app.editExcel.Value      = 'Import successful!';
                app.editExcel.Value      = [ExcelFilePath,ExcelFileName];
            catch ME
                cd (MainDirectory)
                write_Error(ME);
                app.editExcel.FontColor  = [1.0 0.0 0.0];
                app.editExcel.Value      = 'Error importing file. See Error file!';
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath FrameType
            clc;
            load(strcat(ProjectPath,ProjectName))
            
            if exist ('ExcelFileName')==0
                errordlg('Please import first the Excel file containing CBF sections!');
                return;
            end
            
            if Units==1; convUnit=1000*1000; else; convUnit=1000*12*12; end

            try

            BraceLayout        = str2double(app.BraceLayoutpopup.Value);
            
            % Geometry
            DIM1    = app.edit1.Value;
            DIM2    = app.edit2.Value;
            nMF     = app.edit14.Value;
            NStory  = app.edit3.Value;
            NBay    = app.edit4.Value;
            nGC     = app.edit5.Value;
            nGB     = app.edit6.Value;
            TAex1   = app.edit10.Value;
            TAex2   = app.edit11.Value;
            TAin1   = app.edit12.Value;
            TAin2   = app.edit13.Value;
            
            % Check dimensions
            if DIM1 < 2*TAex1+(NBay+1-2)*TAin1
                errordlg('The dimension of the building in Dir #1 is less than the sum of those of the columns! Please check.');
                return;
            end
            
            [Column_Index, Beam_Index, Brace_Index, XSecID] = Get_Section_Database_Index(FrameType, NStory, NBay, MF_COLUMNS, MF_BEAMS, BRACES, Units);
            [GridX, GridY, AXIS, Elevation, HalfElevation, XTickLabel, YTickLabel] = Get_Grid (NStory, NBay,HStory, WBay);

            FootPrintArea = DIM1 * DIM2;
            Perimeter     = 2 * DIM1 + 2 * DIM2;
            if nMF==2
                TA_MF         = DIM1 * DIM2 / nMF;            
                TD_MF         = DIM2 / nMF;            
            else
                TA_MF         = DIM1 * DIM2 / (nMF-1);            
                TD_MF         = DIM2 / (nMF-1);                 
            end
            TribAreaEx    = TAex1 * TAex2;
            TribAreaIn    = TAin1 * TAin2;
            

            % Loads
            TypicalDL   = app.edit15.Value /convUnit;
            RoofDL      = app.edit16.Value /convUnit;
            TypicalLL   = app.edit18.Value /convUnit;
            RoofLL      = app.edit19.Value /convUnit;
            Cladding    = app.edit20.Value /convUnit;
            TypicalGL   = app.editGL1.Value/convUnit;
            RoofGL      = app.editGL2.Value/convUnit;
            
            if Units==2 && TypicalDL*convUnit<1
                warndlg('The entered loads do not seem to be in the correct units (psf)! Please check.', 'Load Alert');
            end

            % Load Combination Coefficeints
            cLoad_status=app.CB1.Value;
            cDL_W  = app.edit30.Value;
            cLL_W  = app.edit31.Value;
            cCL_W  = app.edit32.Value;
            cDL_M  = app.edit34.Value;
            cLL_M  = app.edit35.Value;
            cCL_M  = app.edit36.Value;
            cGL_W  = app.edit37.Value;
            cGL_M  = app.edit38.Value;
            
            % Materials
            SteelMatID   = str2double(app.Materialpopup.Value);
            SteelMatType = app.Materialpopup.Items{SteelMatID};
            E       = app.E.Value;
            fy      = app.fy.Value;
            fyBrace = app.fyBrace.Value;
            fyGP    = app.fyGP.Value;
            mu0      = app.mu0.Value;

            % Supports & Connections
            Support           = str2double(app.Supportpopup.Value);
            MFconnectionEven  = str2double(app.ConnectionEvenpopup.Value);
            MFconnectionOdd   = str2double(app.ConnectionOddpopup.Value);
            MidSpanConstraint = str2double(app.MidSpanpopup.Value);
            ColElementOption  = str2double(app.ColElementpopup.Value);
            SpliceConnection  = str2double(app.Splicepopup.Value);

            if app.Linkpopup.Value=='1'
                FloorLink = 1;
                Fs        = 1000000.;
                Ks        = 0;
            else
                FloorLink = 2;
                Fs        = app.edit63.Value;
                Ks        = app.edit64.Value;
            end
            
            nSegments    = app.edit65.Value;
            nIntegration = app.edit66.Value;
            initialGI    = app.edit67.Value;
            Kshearstatus = app.Kshearstatus.Value;

            if app.ColMppopup.Value=='1'
                PM_Option=1;
                Pscale=1.0;
            else
                PM_Option=2;
                Pscale=app.edit68.Value;
            end
            
            DampModeJ=min(DampModeJ,NStory);

            Definestatus = 1;
            
            % Save Data to .mat File
            save(strcat(ProjectPath,ProjectName), '-regexp', '^(?!(event|app)$).')

            app.MainApp.status1.BackgroundColor  = 'g';
            app.MainApp.status1.FontColor='k';
            if FrameType == 2
                if BraceLayout==1; app.MainApp.status1.Text=[num2str(NStory),'-story CBF - X-Brace']; end
                if BraceLayout==2; app.MainApp.status1.Text=[num2str(NStory),'-story CBF - Chevron']; end
            elseif FrameType == 3
                if BraceLayout==1; app.MainApp.status1.Text=[num2str(NStory),'-story EBF - Double']; end
                if BraceLayout==2; app.MainApp.status1.Text=[num2str(NStory),'-story EBF - Single']; end
            end
            app.MainApp.status1b.BackgroundColor = 'g';
            
            app.MainApp.AnalysisButton.Enable    = 'on';
            app.MainApp.GetperiodButton.Enable   = 'on';
            app.MainApp.BuildtclfileButton.Enable = 'on';
            
            app.MainApp.ProgressText.FontColor='g';
            app.MainApp.ProgressText.Text=('Building data defined'); drawnow;

            fclose all;
            
            figure1CloseRequest(app);
            
                            
            catch ME
                errordlg('An error has occured. Please see Error file!');
                cd(MainDirectory)
                write_Error(ME);
            end
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            if app.CB1.Value == 1
                app.edit30.Enable='off';
                app.edit31.Enable='off'; 
                app.edit32.Enable='off'; 
                app.edit34.Enable='off'; 
                app.edit35.Enable='off'; 
                app.edit36.Enable='off'; 
                app.edit37.Enable='off'; 
                app.edit38.Enable='off'; 
                
                app.edit30.Value=1.05;
                app.edit31.Value=0.25; 
                app.edit32.Value=1.05; 
                app.edit34.Value=1.0; 
                app.edit35.Value=0.0; 
                app.edit36.Value=1.0; 
                app.edit37.Value=1.0; 
                app.edit38.Value=1.0; 
            else
                app.edit30.Enable='on';
                app.edit31.Enable='on'; 
                app.edit32.Enable='on'; 
                app.edit34.Enable='on'; 
                app.edit35.Enable='on'; 
                app.edit36.Enable='on';                 
                app.edit37.Enable='on';                 
                app.edit38.Enable='on';                 
            end
            
        end

        % Value changed function: Linkpopup
        function LinkpopupValueChanged(app, event)
            if app.Linkpopup.Value == '1'
                app.edit63.Enable='off';
                app.edit64.Enable='off';
                app.FuseF_ProfileCB.Enable='off';
                
                app.edit63.Value = 10000;
                app.edit64.Value = 0.0;
            else
                app.edit63.Enable='on';
                app.edit64.Enable='on';
                app.FuseF_ProfileCB.Enable='on';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible = 'off';
            delete(app);
        end

        % Value changed function: ConnectionEvenpopup
        function ConnectionEvenpopupValueChanged(app, event)
            if app.BraceLayoutpopup.Value=='2'
               if  app.ConnectionEvenpopup.Value=='1'
                   app.ConnectionOddpopup.Value='1';
               elseif app.ConnectionEvenpopup.Value=='2'
                   app.ConnectionOddpopup.Value='2';
               elseif app.ConnectionEvenpopup.Value=='3'
                   app.ConnectionOddpopup.Value='3';
               end
            end            
        end

        % Value changed function: ConnectionOddpopup
        function ConnectionOddpopupValueChanged(app, event)
            if app.BraceLayoutpopup.Value=='2'
               if  app.ConnectionOddpopup.Value=='1'
                   app.ConnectionEvenpopup.Value='1';
               elseif app.ConnectionOddpopup.Value=='2'
                   app.ConnectionEvenpopup.Value='2';
               elseif app.ConnectionOddpopup.Value=='3'
                   app.ConnectionEvenpopup.Value='3';
               end
            end            
        end

        % Value changed function: ColElementpopup
        function ColElementpopupValueChanged(app, event)
            if app.ColElementpopup.Value == '1'
                app.ColMppopup.Enable='on';
                app.ColMppopup.Value='2';
                app.edit68.Enable = 'on';
                app.edit68.Value = 1.0;
                app.textP1.Enable='on';
                app.textP2.Enable='on';
            else
                app.ColMppopup.Enable='off';
                app.ColMppopup.Value='2';
                app.edit68.Enable = 'off';
                app.edit68.Value = 1.0;
                app.textP1.Enable='off';
                app.textP2.Enable='off';
            end            
        end

        % Value changed function: Materialpopup
        function MaterialpopupValueChanged(app, event)
            global MainDirectory Units
            clc;
            cd(MainDirectory);
            load('Material_Database.mat');
            if Units==1; transUnit=6.89476/1000; else; transUnit=1; end
            x=str2double(app.Materialpopup.Value);
            app.E.Value=Material.E(x+1,1)*transUnit;
            app.fy.Value=Material.fy(x+1,1)*transUnit;            
            app.fyBrace.Value=Material.fy(x+1,1)*transUnit;            
            app.fyGP.Value=Material.fy(x+1,1)*transUnit;            

        end

        % Button pushed function: TipIcon5
        function TipIcon5ButtonPushed(app, event)
            figure('position',[100 100 450 350],'color','white','Resize','off');
            data=imread('FLoor Link.jpg');
            imshow(data); 
        end

        % Value changed function: ColMppopup
        function ColMppopupValueChanged(app, event)
            if app.ColMppopup.Value=='1'
                app.edit68.Enable='off';
            else
                app.edit68.Enable='on';
            end
        end

        % Button pushed function: TipIcon6
        function TipIcon6ButtonPushed(app, event)
            figure('position',[100 100 450 350],'color','white','Resize','off');
            data=imread('MidPointConstraint.jpg');
            imshow(data); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Building Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'BuildButton';
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.BuildingGeometryTab = uitab(app.TabGroup);
            app.BuildingGeometryTab.Title = 'Building Geometry';
            app.text31 = uilabel(app.BuildingGeometryTab);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Building Plan Dimensions';
            app.text32 = uilabel(app.BuildingGeometryTab);
            app.text32.Tag = 'text32';
            app.text32.Text = 'GFS Configuration';
            app.text33 = uilabel(app.BuildingGeometryTab);
            app.text33.Tag = 'text33';
            app.text33.Tooltip = {'number of GFS beams in the considered orthogonal direction only'};
            app.text33.Text = 'Number of Beams';
            app.text42 = uilabel(app.BuildingGeometryTab);
            app.text42.Tag = 'text42';
            app.text42.Text = 'MF Configuration';
            app.text45 = uilabel(app.BuildingGeometryTab);
            app.text45.Tag = 'text45';
            app.text45.Text = 'Number of Bays';
            app.text46 = uilabel(app.BuildingGeometryTab);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Exterior MF Column Tributary Area';
            app.text47 = uilabel(app.BuildingGeometryTab);
            app.text47.Tag = 'text47';
            app.text47.Text = 'Interior MF Column Tributary Area';
            app.text49 = uilabel(app.BuildingGeometryTab);
            app.text49.Tag = 'text49';
            app.text49.Text = 'x';
            app.text50 = uilabel(app.BuildingGeometryTab);
            app.text50.Tag = 'text50';
            app.text50.Text = 'x';
            app.text51 = uilabel(app.BuildingGeometryTab);
            app.text51.Tag = 'text51';
            app.text51.Text = 'x';
            app.text65 = uilabel(app.BuildingGeometryTab);
            app.text65.Tag = 'text65';
            app.text65.Text = 'No. of MFs in the Considered Direction';
            app.text42_2 = uilabel(app.BuildingGeometryTab);
            app.text42_2.Tag = 'text42';
            app.text42_2.Text = 'Number of Stories';
            app.text32_2 = uilabel(app.BuildingGeometryTab);
            app.text32_2.Tag = 'text32';
            app.text32_2.Text = 'Number of Columns';
            app.text25 = uilabel(app.BuildingGeometryTab);
            app.text25.Tag = 'text40';
            app.text25.Text = 'Building Geometry [Units: inches]';
            app.text25_2 = uilabel(app.BuildingGeometryTab);
            app.text25_2.Tag = 'text40';
            app.text25_2.Text = 'Building Members';
            app.BrowseButton = uibutton(app.BuildingGeometryTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tag = 'BrowseButton';
            app.BrowseButton.Text = 'browse';
            app.text84 = uilabel(app.BuildingGeometryTab);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Import Excel File';
            app.edit1 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit1.Limits = [0.0001 Inf];
            app.edit1.Value = 100;
            app.edit2 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit2.Limits = [0.0001 Inf];
            app.edit2.Value = 100;
            app.edit10 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit10.Limits = [0.0001 Inf];
            app.edit10.Value = 100;
            app.edit11 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit11.Limits = [0.0001 Inf];
            app.edit11.Value = 100;
            app.edit12 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit12.Limits = [0.0001 Inf];
            app.edit12.Value = 100;
            app.edit13 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit13.Limits = [0.0001 Inf];
            app.edit13.Value = 100;
            app.edit14 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit14.Limits = [1 Inf];
            app.edit14.ValueDisplayFormat = '%.0f';
            app.edit14.Value = 2;
            app.edit3 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit3.Limits = [1 Inf];
            app.edit3.ValueDisplayFormat = '%.0f';
            app.edit3.Value = 1;
            app.edit4 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit4.Limits = [1 Inf];
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.Enable = 'off';
            app.edit4.Value = 1;
            app.edit5 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit5.Limits = [1 Inf];
            app.edit5.ValueDisplayFormat = '%.0f';
            app.edit5.Value = 10;
            app.edit6 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit6.Limits = [1 Inf];
            app.edit6.ValueDisplayFormat = '%.0f';
            app.edit6.Tooltip = {'number of GFS beams in the considered orthogonal direction only'};
            app.edit6.Value = 10;
            app.editExcel = uieditfield(app.BuildingGeometryTab, 'text');
            app.editExcel.Editable = 'off';
            app.text67 = uilabel(app.BuildingGeometryTab);
            app.text67.Tag = 'text67';
            app.text67.Text = 'Bracing Layout';
            app.BraceLayoutpopup = uidropdown(app.BuildingGeometryTab);
            app.BraceLayoutpopup.Items = {'X-Bracing', 'Chevron'};
            app.BraceLayoutpopup.ItemsData = {'1', '2'};
            app.BraceLayoutpopup.Tag = 'BraceLayoutpopup';
            app.BraceLayoutpopup.Value = '1';
            app.text31_7 = uilabel(app.BuildingGeometryTab);
            app.text31_7.Tag = 'text31';
            app.text31_7.Text = 'Dir #1';
            app.text31_8 = uilabel(app.BuildingGeometryTab);
            app.text31_8.Tag = 'text31';
            app.text31_8.Text = 'Dir #2';
            app.SupportsConnectionsTab = uitab(app.TabGroup);
            app.SupportsConnectionsTab.Title = 'Supports & Connections';
            app.Supportpopup = uidropdown(app.SupportsConnectionsTab);
            app.Supportpopup.Items = {'Fixed', 'Pinned'};
            app.Supportpopup.ItemsData = {'1', '2'};
            app.Supportpopup.Tag = 'Supportpopup';
            app.Supportpopup.Value = '1';
            app.text31_2 = uilabel(app.SupportsConnectionsTab);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'MF Column Supports';
            app.text31_3 = uilabel(app.SupportsConnectionsTab);
            app.text31_3.Tag = 'text31';
            app.text31_3.Text = 'MF Beam Mid-Span Condition';
            app.Linkpopup = uidropdown(app.SupportsConnectionsTab);
            app.Linkpopup.Items = {'Rigid', 'Flexible'};
            app.Linkpopup.ItemsData = {'1', '2'};
            app.Linkpopup.ValueChangedFcn = createCallbackFcn(app, @LinkpopupValueChanged, true);
            app.Linkpopup.Tag = 'Linkpopup';
            app.Linkpopup.Value = '1';
            app.text72 = uilabel(app.SupportsConnectionsTab);
            app.text72.Tag = 'text72';
            app.text72.Text = 'Fs=';
            app.text73 = uilabel(app.SupportsConnectionsTab);
            app.text73.Tag = 'text73';
            app.text73.Text = 'Ks=';
            app.text74 = uilabel(app.SupportsConnectionsTab);
            app.text74.Tag = 'text74';
            app.text74.Text = 'kip';
            app.text75 = uilabel(app.SupportsConnectionsTab);
            app.text75.Tag = 'text75';
            app.text75.Text = 'kip/in';
            app.text31_4 = uilabel(app.SupportsConnectionsTab);
            app.text31_4.Tag = 'text31';
            app.text31_4.Text = 'MF to EGF Link Condition';
            app.edit63 = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.edit63.Limits = [0 Inf];
            app.edit63.Value = 10000;
            app.edit64 = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.edit64.Limits = [0 Inf];
            app.MidSpanpopup = uidropdown(app.SupportsConnectionsTab);
            app.MidSpanpopup.Items = {'Free to Sag', 'Floor-Constrained'};
            app.MidSpanpopup.ItemsData = {'1', '2'};
            app.MidSpanpopup.Tag = 'MidSpanpopup';
            app.MidSpanpopup.Value = '1';
            app.text78 = uilabel(app.SupportsConnectionsTab);
            app.text78.Tag = 'text78';
            app.text78.Text = 'Even Floors';
            app.text79_2 = uilabel(app.SupportsConnectionsTab);
            app.text79_2.Tag = 'text79';
            app.text79_2.Text = 'Odd Floors';
            app.text31_5 = uilabel(app.SupportsConnectionsTab);
            app.text31_5.Tag = 'text31';
            app.text31_5.Text = 'MF Beam-to-Column Connection';
            app.FuseF_ProfileCB = uicheckbox(app.SupportsConnectionsTab);
            app.FuseF_ProfileCB.Tag = 'FuseF_ProfileCB';
            app.FuseF_ProfileCB.Text = 'Non-uniform Fs Profile';
            app.Splicepopup = uidropdown(app.SupportsConnectionsTab);
            app.Splicepopup.Items = {'Pinned', 'Fixed'};
            app.Splicepopup.ItemsData = {'1', '2'};
            app.Splicepopup.Tag = 'Supportpopup';
            app.Splicepopup.Value = '1';
            app.text31_6 = uilabel(app.SupportsConnectionsTab);
            app.text31_6.Tag = 'text31';
            app.text31_6.Text = 'MF Column Splice Condition';
            app.ConnectionOddpopup = uidropdown(app.SupportsConnectionsTab);
            app.ConnectionOddpopup.Items = {'Pinned', 'Shear', 'Moment'};
            app.ConnectionOddpopup.ItemsData = {'1', '2', '3'};
            app.ConnectionOddpopup.ValueChangedFcn = createCallbackFcn(app, @ConnectionOddpopupValueChanged, true);
            app.ConnectionOddpopup.Tag = 'ConnectionOddpopup';
            app.ConnectionOddpopup.Value = '1';
            app.ConnectionEvenpopup = uidropdown(app.SupportsConnectionsTab);
            app.ConnectionEvenpopup.Items = {'Pinned', 'Shear', 'Moment'};
            app.ConnectionEvenpopup.ItemsData = {'1', '2', '3'};
            app.ConnectionEvenpopup.ValueChangedFcn = createCallbackFcn(app, @ConnectionEvenpopupValueChanged, true);
            app.ConnectionEvenpopup.Tag = 'ConnectionEvenpopup';
            app.ConnectionEvenpopup.Value = '1';
            app.TipIcon5 = uibutton(app.SupportsConnectionsTab, 'push');
            app.TipIcon5.ButtonPushedFcn = createCallbackFcn(app, @TipIcon5ButtonPushed, true);
            app.TipIcon5.Text = '?';
            app.TipIcon6 = uibutton(app.SupportsConnectionsTab, 'push');
            app.TipIcon6.ButtonPushedFcn = createCallbackFcn(app, @TipIcon6ButtonPushed, true);
            app.TipIcon6.Text = '?';
            app.LoadsTab = uitab(app.TabGroup);
            app.LoadsTab.Title = 'Loads';
            app.text40 = uilabel(app.LoadsTab);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Typical';
            app.text41 = uilabel(app.LoadsTab);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Roof';
            app.text35 = uilabel(app.LoadsTab);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Floor Dead Load';
            app.text37 = uilabel(app.LoadsTab);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Cladding Load';
            app.text36 = uilabel(app.LoadsTab);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Floor Live Load';
            app.text24 = uilabel(app.LoadsTab);
            app.text24.Tag = 'text40';
            app.text24.Text = 'Loads [psf]';
            app.text44 = uilabel(app.LoadsTab);
            app.text44.Tag = 'text40';
            app.text44.Text = 'Load Combination Coefficients';
            app.text35_2 = uilabel(app.LoadsTab);
            app.text35_2.Tag = 'text35';
            app.text35_2.Text = 'Seismic weight';
            app.edit30 = uieditfield(app.LoadsTab, 'numeric');
            app.edit30.Limits = [0 Inf];
            app.edit30.ValueDisplayFormat = '%.2f';
            app.edit30.Value = 1.05;
            app.edit31 = uieditfield(app.LoadsTab, 'numeric');
            app.edit31.Limits = [0 Inf];
            app.edit31.ValueDisplayFormat = '%.2f';
            app.edit31.Value = 1.25;
            app.edit32 = uieditfield(app.LoadsTab, 'numeric');
            app.edit32.Limits = [0 Inf];
            app.edit32.ValueDisplayFormat = '%.2f';
            app.edit32.Value = 1.05;
            app.edit15 = uieditfield(app.LoadsTab, 'numeric');
            app.edit15.Limits = [0 Inf];
            app.edit16 = uieditfield(app.LoadsTab, 'numeric');
            app.edit16.Limits = [0 Inf];
            app.edit18 = uieditfield(app.LoadsTab, 'numeric');
            app.edit18.Limits = [0 Inf];
            app.edit19 = uieditfield(app.LoadsTab, 'numeric');
            app.edit19.Limits = [0 Inf];
            app.edit20 = uieditfield(app.LoadsTab, 'numeric');
            app.edit20.Limits = [0 Inf];
            app.text40_2 = uilabel(app.LoadsTab);
            app.text40_2.Tag = 'text40';
            app.text40_2.Text = 'Dead';
            app.text40_3 = uilabel(app.LoadsTab);
            app.text40_3.Tag = 'text40';
            app.text40_3.Text = 'Live';
            app.text40_4 = uilabel(app.LoadsTab);
            app.text40_4.Tag = 'text40';
            app.text40_4.Text = 'Cladding';
            app.text35_3 = uilabel(app.LoadsTab);
            app.text35_3.Tag = 'text35';
            app.text35_3.Text = 'Mass';
            app.edit34 = uieditfield(app.LoadsTab, 'numeric');
            app.edit34.Limits = [0 Inf];
            app.edit34.ValueDisplayFormat = '%.2f';
            app.edit34.Value = 1;
            app.edit35 = uieditfield(app.LoadsTab, 'numeric');
            app.edit35.Limits = [0 Inf];
            app.edit35.ValueDisplayFormat = '%.2f';
            app.edit36 = uieditfield(app.LoadsTab, 'numeric');
            app.edit36.Limits = [0 Inf];
            app.edit36.ValueDisplayFormat = '%.2f';
            app.edit36.Value = 1;
            app.CB1 = uicheckbox(app.LoadsTab);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'default values';
            app.CB1.Value = true;
            app.text36_2 = uilabel(app.LoadsTab);
            app.text36_2.Tag = 'text36';
            app.text36_2.Text = 'Floor Generic Load';
            app.editGL1 = uieditfield(app.LoadsTab, 'numeric');
            app.editGL1.Limits = [0 Inf];
            app.editGL2 = uieditfield(app.LoadsTab, 'numeric');
            app.editGL2.Limits = [0 Inf];
            app.edit37 = uieditfield(app.LoadsTab, 'numeric');
            app.edit37.Limits = [0 Inf];
            app.edit37.ValueDisplayFormat = '%.2f';
            app.text40_5 = uilabel(app.LoadsTab);
            app.text40_5.Tag = 'text40';
            app.text40_5.Text = 'Generic';
            app.edit38 = uieditfield(app.LoadsTab, 'numeric');
            app.edit38.Limits = [0 Inf];
            app.edit38.ValueDisplayFormat = '%.2f';
            app.text35_8 = uilabel(app.LoadsTab);
            app.text35_8.Tag = 'text35';
            app.text35_8.Text = '----------------------------------------------------------------------';
            app.MemberModelingTab = uitab(app.TabGroup);
            app.MemberModelingTab.Title = 'Member Modeling';
            app.ColElementpopup = uidropdown(app.MemberModelingTab);
            app.ColElementpopup.Items = {'Elastic Beam-Column', 'Fiber D-based', 'Fiber F-based'};
            app.ColElementpopup.ItemsData = {'1', '2', '3'};
            app.ColElementpopup.ValueChangedFcn = createCallbackFcn(app, @ColElementpopupValueChanged, true);
            app.ColElementpopup.Tag = 'ColElementpopup';
            app.ColElementpopup.Value = '1';
            app.text35_4 = uilabel(app.MemberModelingTab);
            app.text35_4.Tag = 'text35';
            app.text35_4.Text = 'MF Column Elements';
            app.text73_2 = uilabel(app.MemberModelingTab);
            app.text73_2.Tag = 'text73';
            app.text73_2.Text = 'Number of segments along length';
            app.text74_2 = uilabel(app.MemberModelingTab);
            app.text74_2.Tag = 'text74';
            app.text74_2.Text = 'Number of integration points per segment';
            app.text75_2 = uilabel(app.MemberModelingTab);
            app.text75_2.Tag = 'text75';
            app.text75_2.Text = 'Global mid-length imperfection [*L]';
            app.edit65 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit65.Limits = [1 Inf];
            app.edit65.ValueDisplayFormat = '%.0f';
            app.edit65.Value = 8;
            app.edit66 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit66.Limits = [1 Inf];
            app.edit66.ValueDisplayFormat = '%.0f';
            app.edit66.Value = 5;
            app.edit67 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit67.Limits = [0 Inf];
            app.edit67.Value = 0.001;
            app.ColMppopup = uidropdown(app.MemberModelingTab);
            app.ColMppopup.Items = {'Max Pushover Load', 'Scaled Gravity Load'};
            app.ColMppopup.ItemsData = {'1', '2'};
            app.ColMppopup.ValueChangedFcn = createCallbackFcn(app, @ColMppopupValueChanged, true);
            app.ColMppopup.Tag = 'ColElementpopup';
            app.ColMppopup.Value = '1';
            app.textP2 = uilabel(app.MemberModelingTab);
            app.textP2.Tag = 'text75';
            app.textP2.Text = 'Scale factor';
            app.edit68 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit68.Limits = [0 Inf];
            app.edit68.Value = 1;
            app.text44_2 = uilabel(app.MemberModelingTab);
            app.text44_2.Tag = 'text40';
            app.text44_2.Text = 'MF Column P-M Interaction';
            app.textP1 = uilabel(app.MemberModelingTab);
            app.textP1.Tag = 'text35';
            app.textP1.Text = 'P computed based on';
            app.text35_7 = uilabel(app.MemberModelingTab);
            app.text35_7.Tag = 'text35';
            app.text35_7.Text = 'Fiber-based Elements (Braces and/or Columns)';
            app.Kshearstatus = uicheckbox(app.MemberModelingTab);
            app.Kshearstatus.Tag = 'Kshear_CB';
            app.Kshearstatus.Tooltip = {'This option can be used to modify the moment of inertia of elasticBeamColumn and ModElasticBeam2d to account for the shear flexibility of I-shaped members, which is currently overlooked by these elements.'};
            app.Kshearstatus.Text = 'Account for shear flexibility in elastic beam-column elements';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.text52 = uilabel(app.MaterialsTab);
            app.text52.Tag = 'text52';
            app.text52.Text = 'E=';
            app.text53 = uilabel(app.MaterialsTab);
            app.text53.Tag = 'text53';
            app.text53.Text = 'fy=';
            app.text43 = uilabel(app.MaterialsTab);
            app.text43.Tag = 'text40';
            app.text43.Text = 'Material [ksi]';
            app.E = uieditfield(app.MaterialsTab, 'numeric');
            app.E.Limits = [0 Inf];
            app.E.Value = 29000;
            app.fy = uieditfield(app.MaterialsTab, 'numeric');
            app.fy.Limits = [0 Inf];
            app.fy.Value = 55;
            app.fyBrace = uieditfield(app.MaterialsTab, 'numeric');
            app.fyBrace.Limits = [0 Inf];
            app.fyBrace.Value = 55;
            app.fyGP = uieditfield(app.MaterialsTab, 'numeric');
            app.fyGP.Limits = [0 Inf];
            app.fyGP.Value = 55;
            app.text53_2 = uilabel(app.MaterialsTab);
            app.text53_2.Tag = 'text53';
            app.text53_2.Text = 'Brace';
            app.text53_3 = uilabel(app.MaterialsTab);
            app.text53_3.Tag = 'text53';
            app.text53_3.Text = 'Gusset';
            app.text53_4 = uilabel(app.MaterialsTab);
            app.text53_4.Tag = 'text53';
            app.text53_4.Text = 'μ =';
            app.mu0 = uieditfield(app.MaterialsTab, 'numeric');
            app.mu0.Limits = [0 Inf];
            app.mu0.Value = 0.3;
            app.Materialpopup = uidropdown(app.MaterialsTab);
            app.Materialpopup.Items = {'User-Defined', 'Generic Gr. 50', 'A992 Gr. 50', 'S355J2+N plate', 'S355J2+N section', 'S460NL', 'S690QL', 'A500 Gr. B', 'BCP325', 'BCR295', 'HYP400'};
            app.Materialpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.Materialpopup.ValueChangedFcn = createCallbackFcn(app, @MaterialpopupValueChanged, true);
            app.Materialpopup.Tag = 'Supportpopup';
            app.Materialpopup.Value = '1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Build_CBF_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Build_CBF_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end