classdef Build_MRF_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        SubmitButton            matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        BuildingGeometryTab     matlab.ui.container.Tab
        text31                  matlab.ui.control.Label
        text32                  matlab.ui.control.Label
        text33                  matlab.ui.control.Label
        text42                  matlab.ui.control.Label
        text45                  matlab.ui.control.Label
        text46                  matlab.ui.control.Label
        text47                  matlab.ui.control.Label
        text49                  matlab.ui.control.Label
        text50                  matlab.ui.control.Label
        text51                  matlab.ui.control.Label
        text65                  matlab.ui.control.Label
        text42_2                matlab.ui.control.Label
        text32_2                matlab.ui.control.Label
        text25                  matlab.ui.control.Label
        text25_2                matlab.ui.control.Label
        BrowseButton            matlab.ui.control.Button
        text84                  matlab.ui.control.Label
        edit1                   matlab.ui.control.NumericEditField
        edit2                   matlab.ui.control.NumericEditField
        edit10                  matlab.ui.control.NumericEditField
        edit11                  matlab.ui.control.NumericEditField
        edit12                  matlab.ui.control.NumericEditField
        edit13                  matlab.ui.control.NumericEditField
        edit14                  matlab.ui.control.NumericEditField
        edit3                   matlab.ui.control.NumericEditField
        edit4                   matlab.ui.control.NumericEditField
        edit5                   matlab.ui.control.NumericEditField
        edit6                   matlab.ui.control.NumericEditField
        editExcel               matlab.ui.control.EditField
        text31_5                matlab.ui.control.Label
        text31_6                matlab.ui.control.Label
        SupportsConnectionsTab  matlab.ui.container.Tab
        Supportpopup            matlab.ui.control.DropDown
        text31_2                matlab.ui.control.Label
        Connectionpopup         matlab.ui.control.DropDown
        text56                  matlab.ui.control.Label
        text57                  matlab.ui.control.Label
        text58                  matlab.ui.control.Label
        text69                  matlab.ui.control.Label
        text70                  matlab.ui.control.Label
        text71                  matlab.ui.control.Label
        text31_3                matlab.ui.control.Label
        Linkpopup               matlab.ui.control.DropDown
        text72                  matlab.ui.control.Label
        text73                  matlab.ui.control.Label
        text74                  matlab.ui.control.Label
        text75                  matlab.ui.control.Label
        text31_4                matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        edit23                  matlab.ui.control.NumericEditField
        edit24                  matlab.ui.control.NumericEditField
        edit25                  matlab.ui.control.NumericEditField
        edit26                  matlab.ui.control.NumericEditField
        edit27                  matlab.ui.control.NumericEditField
        FuseF_ProfileCB         matlab.ui.control.CheckBox
        Splicepopup             matlab.ui.control.DropDown
        text31_7                matlab.ui.control.Label
        TipIcon5                matlab.ui.control.Button
        LoadsMaterialTab        matlab.ui.container.Tab
        text40                  matlab.ui.control.Label
        text41                  matlab.ui.control.Label
        text35                  matlab.ui.control.Label
        text37                  matlab.ui.control.Label
        text36                  matlab.ui.control.Label
        text24                  matlab.ui.control.Label
        text52                  matlab.ui.control.Label
        text53                  matlab.ui.control.Label
        text43                  matlab.ui.control.Label
        text44                  matlab.ui.control.Label
        text35_2                matlab.ui.control.Label
        edit30                  matlab.ui.control.NumericEditField
        edit31                  matlab.ui.control.NumericEditField
        edit32                  matlab.ui.control.NumericEditField
        edit15                  matlab.ui.control.NumericEditField
        edit16                  matlab.ui.control.NumericEditField
        edit18                  matlab.ui.control.NumericEditField
        edit19                  matlab.ui.control.NumericEditField
        edit20                  matlab.ui.control.NumericEditField
        text40_2                matlab.ui.control.Label
        text40_3                matlab.ui.control.Label
        text40_4                matlab.ui.control.Label
        text35_3                matlab.ui.control.Label
        edit34                  matlab.ui.control.NumericEditField
        edit35                  matlab.ui.control.NumericEditField
        edit36                  matlab.ui.control.NumericEditField
        edit21                  matlab.ui.control.NumericEditField
        edit22                  matlab.ui.control.NumericEditField
        CB1                     matlab.ui.control.CheckBox
        text53_2                matlab.ui.control.Label
        edit29                  matlab.ui.control.NumericEditField
        text36_2                matlab.ui.control.Label
        editGL1                 matlab.ui.control.NumericEditField
        editGL2                 matlab.ui.control.NumericEditField
        edit37                  matlab.ui.control.NumericEditField
        text40_5                matlab.ui.control.Label
        edit38                  matlab.ui.control.NumericEditField
        text35_7                matlab.ui.control.Label
        text35_8                matlab.ui.control.Label
        text35_9                matlab.ui.control.Label
        Materialpopup           matlab.ui.control.DropDown
        text53_3                matlab.ui.control.Label
        fyR                     matlab.ui.control.NumericEditField
        text52_2                matlab.ui.control.Label
        Er                      matlab.ui.control.NumericEditField
        text53_4                matlab.ui.control.Label
        muR                     matlab.ui.control.NumericEditField
        text53_5                matlab.ui.control.Label
        fc                      matlab.ui.control.NumericEditField
        text52_3                matlab.ui.control.Label
        Ec                      matlab.ui.control.NumericEditField
        text53_6                matlab.ui.control.Label
        muC                     matlab.ui.control.NumericEditField
        MemberModelingTab       matlab.ui.container.Tab
        ColElementpopup         matlab.ui.control.DropDown
        text35_4                matlab.ui.control.Label
        text73_2                matlab.ui.control.Label
        text74_2                matlab.ui.control.Label
        text75_2                matlab.ui.control.Label
        edit65                  matlab.ui.control.NumericEditField
        edit66                  matlab.ui.control.NumericEditField
        edit67                  matlab.ui.control.NumericEditField
        textP1                  matlab.ui.control.Label
        textP2                  matlab.ui.control.Label
        edit68                  matlab.ui.control.NumericEditField
        text444                 matlab.ui.control.Label
        text35_6                matlab.ui.control.Label
        ColMppopup              matlab.ui.control.DropDown
        text73_3                matlab.ui.control.Label
        coeff_cracked           matlab.ui.control.NumericEditField
        text333                 matlab.ui.control.Label
        bond_slip               matlab.ui.control.CheckBox
        BeamElementpopup        matlab.ui.control.DropDown
        text351                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp;
        TipWindow;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Build_MRF_App_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image.Visible='on';  
            
            global MainDirectory ProjectName ProjectPath Units
            clc;
            cd(ProjectPath)
            load(ProjectName)
            cd(MainDirectory)
            
            if Units==1
                app.text25.Text='Building Geometry [unit: mm]';
                app.text24.Text='Loads [kN/m2]';
                app.text43.Text='Material [kN/mm2]';
                app.text74.Text='kN';
                app.text75.Text='kN/mm';
                convUnit=1000*1000;
            else
                app.text25.Text='Building Geometry [unit: inches]';
                app.text24.Text='Loads [psf]';
                app.text43.Text='Material [ksi]';
                app.text74.Text='kip';
                app.text75.Text='kip/in';
                convUnit=1000*12*12;
            end

            
            if Definestatus~=1
                
                app.Connectionpopup.Value='1';
                app.Supportpopup.Value='1';
                app.Splicepopup.Value='2';

            else
            
                app.edit1.Value=DIM1;
                app.edit2.Value=DIM2;
                app.edit14.Value=nMF;
            
                app.edit3.Value=NStory;
                app.edit4.Value=NBay;
                app.edit5.Value=nGC*nMF;
                app.edit6.Value=nGB*nMF;
            
                app.edit10.Value=TAex1;
                app.edit11.Value=TAex2;
                app.edit12.Value=TAin1;
                app.edit13.Value=TAin2;
            
                app.edit15.Value=TypicalDL *convUnit;
                app.edit16.Value=RoofDL    *convUnit;
                app.edit18.Value=TypicalLL *convUnit;
                app.edit19.Value=RoofLL    *convUnit;
                app.edit20.Value=Cladding  *convUnit;
                app.editGL1.Value=TypicalGL*convUnit;
                app.editGL2.Value=RoofGL   *convUnit;
                
                app.CB1.Value=cLoad_status;
                app.edit30.Value=cDL_W;
                app.edit31.Value=cLL_W;
                app.edit32.Value=cCL_W;
                app.edit37.Value=cGL_W;
                app.edit34.Value=cDL_M;
                app.edit35.Value=cLL_M;
                app.edit36.Value=cCL_M;
                app.edit38.Value=cGL_M;
                
                app.Materialpopup.Value=num2str(SteelMatID);
                app.edit21.Value=E;
                app.edit22.Value=fy;
                app.edit29.Value=mu;
                if FrameType==4
                    app.Er.Value=Er;
                    app.Ec.Value=Ec;
                    app.fc.Value=fc;
                    app.fyR.Value=fyR;
                    app.muR.Value=muR;
                    app.muC.Value=muC;
                    app.BeamElementpopup.Value=num2str(BeamElementOption);
                    app.coeff_cracked.Value = coeff_cracked;
                    app.bond_slip.Value = bond_slip;
                end
                app.Supportpopup.Value=num2str(Support);
                app.Connectionpopup.Value=num2str(MFconnection+1);
                
                app.edit23.Value=a;
                app.edit24.Value=b;
                app.edit25.Value=c;
            
                app.Linkpopup.Value=num2str(FloorLink);
                app.edit26.Value=Fs;
                app.edit27.Value=Ks;
                
                app.editExcel.FontColor = [0.0 1.0 0.0];
                app.editExcel.Value =[ExcelFilePath,ExcelFileName];
                
                app.ColElementpopup.Value = num2str(ColElementOption);
                app.edit65.Enable=nSegments;
                app.edit66.Enable=nIntegration;
                app.edit67.Enable=initialGI;
                app.Splicepopup.Value = num2str(SpliceConnection);
                
                app.ColMppopup.Value = num2str(PM_Option);
                app.edit68.Enable=Pscale;
                
            end
            
            if GFX == 1
                app.edit5.Enable='on';
                app.edit6.Enable='on';
            else
                app.edit5.Enable='off';
                app.edit6.Enable='off';
            end
               
            if FrameType ~= 4
                app.text351.Enable='off';
                app.BeamElementpopup.Enable='off';
                
                app.Er.Enable='off';
                app.Ec.Enable='off';
                app.fc.Enable='off';
                app.fyR.Enable='off';
                app.muR.Enable='off';
                app.muC.Enable='off';

                app.text333.Visible='off';
                app.coeff_cracked.Visible='off';
                app.bond_slip.Visible='off';
            else
                
                app.Splicepopup.Enable='off';
                app.Connectionpopup.Enable='off';
                app.Image.Enable='off';
                app.edit23.Enable='off';
                app.edit24.Enable='off';
                app.edit25.Enable='off';

                app.text351.Enable='on';
                app.BeamElementpopup.Enable='on';
                
                app.Er.Enable='on';
                app.Ec.Enable='on';
                app.fc.Enable='on';
                app.fyR.Enable='on';
                app.muR.Enable='on';
                app.muC.Enable='on';

                app.text333.Visible='on';
                app.coeff_cracked.Visible='on';
                app.bond_slip.Visible='on';
            end
                        
            Connectionpopup_Callback(app);
            CB1ValueChanged(app);
            LinkpopupValueChanged(app);
            ColElementpopupValueChanged(app);
            ColMppopupValueChanged(app);

        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            global MainDirectory FrameType
            clc

            NStory    = app.edit3.Value;
            NBay      = app.edit4.Value;
            FloorLink = str2double(app.Linkpopup.Value);
          
            [ExcelFileName,ExcelFilePath] = uigetfile('*.xlsx','Select the .xlsx file containg building geometry and member sizes');
            
            try
                app.editExcel.FontColor = [1.0 1.0 0.0];
                app.editExcel.Value     = ('Please wait ...'); drawnow;

                % Read Frame Cross-Section Data From Excel
                if FrameType==1
                    Import_ExcelData_MRF(ExcelFilePath,ExcelFileName,NStory,NBay,FloorLink);
                else
                    Import_ExcelData_RCMRF(ExcelFilePath,ExcelFileName,NStory,NBay,FloorLink);                
                end
                
                app.editExcel.FontColor = [0.0 1.0 0.0];
                app.editExcel.Value     = 'Import successful!'; drawnow;
            catch ME
                cd (MainDirectory)
                write_Error(ME);
                app.editExcel.FontColor = [1 0. 0.];
                app.editExcel.Value     = 'Error importing file. See Error file!'; drawnow;
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath FrameType
            clc;
            cd(ProjectPath);
            load(ProjectName);
            cd(MainDirectory);
                        
            if exist ('ExcelFileName')==0
                errordlg('Please import first the Excel file containing MRF sections!');
                return;
            end

            if Units==1; convUnit=1000*1000; else; convUnit=1000*12*12; end
            
            try
                
            % Geometry
            DIM1    = app.edit1.Value;
            DIM2    = app.edit2.Value;
            nMF     = app.edit14.Value;
            NStory  = app.edit3.Value;
            NBay    = app.edit4.Value;
            nGC     = app.edit5.Value/nMF;
            nGB     = app.edit6.Value/nMF;
            TAex1   = app.edit10.Value;
            TAex2   = app.edit11.Value;
            TAin1   = app.edit12.Value;
            TAin2   = app.edit13.Value;
            
            % Check dimensions
            if DIM1 < 2*TAex1+(NBay+1-2)*TAin1
                errordlg('The dimension of the building in Dir #1 is less than the sum of those of the columns! Please check.');
                return;
            end
            
            if FrameType==1
                  [Column_Index, Beam_Index, Brace_Index, XSecID] = Get_Section_Database_Index(FrameType, NStory, NBay, MF_COLUMNS, MF_BEAMS, BRACES, Units);
            end
            [GridX, GridY, AXIS, Elevation, HalfElevation, XTickLabel, YTickLabel] = Get_Grid (NStory, NBay,HStory, WBay);

            FootPrintArea = DIM1 * DIM2;
            Perimeter     = 2 * DIM1 + 2 * DIM2;
            TA_MF         = DIM1 * DIM2 / nMF;            
            TribAreaEx    = TAex1 * TAex2;
            TribAreaIn    = TAin1 * TAin2;
            
            % Loads
            TypicalDL   = app.edit15.Value /convUnit;
            RoofDL      = app.edit16.Value /convUnit;
            TypicalLL   = app.edit18.Value /convUnit;
            RoofLL      = app.edit19.Value /convUnit;
            Cladding    = app.edit20.Value /convUnit;
            TypicalGL   = app.editGL1.Value/convUnit;
            RoofGL      = app.editGL2.Value/convUnit;
                
            % Load Combination Coefficeints
            cLoad_status=app.CB1.Value;
            cDL_W  = app.edit30.Value;
            cLL_W  = app.edit31.Value;
            cCL_W  = app.edit32.Value;
            cGL_W  = app.edit37.Value;
            cDL_M  = app.edit34.Value;
            cLL_M  = app.edit35.Value;
            cCL_M  = app.edit36.Value;
            cGL_M  = app.edit38.Value;
            
            % Materials
            SteelMatID = str2double(app.Materialpopup.Value);
            SteelMatType = app.Materialpopup.Items{SteelMatID};
            E  = app.edit21.Value;
            fy = app.edit22.Value;
            mu = app.edit29.Value;

            Ec  = app.Ec.Value;
            fc  = app.fc.Value;
            muC = app.muC.Value;
            Er  = app.Er.Value;
            fyR  = app.fyR.Value;
            muR = app.muR.Value;
            
            % Supports & Connections
            Support      = str2double(app.Supportpopup.Value);
            MFconnection = str2double(app.Connectionpopup.Value)-1;
            SpliceConnection  = str2double(app.Splicepopup.Value);

            a = app.edit23.Value;
            b = app.edit24.Value;
            c = app.edit25.Value;

            if app.Linkpopup.Value=='1'
                FloorLink = 1;
                Fs        = 1000000.;
                Ks        = 0;
            else
                FloorLink = 2;
                Fs        = app.edit26.Value;
                Ks        = app.edit27.Value;
            end
            
            ColElementOption  = str2double(app.ColElementpopup.Value);
            BeamElementOption  = str2double(app.BeamElementpopup.Value);
            nSegments    = app.edit65.Value;
            nIntegration = app.edit66.Value;
            initialGI    = app.edit67.Value;
            
            if app.ColMppopup.Value=='1'
                PM_Option=1;
                Pscale=1.0;
            else
                PM_Option=2;
                Pscale=app.edit68.Value;
            end
            
            coeff_cracked = app.coeff_cracked.Value;
            bond_slip     = app.bond_slip.Value;
                
            DampModeJ=min(DampModeJ,NStory);
            
            Definestatus = 1;
            
            % Save Data to .mat File
            cd(ProjectPath)
            save(ProjectName, '-regexp', '^(?!(event|app)$).')
            cd(MainDirectory)

            app.MainApp.status1.BackgroundColor  = 'g';
            app.MainApp.status1.FontColor='k';
            if FrameType==1; app.MainApp.status1.Text=[num2str(NStory),'-story MRF']; end
            if FrameType==4; app.MainApp.status1.Text=[num2str(NStory),'-story RC MRF']; end
            app.MainApp.status1b.BackgroundColor = 'g';
            
            app.MainApp.AnalysisButton.Enable    = 'on';
            app.MainApp.GetperiodButton.Enable   = 'on';
            app.MainApp.BuildtclfileButton.Enable   = 'on';
            
            fclose all;
            
            figure1CloseRequest(app)
            
            catch ME
                errordlg('An error has occured. Please see Error file!');
                cd(MainDirectory)
                write_Error(ME);
            end
        end

        % Value changed function: Connectionpopup
        function Connectionpopup_Callback(app, event)
            if app.Connectionpopup.Value == '1'
                app.edit23.Enable='on';
                app.edit24.Enable='on';
                app.edit25.Enable='on';
                
                app.edit23.Value=0.625;
                app.edit24.Value=0.75;
                app.edit25.Value=0.25;
                
                app.Image.Visible='on';
            else
                app.edit23.Enable='off';
                app.edit24.Enable='off';
                app.edit25.Enable='off';
                
                app.edit23.Value=0.1;
                app.edit24.Value=0.0;
                app.edit25.Value=0.0;
                
                app.Image.Visible='off';
            end
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            if app.CB1.Value == 1
                app.edit30.Enable='off';
                app.edit31.Enable='off'; 
                app.edit32.Enable='off'; 
                app.edit34.Enable='off'; 
                app.edit35.Enable='off'; 
                app.edit36.Enable='off'; 
                app.edit37.Enable='off'; 
                app.edit38.Enable='off'; 
                
                app.edit30.Value=1.05;
                app.edit31.Value=0.25; 
                app.edit32.Value=1.05; 
                app.edit34.Value=1.0; 
                app.edit35.Value=0.0; 
                app.edit36.Value=1.0; 
                app.edit37.Value=1.0; 
                app.edit38.Value=1.0; 
            else
                app.edit30.Enable='on';
                app.edit31.Enable='on'; 
                app.edit32.Enable='on'; 
                app.edit34.Enable='on'; 
                app.edit35.Enable='on'; 
                app.edit36.Enable='on';    
                app.edit37.Enable='on';                 
                app.edit38.Enable='on'; 
            end
            
        end

        % Value changed function: Linkpopup
        function LinkpopupValueChanged(app, event)
            if app.Linkpopup.Value == '1'
                app.edit26.Enable='off';
                app.edit27.Enable='off';
                app.FuseF_ProfileCB.Enable='off';

                app.edit26.Value = 10000;
                app.edit27.Value = 0.0;
            else
                app.edit26.Enable='on';
                app.edit27.Enable='on';
                app.FuseF_ProfileCB.Enable='on';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible            = 'off';
            delete(app)
        end

        % Value changed function: ColElementpopup
        function ColElementpopupValueChanged(app, event)
            if app.ColElementpopup.Value == '1'
                app.edit65.Enable='off';
                app.edit66.Enable='off';
                app.edit67.Enable='off';

                app.ColMppopup.Enable = 'on';
                app.ColMppopup.Value = '2';
                app.edit68.Enable = 'on';
                app.edit68.Value = 1.0;
                app.textP1.Enable='on';
                app.textP2.Enable='on';
            else
                app.edit65.Enable='on';
                app.edit66.Enable='on';
                app.edit67.Enable='on';

                app.ColMppopup.Enable = 'off';
                app.ColMppopup.Value = '2';
                app.edit68.Enable = 'off';
                app.edit68.Value = 1.0;
                app.textP1.Enable='off';
                app.textP2.Enable='off';
            end
        end

        % Value changed function: ColMppopup
        function ColMppopupValueChanged(app, event)
            if app.ColMppopup.Value == '2'
                app.edit68.Enable='on';
            else
                app.edit68.Enable='off';
            end
        end

        % Value changed function: Materialpopup
        function MaterialpopupValueChanged(app, event)
            global MainDirectory Units
            clc;
            cd(MainDirectory);
            load('Material_Database.mat');
            if Units==1; transUnit=6.89476/1000; else; transUnit=1; end
            x=str2double(app.Materialpopup.Value);
            app.edit21.Value=Material.E(x+1,1)*transUnit;
            app.edit22.Value=Material.fy(x+1,1)*transUnit;
        end

        % Button pushed function: TipIcon5
        function TipIcon5ButtonPushed(app, event)
            figure('position',[100 100 450 350],'color','white','Resize','off');
            data=imread('FLoor Link.jpg');
            imshow(data); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Building Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'BuildButton';
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.BuildingGeometryTab = uitab(app.TabGroup);
            app.BuildingGeometryTab.Title = 'Building Geometry';
            app.text31 = uilabel(app.BuildingGeometryTab);
            app.text31.Tag = 'text31';
            app.text31.Text = 'Building Plan Dimensions';
            app.text32 = uilabel(app.BuildingGeometryTab);
            app.text32.Tag = 'text32';
            app.text32.Text = 'GFS Configuration';
            app.text33 = uilabel(app.BuildingGeometryTab);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Number of Beams';
            app.text42 = uilabel(app.BuildingGeometryTab);
            app.text42.Tag = 'text42';
            app.text42.Text = 'MF Configuration';
            app.text45 = uilabel(app.BuildingGeometryTab);
            app.text45.Tag = 'text45';
            app.text45.Text = 'Number of Bays';
            app.text46 = uilabel(app.BuildingGeometryTab);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Exterior MF Column Tributary Area';
            app.text47 = uilabel(app.BuildingGeometryTab);
            app.text47.Tag = 'text47';
            app.text47.Text = 'Interior MF Column Tributary Area';
            app.text49 = uilabel(app.BuildingGeometryTab);
            app.text49.Tag = 'text49';
            app.text49.Text = 'x';
            app.text50 = uilabel(app.BuildingGeometryTab);
            app.text50.Tag = 'text50';
            app.text50.Text = 'x';
            app.text51 = uilabel(app.BuildingGeometryTab);
            app.text51.Tag = 'text51';
            app.text51.Text = 'x';
            app.text65 = uilabel(app.BuildingGeometryTab);
            app.text65.Tag = 'text65';
            app.text65.Text = 'No. of MFs in the Considered Direction';
            app.text42_2 = uilabel(app.BuildingGeometryTab);
            app.text42_2.Tag = 'text42';
            app.text42_2.Text = 'Number of Stories';
            app.text32_2 = uilabel(app.BuildingGeometryTab);
            app.text32_2.Tag = 'text32';
            app.text32_2.Text = 'Number of Columns';
            app.text25 = uilabel(app.BuildingGeometryTab);
            app.text25.Tag = 'text40';
            app.text25.Text = 'Building Geometry [unit: inches]';
            app.text25_2 = uilabel(app.BuildingGeometryTab);
            app.text25_2.Tag = 'text40';
            app.text25_2.Text = 'Building Members';
            app.BrowseButton = uibutton(app.BuildingGeometryTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tag = 'BrowseButton';
            app.BrowseButton.Text = 'browse';
            app.text84 = uilabel(app.BuildingGeometryTab);
            app.text84.Tag = 'text84';
            app.text84.Text = 'Import Excel File';
            app.edit1 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit1.Limits = [0.0001 Inf];
            app.edit1.Value = 100;
            app.edit2 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit2.Limits = [0.0001 Inf];
            app.edit2.Value = 100;
            app.edit10 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit10.Limits = [0.0001 Inf];
            app.edit10.Value = 100;
            app.edit11 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit11.Limits = [0.0001 Inf];
            app.edit11.Value = 100;
            app.edit12 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit12.Limits = [0.0001 Inf];
            app.edit12.Value = 100;
            app.edit13 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit13.Limits = [0.0001 Inf];
            app.edit13.Value = 100;
            app.edit14 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit14.Limits = [1 Inf];
            app.edit14.ValueDisplayFormat = '%.0f';
            app.edit14.Value = 2;
            app.edit3 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit3.Limits = [1 Inf];
            app.edit3.ValueDisplayFormat = '%.0f';
            app.edit3.Value = 1;
            app.edit4 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit4.Limits = [1 Inf];
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.Value = 1;
            app.edit5 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit5.Limits = [1 Inf];
            app.edit5.ValueDisplayFormat = '%.0f';
            app.edit5.Value = 10;
            app.edit6 = uieditfield(app.BuildingGeometryTab, 'numeric');
            app.edit6.Limits = [1 Inf];
            app.edit6.ValueDisplayFormat = '%.0f';
            app.edit6.Value = 10;
            app.editExcel = uieditfield(app.BuildingGeometryTab, 'text');
            app.editExcel.Editable = 'off';
            app.text31_5 = uilabel(app.BuildingGeometryTab);
            app.text31_5.Tag = 'text31';
            app.text31_5.Text = 'Dir #1';
            app.text31_6 = uilabel(app.BuildingGeometryTab);
            app.text31_6.Tag = 'text31';
            app.text31_6.Text = 'Dir #2';
            app.SupportsConnectionsTab = uitab(app.TabGroup);
            app.SupportsConnectionsTab.Title = 'Supports & Connections';
            app.Supportpopup = uidropdown(app.SupportsConnectionsTab);
            app.Supportpopup.Items = {'Fixed', 'Pinned'};
            app.Supportpopup.ItemsData = {'1', '2'};
            app.Supportpopup.Tag = 'Supportpopup';
            app.Supportpopup.Value = '1';
            app.text31_2 = uilabel(app.SupportsConnectionsTab);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'MF Column Supports';
            app.Connectionpopup = uidropdown(app.SupportsConnectionsTab);
            app.Connectionpopup.Items = {'Reduced Beam Section', 'Welded/Bolted (Full-Section)'};
            app.Connectionpopup.ItemsData = {'1', '2'};
            app.Connectionpopup.ValueChangedFcn = createCallbackFcn(app, @Connectionpopup_Callback, true);
            app.Connectionpopup.Tag = 'Connectionpopup';
            app.Connectionpopup.Value = '1';
            app.text56 = uilabel(app.SupportsConnectionsTab);
            app.text56.Tag = 'text56';
            app.text56.Text = 'a =';
            app.text57 = uilabel(app.SupportsConnectionsTab);
            app.text57.Tag = 'text57';
            app.text57.Text = 'b =';
            app.text58 = uilabel(app.SupportsConnectionsTab);
            app.text58.Tag = 'text58';
            app.text58.Text = 'c =';
            app.text69 = uilabel(app.SupportsConnectionsTab);
            app.text69.Tag = 'text69';
            app.text69.Text = 'bf';
            app.text70 = uilabel(app.SupportsConnectionsTab);
            app.text70.Tag = 'text70';
            app.text70.Text = 'd';
            app.text71 = uilabel(app.SupportsConnectionsTab);
            app.text71.Tag = 'text71';
            app.text71.Text = 'bf';
            app.text31_3 = uilabel(app.SupportsConnectionsTab);
            app.text31_3.Tag = 'text31';
            app.text31_3.Text = 'MF Beam-to-Column Connection';
            app.Linkpopup = uidropdown(app.SupportsConnectionsTab);
            app.Linkpopup.Items = {'Rigid', 'Flexible'};
            app.Linkpopup.ItemsData = {'1', '2'};
            app.Linkpopup.ValueChangedFcn = createCallbackFcn(app, @LinkpopupValueChanged, true);
            app.Linkpopup.Tag = 'Linkpopup';
            app.Linkpopup.Value = '1';
            app.text72 = uilabel(app.SupportsConnectionsTab);
            app.text72.Tag = 'text72';
            app.text72.Text = 'Fs=';
            app.text73 = uilabel(app.SupportsConnectionsTab);
            app.text73.Tag = 'text73';
            app.text73.Text = 'Ks=';
            app.text74 = uilabel(app.SupportsConnectionsTab);
            app.text74.Tag = 'text74';
            app.text74.Text = 'kip';
            app.text75 = uilabel(app.SupportsConnectionsTab);
            app.text75.Tag = 'text75';
            app.text75.Text = 'kip/in';
            app.text31_4 = uilabel(app.SupportsConnectionsTab);
            app.text31_4.Tag = 'text31';
            app.text31_4.Text = 'MF to EGF Link Condition';
            app.Image = uiimage(app.SupportsConnectionsTab);
            app.Image.ImageSource = 'RBS.jpg';
            app.edit23 = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.edit23.Limits = [0 Inf];
            app.edit23.ValueDisplayFormat = '%.3f';
            app.edit23.Value = 0.625;
            app.edit24 = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.edit24.Limits = [0 Inf];
            app.edit24.ValueDisplayFormat = '%.3f';
            app.edit24.Value = 0.75;
            app.edit25 = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.edit25.Limits = [0 Inf];
            app.edit25.ValueDisplayFormat = '%.3f';
            app.edit25.Value = 0.25;
            app.edit26 = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.edit26.Limits = [0 Inf];
            app.edit26.Value = 10000;
            app.edit27 = uieditfield(app.SupportsConnectionsTab, 'numeric');
            app.edit27.Limits = [0 Inf];
            app.FuseF_ProfileCB = uicheckbox(app.SupportsConnectionsTab);
            app.FuseF_ProfileCB.Tag = 'FuseF_ProfileCB';
            app.FuseF_ProfileCB.Text = 'Non-uniform Fs Profile';
            app.Splicepopup = uidropdown(app.SupportsConnectionsTab);
            app.Splicepopup.Items = {'Pinned', 'Fixed'};
            app.Splicepopup.ItemsData = {'1', '2'};
            app.Splicepopup.Tag = 'Supportpopup';
            app.Splicepopup.Value = '1';
            app.text31_7 = uilabel(app.SupportsConnectionsTab);
            app.text31_7.Tag = 'text31';
            app.text31_7.Text = 'MF Column Splice Condition';
            app.TipIcon5 = uibutton(app.SupportsConnectionsTab, 'push');
            app.TipIcon5.ButtonPushedFcn = createCallbackFcn(app, @TipIcon5ButtonPushed, true);
            app.TipIcon5.Text = '?';
            app.LoadsMaterialTab = uitab(app.TabGroup);
            app.LoadsMaterialTab.Title = 'Loads & Material';
            app.text40 = uilabel(app.LoadsMaterialTab);
            app.text40.Tag = 'text40';
            app.text40.Text = 'Typical';
            app.text41 = uilabel(app.LoadsMaterialTab);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Roof';
            app.text35 = uilabel(app.LoadsMaterialTab);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Floor Dead Load';
            app.text37 = uilabel(app.LoadsMaterialTab);
            app.text37.Tag = 'text37';
            app.text37.Text = 'Cladding Load';
            app.text36 = uilabel(app.LoadsMaterialTab);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Floor Live Load';
            app.text24 = uilabel(app.LoadsMaterialTab);
            app.text24.Tag = 'text40';
            app.text24.Text = 'Loads [psf]';
            app.text52 = uilabel(app.LoadsMaterialTab);
            app.text52.Tag = 'text52';
            app.text52.Text = 'E';
            app.text53 = uilabel(app.LoadsMaterialTab);
            app.text53.Tag = 'text53';
            app.text53.Text = 'fy';
            app.text43 = uilabel(app.LoadsMaterialTab);
            app.text43.Tag = 'text40';
            app.text43.Text = 'Material [ksi]';
            app.text44 = uilabel(app.LoadsMaterialTab);
            app.text44.Tag = 'text40';
            app.text44.Text = 'Load Combination Coefficients';
            app.text35_2 = uilabel(app.LoadsMaterialTab);
            app.text35_2.Tag = 'text35';
            app.text35_2.Text = 'Seismic weight';
            app.edit30 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit30.Limits = [0 Inf];
            app.edit30.ValueDisplayFormat = '%.2f';
            app.edit30.Value = 1.05;
            app.edit31 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit31.Limits = [0 Inf];
            app.edit31.ValueDisplayFormat = '%.2f';
            app.edit31.Value = 1.25;
            app.edit32 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit32.Limits = [0 Inf];
            app.edit32.ValueDisplayFormat = '%.2f';
            app.edit32.Value = 1.05;
            app.edit15 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit15.Limits = [0 Inf];
            app.edit16 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit16.Limits = [0 Inf];
            app.edit18 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit18.Limits = [0 Inf];
            app.edit19 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit19.Limits = [0 Inf];
            app.edit20 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit20.Limits = [0 Inf];
            app.text40_2 = uilabel(app.LoadsMaterialTab);
            app.text40_2.Tag = 'text40';
            app.text40_2.Text = 'Dead';
            app.text40_3 = uilabel(app.LoadsMaterialTab);
            app.text40_3.Tag = 'text40';
            app.text40_3.Text = 'Live';
            app.text40_4 = uilabel(app.LoadsMaterialTab);
            app.text40_4.Tag = 'text40';
            app.text40_4.Text = 'Cladding';
            app.text35_3 = uilabel(app.LoadsMaterialTab);
            app.text35_3.Tag = 'text35';
            app.text35_3.Text = 'Mass';
            app.edit34 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit34.Limits = [0 Inf];
            app.edit34.ValueDisplayFormat = '%.2f';
            app.edit34.Value = 1;
            app.edit35 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit35.Limits = [0 Inf];
            app.edit35.ValueDisplayFormat = '%.2f';
            app.edit36 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit36.Limits = [0 Inf];
            app.edit36.ValueDisplayFormat = '%.2f';
            app.edit36.Value = 1;
            app.edit21 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit21.Limits = [0 Inf];
            app.edit21.Value = 29000;
            app.edit22 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit22.Limits = [0 Inf];
            app.edit22.Value = 55;
            app.CB1 = uicheckbox(app.LoadsMaterialTab);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'default values';
            app.CB1.Value = true;
            app.text53_2 = uilabel(app.LoadsMaterialTab);
            app.text53_2.Tag = 'text53';
            app.text53_2.Text = 'μ';
            app.edit29 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit29.Limits = [0 Inf];
            app.edit29.Value = 0.3;
            app.text36_2 = uilabel(app.LoadsMaterialTab);
            app.text36_2.Tag = 'text36';
            app.text36_2.Text = 'Floor Generic Load';
            app.editGL1 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.editGL1.Limits = [0 Inf];
            app.editGL2 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.editGL2.Limits = [0 Inf];
            app.edit37 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit37.Limits = [0 Inf];
            app.edit37.ValueDisplayFormat = '%.2f';
            app.text40_5 = uilabel(app.LoadsMaterialTab);
            app.text40_5.Tag = 'text40';
            app.text40_5.Text = 'Generic';
            app.edit38 = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.edit38.Limits = [0 Inf];
            app.edit38.ValueDisplayFormat = '%.2f';
            app.text35_7 = uilabel(app.LoadsMaterialTab);
            app.text35_7.Tag = 'text35';
            app.text35_7.Text = 'Steel';
            app.text35_8 = uilabel(app.LoadsMaterialTab);
            app.text35_8.Tag = 'text35';
            app.text35_8.Text = 'Rebar';
            app.text35_9 = uilabel(app.LoadsMaterialTab);
            app.text35_9.Tag = 'text35';
            app.text35_9.Text = 'Concrete';
            app.Materialpopup = uidropdown(app.LoadsMaterialTab);
            app.Materialpopup.Items = {'User-Defined', 'Generic Gr. 50', 'A992 Gr. 50', 'S355J2+N plate', 'S355J2+N section', 'S460NL', 'S690QL', 'A500 Gr. B', 'BCP325', 'BCR295', 'HYP400'};
            app.Materialpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.Materialpopup.ValueChangedFcn = createCallbackFcn(app, @MaterialpopupValueChanged, true);
            app.Materialpopup.Tag = 'Supportpopup';
            app.Materialpopup.Value = '1';
            app.text53_3 = uilabel(app.LoadsMaterialTab);
            app.text53_3.Tag = 'text53';
            app.text53_3.Text = 'fy';
            app.fyR = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.fyR.Limits = [0 Inf];
            app.fyR.Value = 55;
            app.text52_2 = uilabel(app.LoadsMaterialTab);
            app.text52_2.Tag = 'text52';
            app.text52_2.Text = 'E';
            app.Er = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.Er.Limits = [0 Inf];
            app.Er.Value = 29000;
            app.text53_4 = uilabel(app.LoadsMaterialTab);
            app.text53_4.Tag = 'text53';
            app.text53_4.Text = 'μ';
            app.muR = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.muR.Limits = [0 Inf];
            app.muR.Value = 0.3;
            app.text53_5 = uilabel(app.LoadsMaterialTab);
            app.text53_5.Tag = 'text53';
            app.text53_5.Text = 'fc';
            app.fc = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.fc.Limits = [0 Inf];
            app.fc.Value = 30;
            app.text52_3 = uilabel(app.LoadsMaterialTab);
            app.text52_3.Tag = 'text52';
            app.text52_3.Text = 'E';
            app.Ec = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.Ec.Limits = [0 Inf];
            app.Ec.Value = 2600;
            app.text53_6 = uilabel(app.LoadsMaterialTab);
            app.text53_6.Tag = 'text53';
            app.text53_6.Text = 'μ';
            app.muC = uieditfield(app.LoadsMaterialTab, 'numeric');
            app.muC.Limits = [0 Inf];
            app.muC.Value = 0.2;
            app.MemberModelingTab = uitab(app.TabGroup);
            app.MemberModelingTab.Title = 'Member Modeling';
            app.ColElementpopup = uidropdown(app.MemberModelingTab);
            app.ColElementpopup.Items = {'Elastic Beam-Column', 'Fiber D-based', 'Fiber F-based'};
            app.ColElementpopup.ItemsData = {'1', '2', '3'};
            app.ColElementpopup.ValueChangedFcn = createCallbackFcn(app, @ColElementpopupValueChanged, true);
            app.ColElementpopup.Tag = 'ColElementpopup';
            app.ColElementpopup.Value = '1';
            app.text35_4 = uilabel(app.MemberModelingTab);
            app.text35_4.Tag = 'text35';
            app.text35_4.Text = 'MF Column Elements';
            app.text73_2 = uilabel(app.MemberModelingTab);
            app.text73_2.Tag = 'text73';
            app.text73_2.Text = 'Number of segments along length';
            app.text74_2 = uilabel(app.MemberModelingTab);
            app.text74_2.Tag = 'text74';
            app.text74_2.Text = 'Number of integration points per segment';
            app.text75_2 = uilabel(app.MemberModelingTab);
            app.text75_2.Tag = 'text75';
            app.text75_2.Text = 'Global mid-length imperfection [*L]';
            app.edit65 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit65.Limits = [1 Inf];
            app.edit65.ValueDisplayFormat = '%.0f';
            app.edit65.Value = 8;
            app.edit66 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit66.Limits = [1 Inf];
            app.edit66.ValueDisplayFormat = '%.0f';
            app.edit66.Value = 5;
            app.edit67 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit67.Limits = [0 Inf];
            app.edit67.Value = 0.001;
            app.textP1 = uilabel(app.MemberModelingTab);
            app.textP1.Tag = 'text35';
            app.textP1.Text = 'P computed based on';
            app.textP2 = uilabel(app.MemberModelingTab);
            app.textP2.Tag = 'text75';
            app.textP2.Text = 'Scale factor';
            app.edit68 = uieditfield(app.MemberModelingTab, 'numeric');
            app.edit68.Limits = [0 Inf];
            app.edit68.Value = 1;
            app.text444 = uilabel(app.MemberModelingTab);
            app.text444.Tag = 'text40';
            app.text444.Text = 'MF Column P-M Interaction';
            app.text35_6 = uilabel(app.MemberModelingTab);
            app.text35_6.Tag = 'text35';
            app.text35_6.Text = 'Fiber-based Elements';
            app.ColMppopup = uidropdown(app.MemberModelingTab);
            app.ColMppopup.Items = {'Max Pushover Load', 'Scaled Gravity Load'};
            app.ColMppopup.ItemsData = {'1', '2'};
            app.ColMppopup.ValueChangedFcn = createCallbackFcn(app, @ColMppopupValueChanged, true);
            app.ColMppopup.Tag = 'ColElementpopup';
            app.ColMppopup.Value = '1';
            app.text73_3 = uilabel(app.MemberModelingTab);
            app.text73_3.Tag = 'text73';
            app.text73_3.Text = 'Reduction factor for cracked section';
            app.coeff_cracked = uieditfield(app.MemberModelingTab, 'numeric');
            app.coeff_cracked.Limits = [0.01 1];
            app.coeff_cracked.ValueDisplayFormat = '%.2f';
            app.coeff_cracked.Value = 0.5;
            app.text333 = uilabel(app.MemberModelingTab);
            app.text333.Tag = 'text35';
            app.text333.Text = 'RC Members';
            app.bond_slip = uicheckbox(app.MemberModelingTab);
            app.bond_slip.Tag = 'FuseF_ProfileCB';
            app.bond_slip.Text = 'Bond slip considered';
            app.bond_slip.Value = true;
            app.BeamElementpopup = uidropdown(app.MemberModelingTab);
            app.BeamElementpopup.Items = {'Elastic Beam-Column', 'Fiber D-based', 'Fiber F-based'};
            app.BeamElementpopup.ItemsData = {'1', '2', '3'};
            app.BeamElementpopup.Tag = 'ColElementpopup';
            app.BeamElementpopup.Value = '1';
            app.text351 = uilabel(app.MemberModelingTab);
            app.text351.Tag = 'text35';
            app.text351.Text = 'MF Beam Elements';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Build_MRF_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Build_MRF_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end