classdef Design_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRFDesignAppUIFigure           matlab.ui.Figure
        ResetButton                    matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        SaveAsButton                   matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        TXTButton                      matlab.ui.control.Button
        TCLButton                      matlab.ui.control.Button
        CheckButton                    matlab.ui.control.Button
        showMRFButton                  matlab.ui.control.Button
        VisualizeButton                matlab.ui.control.Button
        text1                          matlab.ui.control.Label
        OpenButton                     matlab.ui.control.Button
        DefineButton                   matlab.ui.control.Button
        Plate_2                        matlab.ui.control.Label
        ProgressText                   matlab.ui.control.Label
        DesignButton                   matlab.ui.control.Button
        FlushEndPlateConnectionsLabel  matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
    end


    methods (Access = private)

        function BLINK_TEXT(app, YourString, pauseTime, nTimes)
            app.ProgressText.FontColor=[0.06 1 1];
            app.ProgressText.Text=YourString;
            drawnow();

            for i=1:nTimes
                app.ProgressText.Text='';
                drawnow();
                pause(pauseTime);
                app.ProgressText.Text=YourString;
                drawnow();
                pause(pauseTime);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [y, Fs] = audioread('sound-start.mp3'); player = audioplayer(y, Fs); play(player); pause(1)

            datenow=string(datetime(floor(now),'ConvertFrom','datenum'));
            if contains(datenow,"2025")==1
                app.SaveAsButton.Enable='off';
                app.DefineButton.Enable='off';
                app.DesignButton.Enable='off';
                app.CheckButton.Enable='off';
                app.TCLButton.Enable='off';
                app.VisualizeButton.Enable='off';
            else
                app.StartButton.Enable='off';
                app.OpenButton.Enable='off';
                app.SaveAsButton.Enable='off';
                app.DefineButton.Enable='off';
                app.DesignButton.Enable='off';
                app.CheckButton.Enable='off';
                app.TCLButton.Enable='off';
                app.VisualizeButton.Enable='off';
                waitfor(errordlg('This explorer version has expired! Please check GitHub for a new release or contact author.', 'Tool expired', 'modal'))
                closereq()
            end

        end

        % Button pushed function: StartButton
        function StartButtonButtonPushed(app, event)
            global FrameType MainDirectory ProjectName ProjectPath Units

            load 'FM-2D Elastic Frame.mat'

            MainDirectory=pwd;
            [ProjectName, ProjectPath] = uiputfile('*.mat','Select the workspace direcotry and specify project name');

            if  isempty(ProjectName) || ischar(ProjectName)==0; return; end

            RFpath=ProjectPath;
            if strcmp(RFpath(end),'\')==1; RFpath(end)=[]; end

            Units       = 0;
            FrameType   = 0;
            waitfor(TypeMessage_App(app));

            if Units==0 || FrameType==0; return; end

            xx=ProjectName;
            xx=xx(1:end-4);
            xx= xx(~isspace(xx)); % remove spaces from project name to get opensees file name
            OpenSEESFileName = [xx,'.tcl'];
            app.text1.Text=['Project: ',xx];

            Startstatus         = 1;
            DefineDesignstatus  = 0;

            % Save Data to .mat File
            save(strcat(ProjectPath,ProjectName), '-regexp', '^(?!(event|app)$).');

            app.DefineButton.Enable = "on";
            app.SaveAsButton.Enable = 'on';

        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            clc;

            global MainDirectory ProjectName ProjectPath
            load(strcat(ProjectPath,ProjectName));

            try

                %% Run basic load cases
                app.ProgressText.FontColor=[1 1 0];
                app.ProgressText.Text='Running basic load combination ...'; drawnow;
                run_basic_load_cases(app);

                %% Compute member demands
                app.ProgressText.Text='Computing member demands ...'; drawnow;
                compute_demands_ASCE7_22();

                %% Run design code
                for i=1:5
                    app.ProgressText.Text='Running design code ...'; drawnow;
                    [CHECKS, MF_COLUMNS, MF_BEAMS] = conduct_design(app, NStory, NBay, MF_COLUMNS, MF_BEAMS, Doubler, PMoption);
                    save(strcat(ProjectPath,ProjectName),'CHECKS', 'MF_COLUMNS', 'MF_BEAMS', '-append');
                end

                %%
                app.ProgressText.FontColor=[0 1 0];
                [y, Fs] = audioread('sound-done.mp3'); player = audioplayer(y, Fs); play(player);  pause(2);
                app.ProgressText.Text='Design finished; Click "Visualize" to generate summary plots'; drawnow;
                app.VisualizeButton.Enable='on';

            catch ME

                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor=[1 0 0];
                app.ProgressText.Text='Error has occured! Please see Error file!'; drawnow;
                
            end
        end

        % Button pushed function: DefineButton
        function DefineButtonPushed(app, event)
            MRF_Design_Parameters_App(app)
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;

            % Get current directory, the user-specified project name, and selected Units
            MainDirectory=pwd;
            [ProjectName,ProjectPath] = uigetfile('*.mat','Select the Project .mat file');

            RFpath=ProjectPath;
            if strcmp(RFpath(end),'\')==1; RFpath(end)=[]; end

            cd(MainDirectory)

            % Get current directory and user-specified project name
            if exist(ProjectPath) ==0
                d=errordlg('Project does not exist in current directory!','Error','modal');
                uiwait(d);
            else
                save(strcat(ProjectPath,ProjectName),'MainDirectory','ProjectPath','ProjectName','RFpath','-append');

                xx=ProjectName;
                xx=xx(1:end-4);
                app.text1.Text=['Project: ',xx];

                app.SaveAsButton.Enable     = 'on';
                app.DefineButton.Enable     = 'on';
                app.DesignButton.Enable     = 'on';
                app.CheckButton.Enable      = 'on';
                app.VisualizeButton.Enable  = 'on';
                app.TCLButton.Enable        = "on";
                app.TXTButton.Enable        = "on";

                app.ProgressText.FontColor=[0 1 0];
                app.ProgressText.Text='Project file loaded successfuly!'; drawnow;

            end
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            
            Visualize_Design_App(app);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clc; fclose all;
            startupFcn(app)
            BLINK_TEXT (app,'© A. Elkady, University of Southampton 2024', 0.2, 2)
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            clc;

            global ProjectPath ProjectName
            load(strcat(ProjectPath,ProjectName))

            %% Import member section data
            [SectionsFileName, SectionsFilePath] = uigetfile('*.xlsx','Select the EXCEL file containing MRF section data');

            [MF_COLUMNS, MF_BEAMS, Doubler] = import_ExcelData_MRF_Sections(SectionsFilePath, SectionsFileName, NStory, NBay);

            save(strcat(ProjectPath,ProjectName),'MF_COLUMNS','MF_BEAMS','Doubler','-append');

            %% Run basic load cases
            app.ProgressText.FontColor=[1 1 0];
            app.ProgressText.Text='Running basic load cases ...'; drawnow;

            run_basic_load_cases(app);

            %%  Compute member demands
            app.ProgressText.Text='Computing member demands ...'; drawnow;

            compute_demands_ASCE7_22();

            %% Run checks
            app.ProgressText.Text='Conducting design checks ...'; drawnow;

            [CHECKS, ~, ~] = check_design(NStory, NBay, MF_COLUMNS, MF_BEAMS, Doubler, PMoption);

            save(strcat(ProjectPath,ProjectName),'CHECKS','-append');

            %%
            app.ProgressText.FontColor=[0 1 0];
            [y, Fs] = audioread('sound-done.mp3'); player = audioplayer(y, Fs); play(player); pause(2);
            app.ProgressText.Text='Check finished; Click "Visualize" to generate summary plots'; drawnow;
            app.VisualizeButton.Enable='on';

        end

        % Button pushed function: TCLButton
        function TCLButtonPushed(app, event)
            global MainDirectory

            try
                app.ProgressText.FontColor=[0 1 0];
                app.ProgressText.Text='Creating tcl File...'; drawnow;

                build_TCL_file(app)

            catch ME
                cd(MainDirectory)
                write_Error(ME);
                app.ProgressText.FontColor=[1 0 0];
                app.ProgressText.Text='Error has occured! Please see Error file!'; drawnow;
                app.Image.Visible          = 'off';
            end
        end

        % Button pushed function: showMRFButton
        function showMRFButtonPushed(app, event)
            global ProjectPath ProjectName
            
            load (strcat(ProjectPath,ProjectName));
                
            plot_MRF_with_labels(1);

            [Steel_Weight] = get_steel_weight (MF_COLUMNS, MF_BEAMS, Splice, Doubler, NStory, NBay, HStory, WBay, Units);
            
            if Units==1; wunit='ton'; else; wunit='kip'; end
            str=['Steel weight = ',num2str(round(Steel_Weight*100)/100),' ',wunit];
            title(str);
        end

        % Button pushed function: TXTButton
        function TXTButtonPushed(app, event)
            %Show_Project_Summary;
            [SummaryText]=get_design_summary();
            Project_Summary_App(SummaryText);
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            global MainDirectory ProjectPath ProjectName MainDirectory2 ProjectPath2 ProjectName2
            clc;
            MainDirectory2=pwd;
            try

                load (strcat(ProjectPath,ProjectName));

                % Get current directory, the user-specified project name, and selected Units
                [ProjectName2, ProjectPath2] = uiputfile('*.mat','Save current project as');

                xx=ProjectName2;
                xx=xx(1:end-4);
                xx1= xx(~isspace(xx)); % remove spaces from project name to get opensees file name
                OpenSEESFileName = [xx1,'.tcl'];

                save(strcat(ProjectPath2,ProjectName2), '-regexp', '^(?!(event|app)$).');

                MainDirectory = MainDirectory2;
                ProjectName   = ProjectName2;
                ProjectPath   = ProjectPath2;

                clear MainDirectory2  ProjectName2  ProjectPath2

                save(strcat(ProjectPath,ProjectName),'MainDirectory','ProjectName','ProjectPath','-append');

                xx=ProjectName;
                xx=xx(1:end-4);
                app.text1.Text=['Project: ',xx];
            catch ME

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MRFDesignAppUIFigure = uifigure('Visible', 'off');
            app.MRFDesignAppUIFigure.Name = 'MRF Design App';
            app.Panel = uipanel(app.MRFDesignAppUIFigure);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FlushEndPlateConnectionsLabel = uilabel(app.Panel);
            app.FlushEndPlateConnectionsLabel.Text = {'Automated Steel MRF Design'; ' '};
            app.DesignButton = uibutton(app.Panel, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Enable = 'off';
            app.DesignButton.Text = 'Design';
            app.ProgressText = uilabel(app.Panel);
            app.ProgressText.Tag = 'text2';
            app.ProgressText.Text = '© Ahmed Elkady, University of Southampton, 2024';
            app.Plate_2 = uilabel(app.Panel);
            app.Plate_2.Text = '-----------------------------------------------------v24.08';
            app.DefineButton = uibutton(app.Panel, 'push');
            app.DefineButton.ButtonPushedFcn = createCallbackFcn(app, @DefineButtonPushed, true);
            app.DefineButton.Enable = 'off';
            app.DefineButton.Text = 'Define';
            app.OpenButton = uibutton(app.Panel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.text1 = uilabel(app.Panel);
            app.text1.Tag = 'text27';
            app.text1.Text = 'Project: --';
            app.VisualizeButton = uibutton(app.Panel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Enable = 'off';
            app.VisualizeButton.Text = 'Visualize';
            app.showMRFButton = uibutton(app.Panel, 'push');
            app.showMRFButton.ButtonPushedFcn = createCallbackFcn(app, @showMRFButtonPushed, true);
            app.showMRFButton.Icon = fullfile(pathToMLAPP, 'scope.png');
            app.showMRFButton.Tooltip = {'Show frame sections'};
            app.showMRFButton.Text = '';
            app.CheckButton = uibutton(app.Panel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Enable = 'off';
            app.CheckButton.Text = 'Check';
            app.TCLButton = uibutton(app.Panel, 'push');
            app.TCLButton.ButtonPushedFcn = createCallbackFcn(app, @TCLButtonPushed, true);
            app.TCLButton.Enable = 'off';
            app.TCLButton.Tooltip = {'Generate tcl file'};
            app.TCLButton.Text = 'TCL';
            app.TXTButton = uibutton(app.Panel, 'push');
            app.TXTButton.ButtonPushedFcn = createCallbackFcn(app, @TXTButtonPushed, true);
            app.TXTButton.Enable = 'off';
            app.TXTButton.Tooltip = {'Show project summary'};
            app.TXTButton.Text = 'TXT';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Southampton_preview_rev_1.png');
            app.SaveAsButton = uibutton(app.Panel, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tag = 'SaveAsButton';
            app.SaveAsButton.Enable = 'off';
            app.SaveAsButton.Tooltip = 'Save current project under a different name';
            app.SaveAsButton.Text = 'Save as';
            app.ResetButton = uibutton(app.MRFDesignAppUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset'};
            app.ResetButton.Text = 'R';
            % Show the figure after all components are created
            app.MRFDesignAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Design_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRFDesignAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRFDesignAppUIFigure)
        end
    end
end