classdef Developers_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CodeHierarchyTab                matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        RootFM2D_AppmlappNode           matlab.ui.container.TreeNode
        registerNode                    matlab.ui.container.TreeNode
        aboutNode                       matlab.ui.container.TreeNode
        About_AppmlappNode              matlab.ui.container.TreeNode
        helpNode                        matlab.ui.container.TreeNode
        developersNode                  matlab.ui.container.TreeNode
        Developers_AppmlappNode         matlab.ui.container.TreeNode
        NewNode                         matlab.ui.container.TreeNode
        TypeMessage_AppmlappNode        matlab.ui.container.TreeNode
        Initialize_ProjectmNode         matlab.ui.container.TreeNode
        OpenNode                        matlab.ui.container.TreeNode
        SaveasNode                      matlab.ui.container.TreeNode
        ScopeNode                       matlab.ui.container.TreeNode
        Get_Project_SummarymNode        matlab.ui.container.TreeNode
        Project_Summary_AppmlappNode    matlab.ui.container.TreeNode
        ModelParametersNode             matlab.ui.container.TreeNode
        Model_AppmlappNode              matlab.ui.container.TreeNode
        BuildingParametersNode          matlab.ui.container.TreeNode
        Build_AppmlappNode              matlab.ui.container.TreeNode
        Tip_AppmlappNode_4              matlab.ui.container.TreeNode
        Import_EXCELmNode               matlab.ui.container.TreeNode
        Get_Section_Database_IndexmNode  matlab.ui.container.TreeNode
        Material_DatabasematNode        matlab.ui.container.TreeNode
        AnalysisParametersNode          matlab.ui.container.TreeNode
        Analysis_AppmlappNode           matlab.ui.container.TreeNode
        Import_GM_DatamNode             matlab.ui.container.TreeNode
        Uncertainty_AppmlappNode        matlab.ui.container.TreeNode
        GetperiodNode                   matlab.ui.container.TreeNode
        Get_Mass_and_PeriodmNode        matlab.ui.container.TreeNode
        get_PeriodmNode                 matlab.ui.container.TreeNode
        CREATOR_MODEL_MRFmNode_2        matlab.ui.container.TreeNode
        Load_SecDatamNode_3             matlab.ui.container.TreeNode
        AISC_WF_Sec_DatabasematNode_3   matlab.ui.container.TreeNode
        EURO_I_Sec_DatabasematNode_3    matlab.ui.container.TreeNode
        HSS_Sec_DatabasematNode_3       matlab.ui.container.TreeNode
        Get_Col_Axial_ForcemNode_4      matlab.ui.container.TreeNode
        write_OpenArgumentsmNode_4      matlab.ui.container.TreeNode
        write_SourceSubroutinemNode_5   matlab.ui.container.TreeNode
        write_ResultsFoldermNode_4      matlab.ui.container.TreeNode
        write_BasicInputmNode_4         matlab.ui.container.TreeNode
        write_PreCalculatedGeometrymNode_4  matlab.ui.container.TreeNode
        write_Nodesm_4                  matlab.ui.container.TreeNode
        write_PZelementmNode_3          matlab.ui.container.TreeNode
        write_PZspringmNode_3           matlab.ui.container.TreeNode
        write_ElasticBeamsColumnsmNode_4  matlab.ui.container.TreeNode
        get_mod_I_with_shearNode_2      matlab.ui.container.TreeNode
        write_BeamSpring_MRFmNode_2     matlab.ui.container.TreeNode
        write_ColumnSpringmNode_3       matlab.ui.container.TreeNode
        write_SpliceSpringmNode_3       matlab.ui.container.TreeNode
        write_FloorLinksmNode_4         matlab.ui.container.TreeNode
        write_EGFelementsmNode_3        matlab.ui.container.TreeNode
        write_EGFspringsmNode_3         matlab.ui.container.TreeNode
        write_BCsmNode_4                matlab.ui.container.TreeNode
        write_RecordersmNode_4          matlab.ui.container.TreeNode
        write_MassmNode_4               matlab.ui.container.TreeNode
        CREATOR_MODEL_MRF_RCmNode_2     matlab.ui.container.TreeNode
        Load_SecData_RCmNode_2          matlab.ui.container.TreeNode
        Get_Col_Axial_ForcemNode_5      matlab.ui.container.TreeNode
        write_OpenArgumentsmNode_5      matlab.ui.container.TreeNode
        write_SourceSubroutinemNode_6   matlab.ui.container.TreeNode
        write_ResultsFoldermNode_5      matlab.ui.container.TreeNode
        write_BasicInputmNode_5         matlab.ui.container.TreeNode
        write_PreCalculatedGeometrymNode_5  matlab.ui.container.TreeNode
        write_Nodesm_5                  matlab.ui.container.TreeNode
        write_PZ_RCmNode_2              matlab.ui.container.TreeNode
        write_ElasticBeamsColumnsmNode_5  matlab.ui.container.TreeNode
        write_BeamSpring_MRF_RCmNode_2  matlab.ui.container.TreeNode
        write_ColumnSpring_RCmNode_2    matlab.ui.container.TreeNode
        write_FloorLinksmNode_5         matlab.ui.container.TreeNode
        write_EGFelements_RCmNode_2     matlab.ui.container.TreeNode
        write_EGFsprings_RCmNode_2      matlab.ui.container.TreeNode
        write_BCsmNode_5                matlab.ui.container.TreeNode
        write_RecordersmNode_5          matlab.ui.container.TreeNode
        write_MassmNode_5               matlab.ui.container.TreeNode
        CREATOR_MODEL_CBFmNode_2        matlab.ui.container.TreeNode
        Load_SecDatamNode_4             matlab.ui.container.TreeNode
        AISC_WF_Sec_DatabasematNode_4   matlab.ui.container.TreeNode
        EURO_I_Sec_DatabasematNode_4    matlab.ui.container.TreeNode
        HSS_Sec_DatabasematNode_4       matlab.ui.container.TreeNode
        Get_Col_Axial_ForcemNode_6      matlab.ui.container.TreeNode
        write_OpenArgumentsmNode_6      matlab.ui.container.TreeNode
        write_SourceSubroutinemNode_7   matlab.ui.container.TreeNode
        write_ResultsFoldermNode_6      matlab.ui.container.TreeNode
        write_BasicInputmNode_6         matlab.ui.container.TreeNode
        write_PreCalculatedGeometrymNode_6  matlab.ui.container.TreeNode
        write_Nodesm_6                  matlab.ui.container.TreeNode
        write_PZelementmNode_4          matlab.ui.container.TreeNode
        write_PZspringmNode_4           matlab.ui.container.TreeNode
        write_BraceRigidLinksmNode_3    matlab.ui.container.TreeNode
        write_GPspringmNode_3           matlab.ui.container.TreeNode
        write_BracesmNode_3             matlab.ui.container.TreeNode
        write_ElasticBeamsColumnsmNode_6  matlab.ui.container.TreeNode
        write_BeamSpring_CBFmNode_3     matlab.ui.container.TreeNode
        write_ColumnSpringmNode_4       matlab.ui.container.TreeNode
        write_SpliceSpringmNode_4       matlab.ui.container.TreeNode
        write_FloorLinksmNode_6         matlab.ui.container.TreeNode
        write_EGFelementsmNode_4        matlab.ui.container.TreeNode
        write_EGFspringsmNode_4         matlab.ui.container.TreeNode
        write_BCsmNode_6                matlab.ui.container.TreeNode
        write_RecordersmNode_6          matlab.ui.container.TreeNode
        write_MassmNode_6               matlab.ui.container.TreeNode
        CREATOR_ANALYSISmNode_2         matlab.ui.container.TreeNode
        write_Analysis_EigenmNode_3     matlab.ui.container.TreeNode
        write_Analysis_GravitymNode_2   matlab.ui.container.TreeNode
        write_AnimationmNode_2          matlab.ui.container.TreeNode
        write_Analysis_PushovermNode_2  matlab.ui.container.TreeNode
        write_Analysis_DynamicmNode_2   matlab.ui.container.TreeNode
        write_Analysis_ELFmNode_2       matlab.ui.container.TreeNode
        BuildtclfileNode                matlab.ui.container.TreeNode
        build_TCL_filemNode             matlab.ui.container.TreeNode
        RunModelNode                    matlab.ui.container.TreeNode
        ExecutionMessage_AppmlappNode   matlab.ui.container.TreeNode
        Execution_CommandsmNode         matlab.ui.container.TreeNode
        CREATOR_MODEL_MRFmNode          matlab.ui.container.TreeNode
        Load_SecDatamNode               matlab.ui.container.TreeNode
        AISC_WF_Sec_DatabasematNode     matlab.ui.container.TreeNode
        EURO_I_Sec_DatabasematNode      matlab.ui.container.TreeNode
        HSS_Sec_DatabasematNode         matlab.ui.container.TreeNode
        Get_Col_Axial_ForcemNode        matlab.ui.container.TreeNode
        write_OpenArgumentsmNode        matlab.ui.container.TreeNode
        write_SourceSubroutinemNode_2   matlab.ui.container.TreeNode
        write_ResultsFoldermNode        matlab.ui.container.TreeNode
        write_BasicInputmNode           matlab.ui.container.TreeNode
        write_PreCalculatedGeometrymNode  matlab.ui.container.TreeNode
        write_Nodesm                    matlab.ui.container.TreeNode
        write_PZelementmNode            matlab.ui.container.TreeNode
        write_PZspringmNode             matlab.ui.container.TreeNode
        write_ElasticBeamsColumnsmNode  matlab.ui.container.TreeNode
        get_mod_I_with_shearNode        matlab.ui.container.TreeNode
        write_BeamSpring_MRFmNode       matlab.ui.container.TreeNode
        write_ColumnSpringmNode         matlab.ui.container.TreeNode
        write_SpliceSpringmNode         matlab.ui.container.TreeNode
        write_FloorLinksmNode           matlab.ui.container.TreeNode
        write_EGFelementsmNode          matlab.ui.container.TreeNode
        write_EGFspringsmNode           matlab.ui.container.TreeNode
        write_BCsmNode                  matlab.ui.container.TreeNode
        write_RecordersmNode            matlab.ui.container.TreeNode
        write_MassmNode                 matlab.ui.container.TreeNode
        CREATOR_MODEL_MRF_RCmNode       matlab.ui.container.TreeNode
        Load_SecData_RCmNode            matlab.ui.container.TreeNode
        Get_Col_Axial_ForcemNode_3      matlab.ui.container.TreeNode
        write_OpenArgumentsmNode_3      matlab.ui.container.TreeNode
        write_SourceSubroutinemNode_4   matlab.ui.container.TreeNode
        write_ResultsFoldermNode_3      matlab.ui.container.TreeNode
        write_BasicInputmNode_3         matlab.ui.container.TreeNode
        write_PreCalculatedGeometrymNode_3  matlab.ui.container.TreeNode
        write_Nodesm_3                  matlab.ui.container.TreeNode
        write_PZ_RCmNode                matlab.ui.container.TreeNode
        write_ElasticBeamsColumnsmNode_3  matlab.ui.container.TreeNode
        write_BeamSpring_MRF_RCmNode    matlab.ui.container.TreeNode
        write_ColumnSpring_RCmNode      matlab.ui.container.TreeNode
        write_FloorLinksmNode_3         matlab.ui.container.TreeNode
        write_EGFelements_RCmNode       matlab.ui.container.TreeNode
        write_EGFsprings_RCmNode        matlab.ui.container.TreeNode
        write_BCsmNode_3                matlab.ui.container.TreeNode
        write_RecordersmNode_3          matlab.ui.container.TreeNode
        write_MassmNode_3               matlab.ui.container.TreeNode
        CREATOR_MODEL_CBFmNode          matlab.ui.container.TreeNode
        Load_SecDatamNode_2             matlab.ui.container.TreeNode
        AISC_WF_Sec_DatabasematNode_2   matlab.ui.container.TreeNode
        EURO_I_Sec_DatabasematNode_2    matlab.ui.container.TreeNode
        HSS_Sec_DatabasematNode_2       matlab.ui.container.TreeNode
        Get_Col_Axial_ForcemNode_2      matlab.ui.container.TreeNode
        write_OpenArgumentsmNode_2      matlab.ui.container.TreeNode
        write_SourceSubroutinemNode_3   matlab.ui.container.TreeNode
        write_ResultsFoldermNode_2      matlab.ui.container.TreeNode
        write_BasicInputmNode_2         matlab.ui.container.TreeNode
        write_PreCalculatedGeometrymNode_2  matlab.ui.container.TreeNode
        write_Nodesm_2                  matlab.ui.container.TreeNode
        write_PZelementmNode_2          matlab.ui.container.TreeNode
        write_PZspringmNode_2           matlab.ui.container.TreeNode
        write_BraceRigidLinksmNode_2    matlab.ui.container.TreeNode
        write_GPspringmNode_2           matlab.ui.container.TreeNode
        write_BracesmNode_2             matlab.ui.container.TreeNode
        write_ElasticBeamsColumnsmNode_2  matlab.ui.container.TreeNode
        write_BeamSpring_CBFmNode_2     matlab.ui.container.TreeNode
        write_ColumnSpringmNode_2       matlab.ui.container.TreeNode
        write_SpliceSpringmNode_2       matlab.ui.container.TreeNode
        write_FloorLinksmNode_2         matlab.ui.container.TreeNode
        write_EGFelementsmNode_2        matlab.ui.container.TreeNode
        write_EGFspringsmNode_2         matlab.ui.container.TreeNode
        write_BCsmNode_2                matlab.ui.container.TreeNode
        write_RecordersmNode_2          matlab.ui.container.TreeNode
        write_MassmNode_2               matlab.ui.container.TreeNode
        CREATOR_ANALYSISmNode           matlab.ui.container.TreeNode
        write_Analysis_EigenmNode_2     matlab.ui.container.TreeNode
        write_Analysis_GravitymNode     matlab.ui.container.TreeNode
        write_AnimationmNode            matlab.ui.container.TreeNode
        write_Analysis_PushovermNode    matlab.ui.container.TreeNode
        write_Analysis_DynamicmNode     matlab.ui.container.TreeNode
        write_Analysis_ELFmNode         matlab.ui.container.TreeNode
        Run_OpenSEESmNode               matlab.ui.container.TreeNode
        Get_IMmNode                     matlab.ui.container.TreeNode
        Get_SA_SDoFmNode                matlab.ui.container.TreeNode
        Run_exemNode                    matlab.ui.container.TreeNode
        Read_Analysis_DatamNode         matlab.ui.container.TreeNode
        Show_Status_DynamicmNode        matlab.ui.container.TreeNode
        Show_Scope_IDAmNode             matlab.ui.container.TreeNode
        Show_Scope_DynamicmNode         matlab.ui.container.TreeNode
        Save_Increment_DatamNode        matlab.ui.container.TreeNode
        Save_Results_IDAmNode           matlab.ui.container.TreeNode
        Save_Results_DynamicmNode       matlab.ui.container.TreeNode
        Process_EigenAnalysismNode      matlab.ui.container.TreeNode
        VisualizeNode                   matlab.ui.container.TreeNode
        Visualize_AppmlappNode          matlab.ui.container.TreeNode
        Plot_Eigen_VectorsmNode         matlab.ui.container.TreeNode
        Plot_BSF_vs_SDR1mNode_2         matlab.ui.container.TreeNode
        Plot_BSF_vs_RDRmNode            matlab.ui.container.TreeNode
        Plot_Drift_Profile_POmNode      matlab.ui.container.TreeNode
        Plot_IDAmNode                   matlab.ui.container.TreeNode
        Plot_Collapse_FragilitymNode    matlab.ui.container.TreeNode
        Plot_SDR_ProfilemNode           matlab.ui.container.TreeNode
        Plot_RDR_ProfilemNode           matlab.ui.container.TreeNode
        Plot_PFA_ProfilemNode_2         matlab.ui.container.TreeNode
        Plot_PFV_ProfilemNode           matlab.ui.container.TreeNode
        Plot_SSF_ProfilemNode           matlab.ui.container.TreeNode
        Plot_EDP_FragilitymNode         matlab.ui.container.TreeNode
        Plot_EDP_vs_RealizationmNode    matlab.ui.container.TreeNode
        get_all_datamNode_3             matlab.ui.container.TreeNode
        Visulaize_Local_AppNode         matlab.ui.container.TreeNode
        Plot_FramemNode                 matlab.ui.container.TreeNode
        Plot_Local_Get_RootNamemNode    matlab.ui.container.TreeNode
        Plot_Local_Get_LabelsmNode      matlab.ui.container.TreeNode
        Plot_Local_Get_DataColIndxmNode  matlab.ui.container.TreeNode
        get_Local_XYdatamNode_2         matlab.ui.container.TreeNode
        write2file_PlotDatamNode        matlab.ui.container.TreeNode
        highlight_componentmNode_2      matlab.ui.container.TreeNode
        Visulaize_Response_Progression_AppNode  matlab.ui.container.TreeNode
        Plot_FramemNode_2               matlab.ui.container.TreeNode
        Plot_Local_Get_RootNamemNode_2  matlab.ui.container.TreeNode
        Plot_Local_Get_LabelsmNode_2    matlab.ui.container.TreeNode
        Plot_Local_Get_DataColIndxmNode_2  matlab.ui.container.TreeNode
        get_Local_XYdatamNode           matlab.ui.container.TreeNode
        write2file_PlotDatamNode_2      matlab.ui.container.TreeNode
        Visulaize_PH_Progression_AppNode  matlab.ui.container.TreeNode
        Plot_Frame_DeformedmNode        matlab.ui.container.TreeNode
        Get_Member_DatamNode            matlab.ui.container.TreeNode
        Plot_BSF_vs_SDR1mNode_3         matlab.ui.container.TreeNode
        plot_PH_statemNode              matlab.ui.container.TreeNode
        VariablesTab                    matlab.ui.container.Tab
        DropDown                        matlab.ui.control.DropDown
        SorttablebyLabel                matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        TipText                         matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ForDevelopers_OpeningFcn(app)
            DropDownValueChanged(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if selectedNodes.NodeData ~=0; app.TipText.Value=selectedNodes.NodeData; else app.TipText.Value=''; end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            global resource_root

            indices = event.Indices;
            sortType=str2double(app.DropDown.Value);
            [NUM,TXT,RAW] = xlsread(strjoin(resource_root+"Variable_Database.xlsx"),'Sheet1','A2:D400');
            if sortType==1; TXT=sortrows(TXT,[1,1]);  else; TXT=sortrows(TXT,[3,1]); end
            app.TipText.Value=TXT{indices,4};
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global resource_root
            
            clc;
            sortType=str2double(app.DropDown.Value);
            [NUM,TXT,RAW] = xlsread(strjoin(resource_root+"Variable_Database.xlsx"),'Sheet1','A2:c400');
            if sortType==1; TXT=sortrows(TXT,[1,1]);  else; TXT=sortrows(TXT,[3,1]); end
            app.UITable.Data=TXT;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FM-2D- For Developers';
            app.TipText = uitextarea(app.figure1);
            app.TipText.Value = {''; ''; ''; ''; ''; ''; ''};
            app.TabGroup = uitabgroup(app.figure1);
            app.CodeHierarchyTab = uitab(app.TabGroup);
            app.CodeHierarchyTab.Title = 'Code Hierarchy';
            app.Tree = uitree(app.CodeHierarchyTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.RootFM2D_AppmlappNode = uitreenode(app.Tree);
            app.RootFM2D_AppmlappNode.NodeData = '[GUI] FM-2D''s main console';
            app.RootFM2D_AppmlappNode.Icon = 'icon.jpg';
            app.RootFM2D_AppmlappNode.Text = '[Root] FM2D_App.mlapp';
            app.registerNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.registerNode.NodeData = '[button] Opens a link to register for FM-2D updates and fixes ';
            app.registerNode.Text = '[register]';
            app.aboutNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.aboutNode.NodeData = '[button] About button';
            app.aboutNode.Text = '[about]';
            app.About_AppmlappNode = uitreenode(app.aboutNode);
            app.About_AppmlappNode.NodeData = '[GUI] Display the FA-2D''s summary description';
            app.About_AppmlappNode.Icon = 'icon_GUI.png';
            app.About_AppmlappNode.Text = 'About_App.mlapp';
            app.helpNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.helpNode.NodeData = '[button] Opens the PDF manual';
            app.helpNode.Icon = 'icon_Help2.jpg';
            app.helpNode.Text = '[help]';
            app.developersNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.developersNode.NodeData = '[button] Developers button';
            app.developersNode.Text = '[developers]';
            app.Developers_AppmlappNode = uitreenode(app.developersNode);
            app.Developers_AppmlappNode.NodeData = '[GUI] Display the platform''s variablles descriptions and code hierarchy';
            app.Developers_AppmlappNode.Icon = 'icon_GUI.png';
            app.Developers_AppmlappNode.Text = 'Developers_App.mlapp';
            app.NewNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.NewNode.NodeData = 'New file button';
            app.NewNode.Icon = 'icon_New.png';
            app.NewNode.Text = '[New]';
            app.TypeMessage_AppmlappNode = uitreenode(app.NewNode);
            app.TypeMessage_AppmlappNode.NodeData = '[GUI] Project units and frame type selection';
            app.TypeMessage_AppmlappNode.Icon = 'icon_GUI.png';
            app.TypeMessage_AppmlappNode.Text = 'TypeMessage_App.mlapp';
            app.Initialize_ProjectmNode = uitreenode(app.NewNode);
            app.Initialize_ProjectmNode.NodeData = '[function] Initializes the project variables';
            app.Initialize_ProjectmNode.Icon = 'icon_Function.png';
            app.Initialize_ProjectmNode.Text = 'Initialize_Project.m';
            app.OpenNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.OpenNode.NodeData = 'Open file button';
            app.OpenNode.Icon = 'icon_Open.png';
            app.OpenNode.Text = '[Open]';
            app.SaveasNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.SaveasNode.NodeData = 'Save as button';
            app.SaveasNode.Icon = 'icon_SaveAs.png';
            app.SaveasNode.Text = '[Save as]';
            app.ScopeNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.ScopeNode.NodeData = 'Scope button - Shows the project summary data';
            app.ScopeNode.Text = '[Scope]';
            app.Get_Project_SummarymNode = uitreenode(app.ScopeNode);
            app.Get_Project_SummarymNode.NodeData = '[function] Gets the summary data in text format --> Output variable: SummaryText';
            app.Get_Project_SummarymNode.Icon = 'icon_Function.png';
            app.Get_Project_SummarymNode.Text = 'Get_Project_Summary.m';
            app.Project_Summary_AppmlappNode = uitreenode(app.ScopeNode);
            app.Project_Summary_AppmlappNode.NodeData = '[GUI] Display the summary data text';
            app.Project_Summary_AppmlappNode.Icon = 'icon_GUI.png';
            app.Project_Summary_AppmlappNode.Text = 'Project_Summary_App.mlapp';
            app.ModelParametersNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.ModelParametersNode.NodeData = 'Model Parameters button';
            app.ModelParametersNode.Text = '[Model Parameters]';
            app.Model_AppmlappNode = uitreenode(app.ModelParametersNode);
            app.Model_AppmlappNode.NodeData = 'Model Parameters module (GUI)';
            app.Model_AppmlappNode.Icon = 'icon_GUI.png';
            app.Model_AppmlappNode.Text = 'Model_App.mlapp';
            app.BuildingParametersNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.BuildingParametersNode.NodeData = 'Building Parameters button';
            app.BuildingParametersNode.Text = '[Building Parameters]';
            app.Build_AppmlappNode = uitreenode(app.BuildingParametersNode);
            app.Build_AppmlappNode.NodeData = '[GUI] Building data module';
            app.Build_AppmlappNode.Icon = 'icon_GUI.png';
            app.Build_AppmlappNode.Text = 'Build_App.mlapp';
            app.Tip_AppmlappNode_4 = uitreenode(app.Build_AppmlappNode);
            app.Tip_AppmlappNode_4.NodeData = '[GUI] Window for showing tips and loading messages';
            app.Tip_AppmlappNode_4.Icon = 'icon_GUI.png';
            app.Tip_AppmlappNode_4.Text = 'Tip_App.mlapp';
            app.Import_EXCELmNode = uitreenode(app.Build_AppmlappNode);
            app.Import_EXCELmNode.NodeData = '[function] Imports the MF geometry and member sizes from the pre-formatted EXCEL file';
            app.Import_EXCELmNode.Icon = 'icon_Function.png';
            app.Import_EXCELmNode.Text = 'Import_EXCEL.m';
            app.Get_Section_Database_IndexmNode = uitreenode(app.Build_AppmlappNode);
            app.Get_Section_Database_IndexmNode.NodeData = '[function] Stores the location index of the beam, column and brace cross-sections in the database';
            app.Get_Section_Database_IndexmNode.Icon = 'icon_Function.png';
            app.Get_Section_Database_IndexmNode.Text = 'Get_Section_Database_Index.m';
            app.Material_DatabasematNode = uitreenode(app.Build_AppmlappNode);
            app.Material_DatabasematNode.NodeData = '[data] Contains a structured array with all the mechanical properties of different steel types/grades based on the Voce-Chaboche constitutive plasticity model. Data is stored in imperial units';
            app.Material_DatabasematNode.Text = 'Material_Database.mat';
            app.AnalysisParametersNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.AnalysisParametersNode.NodeData = 'Analysis parameters module';
            app.AnalysisParametersNode.Text = '[Analysis Parameters]';
            app.Analysis_AppmlappNode = uitreenode(app.AnalysisParametersNode);
            app.Analysis_AppmlappNode.NodeData = '[GUI] Analysis type option and their parameters';
            app.Analysis_AppmlappNode.Icon = 'icon_GUI.png';
            app.Analysis_AppmlappNode.Text = 'Analysis_App.mlapp';
            app.Import_GM_DatamNode = uitreenode(app.Analysis_AppmlappNode);
            app.Import_GM_DatamNode.NodeData = '[function] Import and process the ground motion record data';
            app.Import_GM_DatamNode.Icon = 'icon_Function.png';
            app.Import_GM_DatamNode.Text = 'Import_GM_Data.m';
            app.Uncertainty_AppmlappNode = uitreenode(app.Analysis_AppmlappNode);
            app.Uncertainty_AppmlappNode.NodeData = '[GUI]';
            app.Uncertainty_AppmlappNode.Icon = 'icon_GUI.png';
            app.Uncertainty_AppmlappNode.Text = 'Uncertainty_App.mlapp';
            app.GetperiodNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.GetperiodNode.NodeData = 'Get the period of the building';
            app.GetperiodNode.Text = '[Get period]';
            app.Get_Mass_and_PeriodmNode = uitreenode(app.GetperiodNode);
            app.Get_Mass_and_PeriodmNode.NodeData = '[function] Get the mass, seismic weight and fundamanetal period of the building';
            app.Get_Mass_and_PeriodmNode.Icon = 'icon_Function.png';
            app.Get_Mass_and_PeriodmNode.Text = 'Get_Mass_and_Period.m';
            app.get_PeriodmNode = uitreenode(app.GetperiodNode);
            app.get_PeriodmNode.NodeData = '[function] Runs eigen analysis and gets the first mode period';
            app.get_PeriodmNode.Icon = 'icon_Function.png';
            app.get_PeriodmNode.Text = 'get_Period.m';
            app.CREATOR_MODEL_MRFmNode_2 = uitreenode(app.RootFM2D_AppmlappNode);
            app.CREATOR_MODEL_MRFmNode_2.NodeData = '[function] Builds the geometry code within the main tcl file of the numerical model';
            app.CREATOR_MODEL_MRFmNode_2.Icon = 'icon_Function.png';
            app.CREATOR_MODEL_MRFmNode_2.Text = 'CREATOR_MODEL_MRF.m';
            app.Load_SecDatamNode_3 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.Load_SecDatamNode_3.NodeData = '[function] Loads the cross-section database based on the member being modeled';
            app.Load_SecDatamNode_3.Icon = 'icon_Function.png';
            app.Load_SecDatamNode_3.Text = 'Load_SecData.m';
            app.AISC_WF_Sec_DatabasematNode_3 = uitreenode(app.Load_SecDatamNode_3);
            app.AISC_WF_Sec_DatabasematNode_3.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the AISC wide-flange cross-section database. Data are stored in imperial units.';
            app.AISC_WF_Sec_DatabasematNode_3.Text = 'AISC_WF_Sec_Database.mat';
            app.EURO_I_Sec_DatabasematNode_3 = uitreenode(app.Load_SecDatamNode_3);
            app.EURO_I_Sec_DatabasematNode_3.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the European I-shaped cross-section database. Data are stored in imperial units.';
            app.EURO_I_Sec_DatabasematNode_3.Text = 'EURO_I_Sec_Database.mat';
            app.HSS_Sec_DatabasematNode_3 = uitreenode(app.Load_SecDatamNode_3);
            app.HSS_Sec_DatabasematNode_3.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the AISC hollow structural section cross-section database. Data are stored in imperial units.';
            app.HSS_Sec_DatabasematNode_3.Text = 'HSS_Sec_Database.mat';
            app.Get_Col_Axial_ForcemNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.Get_Col_Axial_ForcemNode_4.NodeData = '[function]';
            app.Get_Col_Axial_ForcemNode_4.Icon = 'icon_Function.png';
            app.Get_Col_Axial_ForcemNode_4.Text = 'Get_Col_Axial_Force.m';
            app.write_OpenArgumentsmNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_OpenArgumentsmNode_4.NodeData = '[function] writes Tcl code lines for the opening arguments of the model.';
            app.write_OpenArgumentsmNode_4.Icon = 'icon_Function.png';
            app.write_OpenArgumentsmNode_4.Text = 'write_OpenArguments.m';
            app.write_SourceSubroutinemNode_5 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_SourceSubroutinemNode_5.NodeData = '[function] writes Tcl code lines for sourcing the required subroutines (procedures)';
            app.write_SourceSubroutinemNode_5.Icon = 'icon_Function.png';
            app.write_SourceSubroutinemNode_5.Text = 'write_SourceSubroutine.m';
            app.write_ResultsFoldermNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_ResultsFoldermNode_4.NodeData = '[function] writes Tcl code lines for creating the results folders';
            app.write_ResultsFoldermNode_4.Icon = 'icon_Function.png';
            app.write_ResultsFoldermNode_4.Text = 'write_ResultsFolder.m';
            app.write_BasicInputmNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_BasicInputmNode_4.NodeData = '[function] writes Tcl code lines for materials, geomtric transformation and modeling uncertainty.';
            app.write_BasicInputmNode_4.Icon = 'icon_Function.png';
            app.write_BasicInputmNode_4.Text = 'write_BasicInput.m';
            app.write_PreCalculatedGeometrymNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_PreCalculatedGeometrymNode_4.NodeData = '[function]';
            app.write_PreCalculatedGeometrymNode_4.Icon = 'icon_Function.png';
            app.write_PreCalculatedGeometrymNode_4.Text = 'write_PreCalculatedGeometry.m';
            app.write_Nodesm_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_Nodesm_4.NodeData = '[function] writes Tcl code lines for model nodes';
            app.write_Nodesm_4.Icon = 'icon_Function.png';
            app.write_Nodesm_4.Text = 'write_Nodes.m';
            app.write_PZelementmNode_3 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_PZelementmNode_3.NodeData = '[function] writes Tcl code lines for panel zone rigid elements';
            app.write_PZelementmNode_3.Icon = 'icon_Function.png';
            app.write_PZelementmNode_3.Text = 'write_PZelement.m';
            app.write_PZspringmNode_3 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_PZspringmNode_3.NodeData = '[function] writes Tcl code lines for panel zone springs';
            app.write_PZspringmNode_3.Icon = 'icon_Function.png';
            app.write_PZspringmNode_3.Text = 'write_PZspring.m';
            app.write_ElasticBeamsColumnsmNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_ElasticBeamsColumnsmNode_4.NodeData = '[function] writes Tcl code lines for the main frame''s elastic beam and column elements';
            app.write_ElasticBeamsColumnsmNode_4.Icon = 'icon_Function.png';
            app.write_ElasticBeamsColumnsmNode_4.Text = 'write_ElasticBeamsColumns.m';
            app.get_mod_I_with_shearNode_2 = uitreenode(app.write_ElasticBeamsColumnsmNode_4);
            app.get_mod_I_with_shearNode_2.NodeData = '[function] get_mod_I_with_shear';
            app.get_mod_I_with_shearNode_2.Icon = fullfile(pathToMLAPP, 'icon_Function.png');
            app.get_mod_I_with_shearNode_2.Text = 'get_mod_I_with_shear';
            app.write_BeamSpring_MRFmNode_2 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_BeamSpring_MRFmNode_2.NodeData = '[function] writes Tcl code lines for the main frame beam springs';
            app.write_BeamSpring_MRFmNode_2.Icon = 'icon_Function.png';
            app.write_BeamSpring_MRFmNode_2.Text = 'write_BeamSpring_MRF.m';
            app.write_ColumnSpringmNode_3 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_ColumnSpringmNode_3.NodeData = '[function] writes Tcl code lines for the main frame column springs';
            app.write_ColumnSpringmNode_3.Icon = 'icon_Function.png';
            app.write_ColumnSpringmNode_3.Text = 'write_ColumnSpring.m';
            app.write_SpliceSpringmNode_3 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_SpliceSpringmNode_3.NodeData = '[function] writes Tcl code lines for column splice connection springs';
            app.write_SpliceSpringmNode_3.Icon = 'icon_Function.png';
            app.write_SpliceSpringmNode_3.Text = 'write_SpliceSpring.m';
            app.write_FloorLinksmNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_FloorLinksmNode_4.NodeData = '[function] writes Tcl code lines for EGF-to-MF floor link elements';
            app.write_FloorLinksmNode_4.Icon = 'icon_Function.png';
            app.write_FloorLinksmNode_4.Text = 'write_FloorLinks.m';
            app.write_EGFelementsmNode_3 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_EGFelementsmNode_3.NodeData = '[function] writes Tcl code lines for the EGF''s elastic beam and column elements';
            app.write_EGFelementsmNode_3.Icon = 'icon_Function.png';
            app.write_EGFelementsmNode_3.Text = 'write_EGFelements.m';
            app.write_EGFspringsmNode_3 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_EGFspringsmNode_3.NodeData = '[function] writes Tcl code lines for the EGF''s beam and column springs';
            app.write_EGFspringsmNode_3.Icon = 'icon_Function.png';
            app.write_EGFspringsmNode_3.Text = 'write_EGFsprings.m';
            app.write_BCsmNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_BCsmNode_4.NodeData = '[function] writes Tcl code lines for the model boundary conditions';
            app.write_BCsmNode_4.Icon = 'icon_Function.png';
            app.write_BCsmNode_4.Text = 'write_BCs.m';
            app.write_RecordersmNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_RecordersmNode_4.NodeData = '[function] writes Tcl code lines for the recorders data';
            app.write_RecordersmNode_4.Icon = 'icon_Function.png';
            app.write_RecordersmNode_4.Text = 'write_Recorders.m';
            app.write_MassmNode_4 = uitreenode(app.CREATOR_MODEL_MRFmNode_2);
            app.write_MassmNode_4.NodeData = '[function] writes Tcl code lines for the nodal mass assignment';
            app.write_MassmNode_4.Icon = 'icon_Function.png';
            app.write_MassmNode_4.Text = 'write_Mass.m';
            app.CREATOR_MODEL_MRF_RCmNode_2 = uitreenode(app.RootFM2D_AppmlappNode);
            app.CREATOR_MODEL_MRF_RCmNode_2.NodeData = '[function] Builds the geometry code within the main tcl file of the numerical model';
            app.CREATOR_MODEL_MRF_RCmNode_2.Icon = 'icon_Function.png';
            app.CREATOR_MODEL_MRF_RCmNode_2.Text = 'CREATOR_MODEL_MRF_RC.m';
            app.Load_SecData_RCmNode_2 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.Load_SecData_RCmNode_2.NodeData = '[function] Loads the cross-section database based on the member being modeled';
            app.Load_SecData_RCmNode_2.Icon = 'icon_Function.png';
            app.Load_SecData_RCmNode_2.Text = 'Load_SecData_RC.m';
            app.Get_Col_Axial_ForcemNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.Get_Col_Axial_ForcemNode_5.NodeData = '[function]';
            app.Get_Col_Axial_ForcemNode_5.Icon = 'icon_Function.png';
            app.Get_Col_Axial_ForcemNode_5.Text = 'Get_Col_Axial_Force.m';
            app.write_OpenArgumentsmNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_OpenArgumentsmNode_5.NodeData = '[function] writes Tcl code lines for the opening arguments of the model.';
            app.write_OpenArgumentsmNode_5.Icon = 'icon_Function.png';
            app.write_OpenArgumentsmNode_5.Text = 'write_OpenArguments.m';
            app.write_SourceSubroutinemNode_6 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_SourceSubroutinemNode_6.NodeData = '[function] writes Tcl code lines for sourcing the required subroutines (procedures)';
            app.write_SourceSubroutinemNode_6.Icon = 'icon_Function.png';
            app.write_SourceSubroutinemNode_6.Text = 'write_SourceSubroutine.m';
            app.write_ResultsFoldermNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_ResultsFoldermNode_5.NodeData = '[function] writes Tcl code lines for creating the results folders';
            app.write_ResultsFoldermNode_5.Icon = 'icon_Function.png';
            app.write_ResultsFoldermNode_5.Text = 'write_ResultsFolder.m';
            app.write_BasicInputmNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_BasicInputmNode_5.NodeData = '[function] writes Tcl code lines for materials, geomtric transformation and modeling uncertainty.';
            app.write_BasicInputmNode_5.Icon = 'icon_Function.png';
            app.write_BasicInputmNode_5.Text = 'write_BasicInput.m';
            app.write_PreCalculatedGeometrymNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_PreCalculatedGeometrymNode_5.NodeData = '[function]';
            app.write_PreCalculatedGeometrymNode_5.Icon = 'icon_Function.png';
            app.write_PreCalculatedGeometrymNode_5.Text = 'write_PreCalculatedGeometry.m';
            app.write_Nodesm_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_Nodesm_5.NodeData = '[function] writes Tcl code lines for model nodes';
            app.write_Nodesm_5.Icon = 'icon_Function.png';
            app.write_Nodesm_5.Text = 'write_Nodes.m';
            app.write_PZ_RCmNode_2 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_PZ_RCmNode_2.NodeData = '[function] writes Tcl code lines for panel zone elements and spring.';
            app.write_PZ_RCmNode_2.Icon = 'icon_Function.png';
            app.write_PZ_RCmNode_2.Text = 'write_PZ_RC.m';
            app.write_ElasticBeamsColumnsmNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_ElasticBeamsColumnsmNode_5.NodeData = '[function] writes Tcl code lines for the main frame''s elastic beam and column elements';
            app.write_ElasticBeamsColumnsmNode_5.Icon = 'icon_Function.png';
            app.write_ElasticBeamsColumnsmNode_5.Text = 'write_ElasticBeamsColumns.m';
            app.write_BeamSpring_MRF_RCmNode_2 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_BeamSpring_MRF_RCmNode_2.NodeData = '[function] writes Tcl code lines for the main frame beam springs';
            app.write_BeamSpring_MRF_RCmNode_2.Icon = 'icon_Function.png';
            app.write_BeamSpring_MRF_RCmNode_2.Text = 'write_BeamSpring_MRF_RC.m';
            app.write_ColumnSpring_RCmNode_2 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_ColumnSpring_RCmNode_2.NodeData = '[function] writes Tcl code lines for the main frame column springs';
            app.write_ColumnSpring_RCmNode_2.Icon = 'icon_Function.png';
            app.write_ColumnSpring_RCmNode_2.Text = 'write_ColumnSpring_RC.m';
            app.write_FloorLinksmNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_FloorLinksmNode_5.NodeData = '[function] writes Tcl code lines for EGF-to-MF floor link elements';
            app.write_FloorLinksmNode_5.Icon = 'icon_Function.png';
            app.write_FloorLinksmNode_5.Text = 'write_FloorLinks.m';
            app.write_EGFelements_RCmNode_2 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_EGFelements_RCmNode_2.NodeData = '[function] writes Tcl code lines for the EGF''s elastic beam and column elements';
            app.write_EGFelements_RCmNode_2.Icon = 'icon_Function.png';
            app.write_EGFelements_RCmNode_2.Text = 'write_EGFelements_RC.m';
            app.write_EGFsprings_RCmNode_2 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_EGFsprings_RCmNode_2.NodeData = '[function] writes Tcl code lines for the EGF''s beam and column springs';
            app.write_EGFsprings_RCmNode_2.Icon = 'icon_Function.png';
            app.write_EGFsprings_RCmNode_2.Text = 'write_EGFsprings_RC.m';
            app.write_BCsmNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_BCsmNode_5.NodeData = '[function] writes Tcl code lines for the model boundary conditions';
            app.write_BCsmNode_5.Icon = 'icon_Function.png';
            app.write_BCsmNode_5.Text = 'write_BCs.m';
            app.write_RecordersmNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_RecordersmNode_5.NodeData = '[function] writes Tcl code lines for the recorders data';
            app.write_RecordersmNode_5.Icon = 'icon_Function.png';
            app.write_RecordersmNode_5.Text = 'write_Recorders.m';
            app.write_MassmNode_5 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode_2);
            app.write_MassmNode_5.NodeData = '[function] writes Tcl code lines for the nodal mass assignment';
            app.write_MassmNode_5.Icon = 'icon_Function.png';
            app.write_MassmNode_5.Text = 'write_Mass.m';
            app.CREATOR_MODEL_CBFmNode_2 = uitreenode(app.RootFM2D_AppmlappNode);
            app.CREATOR_MODEL_CBFmNode_2.NodeData = '[function] Builds the the geometry code within the main tcl file of the numerical model';
            app.CREATOR_MODEL_CBFmNode_2.Icon = 'icon_Function.png';
            app.CREATOR_MODEL_CBFmNode_2.Text = 'CREATOR_MODEL_CBF.m';
            app.Load_SecDatamNode_4 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.Load_SecDatamNode_4.NodeData = '[function] Loads the cross-section database based on the member being modeled';
            app.Load_SecDatamNode_4.Icon = 'icon_Function.png';
            app.Load_SecDatamNode_4.Text = 'Load_SecData.m';
            app.AISC_WF_Sec_DatabasematNode_4 = uitreenode(app.Load_SecDatamNode_4);
            app.AISC_WF_Sec_DatabasematNode_4.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the AISC wide-flange cross-section database. Data are stored in imperial units.';
            app.AISC_WF_Sec_DatabasematNode_4.Text = 'AISC_WF_Sec_Database.mat';
            app.EURO_I_Sec_DatabasematNode_4 = uitreenode(app.Load_SecDatamNode_4);
            app.EURO_I_Sec_DatabasematNode_4.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the European I-shaped cross-section database. Data are stored in imperial units.';
            app.EURO_I_Sec_DatabasematNode_4.Text = 'EURO_I_Sec_Database.mat';
            app.HSS_Sec_DatabasematNode_4 = uitreenode(app.Load_SecDatamNode_4);
            app.HSS_Sec_DatabasematNode_4.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the AISC hollow structural section cross-section database. Data are stored in imperial units.';
            app.HSS_Sec_DatabasematNode_4.Text = 'HSS_Sec_Database.mat';
            app.Get_Col_Axial_ForcemNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.Get_Col_Axial_ForcemNode_6.NodeData = '[function]';
            app.Get_Col_Axial_ForcemNode_6.Icon = 'icon_Function.png';
            app.Get_Col_Axial_ForcemNode_6.Text = 'Get_Col_Axial_Force.m';
            app.write_OpenArgumentsmNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_OpenArgumentsmNode_6.NodeData = '[function] writes Tcl code lines for the opening arguments of the model.';
            app.write_OpenArgumentsmNode_6.Icon = 'icon_Function.png';
            app.write_OpenArgumentsmNode_6.Text = 'write_OpenArguments.m';
            app.write_SourceSubroutinemNode_7 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_SourceSubroutinemNode_7.NodeData = '[function] writes Tcl code lines for sourcing the required subroutines (procedures)';
            app.write_SourceSubroutinemNode_7.Icon = 'icon_Function.png';
            app.write_SourceSubroutinemNode_7.Text = 'write_SourceSubroutine.m';
            app.write_ResultsFoldermNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_ResultsFoldermNode_6.NodeData = '[function] writes Tcl code lines for creating the results folders';
            app.write_ResultsFoldermNode_6.Icon = 'icon_Function.png';
            app.write_ResultsFoldermNode_6.Text = 'write_ResultsFolder.m';
            app.write_BasicInputmNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_BasicInputmNode_6.NodeData = '[function] writes Tcl code lines for materials, geomtric transformation and modeling uncertainty.';
            app.write_BasicInputmNode_6.Icon = 'icon_Function.png';
            app.write_BasicInputmNode_6.Text = 'write_BasicInput.m';
            app.write_PreCalculatedGeometrymNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_PreCalculatedGeometrymNode_6.NodeData = '[function]';
            app.write_PreCalculatedGeometrymNode_6.Icon = 'icon_Function.png';
            app.write_PreCalculatedGeometrymNode_6.Text = 'write_PreCalculatedGeometry.m';
            app.write_Nodesm_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_Nodesm_6.NodeData = '[function] writes Tcl code lines for model nodes';
            app.write_Nodesm_6.Icon = 'icon_Function.png';
            app.write_Nodesm_6.Text = 'write_Nodes.m';
            app.write_PZelementmNode_4 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_PZelementmNode_4.NodeData = '[function] writes Tcl code lines for panel zone rigid elements';
            app.write_PZelementmNode_4.Icon = 'icon_Function.png';
            app.write_PZelementmNode_4.Text = 'write_PZelement.m';
            app.write_PZspringmNode_4 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_PZspringmNode_4.NodeData = '[function] writes Tcl code lines for panel zone springs';
            app.write_PZspringmNode_4.Icon = 'icon_Function.png';
            app.write_PZspringmNode_4.Text = 'write_PZspring.m';
            app.write_BraceRigidLinksmNode_3 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_BraceRigidLinksmNode_3.NodeData = '[function] writes Tcl code lines for the brace gusset plates'' rigid link elements';
            app.write_BraceRigidLinksmNode_3.Icon = 'icon_Function.png';
            app.write_BraceRigidLinksmNode_3.Text = 'write_BraceRigidLinks.m';
            app.write_GPspringmNode_3 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_GPspringmNode_3.NodeData = '[function] writes Tcl code lines for gusset plate springs';
            app.write_GPspringmNode_3.Icon = 'icon_Function.png';
            app.write_GPspringmNode_3.Text = 'write_GPspring.m';
            app.write_BracesmNode_3 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_BracesmNode_3.NodeData = '[function] writes Tcl code lines for brace elements';
            app.write_BracesmNode_3.Icon = 'icon_Function.png';
            app.write_BracesmNode_3.Text = 'write_Braces.m';
            app.write_ElasticBeamsColumnsmNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_ElasticBeamsColumnsmNode_6.NodeData = '[function] writes Tcl code lines for the main frame''s elastic beam and column elements';
            app.write_ElasticBeamsColumnsmNode_6.Icon = 'icon_Function.png';
            app.write_ElasticBeamsColumnsmNode_6.Text = 'write_ElasticBeamsColumns.m';
            app.write_BeamSpring_CBFmNode_3 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_BeamSpring_CBFmNode_3.NodeData = '[function] writes Tcl code lines for the main frame beam springs';
            app.write_BeamSpring_CBFmNode_3.Icon = 'icon_Function.png';
            app.write_BeamSpring_CBFmNode_3.Text = 'write_BeamSpring_CBF.m';
            app.write_ColumnSpringmNode_4 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_ColumnSpringmNode_4.NodeData = '[function] writes Tcl code lines for the main frame column springs';
            app.write_ColumnSpringmNode_4.Icon = 'icon_Function.png';
            app.write_ColumnSpringmNode_4.Text = 'write_ColumnSpring.m';
            app.write_SpliceSpringmNode_4 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_SpliceSpringmNode_4.NodeData = '[function] writes Tcl code lines for column splice connection springs';
            app.write_SpliceSpringmNode_4.Icon = 'icon_Function.png';
            app.write_SpliceSpringmNode_4.Text = 'write_SpliceSpring.m';
            app.write_FloorLinksmNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_FloorLinksmNode_6.NodeData = '[function] writes Tcl code lines for EGF-to-MF floor link elements';
            app.write_FloorLinksmNode_6.Icon = 'icon_Function.png';
            app.write_FloorLinksmNode_6.Text = 'write_FloorLinks.m';
            app.write_EGFelementsmNode_4 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_EGFelementsmNode_4.NodeData = '[function] writes Tcl code lines for the EGF''s elastic beam and column elements';
            app.write_EGFelementsmNode_4.Icon = 'icon_Function.png';
            app.write_EGFelementsmNode_4.Text = 'write_EGFelements.m';
            app.write_EGFspringsmNode_4 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_EGFspringsmNode_4.NodeData = '[function] writes Tcl code lines for the EGF''s beam and column springs';
            app.write_EGFspringsmNode_4.Icon = 'icon_Function.png';
            app.write_EGFspringsmNode_4.Text = 'write_EGFsprings.m';
            app.write_BCsmNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_BCsmNode_6.NodeData = '[function] writes Tcl code lines for the model boundary conditions';
            app.write_BCsmNode_6.Icon = 'icon_Function.png';
            app.write_BCsmNode_6.Text = 'write_BCs.m';
            app.write_RecordersmNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_RecordersmNode_6.NodeData = '[function] writes Tcl code lines for the recorders data';
            app.write_RecordersmNode_6.Icon = 'icon_Function.png';
            app.write_RecordersmNode_6.Text = 'write_Recorders.m';
            app.write_MassmNode_6 = uitreenode(app.CREATOR_MODEL_CBFmNode_2);
            app.write_MassmNode_6.NodeData = '[function] writes Tcl code lines for the nodal mass assignment';
            app.write_MassmNode_6.Icon = 'icon_Function.png';
            app.write_MassmNode_6.Text = 'write_Mass.m';
            app.CREATOR_ANALYSISmNode_2 = uitreenode(app.RootFM2D_AppmlappNode);
            app.CREATOR_ANALYSISmNode_2.NodeData = '[function] Builds the the analysis code within the main tcl file of the numerical model';
            app.CREATOR_ANALYSISmNode_2.Icon = 'icon_Function.png';
            app.CREATOR_ANALYSISmNode_2.Text = 'CREATOR_ANALYSIS.m';
            app.write_Analysis_EigenmNode_3 = uitreenode(app.CREATOR_ANALYSISmNode_2);
            app.write_Analysis_EigenmNode_3.NodeData = '[function] writes Tcl code lines for the Eigen value analysis';
            app.write_Analysis_EigenmNode_3.Icon = 'icon_Function.png';
            app.write_Analysis_EigenmNode_3.Text = 'write_Analysis_Eigen.m';
            app.write_Analysis_GravitymNode_2 = uitreenode(app.CREATOR_ANALYSISmNode_2);
            app.write_Analysis_GravitymNode_2.NodeData = '[function] writes Tcl code lines for the gravity load application';
            app.write_Analysis_GravitymNode_2.Icon = 'icon_Function.png';
            app.write_Analysis_GravitymNode_2.Text = 'write_Analysis_Gravity.m';
            app.write_AnimationmNode_2 = uitreenode(app.CREATOR_ANALYSISmNode_2);
            app.write_AnimationmNode_2.NodeData = '[function] writes Tcl code lines for the deformed shape animation';
            app.write_AnimationmNode_2.Icon = 'icon_Function.png';
            app.write_AnimationmNode_2.Text = 'write_Animation.m';
            app.write_Analysis_PushovermNode_2 = uitreenode(app.CREATOR_ANALYSISmNode_2);
            app.write_Analysis_PushovermNode_2.NodeData = '[function] writes Tcl code lines for the static pushover analysis';
            app.write_Analysis_PushovermNode_2.Icon = 'icon_Function.png';
            app.write_Analysis_PushovermNode_2.Text = 'write_Analysis_Pushover.m';
            app.write_Analysis_DynamicmNode_2 = uitreenode(app.CREATOR_ANALYSISmNode_2);
            app.write_Analysis_DynamicmNode_2.NodeData = '[function] writes Tcl code lines for the dynamic analysis';
            app.write_Analysis_DynamicmNode_2.Icon = 'icon_Function.png';
            app.write_Analysis_DynamicmNode_2.Text = 'write_Analysis_Dynamic.m';
            app.write_Analysis_ELFmNode_2 = uitreenode(app.CREATOR_ANALYSISmNode_2);
            app.write_Analysis_ELFmNode_2.NodeData = '[function] writes Tcl code lines for the equivelant lateral force analysis';
            app.write_Analysis_ELFmNode_2.Icon = 'icon_Function.png';
            app.write_Analysis_ELFmNode_2.Text = 'write_Analysis_ELF.m';
            app.BuildtclfileNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.BuildtclfileNode.NodeData = 'Builds a standalone tcl file';
            app.BuildtclfileNode.Text = '[Build tcl file]';
            app.build_TCL_filemNode = uitreenode(app.BuildtclfileNode);
            app.build_TCL_filemNode.NodeData = '[function] builds the tcl file for the model';
            app.build_TCL_filemNode.Icon = 'icon_Function.png';
            app.build_TCL_filemNode.Text = 'build_TCL_file.m';
            app.RunModelNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.RunModelNode.NodeData = 'Run anslysis button';
            app.RunModelNode.Text = '[Run Model]';
            app.ExecutionMessage_AppmlappNode = uitreenode(app.RunModelNode);
            app.ExecutionMessage_AppmlappNode.NodeData = '[GUI] Interface for selecting the execution type; whether a new tcl file will be built for the run or if the run will be based on the existing tcl file.';
            app.ExecutionMessage_AppmlappNode.Icon = 'icon_Function.png';
            app.ExecutionMessage_AppmlappNode.Text = 'ExecutionMessage_App.mlapp';
            app.Execution_CommandsmNode = uitreenode(app.RunModelNode);
            app.Execution_CommandsmNode.NodeData = '[function] Calls the different scripts for constructing the Tcl files and then executes the OpenSEES analysis';
            app.Execution_CommandsmNode.Icon = 'icon_Function.png';
            app.Execution_CommandsmNode.Text = 'Execution_Commands.m';
            app.CREATOR_MODEL_MRFmNode = uitreenode(app.Execution_CommandsmNode);
            app.CREATOR_MODEL_MRFmNode.NodeData = '[function] Builds the geometry code within the main tcl file of the numerical model';
            app.CREATOR_MODEL_MRFmNode.Icon = 'icon_Function.png';
            app.CREATOR_MODEL_MRFmNode.Text = 'CREATOR_MODEL_MRF.m';
            app.Load_SecDatamNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.Load_SecDatamNode.NodeData = '[function] Loads the cross-section database based on the member being modeled';
            app.Load_SecDatamNode.Icon = 'icon_Function.png';
            app.Load_SecDatamNode.Text = 'Load_SecData.m';
            app.AISC_WF_Sec_DatabasematNode = uitreenode(app.Load_SecDatamNode);
            app.AISC_WF_Sec_DatabasematNode.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the AISC wide-flange cross-section database. Data are stored in imperial units.';
            app.AISC_WF_Sec_DatabasematNode.Text = 'AISC_WF_Sec_Database.mat';
            app.EURO_I_Sec_DatabasematNode = uitreenode(app.Load_SecDatamNode);
            app.EURO_I_Sec_DatabasematNode.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the European I-shaped cross-section database. Data are stored in imperial units.';
            app.EURO_I_Sec_DatabasematNode.Text = 'EURO_I_Sec_Database.mat';
            app.HSS_Sec_DatabasematNode = uitreenode(app.Load_SecDatamNode);
            app.HSS_Sec_DatabasematNode.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the AISC hollow structural section cross-section database. Data are stored in imperial units.';
            app.HSS_Sec_DatabasematNode.Text = 'HSS_Sec_Database.mat';
            app.Get_Col_Axial_ForcemNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.Get_Col_Axial_ForcemNode.NodeData = '[function]';
            app.Get_Col_Axial_ForcemNode.Icon = 'icon_Function.png';
            app.Get_Col_Axial_ForcemNode.Text = 'Get_Col_Axial_Force.m';
            app.write_OpenArgumentsmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_OpenArgumentsmNode.NodeData = '[function] writes Tcl code lines for the opening arguments of the model.';
            app.write_OpenArgumentsmNode.Icon = 'icon_Function.png';
            app.write_OpenArgumentsmNode.Text = 'write_OpenArguments.m';
            app.write_SourceSubroutinemNode_2 = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_SourceSubroutinemNode_2.NodeData = '[function] writes Tcl code lines for sourcing the required subroutines (procedures)';
            app.write_SourceSubroutinemNode_2.Icon = 'icon_Function.png';
            app.write_SourceSubroutinemNode_2.Text = 'write_SourceSubroutine.m';
            app.write_ResultsFoldermNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_ResultsFoldermNode.NodeData = '[function] writes Tcl code lines for creating the results folders';
            app.write_ResultsFoldermNode.Icon = 'icon_Function.png';
            app.write_ResultsFoldermNode.Text = 'write_ResultsFolder.m';
            app.write_BasicInputmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_BasicInputmNode.NodeData = '[function] writes Tcl code lines for materials, geomtric transformation and modeling uncertainty.';
            app.write_BasicInputmNode.Icon = 'icon_Function.png';
            app.write_BasicInputmNode.Text = 'write_BasicInput.m';
            app.write_PreCalculatedGeometrymNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_PreCalculatedGeometrymNode.NodeData = '[function]';
            app.write_PreCalculatedGeometrymNode.Icon = 'icon_Function.png';
            app.write_PreCalculatedGeometrymNode.Text = 'write_PreCalculatedGeometry.m';
            app.write_Nodesm = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_Nodesm.NodeData = '[function] writes Tcl code lines for model nodes';
            app.write_Nodesm.Icon = 'icon_Function.png';
            app.write_Nodesm.Text = 'write_Nodes.m';
            app.write_PZelementmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_PZelementmNode.NodeData = '[function] writes Tcl code lines for panel zone rigid elements';
            app.write_PZelementmNode.Icon = 'icon_Function.png';
            app.write_PZelementmNode.Text = 'write_PZelement.m';
            app.write_PZspringmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_PZspringmNode.NodeData = '[function] writes Tcl code lines for panel zone springs';
            app.write_PZspringmNode.Icon = 'icon_Function.png';
            app.write_PZspringmNode.Text = 'write_PZspring.m';
            app.write_ElasticBeamsColumnsmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_ElasticBeamsColumnsmNode.NodeData = '[function] writes Tcl code lines for the main frame''s elastic beam and column elements';
            app.write_ElasticBeamsColumnsmNode.Icon = 'icon_Function.png';
            app.write_ElasticBeamsColumnsmNode.Text = 'write_ElasticBeamsColumns.m';
            app.get_mod_I_with_shearNode = uitreenode(app.write_ElasticBeamsColumnsmNode);
            app.get_mod_I_with_shearNode.NodeData = '[function] get_mod_I_with_shear';
            app.get_mod_I_with_shearNode.Icon = fullfile(pathToMLAPP, 'icon_Function.png');
            app.get_mod_I_with_shearNode.Text = 'get_mod_I_with_shear';
            app.write_BeamSpring_MRFmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_BeamSpring_MRFmNode.NodeData = '[function] writes Tcl code lines for the main frame beam springs';
            app.write_BeamSpring_MRFmNode.Icon = 'icon_Function.png';
            app.write_BeamSpring_MRFmNode.Text = 'write_BeamSpring_MRF.m';
            app.write_ColumnSpringmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_ColumnSpringmNode.NodeData = '[function] writes Tcl code lines for the main frame column springs';
            app.write_ColumnSpringmNode.Icon = 'icon_Function.png';
            app.write_ColumnSpringmNode.Text = 'write_ColumnSpring.m';
            app.write_SpliceSpringmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_SpliceSpringmNode.NodeData = '[function] writes Tcl code lines for column splice connection springs';
            app.write_SpliceSpringmNode.Icon = 'icon_Function.png';
            app.write_SpliceSpringmNode.Text = 'write_SpliceSpring.m';
            app.write_FloorLinksmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_FloorLinksmNode.NodeData = '[function] writes Tcl code lines for EGF-to-MF floor link elements';
            app.write_FloorLinksmNode.Icon = 'icon_Function.png';
            app.write_FloorLinksmNode.Text = 'write_FloorLinks.m';
            app.write_EGFelementsmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_EGFelementsmNode.NodeData = '[function] writes Tcl code lines for the EGF''s elastic beam and column elements';
            app.write_EGFelementsmNode.Icon = 'icon_Function.png';
            app.write_EGFelementsmNode.Text = 'write_EGFelements.m';
            app.write_EGFspringsmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_EGFspringsmNode.NodeData = '[function] writes Tcl code lines for the EGF''s beam and column springs';
            app.write_EGFspringsmNode.Icon = 'icon_Function.png';
            app.write_EGFspringsmNode.Text = 'write_EGFsprings.m';
            app.write_BCsmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_BCsmNode.NodeData = '[function] writes Tcl code lines for the model boundary conditions';
            app.write_BCsmNode.Icon = 'icon_Function.png';
            app.write_BCsmNode.Text = 'write_BCs.m';
            app.write_RecordersmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_RecordersmNode.NodeData = '[function] writes Tcl code lines for the recorders data';
            app.write_RecordersmNode.Icon = 'icon_Function.png';
            app.write_RecordersmNode.Text = 'write_Recorders.m';
            app.write_MassmNode = uitreenode(app.CREATOR_MODEL_MRFmNode);
            app.write_MassmNode.NodeData = '[function] writes Tcl code lines for the nodal mass assignment';
            app.write_MassmNode.Icon = 'icon_Function.png';
            app.write_MassmNode.Text = 'write_Mass.m';
            app.CREATOR_MODEL_MRF_RCmNode = uitreenode(app.Execution_CommandsmNode);
            app.CREATOR_MODEL_MRF_RCmNode.NodeData = '[function] Builds the geometry code within the main tcl file of the numerical model';
            app.CREATOR_MODEL_MRF_RCmNode.Icon = 'icon_Function.png';
            app.CREATOR_MODEL_MRF_RCmNode.Text = 'CREATOR_MODEL_MRF_RC.m';
            app.Load_SecData_RCmNode = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.Load_SecData_RCmNode.NodeData = '[function] Loads the cross-section database based on the member being modeled';
            app.Load_SecData_RCmNode.Icon = 'icon_Function.png';
            app.Load_SecData_RCmNode.Text = 'Load_SecData_RC.m';
            app.Get_Col_Axial_ForcemNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.Get_Col_Axial_ForcemNode_3.NodeData = '[function]';
            app.Get_Col_Axial_ForcemNode_3.Icon = 'icon_Function.png';
            app.Get_Col_Axial_ForcemNode_3.Text = 'Get_Col_Axial_Force.m';
            app.write_OpenArgumentsmNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_OpenArgumentsmNode_3.NodeData = '[function] writes Tcl code lines for the opening arguments of the model.';
            app.write_OpenArgumentsmNode_3.Icon = 'icon_Function.png';
            app.write_OpenArgumentsmNode_3.Text = 'write_OpenArguments.m';
            app.write_SourceSubroutinemNode_4 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_SourceSubroutinemNode_4.NodeData = '[function] writes Tcl code lines for sourcing the required subroutines (procedures)';
            app.write_SourceSubroutinemNode_4.Icon = 'icon_Function.png';
            app.write_SourceSubroutinemNode_4.Text = 'write_SourceSubroutine.m';
            app.write_ResultsFoldermNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_ResultsFoldermNode_3.NodeData = '[function] writes Tcl code lines for creating the results folders';
            app.write_ResultsFoldermNode_3.Icon = 'icon_Function.png';
            app.write_ResultsFoldermNode_3.Text = 'write_ResultsFolder.m';
            app.write_BasicInputmNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_BasicInputmNode_3.NodeData = '[function] writes Tcl code lines for materials, geomtric transformation and modeling uncertainty.';
            app.write_BasicInputmNode_3.Icon = 'icon_Function.png';
            app.write_BasicInputmNode_3.Text = 'write_BasicInput.m';
            app.write_PreCalculatedGeometrymNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_PreCalculatedGeometrymNode_3.NodeData = '[function]';
            app.write_PreCalculatedGeometrymNode_3.Icon = 'icon_Function.png';
            app.write_PreCalculatedGeometrymNode_3.Text = 'write_PreCalculatedGeometry.m';
            app.write_Nodesm_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_Nodesm_3.NodeData = '[function] writes Tcl code lines for model nodes';
            app.write_Nodesm_3.Icon = 'icon_Function.png';
            app.write_Nodesm_3.Text = 'write_Nodes.m';
            app.write_PZ_RCmNode = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_PZ_RCmNode.NodeData = '[function] writes Tcl code lines for panel zone elements and spring.';
            app.write_PZ_RCmNode.Icon = 'icon_Function.png';
            app.write_PZ_RCmNode.Text = 'write_PZ_RC.m';
            app.write_ElasticBeamsColumnsmNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_ElasticBeamsColumnsmNode_3.NodeData = '[function] writes Tcl code lines for the main frame''s elastic beam and column elements';
            app.write_ElasticBeamsColumnsmNode_3.Icon = 'icon_Function.png';
            app.write_ElasticBeamsColumnsmNode_3.Text = 'write_ElasticBeamsColumns.m';
            app.write_BeamSpring_MRF_RCmNode = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_BeamSpring_MRF_RCmNode.NodeData = '[function] writes Tcl code lines for the main frame beam springs';
            app.write_BeamSpring_MRF_RCmNode.Icon = 'icon_Function.png';
            app.write_BeamSpring_MRF_RCmNode.Text = 'write_BeamSpring_MRF_RC.m';
            app.write_ColumnSpring_RCmNode = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_ColumnSpring_RCmNode.NodeData = '[function] writes Tcl code lines for the main frame column springs';
            app.write_ColumnSpring_RCmNode.Icon = 'icon_Function.png';
            app.write_ColumnSpring_RCmNode.Text = 'write_ColumnSpring_RC.m';
            app.write_FloorLinksmNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_FloorLinksmNode_3.NodeData = '[function] writes Tcl code lines for EGF-to-MF floor link elements';
            app.write_FloorLinksmNode_3.Icon = 'icon_Function.png';
            app.write_FloorLinksmNode_3.Text = 'write_FloorLinks.m';
            app.write_EGFelements_RCmNode = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_EGFelements_RCmNode.NodeData = '[function] writes Tcl code lines for the EGF''s elastic beam and column elements';
            app.write_EGFelements_RCmNode.Icon = 'icon_Function.png';
            app.write_EGFelements_RCmNode.Text = 'write_EGFelements_RC.m';
            app.write_EGFsprings_RCmNode = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_EGFsprings_RCmNode.NodeData = '[function] writes Tcl code lines for the EGF''s beam and column springs';
            app.write_EGFsprings_RCmNode.Icon = 'icon_Function.png';
            app.write_EGFsprings_RCmNode.Text = 'write_EGFsprings_RC.m';
            app.write_BCsmNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_BCsmNode_3.NodeData = '[function] writes Tcl code lines for the model boundary conditions';
            app.write_BCsmNode_3.Icon = 'icon_Function.png';
            app.write_BCsmNode_3.Text = 'write_BCs.m';
            app.write_RecordersmNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_RecordersmNode_3.NodeData = '[function] writes Tcl code lines for the recorders data';
            app.write_RecordersmNode_3.Icon = 'icon_Function.png';
            app.write_RecordersmNode_3.Text = 'write_Recorders.m';
            app.write_MassmNode_3 = uitreenode(app.CREATOR_MODEL_MRF_RCmNode);
            app.write_MassmNode_3.NodeData = '[function] writes Tcl code lines for the nodal mass assignment';
            app.write_MassmNode_3.Icon = 'icon_Function.png';
            app.write_MassmNode_3.Text = 'write_Mass.m';
            app.CREATOR_MODEL_CBFmNode = uitreenode(app.Execution_CommandsmNode);
            app.CREATOR_MODEL_CBFmNode.NodeData = '[function] Builds the the geometry code within the main tcl file of the numerical model';
            app.CREATOR_MODEL_CBFmNode.Icon = 'icon_Function.png';
            app.CREATOR_MODEL_CBFmNode.Text = 'CREATOR_MODEL_CBF.m';
            app.Load_SecDatamNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.Load_SecDatamNode_2.NodeData = '[function] Loads the cross-section database based on the member being modeled';
            app.Load_SecDatamNode_2.Icon = 'icon_Function.png';
            app.Load_SecDatamNode_2.Text = 'Load_SecData.m';
            app.AISC_WF_Sec_DatabasematNode_2 = uitreenode(app.Load_SecDatamNode_2);
            app.AISC_WF_Sec_DatabasematNode_2.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the AISC wide-flange cross-section database. Data are stored in imperial units.';
            app.AISC_WF_Sec_DatabasematNode_2.Text = 'AISC_WF_Sec_Database.mat';
            app.EURO_I_Sec_DatabasematNode_2 = uitreenode(app.Load_SecDatamNode_2);
            app.EURO_I_Sec_DatabasematNode_2.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the European I-shaped cross-section database. Data are stored in imperial units.';
            app.EURO_I_Sec_DatabasematNode_2.Text = 'EURO_I_Sec_Database.mat';
            app.HSS_Sec_DatabasematNode_2 = uitreenode(app.Load_SecDatamNode_2);
            app.HSS_Sec_DatabasematNode_2.NodeData = '[data] Contains a structured array with all the cross-section geometric properties based on the AISC hollow structural section cross-section database. Data are stored in imperial units.';
            app.HSS_Sec_DatabasematNode_2.Text = 'HSS_Sec_Database.mat';
            app.Get_Col_Axial_ForcemNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.Get_Col_Axial_ForcemNode_2.NodeData = '[function]';
            app.Get_Col_Axial_ForcemNode_2.Icon = 'icon_Function.png';
            app.Get_Col_Axial_ForcemNode_2.Text = 'Get_Col_Axial_Force.m';
            app.write_OpenArgumentsmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_OpenArgumentsmNode_2.NodeData = '[function] writes Tcl code lines for the opening arguments of the model.';
            app.write_OpenArgumentsmNode_2.Icon = 'icon_Function.png';
            app.write_OpenArgumentsmNode_2.Text = 'write_OpenArguments.m';
            app.write_SourceSubroutinemNode_3 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_SourceSubroutinemNode_3.NodeData = '[function] writes Tcl code lines for sourcing the required subroutines (procedures)';
            app.write_SourceSubroutinemNode_3.Icon = 'icon_Function.png';
            app.write_SourceSubroutinemNode_3.Text = 'write_SourceSubroutine.m';
            app.write_ResultsFoldermNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_ResultsFoldermNode_2.NodeData = '[function] writes Tcl code lines for creating the results folders';
            app.write_ResultsFoldermNode_2.Icon = 'icon_Function.png';
            app.write_ResultsFoldermNode_2.Text = 'write_ResultsFolder.m';
            app.write_BasicInputmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_BasicInputmNode_2.NodeData = '[function] writes Tcl code lines for materials, geomtric transformation and modeling uncertainty.';
            app.write_BasicInputmNode_2.Icon = 'icon_Function.png';
            app.write_BasicInputmNode_2.Text = 'write_BasicInput.m';
            app.write_PreCalculatedGeometrymNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_PreCalculatedGeometrymNode_2.NodeData = '[function]';
            app.write_PreCalculatedGeometrymNode_2.Icon = 'icon_Function.png';
            app.write_PreCalculatedGeometrymNode_2.Text = 'write_PreCalculatedGeometry.m';
            app.write_Nodesm_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_Nodesm_2.NodeData = '[function] writes Tcl code lines for model nodes';
            app.write_Nodesm_2.Icon = 'icon_Function.png';
            app.write_Nodesm_2.Text = 'write_Nodes.m';
            app.write_PZelementmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_PZelementmNode_2.NodeData = '[function] writes Tcl code lines for panel zone rigid elements';
            app.write_PZelementmNode_2.Icon = 'icon_Function.png';
            app.write_PZelementmNode_2.Text = 'write_PZelement.m';
            app.write_PZspringmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_PZspringmNode_2.NodeData = '[function] writes Tcl code lines for panel zone springs';
            app.write_PZspringmNode_2.Icon = 'icon_Function.png';
            app.write_PZspringmNode_2.Text = 'write_PZspring.m';
            app.write_BraceRigidLinksmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_BraceRigidLinksmNode_2.NodeData = '[function] writes Tcl code lines for the brace gusset plates'' rigid link elements';
            app.write_BraceRigidLinksmNode_2.Icon = 'icon_Function.png';
            app.write_BraceRigidLinksmNode_2.Text = 'write_BraceRigidLinks.m';
            app.write_GPspringmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_GPspringmNode_2.NodeData = '[function] writes Tcl code lines for gusset plate springs';
            app.write_GPspringmNode_2.Icon = 'icon_Function.png';
            app.write_GPspringmNode_2.Text = 'write_GPspring.m';
            app.write_BracesmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_BracesmNode_2.NodeData = '[function] writes Tcl code lines for brace elements';
            app.write_BracesmNode_2.Icon = 'icon_Function.png';
            app.write_BracesmNode_2.Text = 'write_Braces.m';
            app.write_ElasticBeamsColumnsmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_ElasticBeamsColumnsmNode_2.NodeData = '[function] writes Tcl code lines for the main frame''s elastic beam and column elements';
            app.write_ElasticBeamsColumnsmNode_2.Icon = 'icon_Function.png';
            app.write_ElasticBeamsColumnsmNode_2.Text = 'write_ElasticBeamsColumns.m';
            app.write_BeamSpring_CBFmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_BeamSpring_CBFmNode_2.NodeData = '[function] writes Tcl code lines for the main frame beam springs';
            app.write_BeamSpring_CBFmNode_2.Icon = 'icon_Function.png';
            app.write_BeamSpring_CBFmNode_2.Text = 'write_BeamSpring_CBF.m';
            app.write_ColumnSpringmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_ColumnSpringmNode_2.NodeData = '[function] writes Tcl code lines for the main frame column springs';
            app.write_ColumnSpringmNode_2.Icon = 'icon_Function.png';
            app.write_ColumnSpringmNode_2.Text = 'write_ColumnSpring.m';
            app.write_SpliceSpringmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_SpliceSpringmNode_2.NodeData = '[function] writes Tcl code lines for column splice connection springs';
            app.write_SpliceSpringmNode_2.Icon = 'icon_Function.png';
            app.write_SpliceSpringmNode_2.Text = 'write_SpliceSpring.m';
            app.write_FloorLinksmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_FloorLinksmNode_2.NodeData = '[function] writes Tcl code lines for EGF-to-MF floor link elements';
            app.write_FloorLinksmNode_2.Icon = 'icon_Function.png';
            app.write_FloorLinksmNode_2.Text = 'write_FloorLinks.m';
            app.write_EGFelementsmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_EGFelementsmNode_2.NodeData = '[function] writes Tcl code lines for the EGF''s elastic beam and column elements';
            app.write_EGFelementsmNode_2.Icon = 'icon_Function.png';
            app.write_EGFelementsmNode_2.Text = 'write_EGFelements.m';
            app.write_EGFspringsmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_EGFspringsmNode_2.NodeData = '[function] writes Tcl code lines for the EGF''s beam and column springs';
            app.write_EGFspringsmNode_2.Icon = 'icon_Function.png';
            app.write_EGFspringsmNode_2.Text = 'write_EGFsprings.m';
            app.write_BCsmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_BCsmNode_2.NodeData = '[function] writes Tcl code lines for the model boundary conditions';
            app.write_BCsmNode_2.Icon = 'icon_Function.png';
            app.write_BCsmNode_2.Text = 'write_BCs.m';
            app.write_RecordersmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_RecordersmNode_2.NodeData = '[function] writes Tcl code lines for the recorders data';
            app.write_RecordersmNode_2.Icon = 'icon_Function.png';
            app.write_RecordersmNode_2.Text = 'write_Recorders.m';
            app.write_MassmNode_2 = uitreenode(app.CREATOR_MODEL_CBFmNode);
            app.write_MassmNode_2.NodeData = '[function] writes Tcl code lines for the nodal mass assignment';
            app.write_MassmNode_2.Icon = 'icon_Function.png';
            app.write_MassmNode_2.Text = 'write_Mass.m';
            app.CREATOR_ANALYSISmNode = uitreenode(app.Execution_CommandsmNode);
            app.CREATOR_ANALYSISmNode.NodeData = '[function] Builds the the analysis code within the main tcl file of the numerical model';
            app.CREATOR_ANALYSISmNode.Icon = 'icon_Function.png';
            app.CREATOR_ANALYSISmNode.Text = 'CREATOR_ANALYSIS.m';
            app.write_Analysis_EigenmNode_2 = uitreenode(app.CREATOR_ANALYSISmNode);
            app.write_Analysis_EigenmNode_2.NodeData = '[function] writes Tcl code lines for the Eigen value analysis';
            app.write_Analysis_EigenmNode_2.Icon = 'icon_Function.png';
            app.write_Analysis_EigenmNode_2.Text = 'write_Analysis_Eigen.m';
            app.write_Analysis_GravitymNode = uitreenode(app.CREATOR_ANALYSISmNode);
            app.write_Analysis_GravitymNode.NodeData = '[function] writes Tcl code lines for the gravity load application';
            app.write_Analysis_GravitymNode.Icon = 'icon_Function.png';
            app.write_Analysis_GravitymNode.Text = 'write_Analysis_Gravity.m';
            app.write_AnimationmNode = uitreenode(app.CREATOR_ANALYSISmNode);
            app.write_AnimationmNode.NodeData = '[function] writes Tcl code lines for the deformed shape animation';
            app.write_AnimationmNode.Icon = 'icon_Function.png';
            app.write_AnimationmNode.Text = 'write_Animation.m';
            app.write_Analysis_PushovermNode = uitreenode(app.CREATOR_ANALYSISmNode);
            app.write_Analysis_PushovermNode.NodeData = '[function] writes Tcl code lines for the static pushover analysis';
            app.write_Analysis_PushovermNode.Icon = 'icon_Function.png';
            app.write_Analysis_PushovermNode.Text = 'write_Analysis_Pushover.m';
            app.write_Analysis_DynamicmNode = uitreenode(app.CREATOR_ANALYSISmNode);
            app.write_Analysis_DynamicmNode.NodeData = '[function] writes Tcl code lines for the dynamic analysis';
            app.write_Analysis_DynamicmNode.Icon = 'icon_Function.png';
            app.write_Analysis_DynamicmNode.Text = 'write_Analysis_Dynamic.m';
            app.write_Analysis_ELFmNode = uitreenode(app.CREATOR_ANALYSISmNode);
            app.write_Analysis_ELFmNode.NodeData = '[function] writes Tcl code lines for the equivelant lateral force analysis';
            app.write_Analysis_ELFmNode.Icon = 'icon_Function.png';
            app.write_Analysis_ELFmNode.Text = 'write_Analysis_ELF.m';
            app.Run_OpenSEESmNode = uitreenode(app.Execution_CommandsmNode);
            app.Run_OpenSEESmNode.NodeData = '[function] Main script for running the different types of analysis, processing the analysis data and saving summary results in text file.';
            app.Run_OpenSEESmNode.Icon = 'icon_Function.png';
            app.Run_OpenSEESmNode.Text = 'Run_OpenSEES.m';
            app.Get_IMmNode = uitreenode(app.Run_OpenSEESmNode);
            app.Get_IMmNode.NodeData = '[function] Computes the value of the intensity measures; Sa(T1) or Sa_avg.';
            app.Get_IMmNode.Icon = 'icon_Function.png';
            app.Get_IMmNode.Text = 'Get_IM.m';
            app.Get_SA_SDoFmNode = uitreenode(app.Get_IMmNode);
            app.Get_SA_SDoFmNode.NodeData = '[function] Computes the spectral acceleration of an elastic single degree of freedom system.';
            app.Get_SA_SDoFmNode.Icon = 'icon_Function.png';
            app.Get_SA_SDoFmNode.Text = 'Get_SA_SDoF.m';
            app.Run_exemNode = uitreenode(app.Run_OpenSEESmNode);
            app.Run_exemNode.NodeData = '[function] Runs the OpenSEES executable.';
            app.Run_exemNode.Icon = 'icon_Function.png';
            app.Run_exemNode.Text = 'Run_exe.m';
            app.Read_Analysis_DatamNode = uitreenode(app.Run_OpenSEESmNode);
            app.Read_Analysis_DatamNode.NodeData = '[function] Reads the dynamic analysis results from the OpenSEES output files.';
            app.Read_Analysis_DatamNode.Icon = 'icon_Function.png';
            app.Read_Analysis_DatamNode.Text = 'Read_Analysis_Data.m';
            app.Show_Status_DynamicmNode = uitreenode(app.Run_OpenSEESmNode);
            app.Show_Status_DynamicmNode.NodeData = '[function] Shows, in the Windows Command Shell, a summary of the ground motion being analyzed, the seismic intensity, scaling factor, etc during dynamic analysis.';
            app.Show_Status_DynamicmNode.Icon = 'icon_Function.png';
            app.Show_Status_DynamicmNode.Text = 'Show_Status_Dynamic.m';
            app.Show_Scope_IDAmNode = uitreenode(app.Run_OpenSEESmNode);
            app.Show_Scope_IDAmNode.NodeData = '[function] Shows a live (instantly-updated) plot of the SDR and PFA IDA curves during IDA.';
            app.Show_Scope_IDAmNode.Icon = 'icon_Function.png';
            app.Show_Scope_IDAmNode.Text = 'Show_Scope_IDA.m';
            app.Show_Scope_DynamicmNode = uitreenode(app.Run_OpenSEESmNode);
            app.Show_Scope_DynamicmNode.NodeData = '[function] Shows a live (instantly-updated) plot of the SDR and PFA output during dynamic analysis.';
            app.Show_Scope_DynamicmNode.Icon = 'icon_Function.png';
            app.Show_Scope_DynamicmNode.Text = 'Show_Scope_Dynamic.m';
            app.Save_Increment_DatamNode = uitreenode(app.Run_OpenSEESmNode);
            app.Save_Increment_DatamNode.NodeData = '[function] Save the incremental dynamic repose data in a structured variable IncdData such that it can be processed later on.';
            app.Save_Increment_DatamNode.Icon = 'icon_Function.png';
            app.Save_Increment_DatamNode.Text = 'Save_Increment_Data.m';
            app.Save_Results_IDAmNode = uitreenode(app.Run_OpenSEESmNode);
            app.Save_Results_IDAmNode.NodeData = '[function] Reads the results of IDA (SDR, RDR and PFA) that are stored in the IncrData variable and saves the IDA data in text files.';
            app.Save_Results_IDAmNode.Icon = 'icon_Function.png';
            app.Save_Results_IDAmNode.Text = 'Save_Results_IDA.m';
            app.Save_Results_DynamicmNode = uitreenode(app.Run_OpenSEESmNode);
            app.Save_Results_DynamicmNode.NodeData = '[function] Reads the results of dynamic analysis (SDR, RDR and PFA) and saves a summary of the maximum absolute quantities in text files.';
            app.Save_Results_DynamicmNode.Icon = 'icon_Function.png';
            app.Save_Results_DynamicmNode.Text = 'Save_Results_Dynamic.m';
            app.Process_EigenAnalysismNode = uitreenode(app.Execution_CommandsmNode);
            app.Process_EigenAnalysismNode.NodeData = '[function] Process the eigen analysis data and save summary data for periods, mode patterns and modal mass participation in text file';
            app.Process_EigenAnalysismNode.Icon = 'icon_Function.png';
            app.Process_EigenAnalysismNode.Text = 'Process_EigenAnalysis.m';
            app.VisualizeNode = uitreenode(app.RootFM2D_AppmlappNode);
            app.VisualizeNode.NodeData = 'Visulaize data button';
            app.VisualizeNode.Icon = 'icon_Visulaize_black.png';
            app.VisualizeNode.Text = '[Visualize]';
            app.Visualize_AppmlappNode = uitreenode(app.VisualizeNode);
            app.Visualize_AppmlappNode.NodeData = '[GUI] Visulaize structural response data options';
            app.Visualize_AppmlappNode.Icon = 'icon_GUI.png';
            app.Visualize_AppmlappNode.Text = 'Visualize_App.mlapp';
            app.Plot_Eigen_VectorsmNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_Eigen_VectorsmNode.NodeData = '[function] Plots the profile of Eigen vectors for each mode period.';
            app.Plot_Eigen_VectorsmNode.Icon = 'icon_Function.png';
            app.Plot_Eigen_VectorsmNode.Text = 'Plot_Eigen_Vectors.m';
            app.Plot_BSF_vs_SDR1mNode_2 = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_BSF_vs_SDR1mNode_2.NodeData = '[function] Plots the normalized based shear force versus the first-story drift ratio.';
            app.Plot_BSF_vs_SDR1mNode_2.Icon = 'icon_Function.png';
            app.Plot_BSF_vs_SDR1mNode_2.Text = 'Plot_BSF_vs_SDR1.m';
            app.Plot_BSF_vs_RDRmNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_BSF_vs_RDRmNode.NodeData = '[function]';
            app.Plot_BSF_vs_RDRmNode.Icon = 'icon_Function.png';
            app.Plot_BSF_vs_RDRmNode.Text = 'Plot_BSF_vs_RDR.m';
            app.Plot_Drift_Profile_POmNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_Drift_Profile_POmNode.NodeData = '[function] Plots the floor displacement profile ar different stages based on pushover analysis.';
            app.Plot_Drift_Profile_POmNode.Icon = 'icon_Function.png';
            app.Plot_Drift_Profile_POmNode.Text = 'Plot_Drift_Profile_PO.m';
            app.Plot_IDAmNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_IDAmNode.NodeData = '[function] Plots the IDA curves (i.e., seismic intensity vs SDR, vsRDR and vs PFA).';
            app.Plot_IDAmNode.Icon = 'icon_Function.png';
            app.Plot_IDAmNode.Text = 'Plot_IDA.m';
            app.Plot_Collapse_FragilitymNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_Collapse_FragilitymNode.NodeData = '[function] Plots the collapse fragility curve based on IDA.';
            app.Plot_Collapse_FragilitymNode.Icon = 'icon_Function.png';
            app.Plot_Collapse_FragilitymNode.Text = 'Plot_Collapse_Fragility.m';
            app.Plot_SDR_ProfilemNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_SDR_ProfilemNode.NodeData = '[function] Plots the maximum SDR profile (along the building height)';
            app.Plot_SDR_ProfilemNode.Icon = 'icon_Function.png';
            app.Plot_SDR_ProfilemNode.Text = 'Plot_SDR_Profile.m';
            app.Plot_RDR_ProfilemNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_RDR_ProfilemNode.NodeData = '[function] Plots the maximum residual story-drift ratio (RDR) profile (along the building height)';
            app.Plot_RDR_ProfilemNode.Icon = 'icon_Function.png';
            app.Plot_RDR_ProfilemNode.Text = 'Plot_RDR_Profile.m';
            app.Plot_PFA_ProfilemNode_2 = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_PFA_ProfilemNode_2.NodeData = '[function] Plots the maximum peak floor acceleration (PFA) profile (along the building height)';
            app.Plot_PFA_ProfilemNode_2.Icon = 'icon_Function.png';
            app.Plot_PFA_ProfilemNode_2.Text = 'Plot_PFA_Profile.m';
            app.Plot_PFV_ProfilemNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_PFV_ProfilemNode.NodeData = '[function] Plots the maximum peak floor velocity (PFV) profile (along the building height)';
            app.Plot_PFV_ProfilemNode.Icon = 'icon_Function.png';
            app.Plot_PFV_ProfilemNode.Text = 'Plot_PFV_Profile.m';
            app.Plot_SSF_ProfilemNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_SSF_ProfilemNode.NodeData = '[function] Plots normalized story shear force profile (normalized by the seismic weight, Ws)';
            app.Plot_SSF_ProfilemNode.Icon = 'icon_Function.png';
            app.Plot_SSF_ProfilemNode.Text = 'Plot_SSF_Profile.m';
            app.Plot_EDP_FragilitymNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_EDP_FragilitymNode.NodeData = '[function] Plots the fragility curve for a given EDP (SDR, RDR, PFA).';
            app.Plot_EDP_FragilitymNode.Icon = 'icon_Function.png';
            app.Plot_EDP_FragilitymNode.Text = 'Plot_EDP_Fragility.m';
            app.Plot_EDP_vs_RealizationmNode = uitreenode(app.Visualize_AppmlappNode);
            app.Plot_EDP_vs_RealizationmNode.NodeData = '[function] Plots the distribution a given EDP (SDR, RDR, PFA) for the analyzed realizations as part of Monte Carlo smulation.';
            app.Plot_EDP_vs_RealizationmNode.Icon = 'icon_Function.png';
            app.Plot_EDP_vs_RealizationmNode.Text = 'Plot_EDP_vs_Realization.m';
            app.get_all_datamNode_3 = uitreenode(app.Visualize_AppmlappNode);
            app.get_all_datamNode_3.NodeData = '[function] Reads and saves all the recorder data in the results folder for subsequqnet visualizion options.';
            app.get_all_datamNode_3.Icon = 'icon_Function.png';
            app.get_all_datamNode_3.Text = 'get_all_data.m';
            app.Visulaize_Local_AppNode = uitreenode(app.Visualize_AppmlappNode);
            app.Visulaize_Local_AppNode.NodeData = '[GUI] Interactive interface for visulaizing the local response of members and springs';
            app.Visulaize_Local_AppNode.Icon = 'icon_GUI.png';
            app.Visulaize_Local_AppNode.Text = 'Visulaize_Local_App';
            app.Plot_FramemNode = uitreenode(app.Visulaize_Local_AppNode);
            app.Plot_FramemNode.NodeData = '[function] Plots the frame numerical model elements for an interactive visualization experience.';
            app.Plot_FramemNode.Icon = 'icon_Function.png';
            app.Plot_FramemNode.Text = 'Plot_Frame.m';
            app.Plot_Local_Get_RootNamemNode = uitreenode(app.Visulaize_Local_AppNode);
            app.Plot_Local_Get_RootNamemNode.NodeData = '[function] Gets the root name of the output file being imported to be plotted.';
            app.Plot_Local_Get_RootNamemNode.Icon = 'icon_Function.png';
            app.Plot_Local_Get_RootNamemNode.Text = 'Plot_Local_Get_RootName.m';
            app.Plot_Local_Get_LabelsmNode = uitreenode(app.Visulaize_Local_AppNode);
            app.Plot_Local_Get_LabelsmNode.NodeData = '[function] Gets the X and Y axes labels for the element response being plotted.';
            app.Plot_Local_Get_LabelsmNode.Icon = 'icon_Function.png';
            app.Plot_Local_Get_LabelsmNode.Text = 'Plot_Local_Get_Labels.m';
            app.Plot_Local_Get_DataColIndxmNode = uitreenode(app.Visulaize_Local_AppNode);
            app.Plot_Local_Get_DataColIndxmNode.NodeData = '[function] Gets the index of the data column being plotted from the imported results output file.';
            app.Plot_Local_Get_DataColIndxmNode.Icon = 'icon_Function.png';
            app.Plot_Local_Get_DataColIndxmNode.Text = 'Plot_Local_Get_DataColIndx.m';
            app.get_Local_XYdatamNode_2 = uitreenode(app.Visulaize_Local_AppNode);
            app.get_Local_XYdatamNode_2.NodeData = '[function] Gets the X and Y data to be plotted.';
            app.get_Local_XYdatamNode_2.Icon = 'icon_Function.png';
            app.get_Local_XYdatamNode_2.Text = 'get_Local_XYdata.m';
            app.write2file_PlotDatamNode = uitreenode(app.Visulaize_Local_AppNode);
            app.write2file_PlotDatamNode.NodeData = '[function] Write X-Y plot data to a text file.';
            app.write2file_PlotDatamNode.Icon = 'icon_Function.png';
            app.write2file_PlotDatamNode.Text = 'write2file_PlotData.m';
            app.highlight_componentmNode_2 = uitreenode(app.Visulaize_Local_AppNode);
            app.highlight_componentmNode_2.NodeData = '[function] Write X-Y plot data to a text file.';
            app.highlight_componentmNode_2.Icon = 'icon_Function.png';
            app.highlight_componentmNode_2.Text = 'highlight_component.m';
            app.Visulaize_Response_Progression_AppNode = uitreenode(app.Visualize_AppmlappNode);
            app.Visulaize_Response_Progression_AppNode.NodeData = '[GUI] Interactive interface for visulaizing the local response of members and springs';
            app.Visulaize_Response_Progression_AppNode.Icon = 'icon_GUI.png';
            app.Visulaize_Response_Progression_AppNode.Text = 'Visulaize_Response_Progression_App';
            app.Plot_FramemNode_2 = uitreenode(app.Visulaize_Response_Progression_AppNode);
            app.Plot_FramemNode_2.NodeData = '[function] Plots the frame numerical model elements for an interactive visualization experience.';
            app.Plot_FramemNode_2.Icon = 'icon_Function.png';
            app.Plot_FramemNode_2.Text = 'Plot_Frame.m';
            app.Plot_Local_Get_RootNamemNode_2 = uitreenode(app.Visulaize_Response_Progression_AppNode);
            app.Plot_Local_Get_RootNamemNode_2.NodeData = '[function] Gets the root name of the output file being imported to be plotted.';
            app.Plot_Local_Get_RootNamemNode_2.Icon = 'icon_Function.png';
            app.Plot_Local_Get_RootNamemNode_2.Text = 'Plot_Local_Get_RootName.m';
            app.Plot_Local_Get_LabelsmNode_2 = uitreenode(app.Visulaize_Response_Progression_AppNode);
            app.Plot_Local_Get_LabelsmNode_2.NodeData = '[function] Gets the X and Y axes labels for the element response being plotted.';
            app.Plot_Local_Get_LabelsmNode_2.Icon = 'icon_Function.png';
            app.Plot_Local_Get_LabelsmNode_2.Text = 'Plot_Local_Get_Labels.m';
            app.Plot_Local_Get_DataColIndxmNode_2 = uitreenode(app.Visulaize_Response_Progression_AppNode);
            app.Plot_Local_Get_DataColIndxmNode_2.NodeData = '[function] Gets the index of the data column being plotted from the imported results output file.';
            app.Plot_Local_Get_DataColIndxmNode_2.Icon = 'icon_Function.png';
            app.Plot_Local_Get_DataColIndxmNode_2.Text = 'Plot_Local_Get_DataColIndx.m';
            app.get_Local_XYdatamNode = uitreenode(app.Visulaize_Response_Progression_AppNode);
            app.get_Local_XYdatamNode.NodeData = '[function] Gets the X and Y data to be plotted.';
            app.get_Local_XYdatamNode.Icon = 'icon_Function.png';
            app.get_Local_XYdatamNode.Text = 'get_Local_XYdata.m';
            app.write2file_PlotDatamNode_2 = uitreenode(app.Visulaize_Response_Progression_AppNode);
            app.write2file_PlotDatamNode_2.NodeData = '[function] Write X-Y plot data to a text file.';
            app.write2file_PlotDatamNode_2.Icon = 'icon_Function.png';
            app.write2file_PlotDatamNode_2.Text = 'write2file_PlotData.m';
            app.Visulaize_PH_Progression_AppNode = uitreenode(app.Visualize_AppmlappNode);
            app.Visulaize_PH_Progression_AppNode.NodeData = '[GUI] Interactive interface for visulaizing the progression of plastic hinges in the model springs';
            app.Visulaize_PH_Progression_AppNode.Icon = 'icon_GUI.png';
            app.Visulaize_PH_Progression_AppNode.Text = 'Visulaize_PH_Progression_App';
            app.Plot_Frame_DeformedmNode = uitreenode(app.Visulaize_PH_Progression_AppNode);
            app.Plot_Frame_DeformedmNode.NodeData = '[function] Plots the deformed frame numerical model elements for an interactive visualization experience.';
            app.Plot_Frame_DeformedmNode.Icon = 'icon_Function.png';
            app.Plot_Frame_DeformedmNode.Text = 'Plot_Frame_Deformed.m';
            app.Get_Member_DatamNode = uitreenode(app.Visulaize_PH_Progression_AppNode);

            app.Get_Member_DatamNode.NodeData = '[function] Gets the basic geomtrical data for all beams, columns, and braces.';
            app.Get_Member_DatamNode.Icon = 'icon_Function.png';
            app.Get_Member_DatamNode.Text = 'Get_Member_Data.m';
            app.Plot_BSF_vs_SDR1mNode_3 = uitreenode(app.Visulaize_PH_Progression_AppNode);
            app.Plot_BSF_vs_SDR1mNode_3.NodeData = '[function] Gets and plots the base shear force versus the first storey drift ratio.';
            app.Plot_BSF_vs_SDR1mNode_3.Icon = 'icon_Function.png';
            app.Plot_BSF_vs_SDR1mNode_3.Text = 'Plot_BSF_vs_SDR1.m';
            app.plot_PH_statemNode = uitreenode(app.Visulaize_PH_Progression_AppNode);
            app.plot_PH_statemNode.NodeData = '[function] Plots the plastic hinge state on the deformed frame plot.';
            app.plot_PH_statemNode.Icon = 'icon_Function.png';
            app.plot_PH_statemNode.Text = 'plot_PH_state.m';
            app.VariablesTab = uitab(app.TabGroup);
            app.VariablesTab.Title = 'Variables';
            app.UITable = uitable(app.VariablesTab);
            app.UITable.ColumnName = {'Name'; 'Type'; 'Parent Modules/Functions'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SorttablebyLabel = uilabel(app.VariablesTab);
            app.SorttablebyLabel.Text = 'Sort table by';
            app.DropDown = uidropdown(app.VariablesTab);
            app.DropDown.Items = {'Variable name', 'Parent Module/Function'};
            app.DropDown.ItemsData = {'1', '2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Developers_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ForDevelopers_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end