classdef ExecutionMessage_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        uipanel1       matlab.ui.container.Panel
        CB1            matlab.ui.control.CheckBox
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button2        matlab.ui.control.RadioButton
        Button1        matlab.ui.control.RadioButton
        ExecuteButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UnitMessage_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButton_Callback(app, event)
            app.MainApp.Image.Visible='on';
            
            global MainDirectory ProjectName ProjectPath ExecutionOption
            clc; fclose all;
            
            
            try
                if app.CB1.Value==1
                    load(strcat(ProjectPath,ProjectName),'PROJECT')
                    cd(PROJECT.RFpath)
                    rmdir ('Results','s')
                    cd(MainDirectory)
                end
                       
                if app.Button1.Value==1
                    ExecutionOption=1;
                else
                    ExecutionOption=2;
                end                
            catch
                cd(MainDirectory)

                if app.Button1.Value==1
                    ExecutionOption=1;
                else
                    ExecutionOption=2;
                end
            end
            
            figure1CloseRequest(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible='off';
            delete(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if app.Button2.Value==1
                app.Button1.FontWeight='normal';
                app.Button2.FontWeight='bold';
            else
                app.Button1.FontWeight='bold';
                app.Button2.FontWeight='normal';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Execution Option';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.ExecuteButton = uibutton(app.uipanel1, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButton_Callback, true);
            app.ExecuteButton.Tag = 'SubmitButton';
            app.ExecuteButton.Text = 'Execute';
            app.ButtonGroup = uibuttongroup(app.uipanel1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button1 = uiradiobutton(app.ButtonGroup);
            app.Button1.Text = 'Build tcl file and Run';
            app.Button1.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Run from existing tcl file';
            app.CB1 = uicheckbox(app.uipanel1);
            app.CB1.Tag = 'FuseF_ProfileCB';
            app.CB1.Text = 'Delete existing results';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExecutionMessage_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)UnitMessage_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end