classdef Model_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        ModelingOptionsTab   matlab.ui.container.Tab
        ModelDiscritization  matlab.ui.control.DropDown
        text25               matlab.ui.control.Label
        TipIcon5             matlab.ui.control.Button
        TipIcon4             matlab.ui.control.Button
        TipIcon3             matlab.ui.control.Button
        TipIcon2             matlab.ui.control.Button
        RigidFloorCB         matlab.ui.control.CheckBox
        text67               matlab.ui.control.Label
        ModelTypepopup       matlab.ui.control.DropDown
        Transformationpopup  matlab.ui.control.DropDown
        text66               matlab.ui.control.Label
        PanelZoneCB          matlab.ui.control.CheckBox
        GravitySystemCB      matlab.ui.control.CheckBox
        CompositeCB          matlab.ui.control.CheckBox
        MemberModelingTab    matlab.ui.container.Tab
        text35_3             matlab.ui.control.Label
        Kshearstatus         matlab.ui.control.CheckBox
        text351              matlab.ui.control.Label
        BeamElementpopup     matlab.ui.control.DropDown
        ColMppopup           matlab.ui.control.DropDown
        text35_2             matlab.ui.control.Label
        text444              matlab.ui.control.Label
        Pscale               matlab.ui.control.NumericEditField
        textP2               matlab.ui.control.Label
        textP1               matlab.ui.control.Label
        initialGI            matlab.ui.control.NumericEditField
        nIntegration         matlab.ui.control.NumericEditField
        nSegments            matlab.ui.control.NumericEditField
        text75               matlab.ui.control.Label
        text73               matlab.ui.control.Label
        text35               matlab.ui.control.Label
        ColElementpopup      matlab.ui.control.DropDown
        FilesFoldersTab      matlab.ui.container.Tab
        editRF               matlab.ui.control.EditField
        BrowseRFButton       matlab.ui.control.Button
        text40_5             matlab.ui.control.Label
        OpenSEESFileName     matlab.ui.control.EditField
        text37_2             matlab.ui.control.Label
        SubmitButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp;
        TipWindow;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Model_App_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image.Visible='on';  
                        
            global  ProjectName ProjectPath
            load(strcat(ProjectPath,ProjectName),'PROJECT','MODEL','STATUS')
           
            clc

            if STATUS.Model~=1
                app.PanelZoneCB.Value          = 1;
                app.RigidFloorCB.Value         = 1;
                app.OpenSEESFileName.Value     = PROJECT.OpenSEESFileName;
                xx=ProjectPath;
                if strcmp(xx(end),'\')==1; xx(end)=[]; end
                app.editRF.Value               = xx;
                app.Transformationpopup.Value  = '2';
                app.ModelTypepopup.Value       = '1';
            else
                app.ModelTypepopup.Value       = num2str(MODEL.ModelELOption);
                app.Transformationpopup.Value  = num2str(MODEL.TransformationX);
                app.ModelDiscritization.Value  = num2str(MODEL.DiscritizationOption);
                app.CompositeCB.Value          = MODEL.CompositeX;
                app.GravitySystemCB.Value      = MODEL.GFX;
                app.PanelZoneCB.Value          = MODEL.PZ_Multiplier;
                app.RigidFloorCB.Value         = MODEL.RigidFloor;
                app.ColElementpopup.Value      = num2str(MODEL.ColElementOption);
                app.BeamElementpopup.Value     = num2str(MODEL.BeamElementOption);
                app.nSegments.Value            = MODEL.nSegments;
                app.nIntegration.Value         = MODEL.nIntegration;
                app.initialGI.Value            = MODEL.initialGI;
                app.Kshearstatus.Value         = MODEL.Kshearstatus;
                app.ColMppopup.Value           = num2str(MODEL.PM_Option);
                app.Pscale.Value               = MODEL.Pscale;

                if strcmp(PROJECT.RFpath(end),'\')==1; PROJECT.RFpath(end)=[]; end
                app.editRF.Value               = PROJECT.RFpath;
                app.OpenSEESFileName.Value     = PROJECT.OpenSEESFileName;
            end
            
            if PROJECT.FrameType==4
                app.CompositeCB.Enable          = "off";
                app.PanelZoneCB.Enable          = "off";
                app.ModelDiscritization.Visible = "on";
                app.BeamElementpopup.Enable     = "on";
                app.text351.Enable              = "on";
            else
                app.ModelDiscritization.Visible = "off";
                app.text351.Enable              = "off";
            end                

            ColElementpopupValueChanged(app);
            ColMppopupValueChanged(app);
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global  ProjectName ProjectPath
            load(strcat(ProjectPath,ProjectName),'PROJECT','MODEL','STATUS');
            
            clc
            
            MODEL.ModelELOption        = str2double(app.ModelTypepopup.Value);
            MODEL.TransformationX      = str2double(app.Transformationpopup.Value);
            MODEL.DiscritizationOption = str2double(app.ModelDiscritization.Value);
            MODEL.CompositeX           = app.CompositeCB.Value;
            MODEL.GFX                  = app.GravitySystemCB.Value;
            MODEL.PZ_Multiplier        = app.PanelZoneCB.Value;
            MODEL.RigidFloor           = app.RigidFloorCB.Value;
            MODEL.ColElementOption      = str2double(app.ColElementpopup.Value);
            MODEL.BeamElementOption     = str2double(app.BeamElementpopup.Value);
            MODEL.nSegments             = app.nSegments.Value;
            MODEL.nIntegration          = app.nIntegration.Value;
            MODEL.initialGI             = app.initialGI.Value;
            MODEL.Kshearstatus          = app.Kshearstatus.Value;

            if app.ColMppopup.Value == '1'
                MODEL.PM_Option = 1;
                MODEL.Pscale    = 1.0;
            else
                MODEL.PM_Option = 2;
                MODEL.Pscale    = app.Pscale.Value;
            end

            if MODEL.ModelELOption == 1
                MODEL.EL_Multiplier = 1;
            else
                MODEL.EL_Multiplier = 100;
            end

            %%%%%%%%%%%%%%
            OpenSEESFileName = app.OpenSEESFileName.Value;
            if isspace(OpenSEESFileName)==1; errordlg('OpenSEES filename should not include spaces!'); return; end
            
            RFpath = app.editRF.Value;

            PROJECT.OpenSEESFileName = OpenSEESFileName;
            PROJECT.RFpath=RFpath;

            %%%%%%%%%%%%%% Checks
            if exist ('OpenSEESFileName')==0 || isempty ('OpenSEESFileName')==1
                errordlg('Please specify the name of the OpenSEES tcl file!');
                return;
            end
            if exist ('RFpath')==0 || isempty ('RFpath')==1
                errordlg('Please specify the location of the Results folder!');
                return;
            end
            
            if RFpath(end)=='\' || length(RFpath)<=3
                d=errordlg('Please use a folder and not a root drive for the results directory!','Error','modal');
                uiwait(d);
                return;
            end

            
            %%%%%%%%%%%%%% Exit commands
            STATUS.Model = 1;

            save(strcat(ProjectPath,ProjectName), 'PROJECT', 'MODEL', 'STATUS', '-append')

            if MODEL.ModelELOption  == 1; s0='NonLinear-';  else; s0='Linear-'; end
            if MODEL.CompositeX     == 1; s1='C';           else; s1='B';       end
            if MODEL.GFX            == 1; s2='G';           else; s2='';        end
            Typestr = [s0,s1,s2];    
            clear s0 s1 s2

            app.MainApp.status0.BackgroundColor  = 'g';
            app.MainApp.status0.FontColor        = 'k';
            app.MainApp.status0.Text             = Typestr;
            app.MainApp.status0b.BackgroundColor = 'g';
            app.MainApp.BuildingButton.Enable    = 'on';            
            app.MainApp.ProgressText.FontColor   = 'g';
            app.MainApp.ProgressText.Text        = ('Model data defined'); drawnow;

            fclose all;
            
            figure1CloseRequest(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible = 'off';
            delete(app)
        end

        % Button pushed function: BrowseRFButton
        function BrowseRFButtonPushed(app, event)
            global ProjectName ProjectPath
            clc

            RFpath= uigetdir;
            if RFpath(end)=='\'
                d=errordlg('Please use a folder and not a root drive for the results directory!','Error','modal');
                uiwait(d);
                return;
            end
            app.editRF.Value=RFpath;
            save (strcat(ProjectPath,ProjectName),'RFpath','-append')

        end

        % Button pushed function: TipIcon2
        function TipIcon2ButtonPushed(app, event)
            str{1}='Consider the strength and stiffness contributions of the gravity framing system';
            str{2}='';
            str{3}='Refer to: ';
            str{4}='----------';
            str{5}='- Elkady & Lignos (2015), Earthquake Eng. & Structural Dynamics: 44(8)';            
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;            
        end

        % Button pushed function: TipIcon3
        function TipIcon3ButtonPushed(app, event)
            str{1}='Consider the composite action effect, provided by the concrete-on-steel-deck floor slab, on the behaviour (strength, stiffness and ductility) of beam-to-column connections';
            str{2}='';
            str{3}='Refer to: ';
            str{4}='----------';
            str{5}='- Elkady & Lignos (2014), Earthquake Eng. & Structural Dynamics: 43(13)';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;              
        end

        % Button pushed function: TipIcon4
        function TipIcon4ButtonPushed(app, event)
            str{1}='Connect all floor nodes with a rigid body constraint';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;              
        end

        % Button pushed function: TipIcon5
        function TipIcon5ButtonPushed(app, event)
            global resource_root
            str{1}='Consider the column panel zone deformation using the idealized parallelogram model and a trilinear hysteretic model for its shear-force/shear-distortion behaviour.';
            str{2}='';
            str{3}='Refer to: ';
            str{4}='----------';
            str{5}='- Krawinkler (1978), Engineering Journal: 15(3)';
            str{6}='- Gupta & Krawinkler (1999), John A. Blume Center Report No. 132';
            str{7}='- Elkady & Lignos (2014), Earthquake Eng. & Structural Dynamics: 43(13)';
            str{8}='- Elkady & Lignos (2015), Earthquake Eng. & Structural Dynamics: 44(8)';            
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;   
            figure('position',[100 100 450 350],'color','white','Resize','off');
            data=imread(strjoin(resource_root+"PZ Model.png"));
            imshow(data);            
        end

        % Value changed function: ColElementpopup
        function ColElementpopupValueChanged(app, event)
            if app.ColElementpopup.Value == '1'
                app.nSegments.Enable    = 'off';
                app.nIntegration.Enable = 'off';
                app.initialGI.Enable    = 'off';
                app.ColMppopup.Enable   = 'on';
                app.ColMppopup.Value    = '2';
                app.Pscale.Enable       = 'on';
                app.Pscale.Value        = 1.0;
                app.textP1.Enable       = 'on';
                app.textP2.Enable       = 'on';
            else
                app.nSegments.Enable    = 'on';
                app.nIntegration.Enable = 'on';
                app.initialGI.Enable    = 'on';
                app.ColMppopup.Enable   = 'off';
                app.ColMppopup.Value    = '2';
                app.Pscale.Enable       = 'off';
                app.Pscale.Value        = 1.0;
                app.textP1.Enable       = 'off';
                app.textP2.Enable       = 'off';
            end            
        end

        % Value changed function: ColMppopup
        function ColMppopupValueChanged(app, event)
            if app.ColMppopup.Value == '2'
                app.Pscale.Enable='on';
            else
                app.Pscale.Enable='off';
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Model Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'BuildButton';
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.ModelingOptionsTab = uitab(app.TabGroup);
            app.ModelingOptionsTab.Title = 'Modeling Options';
            app.CompositeCB = uicheckbox(app.ModelingOptionsTab);
            app.CompositeCB.Tag = 'checkbox1';
            app.CompositeCB.Text = 'Composite Action of the Floor Slab ';
            app.GravitySystemCB = uicheckbox(app.ModelingOptionsTab);
            app.GravitySystemCB.Tag = 'checkbox3';
            app.GravitySystemCB.Text = 'Gravity Framing System';
            app.PanelZoneCB = uicheckbox(app.ModelingOptionsTab);
            app.PanelZoneCB.Tag = 'checkbox4';
            app.PanelZoneCB.Text = 'Panel Zone Deformation';
            app.text66 = uilabel(app.ModelingOptionsTab);
            app.text66.Tag = 'text66';
            app.text66.Text = 'Geometric Transformation';
            app.Transformationpopup = uidropdown(app.ModelingOptionsTab);
            app.Transformationpopup.Items = {'Linear', 'P-Delta', 'Corotational'};
            app.Transformationpopup.ItemsData = {'1', '2', '3'};
            app.Transformationpopup.Tag = 'Transformationpopup';
            app.Transformationpopup.Value = '1';
            app.ModelTypepopup = uidropdown(app.ModelingOptionsTab);
            app.ModelTypepopup.Items = {'Nonlinear', 'Linear (Elastic)'};
            app.ModelTypepopup.ItemsData = {'1', '2'};
            app.ModelTypepopup.Tag = 'ModelTypepopup';
            app.ModelTypepopup.Value = '1';
            app.text67 = uilabel(app.ModelingOptionsTab);
            app.text67.Tag = 'text67';
            app.text67.Tooltip = {'this refers to the material nonlinearity'};
            app.text67.Text = 'Numerical Model Type';
            app.RigidFloorCB = uicheckbox(app.ModelingOptionsTab);
            app.RigidFloorCB.Tag = 'checkbox5';
            app.RigidFloorCB.Text = 'Rigid Floor Diaphragm';
            app.TipIcon2 = uibutton(app.ModelingOptionsTab, 'push');
            app.TipIcon2.ButtonPushedFcn = createCallbackFcn(app, @TipIcon2ButtonPushed, true);
            app.TipIcon2.Text = '?';
            app.TipIcon3 = uibutton(app.ModelingOptionsTab, 'push');
            app.TipIcon3.ButtonPushedFcn = createCallbackFcn(app, @TipIcon3ButtonPushed, true);
            app.TipIcon3.Text = '?';
            app.TipIcon4 = uibutton(app.ModelingOptionsTab, 'push');
            app.TipIcon4.ButtonPushedFcn = createCallbackFcn(app, @TipIcon4ButtonPushed, true);
            app.TipIcon4.Text = '?';
            app.TipIcon5 = uibutton(app.ModelingOptionsTab, 'push');
            app.TipIcon5.ButtonPushedFcn = createCallbackFcn(app, @TipIcon5ButtonPushed, true);
            app.TipIcon5.Text = '?';
            app.text25 = uilabel(app.ModelingOptionsTab);
            app.text25.Tag = 'text40';
            app.text25.Text = 'Modeling Considerations';
            app.ModelDiscritization = uidropdown(app.ModelingOptionsTab);
            app.ModelDiscritization.Items = {'Concentrated plasticity', 'Distributed plasticity'};
            app.ModelDiscritization.ItemsData = {'1', '2'};
            app.ModelDiscritization.Tag = 'ModelTypepopup';
            app.ModelDiscritization.Tooltip = {'The method to be used in discritizing the RC frame.'};
            app.ModelDiscritization.Value = '1';
            app.MemberModelingTab = uitab(app.TabGroup);
            app.MemberModelingTab.Title = 'Member Modeling';
            app.ColElementpopup = uidropdown(app.MemberModelingTab);
            app.ColElementpopup.Items = {'elasticBeamColumn + IMK springs', 'dispBeamColumn + UVC fiber', 'forceBeamColumn + UVC fiber'};
            app.ColElementpopup.ItemsData = {'1', '2', '3'};
            app.ColElementpopup.ValueChangedFcn = createCallbackFcn(app, @ColElementpopupValueChanged, true);
            app.ColElementpopup.Tag = 'ColElementpopup';
            app.ColElementpopup.Value = '1';
            app.text35 = uilabel(app.MemberModelingTab);
            app.text35.Tag = 'text35';
            app.text35.Text = 'MF Column Elements';
            app.text73 = uilabel(app.MemberModelingTab);
            app.text73.Tag = 'text73';
            app.text73.Text = 'Discritize by      segments with      integration points per segment';
            app.text75 = uilabel(app.MemberModelingTab);
            app.text75.Tag = 'text75';
            app.text75.Text = 'Global mid-length imperfection [*L]';
            app.nSegments = uieditfield(app.MemberModelingTab, 'numeric');
            app.nSegments.Limits = [1 Inf];
            app.nSegments.ValueDisplayFormat = '%.0f';
            app.nSegments.Value = 8;
            app.nIntegration = uieditfield(app.MemberModelingTab, 'numeric');
            app.nIntegration.Limits = [1 Inf];
            app.nIntegration.ValueDisplayFormat = '%.0f';
            app.nIntegration.Value = 5;
            app.initialGI = uieditfield(app.MemberModelingTab, 'numeric');
            app.initialGI.Limits = [0 Inf];
            app.initialGI.Value = 0.001;
            app.textP1 = uilabel(app.MemberModelingTab);
            app.textP1.Tag = 'text35';
            app.textP1.Text = 'P computed based on';
            app.textP2 = uilabel(app.MemberModelingTab);
            app.textP2.Tag = 'text75';
            app.textP2.Text = 'Scale factor';
            app.Pscale = uieditfield(app.MemberModelingTab, 'numeric');
            app.Pscale.Limits = [0 Inf];
            app.Pscale.Value = 1;
            app.text444 = uilabel(app.MemberModelingTab);
            app.text444.Tag = 'text40';
            app.text444.Text = 'MF Column P-M Interaction';
            app.text35_2 = uilabel(app.MemberModelingTab);
            app.text35_2.Tag = 'text35';
            app.text35_2.Text = 'Fiber-based Elements';
            app.ColMppopup = uidropdown(app.MemberModelingTab);
            app.ColMppopup.Items = {'Max Pushover Load', 'Scaled Gravity Load'};
            app.ColMppopup.ItemsData = {'1', '2'};
            app.ColMppopup.ValueChangedFcn = createCallbackFcn(app, @ColMppopupValueChanged, true);
            app.ColMppopup.Tag = 'ColElementpopup';
            app.ColMppopup.Value = '2';
            app.BeamElementpopup = uidropdown(app.MemberModelingTab);
            app.BeamElementpopup.Items = {'elasticBeamColumn + IMK springs', 'dispBeamColumn + UVC fiber', 'forceBeamColumn + UVC fiber'};
            app.BeamElementpopup.ItemsData = {'1', '2', '3'};
            app.BeamElementpopup.Tag = 'ColElementpopup';
            app.BeamElementpopup.Value = '1';
            app.text351 = uilabel(app.MemberModelingTab);
            app.text351.Tag = 'text35';
            app.text351.Text = 'MF Beam Elements';
            app.Kshearstatus = uicheckbox(app.MemberModelingTab);
            app.Kshearstatus.Tag = 'Kshear_CB';
            app.Kshearstatus.Tooltip = {'This option can be used to modify the moment of inertia of elasticBeamColumn and ModElasticBeam2d to account for the shear flexibility of I-shaped members, which is currently overlooked by these elements.'};
            app.Kshearstatus.Text = 'Account for shear flexibility in elastic beam-column elements';
            app.text35_3 = uilabel(app.MemberModelingTab);
            app.text35_3.Tag = 'text35';
            app.text35_3.Text = '---------------------------------------------------------------------------';
            app.FilesFoldersTab = uitab(app.TabGroup);
            app.FilesFoldersTab.Title = 'Files & Folders';
            app.text37_2 = uilabel(app.FilesFoldersTab);
            app.text37_2.Tag = 'text37';
            app.text37_2.Text = 'OpenSEES File Name';
            app.OpenSEESFileName = uieditfield(app.FilesFoldersTab, 'text');
            app.OpenSEESFileName.Tag = 'edit28';
            app.OpenSEESFileName.Tooltip = {'File name shall no have any "space" and should end with the extension .tcl'};
            app.text40_5 = uilabel(app.FilesFoldersTab);
            app.text40_5.Tag = 'text40';
            app.text40_5.Text = 'Results Folder Path';
            app.BrowseRFButton = uibutton(app.FilesFoldersTab, 'push');
            app.BrowseRFButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRFButtonPushed, true);
            app.BrowseRFButton.Tag = 'BrowseButton';
            app.BrowseRFButton.Text = 'browse';
            app.editRF = uieditfield(app.FilesFoldersTab, 'text');
            app.editRF.Editable = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Model_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Model_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end