classdef TypeMessage_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        textbox       matlab.ui.control.TextArea
        ButtonGroup   matlab.ui.container.ButtonGroup
        text6_3       matlab.ui.control.Label
        text6_2       matlab.ui.control.Label
        Image_RCMF    matlab.ui.control.Image
        RCMRFButton   matlab.ui.control.RadioButton
        Image_EBF     matlab.ui.control.Image
        EBFButton     matlab.ui.control.RadioButton
        Image_MRF     matlab.ui.control.Image
        Image_CBF     matlab.ui.control.Image
        CBFButton     matlab.ui.control.RadioButton
        MRFButton     matlab.ui.control.RadioButton
        popupmenu1    matlab.ui.control.DropDown
        text6         matlab.ui.control.Label
        SubmitButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UnitMessage_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;

             global resource_root
            
            app.MainApp.Image.Visible='on';

            app.Image_MRF.ImageSource  = strjoin(resource_root+"MRF.png");
            app.Image_CBF.ImageSource  = strjoin(resource_root+"CBF.png");
            app.Image_EBF.ImageSource  = strjoin(resource_root+"EBF.png");
            app.Image_RCMF.ImageSource = strjoin(resource_root+"RCMF.png");

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)

            
            global Units FrameType BuildingDescription
            clc;
            
            Units=str2double(app.popupmenu1.Value);
            
            if app.MRFButton.Value==1
                FrameType=1;
            elseif app.CBFButton.Value==1
                FrameType=2;
            elseif app.EBFButton.Value==1
                FrameType=3;
            elseif app.RCMRFButton.Value==1
                FrameType=4;
            end
            
            BuildingDescription=app.textbox.Value;
            if strcmp(BuildingDescription,'[Optional Project Description]')==1; BuildingDescription='No descritption is provided.'; end            
            if isempty(BuildingDescription)==1; BuildingDescription=' '; end
            
            figure1CloseRequest(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image.Visible='off';
            delete(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.RCMRFButton.Value==1
                app.popupmenu1.Value='2';
                app.popupmenu1.Enable='off';
            else
                app.popupmenu1.Enable='on';                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Frame Type & Units';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'SubmitButton';
            app.SubmitButton.Text = 'Submit';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Project Units:';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'SI: kN & mm', 'Imperial: kip & in'};
            app.popupmenu1.ItemsData = {'1', '2'};
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = '1';
            app.ButtonGroup = uibuttongroup(app.uipanel1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.MRFButton = uiradiobutton(app.ButtonGroup);
            app.MRFButton.Tooltip = {'Moment-resisting frame'};
            app.MRFButton.Text = 'MRF';
            app.MRFButton.Value = true;
            app.CBFButton = uiradiobutton(app.ButtonGroup);
            app.CBFButton.Tooltip = {'Conentrically-braced frame'};
            app.CBFButton.Text = 'CBF';
            app.Image_CBF = uiimage(app.ButtonGroup);
            app.Image_CBF.ImageSource = fullfile(pathToMLAPP, 'CBF.png');
            app.Image_MRF = uiimage(app.ButtonGroup);
            app.Image_MRF.ImageSource = fullfile(pathToMLAPP, 'MRF.png');
            app.EBFButton = uiradiobutton(app.ButtonGroup);
            app.EBFButton.Tooltip = {'Eccentrically-braced frame'};
            app.EBFButton.Text = 'EBF';
            app.Image_EBF = uiimage(app.ButtonGroup);
            app.Image_EBF.ImageSource = fullfile(pathToMLAPP, 'EBF.png');
            app.RCMRFButton = uiradiobutton(app.ButtonGroup);
            app.RCMRFButton.Tooltip = {'Reinforced concrete moment frame'};
            app.RCMRFButton.Text = 'RC MRF';
            app.Image_RCMF = uiimage(app.ButtonGroup);
            app.Image_RCMF.ImageSource = fullfile(pathToMLAPP, 'RCMF.png');
            app.text6_2 = uilabel(app.ButtonGroup);
            app.text6_2.Tag = 'text6';
            app.text6_2.Text = 'beta';
            app.text6_3 = uilabel(app.ButtonGroup);
            app.text6_3.Tag = 'text6';
            app.text6_3.Text = 'beta';
            app.textbox = uitextarea(app.uipanel1);
            app.textbox.Value = {'[Optional Project Description]'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TypeMessage_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)UnitMessage_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end