classdef Uncertainty_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        SubmitButton          matlab.ui.control.Button
        uipanel1              matlab.ui.container.Panel
        text1_7               matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        IMKBilinColumnsTab    matlab.ui.container.Tab
        text1_6               matlab.ui.control.Label
        text1_5               matlab.ui.control.Label
        text1_4               matlab.ui.control.Label
        editIMK9              matlab.ui.control.NumericEditField
        editIMK8              matlab.ui.control.NumericEditField
        editIMK7              matlab.ui.control.NumericEditField
        editIMK6              matlab.ui.control.NumericEditField
        editIMK5              matlab.ui.control.NumericEditField
        editIMK4              matlab.ui.control.NumericEditField
        editIMK3              matlab.ui.control.NumericEditField
        editIMK2              matlab.ui.control.NumericEditField
        editIMK1              matlab.ui.control.NumericEditField
        Image                 matlab.ui.control.Image
        IMKBilinBeamsTab      matlab.ui.container.Tab
        text1_16              matlab.ui.control.Label
        text1_15              matlab.ui.control.Label
        text1_14              matlab.ui.control.Label
        editIMK9_2            matlab.ui.control.NumericEditField
        editIMK8_2            matlab.ui.control.NumericEditField
        editIMK7_2            matlab.ui.control.NumericEditField
        editIMK6_2            matlab.ui.control.NumericEditField
        editIMK5_2            matlab.ui.control.NumericEditField
        editIMK4_2            matlab.ui.control.NumericEditField
        editIMK3_2            matlab.ui.control.NumericEditField
        editIMK2_2            matlab.ui.control.NumericEditField
        editIMK1_2            matlab.ui.control.NumericEditField
        Image_4               matlab.ui.control.Image
        HystereticTab         matlab.ui.container.Tab
        editPZ4               matlab.ui.control.NumericEditField
        editPZ3               matlab.ui.control.NumericEditField
        editPZ1               matlab.ui.control.NumericEditField
        editPZ2               matlab.ui.control.NumericEditField
        Image_2               matlab.ui.control.Image
        Pinching4Tab          matlab.ui.container.Tab
        editPinch8            matlab.ui.control.NumericEditField
        editPinch7            matlab.ui.control.NumericEditField
        editPinch6            matlab.ui.control.NumericEditField
        editPinch5            matlab.ui.control.NumericEditField
        editPinch4            matlab.ui.control.NumericEditField
        editPinch3            matlab.ui.control.NumericEditField
        editPinch2            matlab.ui.control.NumericEditField
        editPinch1            matlab.ui.control.NumericEditField
        Image_3               matlab.ui.control.Image
        GeneralParametersTab  matlab.ui.container.Tab
        text1_19              matlab.ui.control.Label
        editOther6            matlab.ui.control.NumericEditField
        text1_18              matlab.ui.control.Label
        editOther5            matlab.ui.control.NumericEditField
        text1_17              matlab.ui.control.Label
        editOther4            matlab.ui.control.NumericEditField
        text1_13              matlab.ui.control.Label
        text1_12              matlab.ui.control.Label
        text1_11              matlab.ui.control.Label
        editOther3            matlab.ui.control.NumericEditField
        editOther2            matlab.ui.control.NumericEditField
        editOther1            matlab.ui.control.NumericEditField
        text1                 matlab.ui.control.Label
        edit0                 matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Uncertainty_OpeningFcn(app, mainapp)
            global resource_root
            
            app.MainApp=mainapp;

            app.Image.ImageSource   = strjoin(resource_root+"IMK backbone.png");
            app.Image_4.ImageSource = strjoin(resource_root+"IMK backbone.png");
            app.Image_2.ImageSource = strjoin(resource_root+"PZ backbone.png");
            app.Image_3.ImageSource = strjoin(resource_root+"Pinching backbone.png");
            
            global ProjectName ProjectPath            
            load(strcat(ProjectPath,ProjectName),'ANALYSIS','STATUS','SIGMA');
            nRealizations=ANALYSIS.nRealizations;

            app.edit0.Value=nRealizations;
            
            app.editIMK1.Value=SIGMA.IMKcol(1);
            app.editIMK2.Value=SIGMA.IMKcol(2);
            app.editIMK3.Value=SIGMA.IMKcol(3);
            app.editIMK4.Value=SIGMA.IMKcol(4);
            app.editIMK5.Value=SIGMA.IMKcol(5);
            app.editIMK6.Value=SIGMA.IMKcol(6);
            app.editIMK7.Value=SIGMA.IMKcol(7);
            app.editIMK8.Value=SIGMA.IMKcol(8);
            app.editIMK9.Value=SIGMA.IMKcol(9);

            app.editIMK1_2.Value=SIGMA.IMKbeam(1);
            app.editIMK2_2.Value=SIGMA.IMKbeam(2);
            app.editIMK3_2.Value=SIGMA.IMKbeam(3);
            app.editIMK4_2.Value=SIGMA.IMKbeam(4);
            app.editIMK5_2.Value=SIGMA.IMKbeam(5);
            app.editIMK6_2.Value=SIGMA.IMKbeam(6);
            app.editIMK7_2.Value=SIGMA.IMKbeam(7);
            app.editIMK8_2.Value=SIGMA.IMKbeam(8);
            app.editIMK9_2.Value=SIGMA.IMKbeam(9);
            
            app.editPZ1.Value=SIGMA.PZ(1);
            app.editPZ2.Value=SIGMA.PZ(2);
            app.editPZ3.Value=SIGMA.PZ(3);
            app.editPZ4.Value=SIGMA.PZ(4);
            
            app.editPinch1.Value=SIGMA.Pinching4(1);
            app.editPinch2.Value=SIGMA.Pinching4(2);
            app.editPinch3.Value=SIGMA.Pinching4(3);
            app.editPinch4.Value=SIGMA.Pinching4(4);
            app.editPinch5.Value=SIGMA.Pinching4(5);
            app.editPinch6.Value=SIGMA.Pinching4(6);
            app.editPinch7.Value=SIGMA.Pinching4(7);
            app.editPinch8.Value=SIGMA.Pinching4(8);
            
            app.editOther1.Value=SIGMA.fy;
            app.editOther2.Value=SIGMA.zeta;
            app.editOther3.Value=SIGMA.GI;

            STATUS.Sigma=1;
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)

            global ProjectName ProjectPath
            clc; fclose all;

            nRealizations=app.edit0.Value;
            
            SIGMA.IMKcol(1)=app.editIMK1.Value;
            SIGMA.IMKcol(2)=app.editIMK2.Value;
            SIGMA.IMKcol(3)=app.editIMK3.Value;
            SIGMA.IMKcol(4)=app.editIMK4.Value;
            SIGMA.IMKcol(5)=app.editIMK5.Value;
            SIGMA.IMKcol(6)=app.editIMK6.Value;
            SIGMA.IMKcol(7)=app.editIMK7.Value;
            SIGMA.IMKcol(8)=app.editIMK8.Value;
            SIGMA.IMKcol(9)=app.editIMK9.Value;

            SIGMA.IMKbeam(1)=app.editIMK1_2.Value;
            SIGMA.IMKbeam(2)=app.editIMK2_2.Value;
            SIGMA.IMKbeam(3)=app.editIMK3_2.Value;
            SIGMA.IMKbeam(4)=app.editIMK4_2.Value;
            SIGMA.IMKbeam(5)=app.editIMK5_2.Value;
            SIGMA.IMKbeam(6)=app.editIMK6_2.Value;
            SIGMA.IMKbeam(7)=app.editIMK7_2.Value;
            SIGMA.IMKbeam(8)=app.editIMK8_2.Value;
            SIGMA.IMKbeam(9)=app.editIMK9_2.Value;
            
            SIGMA.PZ(1)=app.editPZ1.Value;
            SIGMA.PZ(2)=app.editPZ2.Value;
            SIGMA.PZ(3)=app.editPZ3.Value;
            SIGMA.PZ(4)=app.editPZ4.Value;
            
            SIGMA.Pinching4(1)=app.editPinch1.Value;
            SIGMA.Pinching4(2)=app.editPinch2.Value;
            SIGMA.Pinching4(3)=app.editPinch3.Value;
            SIGMA.Pinching4(4)=app.editPinch4.Value;
            SIGMA.Pinching4(5)=app.editPinch5.Value;
            SIGMA.Pinching4(6)=app.editPinch6.Value;
            SIGMA.Pinching4(7)=app.editPinch7.Value;
            SIGMA.Pinching4(8)=app.editPinch8.Value;
            
            SIGMA.fy        = app.editOther1.Value;
            SIGMA.fyB       = app.editOther1.Value;
            SIGMA.fyG       = app.editOther1.Value;
            SIGMA.zeta      = app.editOther2.Value;
            SIGMA.GI        = app.editOther3.Value;
            SIGMA.Mass      = app.editOther4.Value;
            SIGMA.Load      = app.editOther5.Value;

            STATUS.Sigma=1;
            ANALYSIS.nRealizations=nRealizations;
            save(strcat(ProjectPath,ProjectName),'ANALYSIS','SIGMA','STATUS','-append');
                
            app.MainApp.editUncertainty.FontColor = [0.0 1.0 0.0];
            app.MainApp.editUncertainty.Value = 'logarithmic standard deviations are defined!';

            figure1CloseRequest(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Uncertainty Definitions';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.edit0 = uieditfield(app.uipanel1, 'numeric');
            app.edit0.Limits = [1 1000];
            app.edit0.ValueDisplayFormat = '%.0f';
            app.edit0.Value = 10;
            app.text1 = uilabel(app.uipanel1);
            app.text1.Text = '# realizations per ground motion';
            app.TabGroup = uitabgroup(app.uipanel1);
            app.IMKBilinColumnsTab = uitab(app.TabGroup);
            app.IMKBilinColumnsTab.Title = 'IMKBilin Columns';
            app.Image = uiimage(app.IMKBilinColumnsTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMK backbone.png');
            app.editIMK1 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK1.Limits = [1e-09 1];
            app.editIMK1.ValueDisplayFormat = '%.3f';
            app.editIMK1.Value = 1e-09;
            app.editIMK2 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK2.Limits = [1e-09 1];
            app.editIMK2.ValueDisplayFormat = '%.3f';
            app.editIMK2.Value = 1e-09;
            app.editIMK3 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK3.Limits = [1e-09 1];
            app.editIMK3.ValueDisplayFormat = '%.3f';
            app.editIMK3.Value = 1e-09;
            app.editIMK4 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK4.Limits = [1e-09 1];
            app.editIMK4.ValueDisplayFormat = '%.3f';
            app.editIMK4.Value = 1e-09;
            app.editIMK5 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK5.Limits = [1e-09 1];
            app.editIMK5.ValueDisplayFormat = '%.3f';
            app.editIMK5.Value = 1e-09;
            app.editIMK6 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK6.Limits = [1e-09 1];
            app.editIMK6.ValueDisplayFormat = '%.3f';
            app.editIMK6.Value = 1e-09;
            app.editIMK7 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK7.Limits = [1e-09 1];
            app.editIMK7.ValueDisplayFormat = '%.3f';
            app.editIMK7.Value = 1e-09;
            app.editIMK8 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK8.Limits = [1e-09 1];
            app.editIMK8.ValueDisplayFormat = '%.3f';
            app.editIMK8.Value = 1e-09;
            app.editIMK9 = uieditfield(app.IMKBilinColumnsTab, 'numeric');
            app.editIMK9.Limits = [1e-09 1];
            app.editIMK9.ValueDisplayFormat = '%.3f';
            app.editIMK9.Value = 1e-09;
            app.text1_4 = uilabel(app.IMKBilinColumnsTab);
            app.text1_4.Text = 'Cyclic deterioration:';
            app.text1_5 = uilabel(app.IMKBilinColumnsTab);
            app.text1_5.Text = 'Ʌ';
            app.text1_6 = uilabel(app.IMKBilinColumnsTab);
            app.text1_6.Text = 'c';
            app.IMKBilinBeamsTab = uitab(app.TabGroup);
            app.IMKBilinBeamsTab.Title = 'IMKBilin Beams';
            app.Image_4 = uiimage(app.IMKBilinBeamsTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'IMK backbone.png');
            app.editIMK1_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK1_2.Limits = [1e-09 1];
            app.editIMK1_2.ValueDisplayFormat = '%.3f';
            app.editIMK1_2.Value = 1e-09;
            app.editIMK2_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK2_2.Limits = [1e-09 1];
            app.editIMK2_2.ValueDisplayFormat = '%.3f';
            app.editIMK2_2.Value = 1e-09;
            app.editIMK3_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK3_2.Limits = [1e-09 1];
            app.editIMK3_2.ValueDisplayFormat = '%.3f';
            app.editIMK3_2.Value = 1e-09;
            app.editIMK4_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK4_2.Limits = [1e-09 1];
            app.editIMK4_2.ValueDisplayFormat = '%.3f';
            app.editIMK4_2.Value = 1e-09;
            app.editIMK5_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK5_2.Limits = [1e-09 1];
            app.editIMK5_2.ValueDisplayFormat = '%.3f';
            app.editIMK5_2.Value = 1e-09;
            app.editIMK6_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK6_2.Limits = [1e-09 1];
            app.editIMK6_2.ValueDisplayFormat = '%.3f';
            app.editIMK6_2.Value = 1e-09;
            app.editIMK7_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK7_2.Limits = [1e-09 1];
            app.editIMK7_2.ValueDisplayFormat = '%.3f';
            app.editIMK7_2.Value = 1e-09;
            app.editIMK8_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK8_2.Limits = [1e-09 1];
            app.editIMK8_2.ValueDisplayFormat = '%.3f';
            app.editIMK8_2.Value = 1e-09;
            app.editIMK9_2 = uieditfield(app.IMKBilinBeamsTab, 'numeric');
            app.editIMK9_2.Limits = [1e-09 1];
            app.editIMK9_2.ValueDisplayFormat = '%.3f';
            app.editIMK9_2.Value = 1e-09;
            app.text1_14 = uilabel(app.IMKBilinBeamsTab);
            app.text1_14.Text = 'Cyclic deterioration:';
            app.text1_15 = uilabel(app.IMKBilinBeamsTab);
            app.text1_15.Text = 'Ʌ';
            app.text1_16 = uilabel(app.IMKBilinBeamsTab);
            app.text1_16.Text = 'c';
            app.HystereticTab = uitab(app.TabGroup);
            app.HystereticTab.Title = 'Hysteretic';
            app.Image_2 = uiimage(app.HystereticTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'PZ backbone.png');
            app.editPZ2 = uieditfield(app.HystereticTab, 'numeric');
            app.editPZ2.Limits = [1e-09 1];
            app.editPZ2.ValueDisplayFormat = '%.3f';
            app.editPZ2.Value = 1e-09;
            app.editPZ1 = uieditfield(app.HystereticTab, 'numeric');
            app.editPZ1.Limits = [1e-09 1];
            app.editPZ1.ValueDisplayFormat = '%.3f';
            app.editPZ1.Value = 1e-09;
            app.editPZ3 = uieditfield(app.HystereticTab, 'numeric');
            app.editPZ3.Limits = [1e-09 1];
            app.editPZ3.ValueDisplayFormat = '%.3f';
            app.editPZ3.Value = 1e-09;
            app.editPZ4 = uieditfield(app.HystereticTab, 'numeric');
            app.editPZ4.Limits = [1e-09 1];
            app.editPZ4.ValueDisplayFormat = '%.3f';
            app.editPZ4.Value = 1e-09;
            app.Pinching4Tab = uitab(app.TabGroup);
            app.Pinching4Tab.Title = 'Pinching4';
            app.Image_3 = uiimage(app.Pinching4Tab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Pinching backbone.png');
            app.editPinch1 = uieditfield(app.Pinching4Tab, 'numeric');
            app.editPinch1.Limits = [1e-09 1];
            app.editPinch1.ValueDisplayFormat = '%.3f';
            app.editPinch1.Value = 1e-09;
            app.editPinch2 = uieditfield(app.Pinching4Tab, 'numeric');
            app.editPinch2.Limits = [1e-09 1];
            app.editPinch2.ValueDisplayFormat = '%.3f';
            app.editPinch2.Value = 1e-09;
            app.editPinch3 = uieditfield(app.Pinching4Tab, 'numeric');
            app.editPinch3.Limits = [1e-09 1];
            app.editPinch3.ValueDisplayFormat = '%.3f';
            app.editPinch3.Value = 1e-09;
            app.editPinch4 = uieditfield(app.Pinching4Tab, 'numeric');
            app.editPinch4.Limits = [1e-09 1];
            app.editPinch4.ValueDisplayFormat = '%.3f';
            app.editPinch4.Value = 1e-09;
            app.editPinch5 = uieditfield(app.Pinching4Tab, 'numeric');
            app.editPinch5.Limits = [1e-09 1];
            app.editPinch5.ValueDisplayFormat = '%.3f';
            app.editPinch5.Value = 1e-09;
            app.editPinch6 = uieditfield(app.Pinching4Tab, 'numeric');
            app.editPinch6.Limits = [1e-09 1];
            app.editPinch6.ValueDisplayFormat = '%.3f';
            app.editPinch6.Value = 1e-09;
            app.editPinch7 = uieditfield(app.Pinching4Tab, 'numeric');
            app.editPinch7.Limits = [1e-09 1];
            app.editPinch7.ValueDisplayFormat = '%.3f';
            app.editPinch7.Value = 1e-09;
            app.editPinch8 = uieditfield(app.Pinching4Tab, 'numeric');
            app.editPinch8.Limits = [1e-09 1];
            app.editPinch8.ValueDisplayFormat = '%.3f';
            app.editPinch8.Value = 1e-09;
            app.GeneralParametersTab = uitab(app.TabGroup);
            app.GeneralParametersTab.Title = 'General Parameters';
            app.editOther1 = uieditfield(app.GeneralParametersTab, 'numeric');
            app.editOther1.Limits = [1e-09 1];
            app.editOther1.ValueDisplayFormat = '%.3f';
            app.editOther1.Value = 1e-09;
            app.editOther2 = uieditfield(app.GeneralParametersTab, 'numeric');
            app.editOther2.Limits = [1e-09 1];
            app.editOther2.ValueDisplayFormat = '%.3f';
            app.editOther2.Value = 1e-09;
            app.editOther3 = uieditfield(app.GeneralParametersTab, 'numeric');
            app.editOther3.Limits = [1e-09 1];
            app.editOther3.ValueDisplayFormat = '%.3f';
            app.editOther3.Value = 1e-09;
            app.text1_11 = uilabel(app.GeneralParametersTab);
            app.text1_11.Text = 'Yield stress';
            app.text1_12 = uilabel(app.GeneralParametersTab);
            app.text1_12.Text = 'Damping coefficient';
            app.text1_13 = uilabel(app.GeneralParametersTab);
            app.text1_13.Text = 'Brace imperfection';
            app.editOther4 = uieditfield(app.GeneralParametersTab, 'numeric');
            app.editOther4.Limits = [1e-09 1];
            app.editOther4.ValueDisplayFormat = '%.3f';
            app.editOther4.Value = 1e-09;
            app.text1_17 = uilabel(app.GeneralParametersTab);
            app.text1_17.Text = 'Elastic modulus';
            app.editOther5 = uieditfield(app.GeneralParametersTab, 'numeric');
            app.editOther5.Limits = [1e-09 1];
            app.editOther5.ValueDisplayFormat = '%.3f';
            app.editOther5.Value = 1e-09;
            app.text1_18 = uilabel(app.GeneralParametersTab);
            app.text1_18.Text = 'Building mass';
            app.editOther6 = uieditfield(app.GeneralParametersTab, 'numeric');
            app.editOther6.Limits = [1e-09 1];
            app.editOther6.ValueDisplayFormat = '%.3f';
            app.editOther6.Value = 1e-09;
            app.text1_19 = uilabel(app.GeneralParametersTab);
            app.text1_19.Text = 'Building weight';
            app.text1_7 = uilabel(app.uipanel1);
            app.text1_7.Text = 'Specify the logarithmic standard deviations';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Tag = 'SubmitButton';
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Uncertainty_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Uncertainty_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end