classdef Visualize_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        LoadresultsButton        matlab.ui.control.Button
        SaveresultsButton        matlab.ui.control.Button
        text7_10                 matlab.ui.control.Label
        ForceDiagramCB           matlab.ui.control.CheckBox
        text7_9                  matlab.ui.control.Label
        ReadDataCB               matlab.ui.control.CheckBox
        text7_8                  matlab.ui.control.Label
        text7_7                  matlab.ui.control.Label
        PlasticHingeCB           matlab.ui.control.CheckBox
        LocalResponsePorgressCB  matlab.ui.control.CheckBox
        editRi                   matlab.ui.control.NumericEditField
        text7_6                  matlab.ui.control.Label
        EDPtype4                 matlab.ui.control.DropDown
        EDPtype3                 matlab.ui.control.DropDown
        EDPtype2                 matlab.ui.control.DropDown
        EDPtype                  matlab.ui.control.DropDown
        EDPvsRealizationCB       matlab.ui.control.CheckBox
        edit100                  matlab.ui.control.EditField
        FragilityCB              matlab.ui.control.CheckBox
        text7_5                  matlab.ui.control.Label
        text7_4                  matlab.ui.control.Label
        text7_3                  matlab.ui.control.Label
        text7_2                  matlab.ui.control.Label
        CollapseFragilityCB      matlab.ui.control.CheckBox
        EigenVectorCB            matlab.ui.control.CheckBox
        idaCB                    matlab.ui.control.CheckBox
        LocalResponseCB          matlab.ui.control.CheckBox
        GMj                      matlab.ui.control.NumericEditField
        GMi                      matlab.ui.control.NumericEditField
        text8                    matlab.ui.control.Label
        text7                    matlab.ui.control.Label
        PlotButton               matlab.ui.control.Button
        EDPProfileCB             matlab.ui.control.CheckBox
        FloorDispCB              matlab.ui.control.CheckBox
        BSFvsRDRCB               matlab.ui.control.CheckBox
        BSFvsSDR1CB              matlab.ui.control.CheckBox
        text3                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Visualize_Options_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global  MainDirectory resource_root
            load(strcat(MainDirectory,'\temp_unpacked'),'FrameType','RECORDERS','PO','ELF','EQ','TTH','CDPO','IDA','nRealizations','GM_Start','GM_Last')

            clc;

            try
                app.figure1.Icon            = strjoin(resource_root+"icon_Visualization.png");
                app.SaveresultsButton.Icon  = strjoin(resource_root+"save.png");
            end
            
            nGMs = GM_Last-GM_Start+1;
            
            if (PO==1 || ELF==1 || CDPO==1) && RECORDERS.Delete_Flag==0
                app.BSFvsSDR1CB.Enable              = 'on';
                app.BSFvsRDRCB.Enable               = 'on';
                app.FloorDispCB.Enable              = 'on';
                app.LocalResponseCB.Enable          = 'on';
                app.LocalResponsePorgressCB.Enable  = 'on';
                app.PlasticHingeCB.Enable           = 'on';
                app.ForceDiagramCB.Enable           = 'on';
            end
            
            if (EQ==1 || TTH==1) && RECORDERS.Delete_Flag==0
                app.BSFvsSDR1CB.Enable              = 'on';
                app.BSFvsRDRCB.Enable               = 'on';
                app.EDPProfileCB.Enable             = 'on';
                app.LocalResponseCB.Enable          = 'on';
                app.LocalResponsePorgressCB.Enable  = 'on';
                app.PlasticHingeCB.Enable           = 'on';
                app.FragilityCB.Enable              = 'on';
                app.ForceDiagramCB.Enable           = 'on';
            end
            
            if IDA == 1
                app.idaCB.Enable                    = 'on';
                app.CollapseFragilityCB.Enable      = 'on';
            end
            
            app.EigenVectorCB.Enable                = 'on';
            
            if EQ ~= 1
                app.GMi.Enable    = 'off';
                app.GMj.Enable    = 'off';
                app.GMi.Value     = 1;
                app.GMj.Value     = 1;
            else
                app.GMi.Enable    = 'on';
                app.GMj.Enable    = 'on';
                app.GMi.Value     = GM_Start;
                app.GMj.Value     = GM_Last;
            end
            
            if RECORDERS.Disp ~= 1
                app.BSFvsRDRCB.Enable = 'off';
            end
            
            if nRealizations > 1
                app.EDPvsRealizationCB.Enable   = 'on';
                app.editRi.Enable               = 'on';
                app.editRi.Limits               = [1 nRealizations];
            end
            
            if FrameType==2 ||  FrameType==3
                app.LocalResponseCB.Enable          = 'off';               
                app.LocalResponsePorgressCB.Enable  = 'off';
                app.PlasticHingeCB.Enable           = 'off';
                app.ForceDiagramCB.Enable           = 'off';
            end

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global MainDirectory
            load(strcat(MainDirectory,'\temp_unpacked'),'RESULTS','RFpath','RECORDERS','EV','PO','ELF','EQ','IDA','CDPO','TTH','Uncertainty','Parallel','nRealizations','GM');

            clc

            app.edit100.FontColor = [1.0 1.0 0.0];
            app.edit100.Value     = 'Please wait while data is processed.'; drawnow;
            
            GMi = app.GMi.Value;
            GMj = app.GMj.Value;
            nGMs = GMj-GMi+1;
            
            if EQ==1 && RECORDERS.Delete_Flag==0 && IDA==0
                EDP_data=importdata(strcat(RFpath,'\Results\Summary Maximum SDR.txt'));
                if size(EDP_data,1)<nGMs 
                app.edit100.Value     = 'Re-computing summary of max EDPs due to a segmental run. Please wait.'; drawnow;
                save_Results_Dynamic;
                end
            end

            Ri = app.editRi.Value;
            
            if     EV==1;                     SubRFname = 'EigenAnalysis';                 
            elseif PO==1;                     SubRFname = 'Pushover';                      
            elseif ELF==1;                    SubRFname = 'ELF';                           
            elseif EQ==1 && Uncertainty==0;   if Parallel == 0; SubRFname = GM.name{GMi}; else; SubRFname = [GM.name{GMi},'_1']; end                    
            elseif EQ==1 && Uncertainty==1;   SubRFname = [GM.name{GMi},'_',num2str(Ri)];  
            elseif CDPO==1;                   SubRFname = 'Pushover';                      
            elseif TTH==1;                    SubRFname = 'Tsunami';                       end

            try
                if app.EigenVectorCB.Value==1
                    plot_Eigen_Vectors();
                end
                
                if app.BSFvsSDR1CB.Value==1
                    if EQ==1
                        for GM_No=GMi:GMj
                            plot_BSF_vs_SDR1(GM_No, Ri);
                        end
                    else
                        plot_BSF_vs_SDR1(1, Ri);
                   end
                end
                
                if app.BSFvsRDRCB.Value==1
                    if EQ==1
                        for GM_No=GMi:GMj
                            plot_BSF_vs_RDR(GM_No, Ri);
                        end
                    else
                        plot_BSF_vs_RDR(1, Ri);
                    end
                end
                
                if app.FloorDispCB.Value==1
                    plot_Profile_Drift;
                end
                
                if app.EDPProfileCB.Value==1
                    EDP_selected=app.EDPtype.Value;
                    if EDP_selected=='1' && RECORDERS.SDR==1;    plot_Profile_SDR(GMi,GMj); end
                    if EDP_selected=='2' && RECORDERS.SDR==1;    plot_Profile_RDR(GMi,GMj); end
                    if EDP_selected=='3' && RECORDERS.RFA==1;    plot_Profile_PFA(GMi,GMj); end
                    if EDP_selected=='4' && RECORDERS.RFA==1;    plot_Profile_PFV(GMi,GMj); end
                    if EDP_selected=='5' && RECORDERS.Column==1; plot_Profile_SSF(GMi,GMj); end
                end
                
                if app.idaCB.Value==1
                    EDP_selected=app.EDPtype4.Value;
                    if EDP_selected=='1' && RECORDERS.SDR==1; plot_IDA(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && RECORDERS.SDR==1; plot_IDA(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && RECORDERS.RFA==1; plot_IDA(GMi,GMj,'PFA'); end
                end
                
                if app.CollapseFragilityCB.Value==1
                    plot_Collapse_Fragility(GMi,GMj)
                end
                
                if app.FragilityCB.Value==1 && nGMs*nRealizations>=3
                    EDP_selected=app.EDPtype3.Value;
                    if EDP_selected=='1' && RECORDERS.SDR==1; plot_EDP_Fragility(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && RECORDERS.SDR==1; plot_EDP_Fragility(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && RECORDERS.RFA==1; plot_EDP_Fragility(GMi,GMj,'PFA'); end
                elseif app.FragilityCB.Value==1 && nGMs*nRealizations<3
                    app.edit100.Value     = 'Number of GMs must be >=3!'; drawnow;
                end
                
                if app.EDPvsRealizationCB.Value==1 && nGMs*nRealizations>=3
                    EDP_selected=app.EDPtype2.Value;
                    if EDP_selected=='1' && RECORDERS.SDR==1; plot_EDP_vs_Realization(GMi,GMj,'SDR'); end
                    if EDP_selected=='2' && RECORDERS.SDR==1; plot_EDP_vs_Realization(GMi,GMj,'RDR'); end
                    if EDP_selected=='3' && RECORDERS.RFA==1; plot_EDP_vs_Realization(GMi,GMj,'PFA'); end
                end

                if app.LocalResponseCB.Value==1
                    cd (strcat(RFpath,'\Results\', SubRFname));
                    TimeX= importdata('Time.out');
                    cd (MainDirectory);

                    if exist('RESULTS')~=1  || app.ReadDataCB.Value==1
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(MainDirectory,'\temp_unpacked'), 'RESULTS', '-append');
                    elseif strcmp(RESULTS.SubRFname, SubRFname) ~= 1 || RESULTS.Time(end) ~= TimeX(end) 
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(MainDirectory,'\temp_unpacked'), 'RESULTS', '-append');
                    end
                    Visualize_Local_App(app)
                end
                
                if app.LocalResponsePorgressCB.Value==1

                    cd (strcat(RFpath,'\Results\', SubRFname));
                    TimeX= importdata('Time.out');
                    cd (MainDirectory);

                    if exist('RESULTS')~=1  || app.ReadDataCB.Value==1
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(MainDirectory,'\temp_unpacked'), 'RESULTS', '-append');
                    elseif strcmp(RESULTS.SubRFname, SubRFname) ~= 1 || RESULTS.Time(end) ~= TimeX(end) 
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(MainDirectory,'\temp_unpacked'), 'RESULTS', '-append');
                    end
                    Visualize_Response_Progression_App(app)
                end

                if app.PlasticHingeCB.Value==1

                    if RECORDERS.ColSpring~=1 || RECORDERS.BeamSpring~=1
                        errordlg('Data recorders for the beam and column springs must be activated before running analysis!')
                        app.edit100.Value     = ''; drawnow;
                        return
                    end

                    TimeX= importdata(strcat(RFpath,'\Results\', SubRFname,'\Time.out'));
                    cd (MainDirectory);

                    if exist('RESULTS')~=1  || app.ReadDataCB.Value==1
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(MainDirectory,'\temp_unpacked'), 'RESULTS', '-append');
                    elseif strcmp(RESULTS.SubRFname, SubRFname) ~= 1 || RESULTS.Time(end) ~= TimeX(end) 
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(MainDirectory,'\temp_unpacked'), 'RESULTS', '-append');
                    end
                    Visualize_PH_Progression_App(app)
                end

                if app.ForceDiagramCB.Value==1

                    if RECORDERS.ColSpring~=1 || RECORDERS.BeamSpring~=1
                        errordlg('Data recorders for the beam and column springs must be activated before running analysis!')
                        app.edit100.Value     = ''; drawnow;
                        return
                    end

                    cd (strcat(RFpath,'\Results\', SubRFname));
                    TimeX= importdata('Time.out');
                    cd (MainDirectory);

                    if exist('RESULTS')~=1  || app.ReadDataCB.Value==1
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(MainDirectory,'\temp_unpacked'), 'RESULTS', '-append');
                    elseif strcmp(RESULTS.SubRFname, SubRFname) ~= 1 || RESULTS.Time(end) ~= TimeX(end) 
                        clear RESULTS;
                        app.edit100.Value     = 'Please wait while results are retrieved. This can take several minutes.'; drawnow;
                        [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);
                        save(strcat(MainDirectory,'\temp_unpacked'), 'RESULTS', '-append');
                    end
                    Visualize_FD_App(app)
                end
                
                app.edit100.FontColor = [0.0 1.0 0.0];
                app.edit100.Value     = 'Plot generated!'; drawnow;
                
            catch ME
                cd(MainDirectory)
                write_Error(ME)
                app.edit100.FontColor = [1.0 0.0 0.0];
                app.edit100.Value     = 'Error occurred. See Error file!'; drawnow;
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app);
        end

        % Value changed function: EDPProfileCB
        function EDPProfileCBValueChanged(app, event)
            if app.EDPProfileCB.Value==1
                app.EDPtype.Enable='on';
            else
                app.EDPtype.Enable='off';
            end
        end

        % Value changed function: EDPvsRealizationCB
        function EDPvsRealizationCBValueChanged(app, event)
            if app.EDPvsRealizationCB.Value==1
                app.EDPtype2.Enable='on';
            else
                app.EDPtype2.Enable='off';
            end
        end

        % Value changed function: idaCB
        function idaCBValueChanged(app, event)
            if app.idaCB.Value==1
                app.EDPtype4.Enable='on';
            else
                app.EDPtype4.Enable='off';
            end
        end

        % Value changed function: FragilityCB
        function FragilityCBValueChanged(app, event)
            if app.FragilityCB.Value==1
                app.EDPtype3.Enable='on';
            else
                app.EDPtype3.Enable='off';
            end
        end

        % Value changed function: LocalResponseCB
        function LocalResponseCBValueChanged(app, event)
            global  MainDirectory
            load(strcat(MainDirectory,'\temp_unpacked'),'EQ');

            if EQ==1 
                if app.LocalResponseCB.Value==1
                    app.GMj.Enable="off";
                else
                    app.GMj.Enable="on";                
                end
            end

            if app.LocalResponseCB.Value==1
                app.ReadDataCB.Enable="on";               
            end
        end

        % Value changed function: PlasticHingeCB
        function PlasticHingeCBValueChanged(app, event)
            global  MainDirectory
            load(strcat(MainDirectory,'\temp_unpacked'),'EQ');

            if EQ==1 
                if app.PlasticHingeCB.Value==1
                    app.GMj.Enable="off";
                else
                    app.GMj.Enable="on";                
                end 
            end
            if app.PlasticHingeCB.Value==1
                app.ReadDataCB.Enable="on";
            else
                app.ReadDataCB.Enable="off";                
            end 
        end

        % Value changed function: LocalResponsePorgressCB
        function LocalResponsePorgressCBValueChanged(app, event)
            global  MainDirectory
            load(strcat(MainDirectory,'\temp_unpacked'),'EQ');
            
            if EQ==1 
                if app.LocalResponsePorgressCB.Value==1
                    app.GMj.Enable="off";
                else
                    app.GMj.Enable="on";                
                end     
            end

            if app.LocalResponsePorgressCB.Value==1
                app.ReadDataCB.Enable="on";               
            end
        end

        % Value changed function: ForceDiagramCB
        function ForceDiagramCBValueChanged(app, event)
            global  MainDirectory
            load(strcat(MainDirectory,'\temp_unpacked'),'EQ');
            
            if EQ==1 
                if app.ForceDiagramCB.Value==1
                    app.GMj.Enable="off";
                else
                    app.GMj.Enable="on";                
                end     
            end
    
            if app.ForceDiagramCB.Value==1
                app.ReadDataCB.Enable="on";              
            end 
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            global MainDirectory

            load(strcat(MainDirectory,'\temp_unpacked'),'RFpath','EV','PO','ELF','EQ','CDPO','TTH','GM','Uncertainty','Parallel');

            app.edit100.FontColor = [1.0 1.0 0.0];
            app.edit100.Value     = 'Please wait while results are extracted.'; drawnow;
            
            GMi = app.GMi.Value;
            GMj = app.GMj.Value;
            Ri  = app.editRi.Value;

            if     EV==1;                     SubRFname = 'EigenAnalysis';                 
            elseif PO==1;                     SubRFname = 'Pushover';                      
            elseif ELF==1;                    SubRFname = 'ELF';                           
            elseif EQ==1 && Uncertainty==0;   if Parallel == 0; SubRFname = GM.name{GMi}; else; SubRFname = [GM.name{GMi},'_1']; end                    
            elseif EQ==1 && Uncertainty==1;   SubRFname = [GM.name{GMi},'_',num2str(Ri)];  
            elseif CDPO==1;                   SubRFname = 'Pushover';                      
            elseif TTH==1;                    SubRFname = 'Tsunami';                       end

            % Get current directory, the user-specified project name, and selected Units
            [ResultFileName, ResultFilePath] = uiputfile('*.mat','Save results data as');

            [RESULTS] = get_all_data (MainDirectory, RFpath, SubRFname);

            save(strcat(ResultFilePath, ResultFileName), 'RESULTS','-v7.3','-nocompression');

            app.edit100.FontColor = [0.0 1.0 0.0];
            app.edit100.Value     = 'Results saved!'; drawnow;
        end

        % Button pushed function: LoadresultsButton
        function LoadresultsButtonPushed(app, event)
            global MainDirectory
            
            [ResultFileName,ResultFilePath] = uigetfile('*.mat','Select the RESULTS .mat file');

            if ResultFileName==0; return; end

            cd(MainDirectory)
            
            % Get current directory and user-specified project name
            if exist(ResultFilePath) ==0
                d=errordlg('Results file does not exist in current directory!','Error','modal');
                uiwait(d);
                return
            end
                
            load(strcat(ResultFilePath,ResultFileName));

            if exist('RESULTS') ~=1
                errordlg('The selected *.mat file does not include the RESULTS variable.', 'Error');
                return
            end

            save(strcat(MainDirectory,'\temp_unpacked'),'RESULTS','-append');

            app.edit100.FontColor = [0.0 1.0 0.0];
            app.edit100.Value     = 'Results loaded successfully!'; drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Visualization Options';
            app.figure1.Icon = fullfile(pathToMLAPP, 'resources', 'images', 'icon_Visualization.png');
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.Panel = uipanel(app.figure1);
            app.text3 = uilabel(app.Panel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Visualization Options';
            app.BSFvsSDR1CB = uicheckbox(app.Panel);
            app.BSFvsSDR1CB.Enable = 'off';
            app.BSFvsSDR1CB.Text = 'Base shear force vs 1st-story drift ratio';
            app.BSFvsRDRCB = uicheckbox(app.Panel);
            app.BSFvsRDRCB.Enable = 'off';
            app.BSFvsRDRCB.Text = 'Base shear force vs Roof drift ratio';
            app.FloorDispCB = uicheckbox(app.Panel);
            app.FloorDispCB.Enable = 'off';
            app.FloorDispCB.Text = 'Pushover floor displacement';
            app.EDPProfileCB = uicheckbox(app.Panel);
            app.EDPProfileCB.ValueChangedFcn = createCallbackFcn(app, @EDPProfileCBValueChanged, true);
            app.EDPProfileCB.Enable = 'off';
            app.EDPProfileCB.Text = 'Profile plot';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Tag = 'BuildButton';
            app.PlotButton.Text = 'Plot';
            app.text7 = uilabel(app.Panel);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Ground motion records to plot';
            app.text8 = uilabel(app.Panel);
            app.text8.Tag = 'text8';
            app.text8.Text = 'to';
            app.GMi = uieditfield(app.Panel, 'numeric');
            app.GMi.Limits = [1 Inf];
            app.GMi.ValueDisplayFormat = '%.0f';
            app.GMi.Value = 1;
            app.GMj = uieditfield(app.Panel, 'numeric');
            app.GMj.Limits = [1 Inf];
            app.GMj.ValueDisplayFormat = '%.0f';
            app.GMj.Value = 44;
            app.LocalResponseCB = uicheckbox(app.Panel);
            app.LocalResponseCB.ValueChangedFcn = createCallbackFcn(app, @LocalResponseCBValueChanged, true);
            app.LocalResponseCB.Enable = 'off';
            app.LocalResponseCB.Text = 'Local member response';
            app.idaCB = uicheckbox(app.Panel);
            app.idaCB.ValueChangedFcn = createCallbackFcn(app, @idaCBValueChanged, true);
            app.idaCB.Enable = 'off';
            app.idaCB.Text = 'IDA curves';
            app.EigenVectorCB = uicheckbox(app.Panel);
            app.EigenVectorCB.Enable = 'off';
            app.EigenVectorCB.Text = 'Eigen vectors';
            app.CollapseFragilityCB = uicheckbox(app.Panel);
            app.CollapseFragilityCB.Enable = 'off';
            app.CollapseFragilityCB.Text = 'Collapse fragility Curve';
            app.text7_2 = uilabel(app.Panel);
            app.text7_2.Tag = 'text7';
            app.text7_2.Text = 'Profile Plots';
            app.text7_3 = uilabel(app.Panel);
            app.text7_3.Tag = 'text7';
            app.text7_3.Text = 'IDA Plots';
            app.text7_4 = uilabel(app.Panel);
            app.text7_4.Tag = 'text7';
            app.text7_4.Text = 'Shear-Drift Plots';
            app.text7_5 = uilabel(app.Panel);
            app.text7_5.Tag = 'text7';
            app.text7_5.Text = 'General Plots';
            app.FragilityCB = uicheckbox(app.Panel);
            app.FragilityCB.ValueChangedFcn = createCallbackFcn(app, @FragilityCBValueChanged, true);
            app.FragilityCB.Enable = 'off';
            app.FragilityCB.Text = 'EDP fragility';
            app.edit100 = uieditfield(app.Panel, 'text');
            app.edit100.Editable = 'off';
            app.EDPvsRealizationCB = uicheckbox(app.Panel);
            app.EDPvsRealizationCB.ValueChangedFcn = createCallbackFcn(app, @EDPvsRealizationCBValueChanged, true);
            app.EDPvsRealizationCB.Enable = 'off';
            app.EDPvsRealizationCB.Text = 'EDP vs Realization';
            app.EDPtype = uidropdown(app.Panel);
            app.EDPtype.Items = {'SDR', 'RDR', 'PFA', 'PFV', 'SSF'};
            app.EDPtype.ItemsData = {'1', '2', '3', '4', '5'};
            app.EDPtype.Enable = 'off';
            app.EDPtype.Value = '1';
            app.EDPtype2 = uidropdown(app.Panel);
            app.EDPtype2.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype2.ItemsData = {'1', '2', '3'};
            app.EDPtype2.Enable = 'off';
            app.EDPtype2.Value = '1';
            app.EDPtype3 = uidropdown(app.Panel);
            app.EDPtype3.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype3.ItemsData = {'1', '2', '3'};
            app.EDPtype3.Enable = 'off';
            app.EDPtype3.Value = '1';
            app.EDPtype4 = uidropdown(app.Panel);
            app.EDPtype4.Items = {'SDR', 'RDR', 'PFA'};
            app.EDPtype4.ItemsData = {'1', '2', '3'};
            app.EDPtype4.Enable = 'off';
            app.EDPtype4.Value = '1';
            app.text7_6 = uilabel(app.Panel);
            app.text7_6.Tag = 'text7';
            app.text7_6.Text = 'Realization';
            app.editRi = uieditfield(app.Panel, 'numeric');
            app.editRi.Limits = [1 Inf];
            app.editRi.ValueDisplayFormat = '%.0f';
            app.editRi.Enable = 'off';
            app.editRi.Value = 1;
            app.LocalResponsePorgressCB = uicheckbox(app.Panel);
            app.LocalResponsePorgressCB.ValueChangedFcn = createCallbackFcn(app, @LocalResponsePorgressCBValueChanged, true);
            app.LocalResponsePorgressCB.Enable = 'off';
            app.LocalResponsePorgressCB.Text = 'Local response progression';
            app.PlasticHingeCB = uicheckbox(app.Panel);
            app.PlasticHingeCB.ValueChangedFcn = createCallbackFcn(app, @PlasticHingeCBValueChanged, true);
            app.PlasticHingeCB.Enable = 'off';
            app.PlasticHingeCB.Text = 'Plastic hinge progression';
            app.text7_7 = uilabel(app.Panel);
            app.text7_7.Tag = 'text7';
            app.text7_7.Text = '(new)';
            app.text7_8 = uilabel(app.Panel);
            app.text7_8.Tag = 'text7';
            app.text7_8.Text = '(new)';
            app.ReadDataCB = uicheckbox(app.Panel);
            app.ReadDataCB.Enable = 'off';
            app.ReadDataCB.Tooltip = {'Enforce the re-reading of the .out data files. generally, this is done automatically by the software whenever analysis type changes.'};
            app.ReadDataCB.Text = 'Re-read Results';
            app.text7_9 = uilabel(app.Panel);
            app.text7_9.Tag = 'text7';
            app.text7_9.Text = 'Interactive Plots';
            app.ForceDiagramCB = uicheckbox(app.Panel);
            app.ForceDiagramCB.ValueChangedFcn = createCallbackFcn(app, @ForceDiagramCBValueChanged, true);
            app.ForceDiagramCB.Enable = 'off';
            app.ForceDiagramCB.Text = 'Force diagrams';
            app.text7_10 = uilabel(app.Panel);
            app.text7_10.Tag = 'text7';
            app.text7_10.Text = '(new)';
            app.SaveresultsButton = uibutton(app.Panel, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Tag = 'BuildButton';
            app.SaveresultsButton.Icon = fullfile(pathToMLAPP, 'save.png');
            app.SaveresultsButton.Tooltip = {'Read all *.out data and stores them in a *.mat file'};
            app.SaveresultsButton.Text = 'Save results';
            app.LoadresultsButton = uibutton(app.Panel, 'push');
            app.LoadresultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadresultsButtonPushed, true);
            app.LoadresultsButton.Tag = 'BuildButton';
            app.LoadresultsButton.Icon = fullfile(pathToMLAPP, 'load.png');
            app.LoadresultsButton.Tooltip = {'Load a results *.mat file into the current project so that plots can be created'};
            app.LoadresultsButton.Text = 'Load results';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Visualize_Options_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end