classdef Visualize_FD_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        fontsize        matlab.ui.control.NumericEditField
        text45_3        matlab.ui.control.Label
        text45_2        matlab.ui.control.Label
        Ftypepopup      matlab.ui.control.DropDown
        scale           matlab.ui.control.NumericEditField
        text45          matlab.ui.control.Label
        savefigButton   matlab.ui.control.Button
        KeypointsPanel  matlab.ui.container.Panel
        CB2             matlab.ui.control.CheckBox
        CB1             matlab.ui.control.CheckBox
        label1          matlab.ui.control.Label
        Spinner         matlab.ui.control.Spinner
        text4_2         matlab.ui.control.Label
        slider          matlab.ui.control.Slider
        axes2           matlab.ui.control.UIAxes
        axes1           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        MainApp;
    end

    methods (Access = private)
        function HighlightSprings(app)
            global  bsf_X bsf_Y dispfloor p1 p2 RESULTS
            clc;

            delete(p1);delete(p2);
            cla(app.axes1);

            if app.CB1.Value==1
                [~,indxmax]=max(abs(bsf_X));
                app.slider.Value=indxmax;
                app.Spinner.Value=indxmax;
            end

            if app.CB2.Value==1
                [~,indxmax]=max(abs(bsf_Y));
                app.slider.Value=indxmax;
                app.Spinner.Value=indxmax;
            end

            Ti = app.slider.Value;
            scale = app.scale.Value;
            FType = str2double(app.Ftypepopup.Value);
            fontsize = app.fontsize.Value;

            dispfloorX=dispfloor(Ti,:).* 1;

            plot_Frame_Deformed(app, dispfloorX);

            str1=['SDR1 = ', num2str(RESULTS.SDR1_MF(Ti)*100),'% rads'];
            app.label1.Text = str1;

            plot_BMD (app, Ti, dispfloorX, FType, scale, fontsize);

            drawnow

            hold(app.axes2,"on");
            p1=xline (app.axes2, bsf_X(Ti), '--k');
            p2=plot (app.axes2, bsf_X(Ti), bsf_Y(Ti), 'ok','MarkerFaceColor','r');

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Visualize_Local_OpeningFcn(app, mainapp)
            global ProjectName ProjectPath NBay bsf_X bsf_Y dispfloor RESULTS resource_root

            app.MainApp=mainapp;

            app.figure1.Icon         = strjoin(resource_root+"icon_PH.jpg");
            app.savefigButton.Icon   = strjoin(resource_root+"save.jpg");

            GMi = app.MainApp.edit1.Value;
            Ri  = app.MainApp.editRi.Value;

            clc;
            load(strcat(ProjectPath,ProjectName),'FrameType','Recorders','NStory','NBay','PO','ELF','EQ','MF_COLUMNS', 'MF_BEAMS', 'BRACES',  'Units', 'fy','RESULTS');

            if Recorders.ColSpring~=1 || Recorders.BeamSpring~=1
                errordlg('Data recorders for the beam and column springs must be activated before running analysis!')
                return
            end
            
            if EQ==1
                app.figure1.Name=['Plastic Hinge Progression: GM#',num2str(GMi)];
            elseif PO==1
                app.figure1.Name=['Plastic Hinge Progression: Pushover'];
            elseif ELF==1
                app.figure1.Name=['Plastic Hinge Progression: ELF'];
            end

            [Column_Data, Beam_Data, Brace_Data] = Get_Member_Data (NStory, NBay, FrameType, MF_COLUMNS, MF_BEAMS, BRACES, Units, fy);
            save(strcat(ProjectPath,ProjectName),'Column_Data', 'Beam_Data', 'Brace_Data','-append');

            app.slider.Limits=[1 size(RESULTS.Time,1)];
            xx3=0:max(100,round(size(RESULTS.Time,1)/10)):size(RESULTS.Time,1); xx3(1,1)=1;
            app.slider.MajorTicks=xx3;
            app.slider.Value=ceil(size(RESULTS.Time,1)/2);
            app.Spinner.Limits=[1 size(RESULTS.Time,1)];
            app.Spinner.Value=ceil(size(RESULTS.Time,1)/2);

            dispfloor=zeros(size(RESULTS.Time,1),NStory+1);
            for Fi = 2:NStory+1
                evalc(['fname=','''','Disp',num2str(Fi),'_MF','''']);
                dispfloor(:,Fi)=RESULTS.(char(fname));
            end

            [bsf_X,bsf_Y]=Plot_BSF_vs_SDR1(GMi, Ri, 0);
            cla(app.axes2);
            hold(app.axes2,"on");
            plot (app.axes2,bsf_X,bsf_Y, '-k','linewidth',1);
            app.axes2.XLabel.String='\itSDR\rm_1 [% rad]';
            app.axes2.YLabel.String='\itBSF\rm /\itW_s';
            yline (app.axes2, 0, '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
            xline (app.axes2, 0 , '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
            if EQ ~= 1
                app.axes2.YLim=[1.05*min(bsf_Y) 1.05*max(bsf_Y)];
                app.axes2.XLim=[1.05*min(bsf_X) 1.05*max(bsf_X)];
            end

            HighlightSprings(app);

        end

        % Value changed function: slider
        function sliderValueChanged(app, event)
            Ti=round(app.slider.Value);
            app.slider.Value=Ti;
            app.Spinner.Value=Ti;
            HighlightSprings(app);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            Ti=app.Spinner.Value;
            app.slider.Value=Ti;
            HighlightSprings(app);
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            if app.CB1.Value==1; app.CB2.Value=0; end 
            HighlightSprings(app);
        end

        % Value changed function: CB2
        function CB2ValueChanged(app, event)
            if app.CB2.Value==1; app.CB1.Value=0; end 
            HighlightSprings(app);            
        end

        % Button pushed function: savefigButton
        function savefigButtonPushed(app, event)
            global ProjectPath ProjectName
            load(strcat(ProjectPath,ProjectName),'RFpath');
           
            Ti = app.slider.Value;

            FigFilename=['PH status at step',Ti,'.fig'];
            SavePath=[RFpath,'\Results\'];

            z=[SavePath, FigFilename];

            fig = figure('position',[100 100 450 600],'color','white');
            h = copyUIAxes(app.axes1, fig);
            savefig(fig, z);
            delete(fig);
        end

        % Value changed function: Ftypepopup
        function FtypepopupValueChanged(app, event)
            HighlightSprings(app);                        
        end

        % Value changed function: scale
        function scaleValueChanged(app, event)
            HighlightSprings(app);                        
        end

        % Value changed function: fontsize
        function fontsizeValueChanged(app, event)
            HighlightSprings(app);                                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Force Diagrams';
            app.figure1.Icon = fullfile(pathToMLAPP, 'resources', 'images', 'icon_PH.jpg');
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            xlabel(app.axes2, '\itSDR\rm_1 [% rad]')
            ylabel(app.axes2, '\itBSF\rm /\itW_s')
            app.axes2.Tag = 'axes2';
            app.slider = uislider(app.figure1);
            app.slider.Limits = [1 1000000];
            app.slider.MajorTicks = [];
            app.slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.slider.MinorTicks = [];
            app.slider.Value = 1;
            app.text4_2 = uilabel(app.figure1);
            app.text4_2.Text = 'Step:';
            app.Spinner = uispinner(app.figure1);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.label1 = uilabel(app.figure1);
            app.label1.Text = 'SDR₁ =';
            app.KeypointsPanel = uipanel(app.figure1);
            app.KeypointsPanel.Title = 'Key points';
            app.CB1 = uicheckbox(app.KeypointsPanel);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'Go to peak drift';
            app.CB2 = uicheckbox(app.KeypointsPanel);
            app.CB2.ValueChangedFcn = createCallbackFcn(app, @CB2ValueChanged, true);
            app.CB2.Text = 'Go to peak strength';
            app.savefigButton = uibutton(app.figure1, 'push');
            app.savefigButton.ButtonPushedFcn = createCallbackFcn(app, @savefigButtonPushed, true);
            app.savefigButton.Icon = fullfile(pathToMLAPP, 'save.jpg');
            app.savefigButton.Tooltip = {'save figure'};
            app.savefigButton.Text = '';
            app.text45 = uilabel(app.figure1);
            app.text45.Tag = 'text45';
            app.text45.Text = 'scale';
            app.scale = uieditfield(app.figure1, 'numeric');
            app.scale.Limits = [1 Inf];
            app.scale.ValueDisplayFormat = '%.0f';
            app.scale.ValueChangedFcn = createCallbackFcn(app, @scaleValueChanged, true);
            app.scale.Value = 1;
            app.Ftypepopup = uidropdown(app.figure1);
            app.Ftypepopup.Items = {'Axial', 'Shear', 'Moment'};
            app.Ftypepopup.ItemsData = {'2', '1', '3'};
            app.Ftypepopup.ValueChangedFcn = createCallbackFcn(app, @FtypepopupValueChanged, true);
            app.Ftypepopup.Tag = 'Supportpopup';
            app.Ftypepopup.Value = '3';
            app.text45_2 = uilabel(app.figure1);
            app.text45_2.Tag = 'text45';
            app.text45_2.Text = 'Force';
            app.text45_3 = uilabel(app.figure1);
            app.text45_3.Tag = 'text45';
            app.text45_3.Text = 'font size';
            app.fontsize = uieditfield(app.figure1, 'numeric');
            app.fontsize.Limits = [1 18];
            app.fontsize.ValueDisplayFormat = '%.0f';
            app.fontsize.ValueChangedFcn = createCallbackFcn(app, @fontsizeValueChanged, true);
            app.fontsize.Value = 8;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_FD_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Visualize_Local_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end