classdef Visualize_GM_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        text24           matlab.ui.control.Label
        text23           matlab.ui.control.Label
        GMpopup          matlab.ui.control.DropDown
        NextButton       matlab.ui.control.Button
        PreviousButton   matlab.ui.control.Button
        popupmenu6       matlab.ui.control.DropDown
        Next2Button      matlab.ui.control.Button
        Previous2Button  matlab.ui.control.Button
        text25           matlab.ui.control.Label
        Savebutton       matlab.ui.control.Button
        text26           matlab.ui.control.Label
        edit7            matlab.ui.control.NumericEditField
        edit8            matlab.ui.control.NumericEditField
        TipIcon1         matlab.ui.control.Button
        text27           matlab.ui.control.Label
        T1               matlab.ui.control.NumericEditField
        axes1            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % Description
        TipWindow
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_GroundMotion_Plot_OpeningFcn(app)
            global  ProjectPath ProjectName resource_root
            load(strcat(ProjectPath,ProjectName),'ANALYSIS')
            GM=ANALYSIS.GM;

            app.figure1.Icon = strjoin(resource_root+"GM_Icon.png");

            nGM=size(GM.name,2);

            app.GMpopup.Items=GM.name;
            app.GMpopup.ItemsData=1:nGM;
            app.GMpopup.UserData=nGM;

            for GM_No=1:nGM
                GAx=GM.acc{:,GM_No};
                MaxPGA(GM_No,1)=GM.pga{:,GM_No};
            end
            app.axes1.UserData=max(MaxPGA);

            app.GMpopup.Value=1;
            GMpopup_Callback(app)

        end

        % Button pushed function: Next2Button
        function Next2Button_Callback(app, event)
            Current_Choice=str2double(app.popupmenu6.Value);
            if Current_Choice<8
                Current_Choice=Current_Choice+1;
                app.popupmenu6.Value=num2str(Current_Choice);
                GMpopup_Callback(app)
            else
                Current_Choice=1;
                app.popupmenu6.Value=num2str(Current_Choice);
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: Previous2Button
        function Previous2Button_Callback(app, event)
            Current_Choice=str2double(app.popupmenu6.Value);
            if Current_Choice>1
                Current_Choice=Current_Choice-1;
                app.popupmenu6.Value=num2str(Current_Choice);
                GMpopup_Callback(app)
            else
                Current_Choice=8;
                app.popupmenu6.Value=num2str(Current_Choice);
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: Savebutton
        function Savebutton_Callback(app, event)
            clc;

            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end

            global MainDirectory ProjectPath ProjectName
            load(strcat(ProjectPath,ProjectName),'ANALYSIS')
            GM=ANALYSIS.GM;
            TFreeVibration=ANALYSIS.TFreeVibration;

            g=1;
            plotlabel='m';

            MaxPGA=app.axes1.UserData;
            GM_No=app.GMpopup.Value;
            Selection=str2double(app.popupmenu6.Value);

            clear VGx UGx Tx GAx


            Tx=GM.dt{:,GM_No}:GM.dt{:,GM_No}:GM.duration{:,GM_No}; Tx=Tx';
            GAx=GM.acc{:,GM_No};

            VGx(1,1)=0;
            UGx(1,1)=0;
            for i=2:size(GAx,1)
                VGx(i,1)=VGx(i-1,1)+0.5*(GAx(i,1)+GAx(i-1,1))*(Tx(i,1)-Tx(i-1,1));
                UGx(i,1)=UGx(i-1,1)+0.5*(VGx(i,1)+VGx(i-1,1))*(Tx(i,1)-Tx(i-1,1));
            end

            GMTime=GM.dt{:,GM_No}:GM.dt{:,GM_No}:GM.duration{:,GM_No}; GMTime=GMTime';
            Duration=GM.duration{:,GM_No};
            MainGMTime=Duration-TFreeVibration;
            for i=1:size(GMTime,1)
                if GMTime(i,1)>=MainGMTime
                    idexMainGM=i;
                    break
                end
            end


            T=GMTime(1:idexMainGM,1);
            GAx = GM.acc{:, GM_No}(1:idexMainGM);

            GV= VGx(1:idexMainGM,1);
            GU=UGx(1:idexMainGM,1);

            if Selection==1

                X=T;
                Y=GAx;

            elseif Selection==2

                X=T;
                Y=GV;

            elseif Selection==3

                X=T;
                Y=GU;

            elseif Selection==4

                %                 Fs=1/(T(3,1)-T(2,1));
                %                 L=length(T);
                %                 Y=fft(GA/g);
                %                 P2 = abs(Y/L);
                %                 P1 = P2(1:L/2+1);
                %                 P1(2:end-1) = 2*P1(2:end-1);
                %                 f = Fs*(0:(L/2))/L;
                %                 X=f';
                %                 Y=P1;

                ts = T(3,1)-T(1,1);
                N = length(T);
                F = fft(GAx);
                f = (1/N/ts)*[0:N-1];

                Y=abs(F/(N/2));

                X=f';
                Y=Y;

                cutoffindx=find(X>20);
                X(cutoffindx:end,:)=[];
                Y(cutoffindx:end,:)=[];

            elseif Selection==5

                threshold= app.edit7.Value/100;
                dGA=diff(GAx);
                dGA_squared=dGA.^2;
                dT=diff(T);
                Sum=0;
                for i=1:size(dT,1)
                    product=pi/g*dGA_squared(i,1)*dT(i,1);
                    Sum=Sum+product;
                    AI(i+1,1)=Sum;
                end
                X=T;
                Y=AI*100/AI(end,1);

            elseif Selection==6

                X=T;
                Y=GAx;

            elseif Selection==7

                [T, FIV]=get_FIV3(app);
                X=T;
                Y=FIV;

            elseif Selection==8
                [Period, MaxAA, SAavg]=get_SAavg(app);
                X=Period;
                Y=MaxAA/g;
            end

            cd (FilePath)
            fileX = fopen(FileName,'wt');
            fprintf(fileX,'%s\t%s','X-Data','Y-Data');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            for i=1:size(X,1)
                fprintf(fileX,'%f\t%f\t', X(i,1), Y(i,1));
                fprintf(fileX,'\n');
            end
            fclose (fileX);
            cd (MainDirectory)
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            if str2double(app.popupmenu6.Value)==5
                get_Ds(app);
            elseif str2double(app.popupmenu6.Value)==7
                get_FIV3(app);
            elseif str2double(app.popupmenu6.Value)==8
                get_SAavg(app);
            end
        end

        % Value changed function: edit8
        function edit8_Callback(app, event)
            if str2double(app.popupmenu6.Value)==7
                app.edit7.Limits=[0.001 1];
                get_FIV3(app);
            elseif str2double(app.popupmenu6.Value)==8
                app.edit7.Limits=[app.edit8.Value+0.001 10];
                get_SAavg(app);
            end
        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global ProjectPath ProjectName
            load(strcat(ProjectPath,ProjectName),'ANALYSIS')
            GM=ANALYSIS.GM;
            TFreeVibration=ANALYSIS.TFreeVibration;

            UnitMod=1;
            g=1;
            plotlabel='m';

            cla(app.axes1)
            app.axes1.FontName = 'Times';
            app.axes1.FontSize = 17;

            MaxPGA=app.axes1.UserData;
            GM_No=app.GMpopup.Value;
            Selection=str2double(app.popupmenu6.Value);

            app.text26.Tooltip='';
            app.text25.Tooltip='';

            clear VGx UGx Tx GAx

            Tx=GM.dt{:,GM_No}:GM.dt{:,GM_No}:GM.duration{:,GM_No}; Tx=Tx';
            GAx=GM.acc{:,GM_No};

            VGx(1,1)=0;
            UGx(1,1)=0;
            for i=2:size(GAx,1)
                VGx(i,1)=VGx(i-1,1)+0.5*(GAx(i,1)+GAx(i-1,1))*(Tx(i,1)-Tx(i-1,1))*UnitMod;
                UGx(i,1)=UGx(i-1,1)+0.5*(VGx(i,1)+VGx(i-1,1))*(Tx(i,1)-Tx(i-1,1));
            end

            GMTime=GM.dt{:,GM_No}:GM.dt{:,GM_No}:GM.duration{:,GM_No};GMTime=GMTime';
            Duration = GM.duration{:,GM_No};
            MainGMTime=Duration-TFreeVibration;
            for i=1:size(GMTime,1)
                if GMTime(i,1)>=MainGMTime
                    idexMainGM=i;
                    break
                end
            end

            T=GMTime(1:idexMainGM,1);
            GAx = GM.acc{:, GM_No}(1:idexMainGM);
            GVx = VGx(1:idexMainGM,1);
            GUx = UGx(1:idexMainGM,1);
            dt  = GM.dt{:,GM_No};

            if Selection==1

                app.edit7.Visible='off';
                app.text25.Visible='off';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
                app.text27.Visible='off';
                app.T1.Visible='off';

                %% Plot the ground acceleration history
                plot(app.axes1,T,GAx/g,'-r','linewidth',1.5)
                app.axes1.XLabel.String='Time [sec]';
                app.axes1.YLabel.String='Acceleration [g]';
                app.axes1.XLabel.FontName='Times';
                app.axes1.YLabel.FontName='Times';

                %% Plot Origin Lines
                plot(app.axes1,[0 MainGMTime*2],[0 0],'-k')
                app.axes1.YLim=[-max(abs(GAx))*1.3/g max(abs(GAx))*1.3/g];
                app.axes1.XLim=[0 MainGMTime*1.1];

                %% Add label at pga
                [MaxGA, IndexMaxGA]=max(abs(GAx));
                plot(app.axes1,T(IndexMaxGA,1),GAx(IndexMaxGA,1)/g,'-ok','linewidth',1,'Markerfacecolor','g')
                text(app.axes1,T(IndexMaxGA,1),GAx(IndexMaxGA,1)*1.15/g,[' ',num2str(round(MaxGA*100/g)/100),' g'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')

                text(app.axes1,0.02*T(end,1),-max(GAx(IndexMaxGA,1))*1.1/g,['dt=',num2str(dt),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')

                if TFreeVibration~=0
                    plot(app.axes1,T(end,1),GAx(end,1)/g,'-ok','linewidth',1,'Markerfacecolor','r');
                    text(app.axes1,T(end,1),abs(GAx(IndexMaxGA,1)/g/5),['+',num2str(TFreeVibration),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold');
                end


            elseif Selection==2

                app.edit7.Visible='off';
                app.text25.Visible='off';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
                app.text27.Visible='off';
                app.T1.Visible='off';

                %% Plot the ground acceleration history
                plot(app.axes1,T,GVx,'-r','linewidth',1.5)
                app.axes1.XLabel.String='Time [sec]';
                app.axes1.YLabel.String=['Velocity [',plotlabel,'/sec]'];

                %% Plot Origin Lines
                plot(app.axes1,[0 MainGMTime*2],[0 0],'-k')
                app.axes1.YLim=[-max(abs(GVx))*1.3 max(abs(GVx))*1.3];
                app.axes1.XLim=[0 MainGMTime*1.1];

                %% Add label at pva
                [MaxGV, IndexMaxGV]=max(abs(GVx));
                plot(app.axes1,T(IndexMaxGV,1),GVx(IndexMaxGV,1),'-ok','linewidth',1,'Markerfacecolor','g')
                text(app.axes1,T(IndexMaxGV,1),GVx(IndexMaxGV,1)*1.15,[' ',num2str(round(MaxGV*100)/100),' ',plotlabel,'/sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold');
                text(app.axes1,0.02*T(end,1),-max(GVx(IndexMaxGV,1))*1.1,['dt=',num2str(dt),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')

                if TFreeVibration~=0
                    plot(app.axes1,T(end,1),GVx(end,1),'-ok','linewidth',1,'Markerfacecolor','r')
                    text(app.axes1,T(end,1),abs(GVx(IndexMaxGV,1)/5),['+',num2str(TFreeVibration),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                end

            elseif Selection==3

                app.edit7.Visible='off';
                app.text25.Visible='off';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
                app.text27.Visible='off';
                app.T1.Visible='off';

                %% Plot the ground acceleration history
                plot(app.axes1,T,GUx,'-r','linewidth',1.5)
                app.axes1.XLabel.String='Time [sec]';
                app.axes1.YLabel.String=['Displacement [',plotlabel,']'];

                %% Plot Origin Lines
                plot(app.axes1,[0 MainGMTime*2],[0 0],'-k')
                app.axes1.YLim=[-max(abs(GUx))*1.3 max(abs(GUx))*1.3];
                app.axes1.XLim=[0 MainGMTime*1.1];

                %% Add label at pga
                [MaxGU, IndexMaxGU]=max(abs(GUx));
                plot(app.axes1,T(IndexMaxGU,1),GUx(IndexMaxGU,1),'-ok','linewidth',1,'Markerfacecolor','g')
                text(app.axes1,T(IndexMaxGU,1),GUx(IndexMaxGU,1)*1.15,[' ',num2str(round(MaxGU*100)/100),' ', plotlabel], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold');
                text(app.axes1,0.02*T(end,1),-max(GUx(IndexMaxGU,1))*1.1,['dt=',num2str(dt),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')

                if TFreeVibration~=0
                    plot(app.axes1,T(end,1),GUx(end,1),'-ok','linewidth',1,'Markerfacecolor','r')
                    text(app.axes1,T(end,1),abs(GUx(IndexMaxGU,1)/5),['+',num2str(TFreeVibration),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                end

                % Plot FFT
            elseif Selection==4

                app.edit7.Visible='off';
                app.text25.Visible='off';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
                app.text27.Visible='off';
                app.T1.Visible='off';

                app.axes1.XLabel.String='Frequency [Hz]';
                app.axes1.YLabel.String='FFT Amplitude';

                %                 Fs=1/(T(3,1)-T(2,1));
                %                 L=length(T);
                %                 Y=fft(GA/g);
                %                 P2 = abs(Y/L);
                %                 P1 = P2(1:L/2+1);
                %                 P1(2:end-1) = 2*P1(2:end-1);
                %
                %                 f = Fs*(0:(L/2))/L;

                ts = T(2,1)-T(1,1);
                N = length(T);
                F = fft(GAx);
                f = (1/N/ts)*[0:N-1];

                F=abs(F/(N/2));

                plot(app.axes1,f,F,'-r','linewidth',1.5)
                app.axes1.YLim=[0 max(F)*1.2];
                app.axes1.XLim=[0 20];

                %% Add label at pga
                [MaxF, IndexMaxF]=max(abs(F));
                plot(app.axes1,f(IndexMaxF),F(IndexMaxF),'-ok','linewidth',1,'Markerfacecolor','g')
                text(app.axes1,f(IndexMaxF),F(IndexMaxF)*1.10,[' ',num2str(round(f(IndexMaxF)*100)/100),' Hz'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                text(app.axes1,f(IndexMaxF),F(IndexMaxF)*1.,[' ',num2str((round((1/f(IndexMaxF))*100)/100)),' sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')

                % Plot Arias Intensity
            elseif Selection==5

                app.edit7.Visible='on';
                app.text25.Visible='on';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.edit7.Limits=[0 50];
                app.edit7.Value=10;
                app.text25.Text='Threshold [%]';
                app.TipIcon1.Visible='off';

                get_Ds (app)

            elseif Selection==6

                app.edit7.Visible='off';
                app.edit8.Visible='off';
                app.text25.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
                app.text27.Visible='off';
                app.T1.Visible='off';

                % Get Maximum Incremental Velocity
                dT=T(2,1)-T(1,1);
                ZEROindx(1,1)=1;
                for i=2:size(GAx,1)-1
                    if GAx(i,1)*GAx(i+1,1)<=0
                        ZEROindx(end+1,1)=i;
                    end
                end

                for i=1:size(ZEROindx,1)-1
                    Sum=0;
                    for j=ZEROindx(i,1):ZEROindx(i+1,1)-1
                        Sum=Sum+abs(0.5*(GAx(j,1)+GAx(j+1,1))*dT);
                    end
                    IV(i,1)=Sum;
                end

                % Getting the effective duration region to set the Xlim
                threshold=0.05;
                dGA=diff(GAx);
                dGA_squared=dGA.^2;
                dT=diff(T);
                Sum=0;
                for i=1:size(dT,1)
                    product=pi/g*dGA_squared(i,1)*dT(i,1);
                    Sum=Sum+product;
                    AI(i+1,1)=Sum;
                end

                for i=2:size(AI,1)
                    if AI(i-1,1)/AI(end,1)<=threshold && AI(i,1)/AI(end,1)>=threshold
                        indexAI_5=i;
                        T_at_AI_5=T(i,1);
                    end
                    if AI(i-1,1)/AI(end,1)<=(1-threshold) && AI(i,1)/AI(end,1)>=(1-threshold)
                        indexAI_95=i;
                        T_at_AI_95=T(i,1);
                    end
                end

                [MaxIV, Index]=max(abs(IV));
                IndexMaxIV1=ZEROindx(Index,1);
                IndexMaxIV2=ZEROindx(Index+1,1);
                PulseDuration=T(IndexMaxIV2,1)-T(IndexMaxIV1,1);

                %% Plot the ground acceleration history
                plot(app.axes1,T,GAx/g,'-r','linewidth',1.5)
                app.axes1.XLabel.String='Time [sec]';
                app.axes1.YLabel.String='Acceleration [g]';

                %% Plot Origin Lines
                plot(app.axes1,[0 MainGMTime*2],[0 0],'-k')
                app.axes1.YLim=[-max(abs(GAx))*1.3/g max(abs(GAx))*1.3/g];
                app.axes1.XLim=[T_at_AI_5*0.85 T_at_AI_95*1.15];
                app.axes1.XLim=[0 MainGMTime*1.1];

                plot(app.axes1,[T(IndexMaxIV1,1) T(IndexMaxIV1,1)],[-max(abs(GAx))*1.3/g max(abs(GAx))*1.3/g],'-k')
                plot(app.axes1,[T(IndexMaxIV2,1) T(IndexMaxIV2,1)],[-max(abs(GAx))*1.3/g max(abs(GAx))*1.3/g],'-k')

                X= T(IndexMaxIV1:IndexMaxIV2,1);
                Y=GAx(IndexMaxIV1:IndexMaxIV2,1)/g;
                fill(app.axes1,X,Y,'y');
                text(app.axes1,T(IndexMaxIV2,1),max(abs(GAx))*1.05/g,['IV=',num2str(round(MaxIV*100)/100),' ', plotlabel, '/sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')


            elseif Selection==7

                app.edit7.Visible='on';
                app.edit8.Visible='on';
                app.text25.Visible='on';
                app.text26.Visible='on';
                app.text27.Visible='on';
                app.text27.Text='T1';
                app.T1.Visible='on';
                app.T1.Limits=[0 10];
                if app.T1.Value <0.1; app.T1.Value=1; end

                app.text25.Text='beta'; %Cut-off Frequency [Hz]
                app.text26.Text='alpha'; %Time Segment [sec]'
                app.edit7.Limits=[0.001 1];
                app.edit8.Limits=[0.001 1];
                app.edit7.Value=0.8;
                app.edit8.Value=0.65;
                app.TipIcon1.Visible='on';
                edit8_Callback(app);

            elseif Selection==8

                app.edit7.Visible='on';
                app.edit8.Visible='on';
                app.text25.Visible='on';
                app.text26.Visible='on';
                app.text27.Visible='on';
                app.text27.Text='zeta';
                app.T1.Visible='on';
                app.T1.Limits=[0 1];
                if app.T1.Value ==1; app.T1.Value=0.02; end

                app.text25.Text='End period [sec]';
                app.text26.Text='Start period [sec]';
                app.edit8.Limits=[0.001 10];
                app.edit7.Limits=[0.001 10];
                app.edit8.Value=1;
                app.edit7.Value=2;
                app.TipIcon1.Visible='off';
                edit8_Callback(app);
            end
        end

        % Value changed function: popupmenu6
        function popupmenu6_Callback(app, event)
            GMpopup_Callback(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end

        % Button pushed function: TipIcon1
        function TipIcon1ButtonPushed(app, event)
            str{1}='For FIV3 computation, refer to this publication:';
            str{2}='';
            str{3}='Dávalos, H. and E. Miranda "Filtered Incremental Velocity: A Novel Approach in Intensity Measures for Seismic Collapse Estimation." Earthquake Engineering & Structural Dynamics. https://doi.org/10.1002/eqe.3205';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Value changed function: T1
        function T1ValueChanged(app, event)
            GMpopup_Callback(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
           nGM=app.GMpopup.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No<nGM
                Next_GM_No=Current_GM_No+1;
                app.GMpopup.Value=Next_GM_No;
                GMpopup_Callback(app)
            end 
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            nGM=app.GMpopup.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No>1
                Previous_GM_No=Current_GM_No-1;
                app.GMpopup.Value=Previous_GM_No;
                GMpopup_Callback(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Ground Motion Plot';
            app.figure1.Icon = fullfile(pathToMLAPP, 'resources', 'images', 'GM_Icon.png');
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [-0.110161604692016 0.110161604692016];
            app.axes1.YLim = [-3.17482251867287 3.17482251867287];
            app.T1 = uieditfield(app.figure1, 'numeric');
            app.T1.Limits = [0 Inf];
            app.T1.ValueDisplayFormat = '%5.2f';
            app.T1.ValueChangedFcn = createCallbackFcn(app, @T1ValueChanged, true);
            app.T1.Value = 1;
            app.text27 = uilabel(app.figure1);
            app.text27.Text = 'T';
            app.TipIcon1 = uibutton(app.figure1, 'push');
            app.TipIcon1.ButtonPushedFcn = createCallbackFcn(app, @TipIcon1ButtonPushed, true);
            app.TipIcon1.Text = '?';
            app.edit8 = uieditfield(app.figure1, 'numeric');
            app.edit8.Limits = [0 Inf];
            app.edit8.ValueDisplayFormat = '%5.2f';
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit8_Callback, true);
            app.edit7 = uieditfield(app.figure1, 'numeric');
            app.edit7.Limits = [0 Inf];
            app.edit7.ValueDisplayFormat = '%5.2f';
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.text26 = uilabel(app.figure1);
            app.text26.Text = 'Threshold [%]';
            app.Savebutton = uibutton(app.figure1, 'push');
            app.Savebutton.ButtonPushedFcn = createCallbackFcn(app, @Savebutton_Callback, true);
            app.Savebutton.Text = 'save plot data';
            app.text25 = uilabel(app.figure1);
            app.text25.Text = 'Threshold [%]';
            app.Previous2Button = uibutton(app.figure1, 'push');
            app.Previous2Button.ButtonPushedFcn = createCallbackFcn(app, @Previous2Button_Callback, true);
            app.Previous2Button.Tooltip = 'Previous Plot';
            app.Previous2Button.Text = '<';
            app.Next2Button = uibutton(app.figure1, 'push');
            app.Next2Button.ButtonPushedFcn = createCallbackFcn(app, @Next2Button_Callback, true);
            app.Next2Button.Tooltip = 'Next Plot';
            app.Next2Button.Text = '>';
            app.popupmenu6 = uidropdown(app.figure1);
            app.popupmenu6.Items = {'Acceleration History', 'Velocity History', 'Displacement History', 'Fourier Transform (Frequency Domain)', 'Significant Duration (Ds)', 'Incremental Velocity', 'Filtered Incremental Velocity 3', 'SAavg'};
            app.popupmenu6.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.popupmenu6.ValueChangedFcn = createCallbackFcn(app, @popupmenu6_Callback, true);
            app.popupmenu6.Value = '1';
            app.PreviousButton = uibutton(app.figure1, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Tooltip = 'Preious Ground Motion';
            app.PreviousButton.Text = '<';
            app.NextButton = uibutton(app.figure1, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tooltip = 'Next Ground Motion';
            app.NextButton.Text = '>';
            app.GMpopup = uidropdown(app.figure1);
            app.GMpopup.Items = {'1'};
            app.GMpopup.ItemsData = {'1'};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = '1';
            app.text23 = uilabel(app.figure1);
            app.text23.Text = 'Ground Motion:';
            app.text24 = uilabel(app.figure1);
            app.text24.Text = 'Plot Type:';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_GM_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_GroundMotion_Plot_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end