classdef Visualize_Local_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        SavedataButton  matlab.ui.control.Button
        PlotTypepopup   matlab.ui.control.DropDown
        Locpopup        matlab.ui.control.DropDown
        Xaxispopup      matlab.ui.control.DropDown
        Yaxispopup      matlab.ui.control.DropDown
        text5           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        Elementpopup    matlab.ui.control.DropDown
        text7           matlab.ui.control.Label
        text8           matlab.ui.control.Label
        text6           matlab.ui.control.Label
        axes2           matlab.ui.control.UIAxes
        axes1           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp;
    end
    
    methods (Access = private)
        function HighlightElement(app)
            global  ProjectName ProjectPath H_Plot
            clc;
            load(strcat(ProjectPath,ProjectName))
    
            [Column_Data, Beam_Data, Brace_Data]=Get_Member_Data(NStory, NBay, FrameType, MF_COLUMNS, MF_BEAMS, BRACES, Units, fy);

            try
                delete (H_Plot.P1);
                delete (H_Plot.P2);
            end
            app.axes1;

            Element     = app.Elementpopup.Items{app.Elementpopup.Value};
            SpringLoc   = app.Locpopup.Value;
            Story       = app.Yaxispopup.Value;
            Floor       = app.Yaxispopup.Value;
            Axis        = app.Xaxispopup.Value;
            Bay        = app.Xaxispopup.Value;
            Fi = Story; Fj = Story+1;
            Bayi = max(1,Axis-1); Bayj = Axis;
                
            if strcmp(Element,'Column Spring')==1
                if SpringLoc==2
                    H_Plot.P1=scatter(app.axes1,GridX(Axis) , GridY(Floor)-Beam_Data.d(Floor,1)/2,'or','Markerfacecolor','y');
                elseif  SpringLoc==1 && Floor==NStory+1
                    H_Plot.P1=scatter(app.axes1,GridX(Axis) , GridY(Floor)-Beam_Data.d(Floor,1)/2,'or','Markerfacecolor','y');
                else
                    H_Plot.P1=scatter(app.axes1,GridX(Axis) , GridY(Floor)+Beam_Data.d(Floor,1)/2,'or','Markerfacecolor','y');
                end
            end
            
            if strcmp(Element,'Beam Spring')==1
                if SpringLoc==2
                    H_Plot.P1=scatter(app.axes1,GridX(Axis)+Column_Data.d(Story,Axis)/2+Beam_Data.LRBS(Floor+1,Bayj), GridY(Floor+1),'or','Markerfacecolor','y');
                elseif  SpringLoc==1 && Axis==1
                    H_Plot.P1=scatter(app.axes1,GridX(Axis)+Column_Data.d(Story,Axis)/2+Beam_Data.LRBS(Floor+1,Bayj), GridY(Floor+1),'or','Markerfacecolor','y');
                else
                    H_Plot.P1=scatter(app.axes1,GridX(Axis)-Column_Data.d(Story,Axis)/2-Beam_Data.LRBS(Floor+1,Bayi), GridY(Floor+1),'or','Markerfacecolor','y');
                end
            end
            
            if strcmp(Element,'Panel Zone Spring')==1
                H_Plot.P1=scatter(app.axes1,GridX(Axis)+Column_Data.d(Story,Axis)/2, GridY(Floor+1)+Beam_Data.d(Floor+1,Bayi)/2,'or','Markerfacecolor','y');
            end
            
            if strcmp(Element,'Column Elastic Element')==1
                H_Plot.P1=plot(app.axes1,[GridX(Axis) GridX(Axis)] , [GridY(Fj)-Beam_Data.d(Fj,Bayi)/2 GridY(Fi)+Beam_Data.d(Fi,Bayi)/2],'-r','Linewidth',2);
                if SpringLoc==1
                    H_Plot.P2=scatter(app.axes1,GridX(Axis) , GridY(Fj)-Beam_Data.d(Fj,Bayi)/2 , 'or','Markerfacecolor','y');
                else
                    H_Plot.P2=scatter(app.axes1,GridX(Axis) , GridY(Fi), 'or','Markerfacecolor','y');
                end
            end
            
            if strcmp(Element,'Beam Elastic Element')==1
                Axisi=Bay; Axisj=Bay+1;
                H_Plot.P1=plot(app.axes1,[GridX(Axisi)+Column_Data.d(Story,Axisi)/2+Beam_Data.LRBS(Floor+1,Bay) GridX(Axisj)-Column_Data.d(Story,Axisj)/2-Beam_Data.LRBS(Floor+1,Bay)] , [GridY(Floor+1) GridY(Floor+1)],'-r','Linewidth',2);
                if SpringLoc==1
                    H_Plot.P2=scatter(app.axes1,GridX(Axisi)+Column_Data.d(Story,Axisi)/2+Beam_Data.LRBS(Floor+1,Bay) , GridY(Floor+1),'or','Markerfacecolor','y');
                else
                    H_Plot.P2=scatter(app.axes1,GridX(Axisj)-Column_Data.d(Story,Axisj)/2-Beam_Data.LRBS(Floor+1,Bay) , GridY(Floor+1),'or','Markerfacecolor','y');
                end
            end
            
            if strcmp(Element,'EGF Connection')==1
                H_Plot.P1=scatter(app.axes1,GridX(NBay+1+Axis), GridY(Floor+1),'or','Markerfacecolor','y');
            end

            if strcmp(Element,'Floor Link')==1
                H_Plot.P1=plot(app.axes1,[GridX(NBay+1)+Column_Data.d(Story,NBay+1)/2 GridX(NBay+2)],[GridY(Floor+1) GridY(Floor+1)],'-or','linewidth',1.5,'Markerfacecolor','k','MarkerSize',3);
            end
            
            if FrameType==2
                if strcmp(Element,'Brace')==1
                    Axis=1;
                    if BraceLayout==1
                        if rem(Story,2)~=0
                            if SpringLoc==1
                                H_Plot.P1=plot(app.axes1,[ GridX(Axis)    (GridX(Axis)+GridX(Axis+1))/2],[GridY(Fi) GridY(Fj)],'-r','linewidth',2);
                            else
                                H_Plot.P1=plot(app.axes1,[ GridX(Axis+1)  (GridX(Axis)+GridX(Axis+1))/2],[GridY(Fi) GridY(Fj)],'-r','linewidth',2);
                            end
                        else
                            if SpringLoc==1
                                H_Plot.P1=plot(app.axes1,[(GridX(Axis)+GridX(Axis+1))/2 GridX(Axis)]  ,[GridY(Fi) GridY(Fj)],'-r','linewidth',2);
                            else
                                H_Plot.P1=plot(app.axes1,[(GridX(Axis)+GridX(Axis+1))/2 GridX(Axis+1)],[GridY(Fi) GridY(Fj)],'-r','linewidth',2);
                            end
                        end
                    else
                        if SpringLoc==1
                            H_Plot.P1=plot(app.axes1,[ GridX(Axis)    (GridX(Axis)+GridX(Axis+1))/2],[GridY(Fi) GridY(Fj)],'-r','linewidth',2);
                        else
                            H_Plot.P1=plot(app.axes1,[ GridX(Axis+1)  (GridX(Axis)+GridX(Axis+1))/2],[GridY(Fi) GridY(Fj)],'-r','linewidth',2);
                        end
                    end
                end
            end
            
            drawnow
        end
        
        
        function [X, Y]=Plot_Response(app)
            global MainDirectory ProjectName ProjectPath Units 
            clc;
            load(strcat(ProjectPath,ProjectName),'NStory','NBay','Filename','PO','EQ','ELF','GM','FrameType','BRACES', 'MF_COLUMNS', 'MF_BEAMS','fy','RFpath','HStory','Uncertainty','RESULTS')
                        
            GM_No        = app.MainApp.edit1.Value;
            Ri           = app.MainApp.editRi.Value;
            Element      = app.Elementpopup.Items{app.Elementpopup.Value};
            SpringLoc    = app.Locpopup.Value;
            PlotType     = app.PlotTypepopup.Value;
            Floor        = app.Yaxispopup.Value;
            Story        = app.Yaxispopup.Value;
            Axis         = app.Xaxispopup.Value;
            Bay          = app.Xaxispopup.Value;
            
            if PO==1;                       SubRFname='Pushover';                         end
            if ELF==1;                      SubRFname='ELF';                              end
            if EQ==1 && Uncertainty==0;     SubRFname = GM.Name{GM_No};                   end
            if EQ==1 && Uncertainty==1;     SubRFname = [GM.Name{GM_No},'_',num2str(Ri)]; end
            
            try
                [Column_Data, Beam_Data, Brace_Data] = Get_Member_Data            (NStory, NBay, FrameType, MF_COLUMNS, MF_BEAMS, BRACES, Units, fy);
                [yRootName, xRootName, Sub_RootName] = Plot_Local_Get_RootName    (Element, PlotType, Floor, Axis, SpringLoc, Filename);
                [XLabel, YLabel]                     = Plot_Local_Get_Labels      (Element, PlotType, Units);
                [DataColIndx]                        = Plot_Local_Get_DataColIndx (Element, PlotType, SpringLoc);
                %[X, Y]                               = Plot_Local_Get_XYdata      (Element, PlotType, Story, Floor, Axis, Bay, yRootName, xRootName, Sub_RootName, DataColIndx, RFpath, SubRFname, Brace_Data, HStory);
                [X, Y]=get_local_XYdata(RESULTS, Element, PlotType, Story, Floor, Axis, Bay, yRootName, xRootName, Sub_RootName, DataColIndx, Brace_Data, HStory);
                
                 % Plot
                cla(app.axes2);
                hold(app.axes2,"on");
                plot (app.axes2,X,Y, '-k','linewidth',1);
                app.axes2.XLabel.String=XLabel;
                app.axes2.YLabel.String=YLabel;
                app.axes2.XLim=[-max(abs(X))*1.2 max(abs(X))*1.2];
                app.axes2.YLim=[-max(abs(Y))*1.2 max(abs(Y))*1.2];
                plot (app.axes2,[-max(abs(X))*1.5 max(abs(X))*1.5],[0 0], '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
                plot (app.axes2,[0 0],[-max(abs(Y))*1.5 max(abs(Y))*1.5], '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
                chi=get(app.axes2, 'Children');
                set(app.axes2, 'Children',flipud(chi));
                app.axes2.FontName='Times';
                app.axes2.FontSize=18;
            catch ME
                cla(app.axes2);
                app.axes2.XLim=[0 1];
                app.axes2.YLim=[0 1];
                text(app.axes2,0.1,0.5,'Error generating plot!','FontSize',24,'Color','r','FontName','Times');
                cd(MainDirectory)
                write_Error(ME);
            end
            
        end
        
        function UpdateLocPoup(app)
            global NStory NBay
            clc
            Element=app.Elementpopup.Items{app.Elementpopup.Value};
            if strcmp(Element,'Column Spring')==1
                Loc=app.Yaxispopup.Value;
                if Loc~=1 && Loc~=NStory+1
                    Z{1}='Top spring';
                    Z{2}='Bottom spring';
                elseif Loc==1
                    Z{1}='Top spring';
                elseif Loc==NStory+1
                    Z{1}='Bottom spring';
                end
            elseif strcmp(Element,'Beam Spring')==1
                Loc=app.Xaxispopup.Value;
                if Loc~=1 && Loc~=NBay+1
                    Z{1}='Left spring';
                    Z{2}='Right spring';
                elseif Loc==1
                    Z{1}='Right spring';
                elseif Loc==NBay+1
                    Z{1}='Left spring';
                end
            elseif strcmp(Element,'EGF Connection')==1
                Loc=app.Xaxispopup.Value;
                if Loc==1
                    Z{1}='Right spring';
                else
                    Z{1}='Left spring';
                end
            elseif strcmp(Element,'Panel Zone Spring')==1 || strcmp(Element,'Floor Link')==1
                Z{1}='--';
            elseif strcmp(Element,'Column Elastic Element')==1
                Z{1}='Top end';
                Z{2}='Bottom end';
            elseif strcmp(Element,'Beam Elastic Element')==1
                Z{1}='Left end';
                Z{2}='Right end';
            elseif strcmp(Element,'Brace')==1
                Z{1}='Left Brace';
                Z{2}='Right Brace';
            end
            app.Locpopup.Items=Z;
            app.Locpopup.ItemsData=1:length(Z);
            app.Locpopup.Value=1;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Visualize_Local_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global ProjectName ProjectPath NStory NBay
            clc;
            load(strcat(ProjectPath,ProjectName),'Recorders','NStory','NBay','PO','ELF','EQ','GM','SubRFname');
            
            Plot_Frame(app);
            
            nRecorders=0;
            if Recorders.ColSpring==1;      Z{nRecorders+1}='Column Spring';            nRecorders=nRecorders+1; end
            if Recorders.BeamSpring==1;     Z{nRecorders+1}='Beam Spring';              nRecorders=nRecorders+1; end
            if Recorders.FloorLink==1;      Z{nRecorders+1}='Floor Link';               nRecorders=nRecorders+1; end
            if Recorders.PZ==1;             Z{nRecorders+1}='Panel Zone Spring';        nRecorders=nRecorders+1; end
            if Recorders.Column==1;         Z{nRecorders+1}='Column Elastic Element';   nRecorders=nRecorders+1; end
            if Recorders.Beam==1;           Z{nRecorders+1}='Beam Elastic Element';     nRecorders=nRecorders+1; end
            if Recorders.Brace==1;          Z{nRecorders+1}='Brace';                    nRecorders=nRecorders+1; end
            if Recorders.EGFconnection==1;  Z{nRecorders+1}='EGF Connection';           nRecorders=nRecorders+1; end
            
            app.Elementpopup.Items=Z;
            app.Elementpopup.ItemsData=1:nRecorders;
            app.Elementpopup.Value=1;
            Elementpopup_Callback(app)

        end

        % Value changed function: Elementpopup
        function Elementpopup_Callback(app, event)
            global NStory NBay
            clc
            
            % Construct the Yaxispopup elements
            Element=app.Elementpopup.Items{app.Elementpopup.Value};
            if strcmp(Element,'Column Spring')==1
                app.text4.Text='Floor';
                Y{1}='Ground';
                for i=2:NStory+1
                    Y{i}=['Floor ',num2str(i)];
                end
                Y{end}='Roof';
            elseif strcmp(Element,'Beam Spring')==1 || strcmp(Element,'Panel Zone Spring')==1 || strcmp(Element,'Beam Elastic Element')==1 || strcmp(Element,'Floor Link')==1 || strcmp(Element,'EGF Connection')==1
                app.text4.Text='Floor';
                for i=2:NStory+1
                    Y{i-1}=['Floor ',num2str(i)];
                end
                Y{end}='Roof';
            elseif strcmp(Element,'Column Elastic Element')==1
                app.text4.Text='Story';
                Y{1}='Story 1';
                for i=2:NStory
                    Y{i}=['Story ',num2str(i)];
                end
            elseif strcmp(Element,'Brace')==1
                app.text4.Text='Story';
                Y{1}='Story 1';
                for i=2:NStory
                    Y{i}=['Story ',num2str(i)];
                end
            else
                app.text4.Text='Floor';
            end
            app.Yaxispopup.Items=Y;
            app.Yaxispopup.ItemsData=1:length(Y);
            app.Yaxispopup.Value=1;
            
            % Construct the Xaxispopup elements
            if strcmp(Element,'Column Spring')==1 || strcmp(Element,'Beam Spring')==1|| strcmp(Element,'Panel Zone Spring')==1 || strcmp(Element,'Column Elastic Element')==1
                app.text5.Text='Axis';
                X{1}='Axis 1';
                for i=2:NBay+1
                    X{i}=['Axis ',num2str(i)];
                end
            elseif strcmp(Element,'Beam Elastic Element')==1 || strcmp(Element,'Brace')==1
                app.text5.Text='Bay';
                X{1}='Bay 1';
                for i=2:NBay
                    X{i}=['Bay ',num2str(i)];
                end
            elseif strcmp(Element,'EGF Connection')==1
                app.text5.Text='Bay';
                X{1}=['Bay ',num2str(NBay+2)];
                X{2}=['Bay ',num2str(NBay+3)];
            elseif strcmp(Element,'Floor Link')==1
                app.text5.Text='Bay';
                X{1}=['Bay ',num2str(NBay+1)];
            end
            app.Xaxispopup.Items=X;
            app.Xaxispopup.ItemsData=1:length(X);
            app.Xaxispopup.Value=1;
            
            Xaxispopup_Callback(app);
            % PlotTypepopup_Callback(app)
            
        end

        % Value changed function: Locpopup
        function Locpopup_Callback(app, event)
            HighlightElement(app);
            Plot_Response(app);
        end

        % Value changed function: PlotTypepopup
        function PlotTypepopup_Callback(app, event)
            global MainDirectory
            
            cd(MainDirectory)
            Element=app.Elementpopup.Items{app.Elementpopup.Value};
            if strcmp(Element,'Column Spring')==1
                Z{1}='Moment-Rotation';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1;
            elseif strcmp(Element,'Beam Spring')==1
                Z{1}='Moment-Rotation';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1;
            elseif strcmp(Element,'EGF Connection')==1
                Z{1}='Moment-Rotation';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1;
            elseif strcmp(Element,'Panel Zone Spring')==1
                Z{1}='Moment-Shear Distortion';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1;
            elseif strcmp(Element,'Column Elastic Element')==1 || strcmp(app.Elementpopup.Items{app.Elementpopup.Value},'Beam Elastic Element')==1
                Z{1}='Axial Force-SDR1';
                Z{2}='Shear Force-SDR1';
                Z{3}='Moment-SDR1';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1:3;
            elseif strcmp(Element,'Brace')==1
                Z{1}='Axial Force-SDRi';
                Z{2}='Axial Force-SDR1';
                Z{3}='Axial Force-Axial Disp';
                Z{4}='Norm. Axial Force-Axial Disp';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1:4;
            elseif strcmp(Element,'Floor Link')==1
                Z{1}='Axial Force-SDR1';
                Z{2}='Axial Force-Axial Disp';
                app.PlotTypepopup.Items=Z;
                app.PlotTypepopup.ItemsData=1:2;
            end
            HighlightElement(app);
            Plot_Response(app);
        end

        % Value changed function: Xaxispopup
        function Xaxispopup_Callback(app, event)
            UpdateLocPoup (app);
            HighlightElement(app);
            Plot_Response(app);
        end

        % Value changed function: Yaxispopup
        function Yaxispopup_Callback(app, event)
            UpdateLocPoup (app);
            Locpopup_Callback(app);
            Plot_Response(app);
        end

        % Button pushed function: SavedataButton
        function SavedataButtonPushed(app, event)
            global ProjectPath ProjectName
            load(strcat(ProjectPath,ProjectName),'RFpath');
            
            Element  = app.Elementpopup.Items{app.Elementpopup.Value};
            PlotType = app.PlotTypepopup.Items{app.PlotTypepopup.Value};

            TextFilename=[Element,'-',PlotType,'.txt'];
            SavePath=[RFpath,'\Results'];
            
            [X, Y]=Plot_Response(app);
            
            write2file_PlotData(SavePath, TextFilename,X,Y);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Visualize Local Response';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Element';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Plot type';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Location';
            app.Elementpopup = uidropdown(app.figure1);
            app.Elementpopup.Items = {'Column Spring', 'Beam Spring', 'Panel Zone Spring', 'Column Elastic Element', 'Beam Elastic Element', 'EGF Connection', 'Floor Link', 'Brace'};
            app.Elementpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.Elementpopup.ValueChangedFcn = createCallbackFcn(app, @Elementpopup_Callback, true);
            app.Elementpopup.Tag = 'Elementpopup';
            app.Elementpopup.Value = '1';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Floor';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Axis';
            app.Yaxispopup = uidropdown(app.figure1);
            app.Yaxispopup.Items = {'Story 1'};
            app.Yaxispopup.ValueChangedFcn = createCallbackFcn(app, @Yaxispopup_Callback, true);
            app.Yaxispopup.Tag = 'Yaxispopup';
            app.Yaxispopup.Value = 'Story 1';
            app.Xaxispopup = uidropdown(app.figure1);
            app.Xaxispopup.Items = {'Axis 1'};
            app.Xaxispopup.ValueChangedFcn = createCallbackFcn(app, @Xaxispopup_Callback, true);
            app.Xaxispopup.Tag = 'Xaxispopup';
            app.Xaxispopup.Value = 'Axis 1';
            app.Locpopup = uidropdown(app.figure1);
            app.Locpopup.Items = {'Top', 'Bottom'};
            app.Locpopup.ValueChangedFcn = createCallbackFcn(app, @Locpopup_Callback, true);
            app.Locpopup.Tag = 'Locpopup';
            app.Locpopup.Value = 'Top';
            app.PlotTypepopup = uidropdown(app.figure1);
            app.PlotTypepopup.Items = {'Axial Load-Rotation', 'Shear Force-Rotation', 'Moment-Rotation'};
            app.PlotTypepopup.ItemsData = {'1', '2', '3'};
            app.PlotTypepopup.ValueChangedFcn = createCallbackFcn(app, @PlotTypepopup_Callback, true);
            app.PlotTypepopup.Tag = 'PlotTypepopup';
            app.PlotTypepopup.Value = '1';
            app.SavedataButton = uibutton(app.figure1, 'push');
            app.SavedataButton.ButtonPushedFcn = createCallbackFcn(app, @SavedataButtonPushed, true);
            app.SavedataButton.Text = 'Save data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_Local_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Visualize_Local_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end