classdef Visualize_Local_PH_Progression_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1  matlab.ui.Figure
        CB2      matlab.ui.control.CheckBox
        CB1      matlab.ui.control.CheckBox
        Image    matlab.ui.control.Image
        label1   matlab.ui.control.Label
        Spinner  matlab.ui.control.Spinner
        text4_2  matlab.ui.control.Label
        slider   matlab.ui.control.Slider
        axes2    matlab.ui.control.UIAxes
        axes1    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        MainApp;
    end

    methods (Access = private)
        function HighlightSprings(app)
            global  ProjectName ProjectPath H_Plot  Column_Data Beam_Data Brace_Data bsf_X bsf_Y p1 p2 p3 p4
            clc;
            load(strcat(ProjectPath,ProjectName))

            try
                delete (H_Plot.P1);
            end
            cla(app.axes1);

            if app.CB1.Value==1
                [~,indxmax]=max(abs(bsf_X));
                app.slider.Value=indxmax;
                app.Spinner.Value=indxmax;
            end

            if app.CB2.Value==1
                [~,indxmax]=max(abs(bsf_Y));
                app.slider.Value=indxmax;
                app.Spinner.Value=indxmax;
            end

            Ti = app.slider.Value;

            dispfloor(1)=0;
            for Fi = 2:NStory+1
                evalc(['fname=','''','Disp',num2str(Fi),'_MF','''']);
                dispfloor(Fi)=RESULTS.(char(fname))(Ti);
            end

            Plot_Frame_Deformed(app, dispfloor);

            DataColIndx=3;
            convfactor=1/1000;

            str1=['SDR1 = ', num2str(RESULTS.SDR1_MF(Ti)*100),'% rads'];
            app.label1.Text = str1;
            % str2=['RD = ', 'num2str(RESULTS.DISP',num2str(NStory+1),'_MF(Ti)*100)','% rads'];

            RootName = 'ColSpring';
            for Floor = 1:NStory+1
                Story=max(NStory,Floor-1);

                for Axis = 1:NBay+1
                    Bayi = max(1,Axis-1); Bayj = Axis;

                    if Floor==1
                        Sub_RootName = 'T';
                        evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_F','''']);
                        evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_D','''']);
                        X=RESULTS.(char(D_Data))(:,3)*100;
                        Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                        slope=abs(diff((Y))./diff((X)));                        
                        indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                        [maxF,indxmaxF]=max(abs(Y));
                        if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  abs(Y(indxmaxF+1))<abs(Y(indxmaxF)); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                        %indxC= min(find(slope(~isinf(slope))<0));
                        if isempty(indxP); indxP=length(Y); end
                        if isempty(indxC); indxC=length(Y);     end

                        if Ti < indxP
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)+0.05*HStory,'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                        elseif Ti >= indxP &&  Ti < indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)+0.05*HStory,'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                        elseif Ti > indxC 
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)+0.05*HStory,'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                        end

                    elseif  Floor==NStory+1
                        Sub_RootName = 'B';
                        evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_F','''']);
                        evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_D','''']);
                        X=RESULTS.(char(D_Data))(:,3)*100;
                        Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                        slope=abs(diff((Y))./diff((X)));                        
                        indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                        [maxF,indxmaxF]=max(abs(Y));
                        if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  abs(Y(indxmaxF+1))<abs(Y(indxmaxF)); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                        %indxC= min(find(slope(~isinf(slope))<0));
                        if isempty(indxP); indxP=length(Y); end
                        if isempty(indxC); indxC=length(Y);     end

                        if Ti < indxP
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)-Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                        elseif Ti >= indxP &&  Ti < indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)-Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                        elseif Ti > indxC 
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)-Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);                            
                        end
                    else
                        Sub_RootName = 'T';
                        evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_F','''']);
                        evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_D','''']);
                        X=RESULTS.(char(D_Data))(:,3)*100;
                        Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                        slope=abs(diff((Y))./diff((X)));                        
                        indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                        [maxF,indxmaxF]=max(abs(Y));
                        if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  abs(Y(indxmaxF+1))<abs(Y(indxmaxF)); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                        %indxC= min(find(slope(~isinf(slope))<0));
                        if isempty(indxP); indxP=length(Y); end
                        if isempty(indxC); indxC=length(Y);     end

                        if Ti < indxP
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)+Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                        elseif Ti >= indxP &&  Ti < indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)+Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                        elseif Ti > indxC 
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)+Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                        end

                        Sub_RootName = 'B';
                        evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_F','''']);
                        evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_D','''']);
                        X=RESULTS.(char(D_Data))(:,3)*100;
                        Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                        slope=abs(diff((Y))./diff((X)));                        
                        indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                        [maxF,indxmaxF]=max(abs(Y));
                        if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  abs(Y(indxmaxF+1))<abs(Y(indxmaxF)); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                        %indxC= min(find(slope(~isinf(slope))<0));
                        if isempty(indxP); indxP=length(Y); end
                        if isempty(indxC); indxC=length(Y);     end

                        if Ti < indxP
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)-Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                        elseif Ti >= indxP &&  Ti < indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)-Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                        elseif Ti > indxC 
                            H_Plot.P1=plot(app.axes1,GridX(Axis)+dispfloor(Floor), GridY(Floor)-Beam_Data.d(Floor,1),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                        end

                    end

                end
            end

            RootName = 'BeamSpring';
            for Floor = 2:NStory+1
                Story=Floor-1;
                for Axis = 1:NBay+1
                    Bayi = max(1,Axis-1); Bayj = min(NBay,Axis+1);

                    if Axis==NBay+1

                        Sub_RootName = 'L';
                        evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_F','''']);
                        evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_D','''']);
                        X=RESULTS.(char(D_Data))(:,3)*100;
                        Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                        slope=abs(diff((Y))./diff((X)));                        
                        indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                        [maxF,indxmaxF]=max(abs(Y));
                        if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  abs(Y(indxmaxF+1))<abs(Y(indxmaxF)); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                        %indxC= min(find(slope(~isinf(slope))<0));
                        if isempty(indxP); indxP=length(Y); end
                        if isempty(indxC); indxC=length(Y);     end


                        if Ti < indxP
                            H_Plot.P1=plot(app.axes1,GridX(Axis)-Column_Data.d(Story,Axis)-Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                        elseif Ti >= indxP &&  Ti < indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)-Column_Data.d(Story,Axis)-Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                        elseif Ti > indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)-Column_Data.d(Story,Axis)-Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                        end

                    elseif  Axis==1

                        Sub_RootName = 'R';
                        evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_F','''']);
                        evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_D','''']);
                        X=RESULTS.(char(D_Data))(:,3)*100;
                        Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                        slope=abs(diff((Y))./diff((X)));                        
                        indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                        [maxF,indxmaxF]=max(abs(Y));
                        if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  abs(Y(indxmaxF+1))<abs(Y(indxmaxF)); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                        %indxC= min(find(slope(~isinf(slope))<0));
                        if isempty(indxP); indxP=length(Y); end
                        if isempty(indxC); indxC=length(Y);     end


                        if Ti < indxP
                            H_Plot.P1=plot(app.axes1,GridX(Axis)  +Column_Data.d(Story,Axis)+Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                        elseif Ti >= indxP &&  Ti < indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)  +Column_Data.d(Story,Axis)+Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                        elseif Ti > indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)  +Column_Data.d(Story,Axis)+Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                        end

                    else

                        Sub_RootName = 'L';
                        evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_F','''']);
                        evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_D','''']);
                        X=RESULTS.(char(D_Data))(:,3)*100;
                        Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                        slope=abs(diff((Y))./diff((X)));                        
                        indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                        [maxF,indxmaxF]=max(abs(Y));
                        if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  abs(Y(indxmaxF+1))<abs(Y(indxmaxF)); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                        %indxC= min(find(slope(~isinf(slope))<0));
                        if isempty(indxP); indxP=length(Y); end
                        if isempty(indxC); indxC=length(Y);     end


                        if Ti < indxP
                            H_Plot.P1=plot(app.axes1,GridX(Axis)-Column_Data.d(Story,Axis)-Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                        elseif Ti >= indxP &&  Ti < indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)-Column_Data.d(Story,Axis)-Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                        elseif Ti > indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)-Column_Data.d(Story,Axis)-Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                        end                        
                        
                        Sub_RootName = 'R';
                        evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_F','''']);
                        evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),Sub_RootName,'_D','''']);
                        X=RESULTS.(char(D_Data))(:,3)*100;
                        Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                        slope=abs(diff((Y))./diff((X)));                        
                        indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                        [maxF,indxmaxF]=max(abs(Y));
                        if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  abs(Y(indxmaxF+1))<abs(Y(indxmaxF)); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                        %indxC= min(find(slope(~isinf(slope))<0));
                        if isempty(indxP); indxP=length(Y); end
                        if isempty(indxC); indxC=length(Y);     end


                        if Ti < indxP
                            H_Plot.P1=plot(app.axes1,GridX(Axis)  +Column_Data.d(Story,Axis)+Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                        elseif Ti >= indxP &&  Ti < indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)  +Column_Data.d(Story,Axis)+Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                        elseif Ti > indxC
                            H_Plot.P1=plot(app.axes1,GridX(Axis)  +Column_Data.d(Story,Axis)+Beam_Data.LRBS(Floor,Bayj)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                        end                    
                    end
                end
            end

            RootName = 'PZ';
            for Floor = 2:NStory+1
            if Recorders.PZ==1
                Story=max(NStory,Floor-1);
                for Axis = 1:NBay+1
                    Bayi = max(1,Axis-1); Bayj = Axis;

                    evalc(['F_Data=','''',RootName,num2str(Floor),num2str(Axis),'_F','''']);
                    evalc(['D_Data=','''',RootName,num2str(Floor),num2str(Axis),'_D','''']);
                    X=RESULTS.(char(D_Data))(:,1)*100;
                    Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                    slope=abs(diff((Y))./diff((X)));                        
                    indxP= min(find(slope(~isinf(slope))<0.5*max(slope(~isinf(slope)))));
                    [maxF,indxmaxF]=max(abs(Y));
                    if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  Y(indxmaxF+1)<Y(indxmaxF); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                    %indxC= min(find(slope(~isinf(slope))<0));
                    if isempty(indxP); indxP=length(Y); end
                    if isempty(indxC); indxC=length(Y);     end

                    if Ti < indxP
                        H_Plot.P1=plot(app.axes1,GridX(Axis)+Column_Data.d(Story,Axis)/2+dispfloor(Floor), GridY(Floor)+Beam_Data.d(Floor,Bayi)/2,'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                    elseif Ti >= indxP &&  Ti < indxC
                        H_Plot.P1=plot(app.axes1,GridX(Axis)+Column_Data.d(Story,Axis)/2+dispfloor(Floor), GridY(Floor)+Beam_Data.d(Floor,Bayi)/2,'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                    elseif Ti > indxC
                        H_Plot.P1=plot(app.axes1,GridX(Axis)+Column_Data.d(Story,Axis)/2+dispfloor(Floor), GridY(Floor)+Beam_Data.d(Floor,Bayi)/2,'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                    end  
                end
            else
                        H_Plot.P1=plot(app.axes1,GridX(Axis)+Column_Data.d(Story,Axis)/2+dispfloor(Floor), GridY(Floor)+Beam_Data.d(Floor,Bayi)/2,'o','Markeredgecolor',[0 0 0],'Markerfacecolor',[0.6 0.6 0.6],'MarkerSize',5);
            end
            end


            RootName = 'EGFconnection';

            for Floor = 2:NStory+1

            if Recorders.EGFconnection==1
                Sub_RootName = 'R';
                evalc(['F_Data=','''',RootName,num2str(Floor),num2str(NBay+2),Sub_RootName,'_F','''']);
                evalc(['D_Data=','''',RootName,num2str(Floor),num2str(NBay+2),Sub_RootName,'_D','''']);
                X=RESULTS.(char(D_Data))(:,3)*100;
                Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                slope=abs(diff((Y))./diff((X)));                        
                indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                [maxF,indxmaxF]=max(abs(Y));
                if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  Y(indxmaxF+1)<Y(indxmaxF); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                %indxC= min(find(slope(~isinf(slope))<0));
                if isempty(indxP); indxP=length(Y); end
                if isempty(indxC); indxC=length(Y);     end

                if Ti < indxP
                    H_Plot.P1=plot(app.axes1,GridX(NBay+2)+0.05*WBay(1)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                elseif Ti >= indxP &&  Ti < indxC
                    H_Plot.P1=plot(app.axes1,GridX(NBay+2)+0.05*WBay(1)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                elseif Ti > indxC
                    H_Plot.P1=plot(app.axes1,GridX(NBay+2)+0.05*WBay(1)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                end


                Sub_RootName = 'L';
                evalc(['F_Data=','''',RootName,num2str(Floor),num2str(NBay+3),Sub_RootName,'_F','''']);
                evalc(['D_Data=','''',RootName,num2str(Floor),num2str(NBay+3),Sub_RootName,'_D','''']);
                X=RESULTS.(char(D_Data))(:,3)*100;
                Y=RESULTS.(char(F_Data))(:,DataColIndx)*convfactor;

                slope=abs(diff((Y))./diff((X)));                        
                indxP= min(find(slope(~isinf(slope))<0.2*max(slope(~isinf(slope)))));
                [maxF,indxmaxF]=max(abs(Y));
                if indxmaxF~=length(Y); if abs(X(indxmaxF+1))>abs(X(indxmaxF)) &&  Y(indxmaxF+1)<Y(indxmaxF); indxC=indxmaxF; else; indxC=length(Y);     end; else; indxC=length(Y);     end
                %indxC= min(find(slope(~isinf(slope))<0));
                if isempty(indxP); indxP=length(Y); end
                if isempty(indxC); indxC=length(Y);     end

                if Ti < indxP
                    H_Plot.P1=plot(app.axes1,GridX(NBay+3)-0.05*WBay(1)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.4660 0.6740 0.1880],'Markerfacecolor','g','MarkerSize',5);
                elseif Ti >= indxP &&  Ti < indxC
                    H_Plot.P1=plot(app.axes1,GridX(NBay+3)-0.05*WBay(1)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.8500 0.3250 0.0980],'Markerfacecolor',[0.9290 0.6940 0.1250],'MarkerSize',5);
                elseif Ti > indxC
                    H_Plot.P1=plot(app.axes1,GridX(NBay+3)-0.05*WBay(1)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0.6350 0.0780 0.1840],'Markerfacecolor','r','MarkerSize',5);
                end
            else
                    H_Plot.P1=plot(app.axes1,GridX(NBay+2)+0.05*WBay(1)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0 0 0],'Markerfacecolor',[0.6 0.6 0.6],'MarkerSize',5);
                    H_Plot.P1=plot(app.axes1,GridX(NBay+3)-0.05*WBay(1)+dispfloor(Floor), GridY(Floor),'o','Markeredgecolor',[0 0 0],'Markerfacecolor',[0.6 0.6 0.6],'MarkerSize',5);

            end

            end

            drawnow


                hold(app.axes2,"on");
                delete(p1);delete(p2);delete(p3);delete(p4); 
                p1=xline (app.axes2, bsf_X(Ti), '--k');
                p2=plot (app.axes2, bsf_X(Ti), bsf_Y(Ti), 'ok','MarkerFaceColor','r');
                if EQ==1
                    p3=yline (app.axes2, 0, '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
                    p4=xline (app.axes2, 0 , '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
                end
        end


        function [X, Y]=Plot_Response(app)
            global MainDirectory ProjectName ProjectPath Units
            clc;
            load(strcat(ProjectPath,ProjectName),'NStory','NBay','Filename','PO','EQ','ELF','GM','FrameType','BRACES', 'MF_COLUMNS', 'MF_BEAMS','fy','RFpath','HStory','Uncertainty')

            GM_No        = app.MainApp.edit1.Value;
            Ri           = app.MainApp.editRi.Value;
            Element      = app.Elementpopup.Items{app.Elementpopup.Value};
            SpringLoc    = app.Locpopup.Value;
            PlotType     = app.PlotTypepopup.Value;
            Floor        = app.Yaxispopup.Value;
            Story        = app.Yaxispopup.Value;
            Axis         = app.Xaxispopup.Value;
            Bay          = app.Xaxispopup.Value;

            if PO==1;                       SubRFname='Pushover';                         end
            if ELF==1;                      SubRFname='ELF';                              end
            if EQ==1 && Uncertainty==0;     SubRFname = GM.Name{GM_No};                   end
            if EQ==1 && Uncertainty==1;     SubRFname = [GM.Name{GM_No},'_',num2str(Ri)]; end

            try
                [Column_Data, Beam_Data, Brace_Data] = Get_Member_Data            (NStory, NBay, FrameType, MF_COLUMNS, MF_BEAMS, BRACES, Units, fy);
                [yRootName, xRootName, Sub_RootName] = Plot_Local_Get_RootName    (Element, PlotType, Floor, Axis, SpringLoc, Filename);
                [XLabel, YLabel]                     = Plot_Local_Get_Labels      (Element, PlotType, Units);
                [DataColIndx]                        = Plot_Local_Get_DataColIndx (Element, PlotType, SpringLoc);
                [X, Y]                               = Plot_Local_Get_XYdata      (Element, PlotType, Story, Floor, Axis, Bay, yRootName, xRootName, Sub_RootName, DataColIndx, RFpath, SubRFname, Brace_Data, HStory);

                % Plot
                cla(app.axes2);
                hold(app.axes2,"on");
                plot (app.axes2,X,Y, '-k','linewidth',1);
                app.axes2.XLabel.String=XLabel;
                app.axes2.YLabel.String=YLabel;
                app.axes2.XLim=[-max(abs(X))*1.2 max(abs(X))*1.2];
                app.axes2.YLim=[-max(abs(Y))*1.2 max(abs(Y))*1.2];
                plot (app.axes2,[-max(abs(X))*1.5 max(abs(X))*1.5],[0 0], '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
                plot (app.axes2,[0 0],[-max(abs(Y))*1.5 max(abs(Y))*1.5], '-','linewidth',0.5,'Color',[0.6 0.6 0.6]);
                chi=get(app.axes2, 'Children');
                set(app.axes2, 'Children',flipud(chi));
                app.axes2.FontName='Times';
                app.axes2.FontSize=18;
            catch ME
                cla(app.axes2);
                app.axes2.XLim=[0 1];
                app.axes2.YLim=[0 1];
                text(app.axes2,0.1,0.5,'Error generating plot!','FontSize',24,'Color','r','FontName','Times');
                cd(MainDirectory)
                write_Error(ME);
            end

        end

        function UpdateLocPoup(app)
            global NStory NBay
            clc
            Element=app.Elementpopup.Items{app.Elementpopup.Value};
            if strcmp(Element,'Column Spring')==1
                Loc=app.Yaxispopup.Value;
                if Loc~=1 && Loc~=NStory+1
                    Z{1}='Top spring';
                    Z{2}='Bottom spring';
                elseif Loc==1
                    Z{1}='Top spring';
                elseif Loc==NStory+1
                    Z{1}='Bottom spring';
                end
            elseif strcmp(Element,'Beam Spring')==1
                Loc=app.Xaxispopup.Value;
                if Loc~=1 && Loc~=NBay+1
                    Z{1}='Left spring';
                    Z{2}='Right spring';
                elseif Loc==1
                    Z{1}='Right spring';
                elseif Loc==NBay+1
                    Z{1}='Left spring';
                end
            elseif strcmp(Element,'EGF Connection')==1
                Loc=app.Xaxispopup.Value;
                if Loc==1
                    Z{1}='Right spring';
                else
                    Z{1}='Left spring';
                end
            elseif strcmp(Element,'Panel Zone Spring')==1 || strcmp(Element,'Floor Link')==1
                Z{1}='--';
            elseif strcmp(Element,'Column Elastic Element')==1
                Z{1}='Top end';
                Z{2}='Bottom end';
            elseif strcmp(Element,'Beam Elastic Element')==1
                Z{1}='Left end';
                Z{2}='Right end';
            elseif strcmp(Element,'Brace')==1
                Z{1}='Left Brace';
                Z{2}='Right Brace';
            end
            app.Locpopup.Items=Z;
            app.Locpopup.ItemsData=1:length(Z);
            app.Locpopup.Value=1;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Visualize_Local_OpeningFcn(app, mainapp)
            global MainDirectory ProjectName ProjectPath NStory NBay Column_Data Beam_Data Brace_Data bsf_X bsf_Y

            app.MainApp=mainapp;

            GMi=app.MainApp.edit1.Value;
            
            clc;
            load(strcat(ProjectPath,ProjectName),'FrameType','Recorders','NStory','NBay','PO','ELF','EQ','GM','RFpath','MF_COLUMNS', 'MF_BEAMS', 'BRACES',  'Units', 'fy','Uncertainty','RESULTS','SubRFname');

            if Recorders.ColSpring~=1 || Recorders.BeamSpring~=1
                errordlg('Data recorders for the beam and column springs must be activated before running analysis!')
                return
            end
            
            if EQ==1
                app.figure1.Name=['Plastic Hinge Progression: GM#',num2str(GMi)];
            elseif PO==1
                app.figure1.Name=['Plastic Hinge Progression: Pushover'];
            end

            [Column_Data, Beam_Data, Brace_Data] = Get_Member_Data            (NStory, NBay, FrameType, MF_COLUMNS, MF_BEAMS, BRACES, Units, fy);

            app.slider.Limits=[1 size(RESULTS.Time,1)];
            xx3=0:max(100,round(size(RESULTS.Time,1)/10)):size(RESULTS.Time,1); xx3(1,1)=1;
            app.slider.MajorTicks=xx3;
            app.slider.Value=ceil(size(RESULTS.Time,1)/2);
            app.Spinner.Value=ceil(size(RESULTS.Time,1)/2);
            app.Spinner.Limits=[1 size(RESULTS.Time,1)];


            GM_No        = app.MainApp.edit1.Value;
            Ri           = app.MainApp.editRi.Value;

            [bsf_X,bsf_Y]=Plot_BSF_vs_SDR1(GM_No, Ri, 0);
            cla(app.axes2);
            hold(app.axes2,"on");
            plot (app.axes2,bsf_X,bsf_Y, '-k','linewidth',1);
            app.axes2.XLabel.String='\itSDR\rm_1 [% rad]';
            app.axes2.YLabel.String='\itBSF\rm /\itW_s';
            % resetplotview(app.axes2)

            Plot_Frame(app);

            HighlightSprings(app);

        end

        % Value changed function: slider
        function sliderValueChanged(app, event)
            Ti=round(app.slider.Value);
            app.slider.Value=Ti;
            app.Spinner.Value=Ti;
            HighlightSprings(app);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            Ti=app.Spinner.Value;
            app.slider.Value=Ti;
            HighlightSprings(app);
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            if app.CB1.Value==1; app.CB2.Value=0; end 
            HighlightSprings(app);
        end

        % Value changed function: CB2
        function CB2ValueChanged(app, event)
            if app.CB2.Value==1; app.CB1.Value=0; end 
            HighlightSprings(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Plastic Hinge Progression';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            xlabel(app.axes2, '\itSDR\rm_1 [% rad]')
            ylabel(app.axes2, '\itBSF\rm /\itW_s')
            app.axes2.Tag = 'axes2';
            app.slider = uislider(app.figure1);
            app.slider.Limits = [1 1000000];
            app.slider.MajorTicks = [];
            app.slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.slider.MinorTicks = [];
            app.slider.Value = 1;
            app.text4_2 = uilabel(app.figure1);
            app.text4_2.Text = 'Step:';
            app.Spinner = uispinner(app.figure1);
            app.Spinner.Limits = [1 10000];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.label1 = uilabel(app.figure1);
            app.label1.Text = 'SDR₁ =';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'legend_PH.jpg');
            app.CB1 = uicheckbox(app.figure1);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'Go to peak drift';
            app.CB2 = uicheckbox(app.figure1);
            app.CB2.ValueChangedFcn = createCallbackFcn(app, @CB2ValueChanged, true);
            app.CB2.Text = 'Go to peak strength';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_Local_PH_Progression_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Visualize_Local_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end